classdef ConstraintSettings < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SelectedConstraintsLabel      matlab.ui.control.Label
        UITable2                      matlab.ui.control.Table
        UITable                       matlab.ui.control.Table
        CloseButton                   matlab.ui.control.Button
        SaveConstraintSettingsButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Table One
            tbl = app.UITable;
            tbl.Data = cell(1,3);
            tbl.Data{1,1} = evalin('base', 'mcr_als.alsOptions.nComponents');
            if evalin('base', 'mcr_als.alsOptions.weighted.constrained') == 0
                tbl.Data(1,2) = {char('no')};
            else
                tbl.Data(1,2) = {char('yes')};
            end
            tbl.Data{1,3} = evalin('base', 'mcr_als.InitEstim.method');

            % Table Two
            tbl = app.UITable2;
            tbl.Data = cell(7,2);
            % Non-negativity?
            tbl.Data(1,:) = {evalin('base','mcr_als.alsOptions.nonegC.noneg'), evalin('base','mcr_als.alsOptions.nonegS.noneg')};
            % Unimmodal? 
            tbl.Data(2,:) = {evalin('base','mcr_als.alsOptions.unimodC.unimodal'), evalin('base','mcr_als.alsOptions.unimodS.unimodal')};
            % Closure?
            tbl.Data(3,:) = {evalin('base', 'mcr_als.alsOptions.closure.type'), 'N/A'};
            % Equality
            tbl.Data(4,:) = {evalin('base', 'mcr_als.alsOptions.cselcC.cselcon'), evalin('base', 'mcr_als.alsOptions.sselcS.sselcon')};
            % Correlation
            tbl.Data(5,:) = {evalin('base', 'mcr_als.alsOptions.correlation.appCorrelation'), 'N/A'};
            % Kinetic HM
            tbl.Data(6,:) = {evalin('base', 'mcr_als.alsOptions.kinetic.appKinetic'), 'N/A'};
            % Trilinearity
            tbl.Data(7,:) = {evalin('base', 'mcr_als.alsOptions.trilin.appTril'), 'N/A'};

            
        end

        % Button pushed function: SaveConstraintSettingsButton
        function push_SaveConstraint(app, event)
            Constraints.nonegC = evalin('base','mcr_als.alsOptions.nonegC'); % Non-negativity
            Constraints.nonegS = evalin('base','mcr_als.alsOptions.nonegS');
            Constraints.unimodC = evalin('base','mcr_als.alsOptions.unimodC'); % Unimodality
            Constraints.unimods = evalin('base','mcr_als.alsOptions.unimodS');
            Constraints.closure = evalin('base','mcr_als.alsOptions.closure'); % Closure Constraint
            Constraints.cselecC = evalin('base','mcr_als.alsOptions.cselcC'); % Equality Constraint
            Constraints.sselcS = evalin('base','mcr_als.alsOptions.sselcS');
            Constraints.trilin = evalin('base','mcr_als.alsOptions.trilin'); % Trilinearity
            Constraints.correlation = evalin('base','mcr_als.alsOptions.correlation'); % Correlation Constraint
            Constraints.kinetic = evalin('base','mcr_als.alsOptions.kinetic'); % Kinetic Constraint
            Constraints.multi = evalin('base','mcr_als.alsOptions.multi'); % Multiway?
            Constraints.opt = evalin('base','mcr_als.alsOptions.opt'); % Options for iterations
            uisave("Constraints")
        end

        % Button pushed function: CloseButton
        function push_close_callback(app, event)
            close(app.UIFigure);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SaveConstraintSettingsButton = uibutton(app.UIFigure, 'push');
            app.SaveConstraintSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @push_SaveConstraint, true);
            app.SaveConstraintSettingsButton.Text = 'Save Constraint Settings';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @push_close_callback, true);
            app.CloseButton.Text = 'Close';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Number of Components'; 'Weighted?'; 'Initial Constraint Method'};
            app.UITable.RowName = {};
            app.UITable2 = uitable(app.UIFigure);
            app.UITable2.ColumnName = {'Concentration Profile?'; 'Spectra Profile?'};
            app.UITable2.RowName = {'Non-Negativity'; 'Unimodality'; 'Closure'; 'Equality'; 'Correlation'; 'Kinetic HM'; 'Trilinearity'};
            app.SelectedConstraintsLabel = uilabel(app.UIFigure);
            app.SelectedConstraintsLabel.Text = 'Selected Constraints';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ConstraintSettings

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end