classdef als2013_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        LoadPreviousConstraintSettingsButton  matlab.ui.control.Button
        popupmenu4   matlab.ui.control.DropDown
        popupmenu3   matlab.ui.control.DropDown
        text5        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        edit3        matlab.ui.control.EditField
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        text1        matlab.ui.control.Label
        loadings     matlab.ui.control.UIAxes
        scores       matlab.ui.control.UIAxes
        spec         matlab.ui.control.UIAxes
        conc         matlab.ui.control.UIAxes
        rows         matlab.ui.control.UIAxes
        columns      matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function push_help_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in push_help.
            
            % hObject    handle to push_help (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            als_info_App;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function als2013_OpeningFcn(app, varargin)
            % --- Executes just before als2013 is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to als2013 (see VARARGIN)
            
            % Choose default command line output for als2013
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);


            %-----ADD OUTPUT FUNCTION-----%

            % --- Executes during object creation, after setting all properties.

            % data initialization from mcr-main
            % ******************************************************************
            matdad=evalin('base','mcr_als.data;');
            assignin('base','matdad',matdad);
            evalin('base','mcr_als.alsOptions.matdad=matdad;');
            evalin('base', 'mcr_als.alsOptions.Predefined=0'); % Set the constraints to not be pre-defined
            
            axes(handles.columns);
            [xi,yi]=size(matdad);
            maxim=max(max(matdad));
            minim=min(min(matdad));
            
            if maxim > 0
                maxim1=maxim+0.2*maxim;
            else
                maxim1=maxim-0.2*abs(maxim);
            end
            
            if minim >= 0
                minim1=minim-0.2*minim;
            else
                minim1=minim-0.2*abs(minim);
            end
            
            plot(matdad);axis([1 xi minim1 maxim1]);title('Columns of raw data matrix');
            zoom on;
            
            axes(handles.rows);
            plot(matdad');axis([1 yi minim1 maxim1]);title('Rows (spectra) of raw data matrix');
            zoom on;
            
            % initial estimation initialization from mcr-main
            % ******************************************************************
            
            iniesta=evalin('base','mcr_als.InitEstim.iniesta;'); % initial estimate
            assignin('base','iniesta',iniesta);
            evalin('base','mcr_als.alsOptions.iniesta=iniesta;');
            matdad=evalin('base','mcr_als.alsOptions.matdad'); % Data matrix            
            
            % check dimensions of matrix and initial estimates
            [nrow,ncol]=size(matdad);
            [nrow2,ncol2]=size(iniesta);
            
            % Determine if if estimate is collumn/concentration or
            % row/Spectra and set nsign to be number of rows/columns of
            % guesses
            if nrow2==nrow,	nsign=ncol2; ils=1;end % if initial estimate is column, ils = 1 (same num of rows)
            if ncol2==nrow, nsign=nrow2; iniesta=iniesta'; ils=1; end % or if initial estimate same length as matix height
            
            if ncol2==ncol, nsign=nrow2; ils=2;end % if initial estimat is a row, ils = 2 (same num of cols)
            if nrow2==ncol, nsign=ncol2; iniesta=iniesta'; ils=2; end % or is heigh same as length of matrix
            
            ils

            if ils==1, % column esimate or concentration
                conc=iniesta;
                assignin('base','conc',conc);
                [nrow,nsign]=size(conc);
                assignin('base','nComponents',nsign);
                evalin('base','mcr_als.alsOptions.nComponents=nComponents;');   
                evalin('base','clear nComponents');
            
                [nsignm,ncolm]=size(matdad);
            
                abss=conc\matdad; % Calculate missing matrix 
            
                assignin('base','abss',abss);
                axes(handles.conc);
            
                [xi,yi]=size(conc);
                maxim=max(max(conc));minim=min(min(conc));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim > 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(conc);axis([1 xi minim1 maxim1]);title('Initial estimates of the concentration profiles');zoom on
                axes(handles.spec);
                [xi,yi]=size(abss);
                maxim=max(max(abss));minim=min(min(abss));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim > 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(abss');axis([1 yi minim1 maxim1]);title('Unconstrained spectra calculated by LS (iter 1)');zoom on
            
                % pca reproduction
            
                disp('   *********** Results obtained after application of PCA to the data matrix ***********');
                disp(' ');
                [u,s,v,d,sd]=pcarep(matdad,nsign);
                disp(' ');
                disp('   ************************************************************************************');
                disp(' ');
                disp(' ');
            
                axes(handles.scores);
                scmat=u*s;
                [xi,yi]=size(scmat);
                maxim=max(max(scmat));minim=min(min(scmat));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim > 0
                    minim1=minim+0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(scmat);axis([1 xi minim1 maxim1]);title('Scores matrix');zoom on
                axes(handles.loadings);
                [xi,yi]=size(v);
                maxim=max(max(v));minim=min(min(v));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim > 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(v);axis([1 xi minim1 maxim1]);
                title('Loadings matrix');zoom on
            
                % modificacio pel pca
            
                assignin('base','u_pca',u);
                evalin('base','mcr_als.alsOptions.pca.u_pca=u_pca;');
            
                assignin('base','s_pca',s);
                evalin('base','mcr_als.alsOptions.pca.s_pca=s_pca;');
            
                assignin('base','v_pca',v);
                evalin('base','mcr_als.alsOptions.pca.v_pca=v_pca;');
            
                assignin('base','d_pca',d);
                evalin('base','mcr_als.alsOptions.pca.d_pca=d_pca;');
            
                assignin('base','sd_pca',sd);
                evalin('base','mcr_als.alsOptions.pca.sd_pca=sd_pca;');
            
                evalin('base','clear u_pca s_pca v_pca d_pca sd_pca');
            end
            
            if ils==2,
                abss=iniesta;
                assignin('base','abss',abss);
                [nsign,ncol]=size(abss);
                assignin('base','nComponents',nsign);
                evalin('base','mcr_als.alsOptions.nComponents=nComponents;');   
                evalin('base','clear nComponents');
            
                conc=matdad/abss;
            
                assignin('base','conc',conc);
                axes(handles.conc);
                [xi,yi]=size(conc);
                maxim=max(max(conc));minim=min(min(conc));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim > 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(conc);axis([1 xi minim1 maxim1]);title('Unconstrained concentration profiles calculated by LS (iter 1)');zoom on
                axes(handles.spec);
                [xi,yi]=size(abss);
                maxim=max(max(abss));minim=min(min(abss));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim > 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(abss');axis([1 yi minim1 maxim1]);title('Initial estimates of the spectra');zoom on
            
                % pca reproduction
            
                disp('   *********** Results obtained after application of PCA to the data matrix ***********');
                disp(' ');
                [u,s,v,d,sd]=pcarep(matdad,nsign);
                disp(' ');
                disp('   ************************************************************************************');
                disp(' ');
                disp(' ');
            
                axes(handles.scores);
                scmat=u*s;
                [xi,yi]=size(scmat);
                maxim=max(max(scmat));minim=min(min(scmat));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim > 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(scmat);axis([1 xi minim1 maxim1]);title('Scores matrix');zoom on
                axes(handles.loadings);
                [xi,yi]=size(v);
                maxim=max(max(v));minim=min(min(v));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim > 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(v);axis([1 xi minim1 maxim1]);
                title('Loadings matrix');zoom on
            
                % pca modification
            
                assignin('base','u_pca',u);
                evalin('base','mcr_als.alsOptions.pca.u_pca=u_pca;');
            
                assignin('base','s_pca',s);
                evalin('base','mcr_als.alsOptions.pca.s_pca=s_pca;');
            
                assignin('base','v_pca',v);
                evalin('base','mcr_als.alsOptions.pca.v_pca=v_pca;');
            
                assignin('base','d_pca',d);
                evalin('base','mcr_als.alsOptions.pca.d_pca=d_pca;');
            
                assignin('base','sd_pca',sd);
                evalin('base','mcr_als.alsOptions.pca.sd_pca=sd_pca;');
            
                evalin('base','clear u_pca s_pca v_pca d_pca sd_pca');
            end


            % popupmenu3_CreateFcn(hObject, eventdata, handles)
            if evalin('base','mcr_als.alsOptions.weighted.constrained;')==1;
                lsv(1)={[evalin('base','mcr_als.aux.data_name;'),' - weight']};
            else
                lsv(1)={evalin('base','mcr_als.aux.data_name;')};
            end
            app.popupmenu3.Items = lsv;

            % popupmenu4_CreateFcn(hObject, eventdata, handles)
            % lsv(1)={'initEstim'};
            lsv(1)={['initEstim',' ','method (',evalin('base','mcr_als.InitEstim.method'),')']};
            app.popupmenu4.Items = lsv;
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit3 as text
            %        str2double(get(hObject,'String')) returns contents of edit3 as a double
            
            nexp=str2num(get(hObject,'String'));
            assignin('base','nexp',nexp);
            evalin('base','mcr_als.alsOptions.nexp=nexp;');
            evalin('base','clear nexp');
            
           
        end

        % Value changed function: popupmenu3
        function popupmenu3_Callback(app, event)
            % --- Executes on selection change in popupmenu3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu3
            
            popmenu3=get(handles.popupmenu3,'String');
            pm3=get(handles.popupmenu3,'Value');
        end

        % Value changed function: popupmenu4
        function popupmenu4_Callback(app, event)
            % --- Executes on selection change in popupmenu4.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu4 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu4
            
            popmenu4=get(handles.popupmenu4,'String');
            pm4=get(handles.popupmenu4,'Value');
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)Predefined
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            nexp=str2double(get(handles.edit3,'String'));
            assignin('base','nexp',nexp);
            evalin('base','mcr_als.alsOptions.nexp=nexp;');
            evalin('base','clear conc abss nexp matdad iniesta');
            
            % *************************************************************************
            % initializations for constraints
            if evalin('base', 'mcr_als.alsOptions.Predefined')==0
                evalin('base','mcr_als.alsOptions.nonegC.noneg=0;');
                evalin('base','mcr_als.alsOptions.nonegS.noneg=0;');
                evalin('base','mcr_als.alsOptions.unimodC.unimodal=0;');
                evalin('base','mcr_als.alsOptions.unimodS.unimodal=0;');
                evalin('base','mcr_als.alsOptions.closure.closure=0;');
                evalin('base','mcr_als.alsOptions.cselcC.cselcon=0;');
                evalin('base','mcr_als.alsOptions.sselcS.sselcon=0;');
                evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
                evalin('base','mcr_als.alsOptions.weighted.appWeight=0;');
                evalin('base','mcr_als.alsOptions.correlation.appCorrelation=0;');
                evalin('base','mcr_als.alsOptions.correlation.checkSNorm=0;');
                evalin('base','mcr_als.alsOptions.kinetic.appKinetic=0;');
                evalin('base','mcr_als.alsOptions.multi.datamod=0;');
                evalin('base','mcr_als.alsOptions.opt.gr=''y'';');

                close;
            
                if evalin('base','mcr_als.alsOptions.nexp') > 1
                    clas3way_App;
                else
                    rowModeConstraints_App;
                end
            else % Skip all the constraint stuff

                close;

                if evalin('base','mcr_als.alsOptions.closure.closure')==0;
                    if evalin('base','mcr_als.alsOptions.correlation.checkSNorm')==0;
                        closure_no_App;
                    else
                        als_parameters_App;
                    end
                else
                    als_parameters_App;
                end
            end
            
            % *************************************************************************
        end

        % Button pushed function: LoadPreviousConstraintSettingsButton
        function push_loadconstraints(app, event)
            [name, path] = uigetfile('*.mat');
            load(strcat(path, name));
            assignin('base', 'Constraints', Constraints);
%             Constraints = Constraints.Constraints;
            
            % Meathod Can Be:
%             assignin('base','vc',vc);
%             evalin('base','mcr_als.alsOptions.closure.vc=vc;');
%             evalin('base','clear vc');

            evalin('base','mcr_als.alsOptions.nonegC=Constraints.nonegC'); % Non-negativity
            evalin('base','mcr_als.alsOptions.nonegS=Constraints.nonegS');
            evalin('base','mcr_als.alsOptions.unimodC=Constraints.unimodC'); % Unimodality
            evalin('base','mcr_als.alsOptions.unimodS=Constraints.unimods');
            evalin('base','mcr_als.alsOptions.closure=Constraints.closure'); % Closure Constraint
            evalin('base','mcr_als.alsOptions.cselcC=Constraints.cselecC'); % Equality Constraint
            evalin('base','mcr_als.alsOptions.sselcS=Constraints.sselcS');
            evalin('base','mcr_als.alsOptions.trilin=Constraints.trilin'); % Trilinearity
            evalin('base','mcr_als.alsOptions.correlation=Constraints.correlation'); % Correlation Constraint
            evalin('base','mcr_als.alsOptions.kinetic=Constraints.kinetic'); % Kinetic Constraint
            evalin('base','mcr_als.alsOptions.multi=Constraints.multi'); % Multiway?
            evalin('base','mcr_als.alsOptions.opt=Constraints.opt'); % Options for iterations
            evalin('base', 'mcr_als.alsOptions.Predefined=1'); % Set the constraints to be pre-defined
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MCR-ALS user friendly interface';
            app.figure1.Tag = 'figure1';
            app.columns = uiaxes(app.figure1);
            app.columns.Tag = 'columns';
            app.rows = uiaxes(app.figure1);
            app.rows.Tag = 'rows';
            app.conc = uiaxes(app.figure1);
            app.conc.Tag = 'conc';
            app.spec = uiaxes(app.figure1);
            app.spec.Tag = 'spec';
            app.scores = uiaxes(app.figure1);
            app.scores.Tag = 'scores';
            app.loadings = uiaxes(app.figure1);
            app.loadings.Tag = 'loadings';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Selection of the data set';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Data matrix';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Initial estimate';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Nr. of matrices';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '1';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Continue';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'MCR GUI connected';
            app.popupmenu3 = uidropdown(app.figure1);
            app.popupmenu3.Items = {'kin_data'};
            app.popupmenu3.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_Callback, true);
            app.popupmenu3.Tag = 'popupmenu3';
            app.popupmenu3.Enable = 'off';
            app.popupmenu3.Value = 'kin_data';
            app.popupmenu4 = uidropdown(app.figure1);
            app.popupmenu4.Items = {'initEstim method (Pur)'};
            app.popupmenu4.ValueChangedFcn = createCallbackFcn(app, @popupmenu4_Callback, true);
            app.popupmenu4.Tag = 'popupmenu4';
            app.popupmenu4.Enable = 'off';
            app.popupmenu4.Value = 'initEstim method (Pur)';
            app.LoadPreviousConstraintSettingsButton = uibutton(app.figure1, 'push');
            app.LoadPreviousConstraintSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @push_loadconstraints, true);
            app.LoadPreviousConstraintSettingsButton.Text = 'Load Previous Constraint Settings';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = als2013_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)als2013_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end