classdef als2013fast_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        text6        matlab.ui.control.Label
        popupmenu4   matlab.ui.control.DropDown
        popupmenu3   matlab.ui.control.DropDown
        text5        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        edit3        matlab.ui.control.EditField
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        text1        matlab.ui.control.Label
    end

    
    methods (Access = private)
        function edit1_CreateFcn(app, hObject, eventdata, handles)
            % hObject    handle to edit1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function edit2_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: edit controls usually have a white background on Windows.
            %       See ISPC and COMPUTER.
            if ispc
                set(hObject,'BackgroundColor','white');
            else
                set(hObject,'BackgroundColor',get(0,'defaultUicontrolBackgroundColor'));
            end
        end
        
        function push_help_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in push_help.
            
            % hObject    handle to push_help (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            als_info;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function als2013fast_OpeningFcn(app, varargin)
            % --- Executes just before als2013fast is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to als2013fast (see VARARGIN)
            
            % Choose default command line output for als2013fast
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit3 as text
            %        str2double(get(hObject,'String')) returns contents of edit3 as a double
            
            nexp=str2num(get(hObject,'String'));
            assignin('base','nexp',nexp);
            evalin('base','mcr_als.alsOptions.nexp=nexp;');
            evalin('base','clear nexp');
        end

        % Value changed function: popupmenu3
        function popupmenu3_Callback(app, event)
            % --- Executes on selection change in popupmenu3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu3
            
            popmenu3=get(handles.popupmenu3,'String');
            pm3=get(handles.popupmenu3,'Value');
        end

        % Value changed function: popupmenu4
        function popupmenu4_Callback(app, event)
            % --- Executes on selection change in popupmenu4.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu4 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu4
            
            popmenu4=get(handles.popupmenu4,'String');
            pm4=get(handles.popupmenu4,'Value');
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            nexp=str2double(get(handles.edit3,'String'));
            assignin('base','nexp',nexp);
            evalin('base','mcr_als.alsOptions.nexp=nexp;');
            evalin('base','clear conc abss nexp matdad iniesta');
            
            % *************************************************************************
            % initializations for constraints
            
            evalin('base','mcr_als.alsOptions.nonegC.noneg=0;');
            evalin('base','mcr_als.alsOptions.nonegS.noneg=0;');
            evalin('base','mcr_als.alsOptions.unimodC.unimodal=0;');
            evalin('base','mcr_als.alsOptions.unimodS.unimodal=0;');
            evalin('base','mcr_als.alsOptions.closure.closure=0;');
            evalin('base','mcr_als.alsOptions.cselcC.cselcon=0;');
            evalin('base','mcr_als.alsOptions.sselcS.sselcon=0;');
            evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
            evalin('base','mcr_als.alsOptions.weighted.appWeight=0;');
            evalin('base','mcr_als.alsOptions.correlation.appCorrelation=0;');
            evalin('base','mcr_als.alsOptions.correlation.checkSNorm=0;');
            evalin('base','mcr_als.alsOptions.kinetic.appKinetic=0;');
            evalin('base','mcr_als.alsOptions.multi.datamod=0;');
            evalin('base','mcr_als.alsOptions.opt.gr=''y'';');
            
            % *************************************************************************
            
            close;
            
            if evalin('base','mcr_als.alsOptions.nexp') > 1
                clas3way;
            else
                rowModeConstraints;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MCR-ALS user friendly interface';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Selection of the data set';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Data matrix';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Initial estimate';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Nr. of matrices';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '1';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Continue';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'MCR GUI connected';
            app.popupmenu3 = uidropdown(app.figure1);
            app.popupmenu3.Items = {'kin_data'};
            app.popupmenu3.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_Callback, true);
            app.popupmenu3.Tag = 'popupmenu3';
            app.popupmenu3.Enable = 'off';
            app.popupmenu3.Value = 'kin_data';
            app.popupmenu4 = uidropdown(app.figure1);
            app.popupmenu4.Items = {'initEstim method (EFA)'};
            app.popupmenu4.ValueChangedFcn = createCallbackFcn(app, @popupmenu4_Callback, true);
            app.popupmenu4.Tag = 'popupmenu4';
            app.popupmenu4.Enable = 'off';
            app.popupmenu4.Value = 'initEstim method (EFA)';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Too big for graphs';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = als2013fast_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)als2013fast_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end