classdef als_parameters_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        push_back2cons  matlab.ui.control.Button
        push_continue   matlab.ui.control.Button
        uipanel3        matlab.ui.container.Panel
        GridLayout2     matlab.ui.container.GridLayout
        edit_ratioOpt   matlab.ui.control.EditField
        edit_resOpt     matlab.ui.control.EditField
        edit_sOpt       matlab.ui.control.EditField
        edit_areaOpt    matlab.ui.control.EditField
        edit_stdOpt     matlab.ui.control.EditField
        edit_cOpt       matlab.ui.control.EditField
        text6           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        text2           matlab.ui.control.Label
        text1           matlab.ui.control.Label
        uipanel2        matlab.ui.container.Panel
        GridLayout      matlab.ui.container.GridLayout
        check_graph     matlab.ui.control.CheckBox
        edit_conv       matlab.ui.control.EditField
        edit_iter       matlab.ui.control.EditField
        text8           matlab.ui.control.Label
        text7           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function als_parameters_OpeningFcn(app, varargin)
            % --- Executes just before als_parameters is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to als_parameters (see VARARGIN)
            
            % Choose default command line output for als_parameters
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

%             if ispc && isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
%                 set(hObject,'BackgroundColor','white');
%             end
            
            % edit_areaOpt_CreateFcn
            out=app.edit_areaOpt.Value;
            assignin('base','out_area',out);
            evalin('base','mcr_als.alsOptions.out.out_area=out_area;');
            evalin('base','clear out_area');
            
            % edit_cOPT_CreateFcn
            out=app.edit_cOpt.Value;
            assignin('base','out_conc',out);
            evalin('base','mcr_als.alsOptions.out.out_conc=out_conc;');
            evalin('base','clear out_conc');

            % edit_Conv_CreateFcn
            tolsigma=str2double(app.edit_conv.Value);
            assignin('base','tolsigma',tolsigma);
            evalin('base','mcr_als.alsOptions.opt.tolsigma=tolsigma;');
            evalin('base','clear tolsigma');
            
            % edit_iter_CreateFcn   
            nit=50;
            assignin('base','nit',nit);
            evalin('base','mcr_als.alsOptions.opt.nit=nit;');
            evalin('base','clear nit');

            % edit_ratioOpt_CreateFcn
            out=app.edit_ratioOpt.Value;
            assignin('base','out_rat',out);
            evalin('base','mcr_als.alsOptions.out.out_rat=out_rat;');
            evalin('base','clear out_rat');

            % edit_resOpt_CreateFcn
            out=app.edit_resOpt.Value;
            assignin('base','out_res',out);
            evalin('base','mcr_als.alsOptions.out.out_res=out_res;');
            evalin('base','clear out_res');

            % edit_sOpt_Create Fcn
            out=app.edit_sOpt.Value;
            assignin('base','out_spec',out);
            evalin('base','mcr_als.alsOptions.out.out_spec=out_spec;');
            evalin('base','clear out_spec');

            % edit_stdOpt_Create Fcn
            out=app.edit_stdOpt.Value;
            assignin('base','out_std',out);
            evalin('base','mcr_als.alsOptions.out.out_std=out_std;');
            evalin('base','clear out_std');
        end

        % Value changed function: check_graph
        function check_graph_Callback(app, event)
            % --- Executes on button press in check_graph.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_graph (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_graph
            
            val=get(hObject,'Value');
            
            if val==1
                gr='y';
            else
                gr='n';
            end
            
            assignin('base','gr',gr);
            evalin('base','mcr_als.alsOptions.opt.gr=gr;');
            evalin('base','clear gr');
        end

        % Value changed function: edit_areaOpt
        function edit_areaOpt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_areaOpt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_areaOpt as text
            %        str2double(get(hObject,'String')) returns contents of edit_areaOpt as a double
            
            out=get(hObject,'String');
            assignin('base','out_area',out);
            evalin('base','mcr_als.alsOptions.out.out_area=out_area;');
            evalin('base','clear out_area');
        end

        % Value changed function: edit_cOpt
        function edit_cOpt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_cOpt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_cOpt as text
            %        str2double(get(hObject,'String')) returns contents of edit_cOpt as a double
            
            out=get(hObject,'String');
            assignin('base','out_conc',out);
            evalin('base','mcr_als.alsOptions.out.out_conc=out_conc;');
            evalin('base','clear out_conc');
        end

        % Value changed function: edit_conv
        function edit_conv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_conv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_conv as text
            %        str2double(get(hObject,'String')) returns contents of edit_conv as a double
            
            tolsigma=str2num(get(hObject,'String'));
            assignin('base','tolsigma',tolsigma);
            evalin('base','mcr_als.alsOptions.opt.tolsigma=tolsigma;');
            evalin('base','clear tolsigma');
        end

        % Value changed function: edit_iter
        function edit_iter_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_iter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_iter as text
            %        str2double(get(hObject,'String')) returns contents of edit_iter as a double
            
            nit=str2num(get(hObject,'String'));
            assignin('base','nit',nit);
            evalin('base','mcr_als.alsOptions.opt.nit=nit;');
            evalin('base','clear nit');
        end

        % Value changed function: edit_ratioOpt
        function edit_ratioOpt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_ratioOpt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_ratioOpt as text
            %        str2double(get(hObject,'String')) returns contents of edit_ratioOpt as a double
            
            out=get(hObject,'String');
            assignin('base','out_rat',out);
            evalin('base','mcr_als.alsOptions.out.out_rat=out_rat;');
            evalin('base','clear out_rat');
        end

        % Value changed function: edit_resOpt
        function edit_resOpt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_resOpt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_resOpt as text
            %        str2double(get(hObject,'String')) returns contents of edit_resOpt as a double
            
            out=get(hObject,'String');
            assignin('base','out_res',out);
            evalin('base','mcr_als.alsOptions.out.out_res=out_res;');
            evalin('base','clear out_res');
        end

        % Value changed function: edit_sOpt
        function edit_sOpt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_sOpt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_sOpt as text
            %        str2double(get(hObject,'String')) returns contents of edit_sOpt as a double
            
            out=get(hObject,'String');
            assignin('base','out_spec',out);
            evalin('base','mcr_als.alsOptions.out.out_spec=out_spec;');
            evalin('base','clear out_spec');
        end

        % Value changed function: edit_stdOpt
        function edit_stdOpt_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_stdOpt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_stdOpt as text
            %        str2double(get(hObject,'String')) returns contents of edit_stdOpt as a double
            
            out=get(hObject,'String');
            assignin('base','out_std',out);
            evalin('base','mcr_als.alsOptions.out.out_std=out_std;');
            evalin('base','clear out_std');
        end

        % Button pushed function: push_back2cons
        function push_back2cons_Callback(app, event)
            % --- Executes on button press in push_back2cons.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_back2cons (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''nonegC'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''nonegS'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''unimodC'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''unimodS'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''closure'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''cselcC'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''sselcS'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''trilin'');');
            % evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''multi'');');
            evalin('base','mcr_als.alsOptions.nonegC.noneg=0;');
            evalin('base','mcr_als.alsOptions.nonegS.noneg=0;');
            evalin('base','mcr_als.alsOptions.unimodC.unimodal=0;');
            evalin('base','mcr_als.alsOptions.unimodS.unimodal=0;');
            evalin('base','mcr_als.alsOptions.closure.closure=0;');
            evalin('base','mcr_als.alsOptions.cselcC.cselcon=0;');
            evalin('base','mcr_als.alsOptions.sselcS.sselcon=0;');
            evalin('base','mcr_als.alsOptions.trilin.tril=0;');
            % evalin('base','mcr_als.alsOptions.multi.datamod=0;');
            evalin('base','mcr_als.alsOptions.opt.gr=''y'';');
            
            if evalin('base','mcr_als.alsOptions.nexp') > 1
                if evalin('base','mcr_als.alsOptions.multi.matc') > 1
                    evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
                    rowMultiConstraints_App;
                else
                    rowModeConstraints_App;
                end
            else
                rowModeConstraints_App;
            end
        end

        % Button pushed function: push_continue
        function push_continue_Callback(app, event)
            % --- Executes on button press in push_continue.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_continue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
            alsOptimization_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Parameters/Output of ALS optimization ';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'General Optimization Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.GridLayout = uigridlayout(app.uipanel2);
            app.text7 = uilabel(app.GridLayout);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Number of iterations';
            app.text8 = uilabel(app.GridLayout);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Convergence criterion';
            app.edit_iter = uieditfield(app.GridLayout, 'text');
            app.edit_iter.ValueChangedFcn = createCallbackFcn(app, @edit_iter_Callback, true);
            app.edit_iter.Tag = 'edit_iter';
            app.edit_iter.Value = '50';
            app.edit_conv = uieditfield(app.GridLayout, 'text');
            app.edit_conv.ValueChangedFcn = createCallbackFcn(app, @edit_conv_Callback, true);
            app.edit_conv.Tag = 'edit_conv';
            app.edit_conv.Value = '0.1';
            app.check_graph = uicheckbox(app.GridLayout);
            app.check_graph.ValueChangedFcn = createCallbackFcn(app, @check_graph_Callback, true);
            app.check_graph.Tag = 'check_graph';
            app.check_graph.Text = 'Graphical output';
            app.check_graph.Value = true;
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Output';
            app.uipanel3.Tag = 'uipanel3';
            app.GridLayout2 = uigridlayout(app.uipanel3);
            app.text1 = uilabel(app.GridLayout2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Concentrations';
            app.text2 = uilabel(app.GridLayout2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Spectra';
            app.text3 = uilabel(app.GridLayout2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Std. deviation';
            app.text4 = uilabel(app.GridLayout2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Area opt';
            app.text5 = uilabel(app.GridLayout2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Residuals';
            app.text6 = uilabel(app.GridLayout2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Ratio opt';
            app.edit_cOpt = uieditfield(app.GridLayout2, 'text');
            app.edit_cOpt.ValueChangedFcn = createCallbackFcn(app, @edit_cOpt_Callback, true);
            app.edit_cOpt.Tag = 'edit_cOpt';
            app.edit_cOpt.Value = 'copt';
            app.edit_stdOpt = uieditfield(app.GridLayout2, 'text');
            app.edit_stdOpt.ValueChangedFcn = createCallbackFcn(app, @edit_stdOpt_Callback, true);
            app.edit_stdOpt.Tag = 'edit_stdOpt';
            app.edit_areaOpt = uieditfield(app.GridLayout2, 'text');
            app.edit_areaOpt.ValueChangedFcn = createCallbackFcn(app, @edit_areaOpt_Callback, true);
            app.edit_areaOpt.Tag = 'edit_areaOpt';
            app.edit_sOpt = uieditfield(app.GridLayout2, 'text');
            app.edit_sOpt.ValueChangedFcn = createCallbackFcn(app, @edit_sOpt_Callback, true);
            app.edit_sOpt.Tag = 'edit_sOpt';
            app.edit_sOpt.Value = 'sopt';
            app.edit_resOpt = uieditfield(app.GridLayout2, 'text');
            app.edit_resOpt.ValueChangedFcn = createCallbackFcn(app, @edit_resOpt_Callback, true);
            app.edit_resOpt.Tag = 'edit_resOpt';
            app.edit_ratioOpt = uieditfield(app.GridLayout2, 'text');
            app.edit_ratioOpt.ValueChangedFcn = createCallbackFcn(app, @edit_ratioOpt_Callback, true);
            app.edit_ratioOpt.Tag = 'edit_ratioOpt';
            app.push_continue = uibutton(app.figure1, 'push');
            app.push_continue.ButtonPushedFcn = createCallbackFcn(app, @push_continue_Callback, true);
            app.push_continue.Tag = 'push_continue';
            app.push_continue.Text = 'Continue';
            app.push_back2cons = uibutton(app.figure1, 'push');
            app.push_back2cons.ButtonPushedFcn = createCallbackFcn(app, @push_back2cons_Callback, true);
            app.push_back2cons.Tag = 'push_back2cons';
            app.push_back2cons.Text = 'Back to constraints';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = als_parameters_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)als_parameters_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end