classdef als_res_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        push_back2param  matlab.ui.control.Button
        push_back2const  matlab.ui.control.Button
        push_bands       matlab.ui.control.Button
        push_info        matlab.ui.control.Button
        als_r2           matlab.ui.control.Label
        push_print       matlab.ui.control.Button
        push_copy        matlab.ui.control.Button
        als_fitexp       matlab.ui.control.Label
        als_fitpca       matlab.ui.control.Label
        als_change       matlab.ui.control.Label
        als_fit          matlab.ui.control.Label
        als_iter         matlab.ui.control.Label
        text1            matlab.ui.control.Label
        spec             matlab.ui.control.UIAxes
        concen           matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function als_res_OpeningFcn(app, varargin)
            % --- Executes just before als_res is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to als_res (see VARARGIN)
            
            % Choose default command line output for als_res
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

        end

        % Button pushed function: push_back2const
        function push_back2const_Callback(app, event)
            % --- Executes on button press in push_back2const.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_back2const (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''nonegC'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''nonegS'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''unimodC'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''unimodS'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''closure'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''cselcC'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''sselcS'');');
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''trilin'');');
            % evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''multi'');');
            evalin('base','mcr_als.alsOptions.nonegC.noneg=0;');
            evalin('base','mcr_als.alsOptions.nonegS.noneg=0;');
            evalin('base','mcr_als.alsOptions.unimodC.unimodal=0;');
            evalin('base','mcr_als.alsOptions.unimodS.unimodal=0;');
            evalin('base','mcr_als.alsOptions.closure.closure=0;');
            evalin('base','mcr_als.alsOptions.cselcC.cselcon=0;');
            evalin('base','mcr_als.alsOptions.sselcS.sselcon=0;');
            evalin('base','mcr_als.alsOptions.trilin.tril=0;');
            % evalin('base','mcr_als.alsOptions.multi.datamod=0;');
            evalin('base','mcr_als.alsOptions.opt.gr=''y'';');
            % als2013;
            
            if evalin('base','mcr_als.alsOptions.nexp') > 1
                if evalin('base','mcr_als.alsOptions.multi.matc') > 1
                    evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
                    rowMultiConstraints_App;
                else
                    rowModeConstraints_App;
                end
            else
                evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
                rowModeConstraints_App;
            end
        end

        % Button pushed function: push_back2param
        function push_back2param_Callback(app, event)
            % --- Executes on button press in push_back2param.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_back2param (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
            als_parameters_reload_App;
        end

        % Button pushed function: push_bands
        function push_bands_Callback(app, event)
            % --- Executes on button press in push_bands.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_bands (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            mcrbandsg_connected_App;
        end

        % Button pushed function: push_copy
        function push_copy_Callback(app, event)
            % --- Executes on button press in push_copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            print -dbitmap;
        end

        % Button pushed function: push_info
        function push_info_Callback(app, event)
            % --- Executes on button press in push_info.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_info (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            optim_info_App;
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf,'PaperPositionMode','auto');
            print('ALS_RESULTS','-dpdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MCR-ALS user frienly interface: RESULTS';
            app.figure1.Tag = 'figure1';
            app.concen = uiaxes(app.figure1);
            app.concen.Tag = 'concen';
            app.spec = uiaxes(app.figure1);
            app.spec.Tag = 'spec';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'ALS optimization';
            app.als_iter = uilabel(app.figure1);
            app.als_iter.Tag = 'als_iter';
            app.als_iter.Text = '';
            app.als_fit = uilabel(app.figure1);
            app.als_fit.Tag = 'als_fit';
            app.als_fit.Text = '';
            app.als_change = uilabel(app.figure1);
            app.als_change.Tag = 'als_change';
            app.als_change.Text = '';
            app.als_fitpca = uilabel(app.figure1);
            app.als_fitpca.Tag = 'als_fitpca';
            app.als_fitpca.Text = '';
            app.als_fitexp = uilabel(app.figure1);
            app.als_fitexp.Tag = 'als_fitexp';
            app.als_fitexp.Text = '';
            app.push_copy = uibutton(app.figure1, 'push');
            app.push_copy.ButtonPushedFcn = createCallbackFcn(app, @push_copy_Callback, true);
            app.push_copy.Tag = 'push_copy';
            app.push_copy.Text = 'Copy';
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Text = 'Print';
            app.als_r2 = uilabel(app.figure1);
            app.als_r2.Tag = 'als_r2';
            app.als_r2.Enable = 'off';
            app.als_r2.Text = '';
            app.push_info = uibutton(app.figure1, 'push');
            app.push_info.ButtonPushedFcn = createCallbackFcn(app, @push_info_Callback, true);
            app.push_info.Tag = 'push_info';
            app.push_info.Text = 'Information';
            app.push_bands = uibutton(app.figure1, 'push');
            app.push_bands.ButtonPushedFcn = createCallbackFcn(app, @push_bands_Callback, true);
            app.push_bands.Tag = 'push_bands';
            app.push_bands.Text = 'MCR Bands';
            app.push_back2const = uibutton(app.figure1, 'push');
            app.push_back2const.ButtonPushedFcn = createCallbackFcn(app, @push_back2const_Callback, true);
            app.push_back2const.Tag = 'push_back2const';
            app.push_back2const.Text = 'Back 2 const';
            app.push_back2param = uibutton(app.figure1, 'push');
            app.push_back2param.ButtonPushedFcn = createCallbackFcn(app, @push_back2param_Callback, true);
            app.push_back2param.Tag = 'push_back2param';
            app.push_back2param.Text = 'Back 2 param';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = als_res_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)als_res_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end