classdef alsgui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        popupmenu4   matlab.ui.control.DropDown
        popupmenu3   matlab.ui.control.DropDown
        push_help    matlab.ui.control.Button
        pushbutton1  matlab.ui.control.Button
        edit3        matlab.ui.control.EditField
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        text1        matlab.ui.control.Label
        loadings     matlab.ui.control.UIAxes
        scores       matlab.ui.control.UIAxes
        spec         matlab.ui.control.UIAxes
        conc         matlab.ui.control.UIAxes
        rows         matlab.ui.control.UIAxes
        columns      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function alsgui_OpeningFcn(app, varargin)
            % --- Executes just before alsgui is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to alsgui (see VARARGIN)
            
            % Choose default command line output for alsgui
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit3 as text
            %        str2double(get(hObject,'String')) returns contents of edit3 as a double
            
            nexp=str2num(get(hObject,'String'));
            assignin('base','nexp',nexp);
            evalin('base','mcr_als.alsOptions.nexp=nexp;');
            evalin('base','clear nexp');
        end

        % Value changed function: popupmenu3
        function popupmenu3_Callback(app, event)
            % --- Executes on selection change in popupmenu3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu3
            
            popmenu3=get(handles.popupmenu3,'String');
            pm3=get(handles.popupmenu3,'Value');
            
            if pm3==1
            
            else
                selec3=char([popmenu3(pm3)]);
                matdad=evalin('base',selec3);
                assignin('base','matdad',matdad);
                evalin('base','mcr_als.data=matdad;');
                evalin('base','mcr_als.alsOptions.matdad=matdad;');
            
                axes(handles.columns);
                [xi,yi]=size(matdad);
                maxim=max(max(matdad));
                minim=min(min(matdad));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim >= 0
                    minim1=minim+0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                plot(matdad);axis([1 xi minim1 maxim1]);title('Columns of raw data matrix');
                zoom on;
            
                axes(handles.rows);
                plot(matdad');axis([1 yi minim1 maxim1]);title('Rows (spectra) of raw data matrix');
                zoom on;
            
                set(handles.popupmenu4,'enable','on')
            
            end
        end

        % Value changed function: popupmenu4
        function popupmenu4_Callback(app, event)
            % --- Executes on selection change in popupmenu4.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu4 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu4
            
            popmenu4=get(handles.popupmenu4,'String');
            pm4=get(handles.popupmenu4,'Value');
            
            if pm4==1
            else
                selec4=char([popmenu4(pm4)]);
                iniesta=evalin('base',selec4);
                assignin('base','iniesta',iniesta);
                evalin('base','mcr_als.alsOptions.iniesta=iniesta;');
                matdad=evalin('base','mcr_als.data');
            
                % check dimensions of initial estimates
            
                [nrow,ncol]=size(matdad);
                [nrow2,ncol2]=size(iniesta);
            
                if nrow2==nrow,	nsign=ncol2; ils=1;end
                if ncol2==nrow, nsign=nrow2; iniesta=iniesta'; ils=1; end
            
                if ncol2==ncol, nsign=nrow2; ils=2;end
                if nrow2==ncol, nsign=ncol2; iniesta=iniesta'; ils=2; end
            
                if ils==1,
                    conc=iniesta;
                    assignin('base','conc',conc);
                    [nrow,nsign]=size(conc);
                    assignin('base','nComponents',nsign);
                    evalin('base','mcr_als.alsOptions.nComponents=nComponents;');
                    evalin('base','clear nComponents');
            
                    abss=conc\matdad;
                    assignin('base','abss',abss);
                    axes(handles.conc);
            
                    [xi,yi]=size(conc);
                    maxim=max(max(conc));minim=min(min(conc));
            
                    if maxim > 0
                        maxim1=maxim+0.2*maxim;
                    else
                        maxim1=maxim-0.2*abs(maxim)
                    end
            
                    if minim > 0
                        minim1=minim+0.2*minim;
                    else
                        minim1=minim-0.2*abs(minim);
                    end
            
                    plot(conc);axis([1 xi minim1 maxim1]);title('Initial estimates of the concentration profiles');zoom on
                    axes(handles.spec);
                    [xi,yi]=size(abss);
                    maxim=max(max(abss));minim=min(min(abss));
            
                    if maxim > 0
                        maxim1=maxim+0.2*maxim;
                    else
                        maxim1=maxim-0.2*abs(maxim)
                    end
            
                    if minim > 0
                        minim1=minim+0.2*minim;
                        else
                        minim1=minim-0.2*abs(minim);
                    end
            
                    plot(abss');axis([1 yi minim1 maxim1]);title('Unconstrained spectra calculated by LS (iter 1)');zoom on
            
                    % pca reproduction
            
                    disp('   *********** Results obtained after application of PCA to the data matrix ***********');
                    disp(' ');
                    [u,s,v,d,sd]=pcarep(matdad,nsign);
                    disp(' ');
                    disp('   ************************************************************************************');
                    disp(' ');
                    disp(' ');
            
                    axes(handles.scores);
                    scmat=u*s;
                    [xi,yi]=size(scmat);
                    maxim=max(max(scmat));minim=min(min(scmat));
            
                    if maxim > 0
                        maxim1=maxim+0.2*maxim;
                    else
                        maxim1=maxim-0.2*abs(maxim)
                    end
            
                    if minim > 0
                        minim1=minim+0.2*minim;
                    else
                        minim1=minim-0.2*abs(minim);
                    end
            
                    plot(scmat);axis([1 xi minim1 maxim1]);title('Scores matrix');zoom on
                    axes(handles.loadings);
                    [xi,yi]=size(v);
                    maxim=max(max(v));minim=min(min(v));
            
                    if maxim > 0
                        maxim1=maxim+0.2*maxim;
                    else
                        maxim1=maxim-0.2*abs(maxim)
                    end
            
                    if minim > 0
                        minim1=minim+0.2*minim;
                    else
                        minim1=minim-0.2*abs(minim);
                    end
            
                    plot(v);axis([1 xi minim1 maxim1]);
                    title('Loadings matrix');zoom on
            
                end
            
                if ils==2,
                    abss=iniesta;
                    assignin('base','abss',abss);
                    [nsign,ncol]=size(abss);
                    assignin('base','nComponents',nsign);
                    evalin('base','mcr_als.alsOptions.nComponents=nComponents;');
                    evalin('base','clear nComponents');
            
            
                    conc=matdad/abss;
                    assignin('base','conc',conc);
                    axes(handles.conc);
                    [xi,yi]=size(conc);
                    maxim=max(max(conc));minim=min(min(conc));
            
                    if maxim > 0
                        maxim1=maxim+0.2*maxim;
                    else
                        maxim1=maxim-0.2*abs(maxim)
                    end
            
                    if minim > 0
                        minim1=minim+0.2*minim;
                    else
                        minim1=minim-0.2*abs(minim);
                    end
            
                    plot(conc);axis([1 xi minim1 maxim1]);title('Unconstrained concentration profiles calculated by LS (iter 1)');zoom on
                    axes(handles.spec);
                    [xi,yi]=size(abss);
                    maxim=max(max(abss));minim=min(min(abss));
            
                    if maxim > 0
                        maxim1=maxim+0.2*maxim;
                    else
                        maxim1=maxim-0.2*abs(maxim)
                    end
            
                    if minim > 0
                        minim1=minim+0.2*minim;
                    else
                        minim1=minim-0.2*abs(minim);
                    end
            
                    plot(abss');axis([1 yi minim1 maxim1]);title('Initial estimates of the spectra');zoom on
            
                    % pca reproduction
            
                    disp('   *********** Results obtained after application of PCA to the data matrix ***********');
                    disp(' ');
                    [u,s,v,d,sd]=pcarep(matdad,nsign);
                    disp(' ');
                    disp('   ************************************************************************************');
                    disp(' ');
                    disp(' ');
            
                    axes(handles.scores);
                    scmat=u*s;
                    [xi,yi]=size(scmat);
                    maxim=max(max(scmat));minim=min(min(scmat));
            
                    if maxim > 0
                        maxim1=maxim+0.2*maxim;
                    else
                        maxim1=maxim-0.2*abs(maxim)
                    end
            
                    if minim > 0
                        minim1=minim+0.2*minim;
                    else
                        minim1=minim-0.2*abs(minim);
                    end
            
                    plot(scmat);axis([1 xi minim1 maxim1]);title('Scores matrix');zoom on
                    axes(handles.loadings);
                    [xi,yi]=size(v);
                    maxim=max(max(v));minim=min(min(v));
            
                    if maxim > 0
                        maxim1=maxim+0.2*maxim;
                    else
                        maxim1=maxim-0.2*abs(maxim)
                    end
            
                    if minim > 0
                        minim1=minim+0.2*minim;
                    else
                        minim1=minim-0.2*abs(minim);
                    end
            
                    plot(v);axis([1 xi minim1 maxim1]);
                    title('Loadings matrix');zoom on
            
                end
            
             set(handles.pushbutton1,'enable','on')
            
            end
        end

        % Button pushed function: push_help
        function push_help_Callback(app, event)
            % --- Executes on button press in push_help.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_help (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            als_info;
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            nexp=str2double(get(handles.edit3,'String'));
            assignin('base','nexp',nexp);
            evalin('base','mcr_als.alsOptions.nexp=nexp;');
            evalin('base','clear conc abss nexp matdad iniesta');
            
            % *************************************************************************
            % initializations for constraints
            % *************************************************************************
            
            evalin('base','mcr_als.alsOptions.nonegC.noneg=0;');
            evalin('base','mcr_als.alsOptions.nonegS.noneg=0;');
            evalin('base','mcr_als.alsOptions.unimodC.unimodal=0;');
            evalin('base','mcr_als.alsOptions.unimodS.unimodal=0;');
            evalin('base','mcr_als.alsOptions.closure.closure=0;');
            evalin('base','mcr_als.alsOptions.cselcC.cselcon=0;');
            evalin('base','mcr_als.alsOptions.sselcS.sselcon=0;');
            evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
            evalin('base','mcr_als.alsOptions.weighted.appWeight=0;');
            evalin('base','mcr_als.alsOptions.correlation.appCorrelation=0;');
            evalin('base','mcr_als.alsOptions.kinetic.appKinetic=0;');
            evalin('base','mcr_als.alsOptions.multi.datamod=0;');
            evalin('base','mcr_als.alsOptions.opt.gr=''y'';');
            
            % *************************************************************************
            
            close;
            
            if evalin('base','mcr_als.alsOptions.nexp') > 1
                clas3way;
            else
                rowModeConstraints;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MCR-ALS user friendly interface';
            app.figure1.Tag = 'figure1';
            app.columns = uiaxes(app.figure1);
            app.columns.Tag = 'columns';
            app.rows = uiaxes(app.figure1);
            app.rows.Tag = 'rows';
            app.conc = uiaxes(app.figure1);
            app.conc.Tag = 'conc';
            app.spec = uiaxes(app.figure1);
            app.spec.Tag = 'spec';
            app.scores = uiaxes(app.figure1);
            app.scores.Tag = 'scores';
            app.loadings = uiaxes(app.figure1);
            app.loadings.Tag = 'loadings';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Selection of the data set';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Data matrix';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Initial estimate';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Nr. of matrices';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '1';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Continue';
            app.push_help = uibutton(app.figure1, 'push');
            app.push_help.ButtonPushedFcn = createCallbackFcn(app, @push_help_Callback, true);
            app.push_help.Tag = 'push_help';
            app.push_help.Text = '?';
            app.popupmenu3 = uidropdown(app.figure1);
            app.popupmenu3.Items = {'select a variable', 'DataDC', 'DataTime', 'colormap', 'copt', 'currentT50Times', 'exportT0_intensities', 'kin_data', 'sopt', 'times', 'waves'};
            app.popupmenu3.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_Callback, true);
            app.popupmenu3.Tag = 'popupmenu3';
            app.popupmenu3.Value = 'select a variable';
            app.popupmenu4 = uidropdown(app.figure1);
            app.popupmenu4.Items = {'select a variable', 'DataDC', 'DataTime', 'colormap', 'copt', 'currentT50Times', 'exportT0_intensities', 'kin_data', 'sopt', 'times', 'waves'};
            app.popupmenu4.ValueChangedFcn = createCallbackFcn(app, @popupmenu4_Callback, true);
            app.popupmenu4.Tag = 'popupmenu4';
            app.popupmenu4.Enable = 'off';
            app.popupmenu4.Value = 'select a variable';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = alsgui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)alsgui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end