classdef clas3way_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        checkbox_samecolumns  matlab.ui.control.CheckBox
        popup_seldat          matlab.ui.control.DropDown
        popup_numC            matlab.ui.control.DropDown
        popup_numS            matlab.ui.control.DropDown
        popup_selnc           matlab.ui.control.DropDown
        popup_selns           matlab.ui.control.DropDown
        edit_external         matlab.ui.control.EditField
        checkbox_external     matlab.ui.control.CheckBox
        checkbox_samerows     matlab.ui.control.CheckBox
        push_can              matlab.ui.control.Button
        push_ok               matlab.ui.control.Button
        edit_nc               matlab.ui.control.EditField
        edit_nr               matlab.ui.control.EditField
        text_nr               matlab.ui.control.Label
        text_nc               matlab.ui.control.Label
        text_selns            matlab.ui.control.Label
        text_selnc            matlab.ui.control.Label
        text_Ssub             matlab.ui.control.Label
        text_Csub             matlab.ui.control.Label
        text_numS             matlab.ui.control.Label
        text_numC             matlab.ui.control.Label
        text_seldat           matlab.ui.control.Label
        text_def              matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function clas3way_OpeningFcn(app, varargin)
            % --- Executes just before clas3way is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to clas3way (see VARARGIN)
            
            % Choose default command line output for clas3way
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            % popup_numC_CreateFcn
            nexp=evalin('base','mcr_als.alsOptions.nexp');
            j=2;
%             llistannc(1)={'select...'};
            app.popup_numC.Items(1)={'select...'};
            
%             llistannc(2,nexp+1) = mat2cell(1:1:nexp, [1,1]);
            for i=1:1:nexp
%                 llistnnc=[i];
%                 llistannc(j)={llistnnc};
                app.popup_numC.Items(j) = {num2str(i)};
                j=j+1;
            end
%             llistannc
%             app.popup_numC.Items = llistannc;

            % popup_numS_CreateFcn
            nexp=evalin('base','mcr_als.alsOptions.nexp');
            j=2;
%             llistannc(1)={'select...'};
            app.popup_numS.Items(1)={'select...'};
            for i=1:1:nexp
                app.popup_numS.Items(j) = {num2str(i)};
%                 llistnnc=num2str(i);
%                 llistannc(j)={llistnnc};
                j=j+1;
            end
%             app.popup_numS.Items = llistannc;
        end

        % Value changed function: checkbox_external
        function checkbox_external_Callback(app, event)
            % --- Executes on button press in checkbox_external.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_external (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_external
            
            
            external=get(hObject,'Value');
            
            if external==0;
                set(handles.text_selnc,'enable','on');
                set(handles.text_nr,'enable','off');
                set(handles.edit_external,'enable','off','string',' ');
                set(handles.edit_nr,'enable','off');
                set(handles.checkbox_samerows,'enable','on','value',0);
            
                matc=evalin('base','mcr_als.alsOptions.multi.matc');
                j=2; % list conc. matrices
                llistannc(1)={'select...'};
                for i=1:1:matc;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
                set(handles.popup_selnc,'string',llistannc,'enable','on','value',1);
                set(handles.push_ok,'enable','off');
                nrsol=zeros(1,matc);
                assignin('base','nrsol',nrsol);
                evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
                evalin('base','clear nrsol ');
            else
            
                set(handles.text_selnc,'enable','off');
                set(handles.text_nr,'enable','off');
                set(handles.edit_external,'enable','on','string',' ');
                set(handles.edit_nr,'enable','off');
                set(handles.checkbox_samerows,'enable','off','value',0);
                llistannc={'select...'};
                set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
                set(handles.push_ok,'enable','off');
            end
        end

        % Value changed function: checkbox_samecolumns
        function checkbox_samecolumns_Callback(app, event)
            % --- Executes on button press in checkbox_samecolumns.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_samecolumns (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_samecolumns
            
            
            samecolumns=get(hObject,'Value');
            
            if samecolumns==0;
                set(handles.edit_nc,'enable','off');
                set(handles.text_selns,'enable','on');
                set(handles.text_nc,'enable','off');
                matr=evalin('base','mcr_als.alsOptions.multi.matr');
            
                j=2; % list conc. spectra
                llistannc(1)={'select...'};
                for i=1:1:matr;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
            
                set(handles.popup_selns,'string',llistannc,'enable','on','value',1);
                set(handles.push_ok,'enable','off');
                ncsol=zeros(1,matr);
                assignin('base','ncsol',ncsol);
                evalin('base','mcr_als.alsOptions.multi.ncsol=ncsol;');
                evalin('base','clear ncsol ');
            else
                set(handles.text_selns,'enable','off');
                set(handles.text_nc,'enable','off');
                set(handles.edit_nc,'enable','off');
                llistannc={'select...'};
                set(handles.popup_selns,'string',llistannc,'enable','off','value',1);
            
                [files,columnes]=size(evalin('base','mcr_als.alsOptions.matdad'));
                matr=evalin('base','mcr_als.alsOptions.multi.matr');
            
                ncolumnes=columnes/matr;
            
                test_num=round(ncolumnes)-ncolumnes;
            
                if test_num == 0
                    ncsol=ncolumnes*ones(1,matr);
                    assignin('base','ncsol',ncsol);
                    evalin('base','mcr_als.alsOptions.multi.ncsol=ncsol;');
                    evalin('base','clear ncsol ');
                    assignin('base','nc',ncolumnes);
                    evalin('base','mcr_als.alsOptions.multi.nc=nc;');
                    evalin('base','clear nc');
                    set(handles.push_ok,'enable','on');
                else
            
                    set(handles.edit_nc,'enable','off');
                    matr=evalin('base','mcr_als.alsOptions.multi.matr');
                    j=2; % list conc. matrices
                    llistannc(1)={'select...'};
                    for i=1:1:matr;
                        llistnnc=[i];
                        llistannc(j)={llistnnc};
                        j=j+1;
                    end;
                    set(handles.popup_selns,'string',llistannc,'enable','on','value',1);
                    set(handles.text_selns,'enable','on');
                    set(handles.push_ok,'enable','off');
                    ncsol=zeros(1,matr);
                    assignin('base','ncsol',ncsol);
                    evalin('base','mcr_als.alsOptions.multi.ncsol=ncsol;');
                    evalin('base','clear ncsol ');
                    set(handles.checkbox_samerows,'enable','on','value',0);
                    errordlg('check dimensions!! It is not possible that each matrix has the same nr. of columns');
                end
            
            end
        end

        % Value changed function: checkbox_samerows
        function checkbox_samerows_Callback(app, event)
            % --- Executes on button press in checkbox_samerows.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_samerows (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of checkbox_samerows
            
            samerows=get(hObject,'Value');
            
            if samerows==0;
                set(handles.edit_external,'enable','off','string',' ');
                set(handles.edit_nr,'enable','off');
                set(handles.checkbox_external,'enable','on','value',0);
                set(handles.text_selnc,'enable','on');
                set(handles.text_nr,'enable','off');
                matc=evalin('base','mcr_als.alsOptions.multi.matc');
            
                j=2; % list conc. matrices
                llistannc(1)={'select...'};
                for i=1:1:matc;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
            
                set(handles.popup_selnc,'string',llistannc,'enable','on','value',1);
                set(handles.push_ok,'enable','off');
                nrsol=zeros(1,matc);
                assignin('base','nrsol',nrsol);
                evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
                evalin('base','clear nrsol ');
            else % same rows == true
                set(handles.text_selnc,'enable','off');
                set(handles.edit_external,'enable','off','string',' ');
                set(handles.checkbox_external,'enable','off','value',0);
                set(handles.text_nr,'enable','off');
                set(handles.edit_nr,'enable','off');
                llistannc={'select...'};
%                 set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
            
                [files,columnes]=size(evalin('base','mcr_als.alsOptions.matdad'));
                matc=evalin('base','mcr_als.alsOptions.multi.matc');
            
                nfiles=files/matc;
            
                test_num=round(nfiles)-nfiles;
            
                if test_num == 0
                    nrsol=nfiles*ones(1,matc);
                    assignin('base','nrsol',nrsol);
                    evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
                    evalin('base','clear nrsol ');
                    assignin('base','nr',nfiles);
                    evalin('base','mcr_als.alsOptions.multi.nr=nr;');
                    evalin('base','clear nr');
                    set(handles.push_ok,'enable','on');
                else
            
                    set(handles.edit_external,'enable','off','string',' ');
                    set(handles.edit_nr,'enable','off');
                    set(handles.checkbox_external,'enable','on','value',0);
                    matc=evalin('base','mcr_als.alsOptions.multi.matc');
                    j=2; % list conc. matrices
                    llistannc(1)={'select...'};
                    for i=1:1:matc;
                        llistnnc=[i];
                        llistannc(j)={llistnnc};
                        j=j+1;
                    end;
                    set(handles.popup_selnc,'string',llistannc,'enable','on','value',1);
                    set(handles.text_selnc,'enable','on');
                    set(handles.push_ok,'enable','off');
                    nrsol=zeros(1,matc);
                    assignin('base','nrsol',nrsol);
                    evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
                    evalin('base','clear nrsol ');
                    set(handles.checkbox_samerows,'enable','on','value',0);
            
                    errordlg('check dimensions!! It is not possible that each matrix has the same nr. of rows');
                end
            
            end
        end

        % Value changed function: edit_external
        function edit_external_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_external (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_external as text
            %        str2double(get(hObject,'String')) returns contents of edit_external as a double
            
            external_char=get(hObject,'String');
            
            nrsol=evalin('base',external_char);
            
            [files,columnes]=size(evalin('base','mcr_als.alsOptions.matdad'));
            nrsuma=sum(nrsol);
            
            if nrsuma==files
                assignin('base','nrsol',nrsol);
                evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
                evalin('base','clear nrsol ');
                set(handles.push_ok,'enable','on');
            else
            
                set(handles.text_selnc,'enable','on');
                set(handles.text_nr,'enable','on');
            
                set(handles.edit_external,'enable','off','string',' ');
                set(handles.edit_nr,'enable','off');
                set(handles.checkbox_external,'enable','on','value',0);
                matc=evalin('base','mcr_als.alsOptions.multi.matc');
                j=2; % list conc. matrices
                llistannc(1)={'select...'};
                for i=1:1:matc;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
                set(handles.popup_selnc,'string',llistannc,'enable','on','value',1);
                set(handles.push_ok,'enable','off');
                nrsol=zeros(1,matc);
                assignin('base','nrsol',nrsol);
                evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
                evalin('base','clear nrsol ');
                set(handles.checkbox_samerows,'enable','on','value',0);
            
                errordlg('Error! Nr. rows of exp. data and of your ext. variable don''t agree');
            
            
            end
        end

        % Value changed function: edit_nc
        function edit_nc_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_nc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_nc as text
            %        str2double(get(hObject,'String')) returns contents of edit_nc as a double
            
            nc=str2num(get(hObject,'String'));
            assignin('base','nc',nc);
            evalin('base','mcr_als.alsOptions.multi.nc=nc;');
            evalin('base','clear nc');
            ncsol=evalin('base','mcr_als.alsOptions.multi.ncsol');
            ncsol(evalin('base','mcr_als.alsOptions.multi.nums'))=nc;
            assignin('base','ncsol',ncsol);
            evalin('base','mcr_als.alsOptions.multi.ncsol=ncsol;');
            evalin('base','clear ncsol ');
        end

        % Value changed function: edit_nr
        function edit_nr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_nr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_nr as text
            %        str2double(get(hObject,'String')) returns contents of edit_nr as a double
            
            nr=str2num(get(hObject,'String'));
            assignin('base','nr',nr);
            evalin('base','mcr_als.alsOptions.multi.nr=nr;');
            evalin('base','clear nr');
            nrsol=evalin('base','mcr_als.alsOptions.multi.nrsol');
            nrsol(evalin('base','mcr_als.alsOptions.multi.numc'))=nr;
            assignin('base','nrsol',nrsol);
            evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
            evalin('base','clear nrsol ');
        end

        % Value changed function: popup_numC
        function popup_numC_Callback(app, event)
            % --- Executes on selection change in popup_numC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_numC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_numC contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_numC
            
            matc=get(handles.popup_numC,'Value')-1;
            
            if evalin('base','mcr_als.alsOptions.multi.datamod')==1
                set(handles.text_Csub,'enable','on');
                set(handles.text_selnc,'enable','on');
                set(handles.text_nr,'enable','off');
                set(handles.text_Ssub,'enable','off');
                set(handles.text_selns,'enable','off');
                set(handles.text_nc,'enable','off');
                set(handles.edit_nr,'enable','off');
                set(handles.edit_nc,'enable','off');
                set(handles.popup_numS,'enable','off','value',2);
                matr=get(handles.popup_numS,'Value')-1;
                assignin('base','matr',matr);
                evalin('base','mcr_als.alsOptions.multi.matr=matr;');
                evalin('base','clear matr ');
                j=2;
                llistannc(1)={'select...'};
                for i=1:1:matc;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
                set(handles.popup_selnc,'string',llistannc,'enable','on','value',1);
                set(handles.checkbox_samerows,'enable','on','value',0);
                set(handles.checkbox_external,'enable','on','value',0);
                set(handles.edit_external,'enable','off','string',' ');
                set(handles.popup_selns,'enable','off');
                set(handles.push_ok,'enable','off');
            
                if matc*matr ~= evalin('base','mcr_als.alsOptions.nexp')
                    errordlg('Warning: nr. of submatrices in C x nr. of submatrices in S should be equal to nexp')
                    set(handles.text_Csub,'enable','off');
                    set(handles.text_selnc,'enable','off');
                    set(handles.text_nr,'enable','off');
                    set(handles.edit_nr,'enable','off','string',' ');
                    set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
                    set(handles.checkbox_samerows,'enable','off','value',0);
                end
            
            else
            
                max=evalin('base','mcr_als.alsOptions.nexp');
                if matc==1 |matc==max |matc==0
                    errordlg('Re-define your data set class');
                    set(handles.text_Ssub,'enable','off');
                    set(handles.text_selns,'enable','off');
                    set(handles.text_nc,'enable','off');
                    set(handles.edit_nc,'enable','off','string',' ');
                    set(handles.popup_selns,'string','select...','enable','off','value',1);
                    set(handles.text_Csub,'enable','off');
                    set(handles.text_selnc,'enable','off');
                    set(handles.text_nr,'enable','off');
                    set(handles.edit_nr,'enable','off','string',' ');
                    set(handles.popup_selnc,'string','select...','enable','off','value',1);
                    set(handles.checkbox_samerows,'enable','off','value',0);
                    set(handles.checkbox_external,'enable','off','value',0);
                else
                    matr=get(handles.popup_numS,'Value')-1;
                    assignin('base','matr',matr);
                    evalin('base','mcr_als.alsOptions.multi.matr=matr;');
                    evalin('base','clear matr ');
                    j=2; % list conc. matrices
                    llistannc(1)={'select...'};
                    for i=1:1:matc;
                        llistnnc=[i];
                        llistannc(j)={llistnnc};
                        j=j+1;
                    end;
            
                    set(handles.push_ok,'enable','off');
            
                    if matr~=1 & matr~=0
            
                        if matc*matr ~= evalin('base','mcr_als.alsOptions.nexp')
                            errordlg('Warning: nr. of submatrices in C x nr. of submatrices in S should be equal to nexp')
                            set(handles.text_Ssub,'enable','off');
                            set(handles.text_selns,'enable','off');
                            set(handles.text_nc,'enable','off');
                            set(handles.edit_nc,'enable','off','string',' ');
                            set(handles.popup_selns,'string',llistannc,'enable','off','value',1);
                            set(handles.text_Csub,'enable','off');
                            set(handles.text_selnc,'enable','off');
                            set(handles.text_nr,'enable','off');
                            set(handles.edit_nr,'enable','off','string',' ');
                            set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
                            set(handles.checkbox_samerows,'enable','off','value',0);
                            set(handles.checkbox_external,'enable','off','value',0);
                        elseif matc*matr ~= evalin('base','mcr_als.alsOptions.nexp') & (matc==1 | matr==1);
                            errordlg('Warning: nr. of submatrices in C x nr. of submatrices in S should be equal to nexp')
                            set(handles.text_Csub,'enable','off');
                            set(handles.text_selnc,'enable','off');
                            set(handles.text_nr,'enable','off');
                            set(handles.edit_nr,'enable','off','string',' ');
                            set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
                            set(handles.checkbox_samerows,'enable','off','value',0);
                            set(handles.checkbox_external,'enable','off','value',0);
                            set(handles.text_Ssub,'enable','off');
                            set(handles.text_selns,'enable','off');
                            set(handles.text_nc,'enable','off');
                            set(handles.edit_nc,'enable','off','string',' ');
                            set(handles.popup_selns,'string',llistannc,'enable','off','value',1);
                        else
                            set(handles.text_Ssub,'enable','on');
                            set(handles.text_selns,'enable','on');
                            set(handles.text_nc,'enable','off');
                            set(handles.edit_nc,'enable','off','string',' ');
                            set(handles.popup_selns,'enable','on','value',1);
                            set(handles.push_ok,'enable','off');
                            set(handles.text_Csub,'enable','on');
                            set(handles.text_selnc,'enable','on');
                            set(handles.text_nr,'enable','off');
                            set(handles.edit_nr,'enable','off','string',' ');
                            set(handles.popup_selnc,'string',llistannc,'enable','on','value',1);
                            set(handles.checkbox_samerows,'enable','on','value',0);
                            set(handles.checkbox_external,'enable','on','value',0);
                            set(handles.edit_external,'enable','off','string',' ');
                            set(handles.push_ok,'enable','off');
                        end
            
                    elseif evalin('base','mcr_als.alsOptions.multi.matr')==1
                        errordlg('Re-define your data set class')
                        set(handles.text_Ssub,'enable','off');
                        set(handles.text_selns,'enable','off');
                        set(handles.text_nc,'enable','off');
                        set(handles.edit_nc,'enable','off','string',' ');
                        set(handles.popup_selns,'string','select...','enable','off','value',1);
                        set(handles.text_Csub,'enable','off');
                        set(handles.text_selnc,'enable','off');
                        set(handles.text_nr,'enable','off');
                        set(handles.edit_nr,'enable','off','string',' ');
                        set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
                        set(handles.checkbox_samerows,'enable','off','value',0);
                        set(handles.checkbox_external,'enable','off','value',0);
                    elseif evalin('base','mcr_als.alsOptions.multi.matr')==0
                        set(handles.text_Csub,'enable','off');
                        set(handles.text_selnc,'enable','off');
                        set(handles.text_nr,'enable','off');
                        set(handles.edit_nr,'enable','off','string',' ');
                        set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
                        set(handles.checkbox_samerows,'enable','off','value',0);
                        set(handles.checkbox_external,'enable','off','value',0);
                    end
                end
            end
            
            nrsol=zeros(1,matc);
            assignin('base','nrsol',nrsol);
            evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
            evalin('base','clear nrsol ');
            assignin('base','matc',matc);
            evalin('base','mcr_als.alsOptions.multi.matc=matc;');
            evalin('base','clear matc ');
        end

        % Value changed function: popup_numS
        function popup_numS_Callback(app, event)
            % --- Executes on selection change in popup_numS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_numS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_numS contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_numS
            
            
            matr=get(handles.popup_numS,'Value')-1;
            
            if evalin('base','mcr_als.alsOptions.multi.datamod')==2
                set(handles.text_Ssub,'enable','on');
                set(handles.text_selns,'enable','on');
                set(handles.text_nc,'enable','off');
                set(handles.text_Csub,'enable','off');
                set(handles.text_selnc,'enable','off');
                set(handles.text_nr,'enable','off');
                set(handles.edit_nc,'enable','off','string',' ');
                set(handles.edit_nr,'enable','off');
                set(handles.popup_numC,'enable','off','value',2);
            
                matc=get(handles.popup_numC,'Value')-1;
                assignin('base','matc',matc);
                evalin('base','mcr_als.alsOptions.multi.matc=matc;');
                evalin('base','clear matc ');
                j=2;
                llistannc(1)={'select...'};
            
                for i=1:1:matr;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
            
                set(handles.popup_selns,'string',llistannc,'enable','on','value',1);
                set(handles.popup_selnc,'enable','off');
                set(handles.checkbox_samerows,'enable','off','value',0);
                set(handles.checkbox_samecolumns,'enable','on','value',0);
                set(handles.checkbox_external,'enable','off','value',0);
                set(handles.push_ok,'enable','off');
            
                if matc*matr ~= evalin('base','mcr_als.alsOptions.nexp')
                    errordlg('Warning: nr. of submatrices in C x nr. of submatrices in S should be equal to nexp')
                    set(handles.text_Ssub,'enable','off');
                    set(handles.text_selns,'enable','off');
                    set(handles.text_nc,'enable','off');
                    set(handles.edit_nc,'enable','off','string',' ');
                    set(handles.popup_selns,'string',llistannc,'enable','off','value',1);
                    set(handles.checkbox_samecolumns,'enable','off','value',0);
                end
            else
                max=evalin('base','mcr_als.alsOptions.nexp');
                if matr==1 |matr==max |matr==0
                    errordlg('Re-define your data set class');
                    set(handles.text_Csub,'enable','off');
                    set(handles.text_selnc,'enable','off');
                    set(handles.text_nr,'enable','off');
                    set(handles.edit_nr,'enable','off','string',' ');
                    set(handles.popup_selnc,'string','select...','enable','off','value',1);
                    set(handles.checkbox_samerows,'enable','off','value',0);
                    set(handles.checkbox_samecolumns,'enable','off','value',0);
                    set(handles.checkbox_external,'enable','off','value',0);
                    set(handles.text_Ssub,'enable','off');
                    set(handles.text_selns,'enable','off');
                    set(handles.text_nc,'enable','off');
                    set(handles.edit_nc,'enable','off','string',' ');
                    set(handles.popup_selns,'string','select...','enable','off','value',1);
                else
                    matc=get(handles.popup_numC,'Value')-1;
                    assignin('base','matc',matc);
                    evalin('base','mcr_als.alsOptions.multi.matc=matc;');
                    evalin('base','clear matc ');
                    j=2;
                    llistannc(1)={'select...'};
                    for i=1:1:matr;
                        llistnnc=[i];
                        llistannc(j)={llistnnc};
                        j=j+1;
                    end;
            
                    set(handles.push_ok,'enable','off');
                    if matc~=1 & matc~=0
                        if matc*matr ~= evalin('base','mcr_als.alsOptions.nexp')
                            errordlg('Warning: nr. of submatrices in C x nr. of submatrices in S should be equal to nexp')
                            set(handles.text_Csub,'enable','off');
                            set(handles.text_selnc,'enable','off');
                            set(handles.text_nr,'enable','off');
                            set(handles.edit_nr,'enable','off','string',' ');
                            set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
                            set(handles.text_Ssub,'enable','off');
                            set(handles.text_selns,'enable','off');
                            set(handles.text_nc,'enable','off');
                            set(handles.edit_nc,'enable','off','string',' ');
                                set(handles.checkbox_samecolumns,'enable','off','value',0);
                            set(handles.popup_selns,'string',llistannc,'enable','off','value',1);
                        elseif matc*matr ~= evalin('base','mcr_als.alsOptions.nexp') & (matc==1 | matr==1);
                            errordlg('Warning: nr. of submatrices in C x nr. of submatrices in S should be equal to nexp')
                            set(handles.text_Csub,'enable','off');
                            set(handles.text_selnc,'enable','off');
                            set(handles.text_nr,'enable','off');
                            set(handles.edit_nr,'enable','off','string',' ');
                            set(handles.popup_selnc,'string',llistannc,'enable','off','value',1);
                            set(handles.checkbox_samerows,'enable','off','value',0);
                                set(handles.checkbox_samecolumns,'enable','off','value',0);
                            set(handles.checkbox_external,'enable','off','value',0);
                            set(handles.text_Ssub,'enable','off');
                            set(handles.text_selns,'enable','off');
                            set(handles.text_nc,'enable','off');
                            set(handles.edit_nc,'enable','off','string',' ');
                            set(handles.popup_selns,'string',llistannc,'enable','off','value',1);
                        else
                            set(handles.text_Csub,'enable','on');
                            set(handles.text_selnc,'enable','on');
                            set(handles.text_nr,'enable','off');
                            set(handles.edit_nr,'enable','off','string',' ');
                            set(handles.popup_selnc,'enable','on','value',1);
                            set(handles.checkbox_samerows,'enable','on','value',0);
                            set(handles.checkbox_samecolumns,'enable','on','value',0);
                            set(handles.checkbox_external,'enable','on','value',0);
                            set(handles.edit_external,'enable','off','string',' ');
                            set(handles.push_ok,'enable','off');
                            set(handles.text_Ssub,'enable','on');
                            set(handles.text_selns,'enable','on');
                            set(handles.text_nc,'enable','off');
                            set(handles.edit_nc,'enable','off','string',' ');
                            set(handles.popup_selns,'string',llistannc,'enable','on','value',1);
                            set(handles.push_ok,'enable','off');
                        end
            
                    elseif evalin('base','mcr_als.alsOptions.multi.matc')==1
                        errordlg('Re-define your data set class')
                        set(handles.text_Csub,'enable','off');
                        set(handles.text_selnc,'enable','off');
                        set(handles.text_nr,'enable','off');
                        set(handles.edit_nr,'enable','off','string',' ');
                        set(handles.popup_selnc,'string','select...','enable','off','value',1);
                        set(handles.checkbox_samerows,'enable','off','value',0);
                            set(handles.checkbox_samecolumns,'enable','off','value',0);
                        set(handles.checkbox_external,'enable','off','value',0);
                        set(handles.text_Ssub,'enable','off');
                        set(handles.text_selns,'enable','off');
                        set(handles.text_nc,'enable','off');
                        set(handles.edit_nc,'enable','off','string',' ');
                        set(handles.popup_selns,'string',llistannc,'enable','off','value',1);
                    elseif evalin('base','mcr_als.alsOptions.multi.matc')==0
                        set(handles.text_Ssub,'enable','off');
                        set(handles.text_selns,'enable','off');
                        set(handles.text_nc,'enable','off');
                        set(handles.edit_nc,'enable','off','string',' ');
                        set(handles.popup_selns,'string',llistannc,'enable','off','value',1);
                                    set(handles.checkbox_samerows,'enable','off','value',0);
                            set(handles.checkbox_samecolumns,'enable','off','value',0);
                    end
                end
            end
            
            ncsol=zeros(1,matr);
            assignin('base','ncsol',ncsol);
            evalin('base','mcr_als.alsOptions.multi.ncsol=ncsol;');
            evalin('base','clear ncsol ');
            assignin('base','matr',matr);
            evalin('base','mcr_als.alsOptions.multi.matr=matr;');
            evalin('base','clear matr ');
        end

        % Value changed function: popup_seldat
        function popup_seldat_Callback(app, event)
            % --- Executes on selection change in popup_seldat.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_seldat (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_seldat contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_seldat
            
            datamod=get(handles.popup_seldat,'Value')-1;
            nexp=evalin('base','mcr_als.alsOptions.nexp'); %control popup +1
            pop_nexp=nexp+1;
            
            if datamod==0
            
                set(handles.text_numC,'enable','off');
                set(handles.text_numS,'enable','off');
                set(handles.text_Csub,'enable','off');
                set(handles.text_selnc,'enable','off');
                set(handles.text_nr,'enable','off');
                set(handles.text_Ssub,'enable','off');
                set(handles.text_selns,'enable','off');
                set(handles.text_nc,'enable','off');
                set(handles.edit_nr,'enable','off');
                set(handles.edit_nc,'enable','off');
                set(handles.popup_selnc,'enable','off','value',1);
                set(handles.checkbox_samerows,'enable','off','value',0);
                set(handles.checkbox_samecolumns,'enable','off','value',0);
                set(handles.checkbox_external,'enable','off','value',0);
                set(handles.popup_selns,'enable','off','value',1);
                set(handles.push_ok,'enable','off');
                set(handles.popup_numC,'enable','off','value',1);
                set(handles.popup_numS,'enable','off','value',1);
            
            elseif datamod==1
            
                set(handles.text_numC,'enable','on');
                set(handles.text_numS,'enable','off');
                set(handles.text_Csub,'enable','on');
                set(handles.text_selnc,'enable','on');
                set(handles.text_nr,'enable','off');
                set(handles.text_Ssub,'enable','off');
                set(handles.text_selns,'enable','off');
                set(handles.text_nc,'enable','off');
                set(handles.edit_nr,'enable','off');
                set(handles.edit_nc,'enable','off');
                set(handles.popup_numC,'enable','inactive','value',pop_nexp); %%%%
                set(handles.popup_numS,'enable','off','value',1);
                set(handles.popup_selnc,'enable','on','value',1);
                set(handles.checkbox_samerows,'enable','on','value',0);
                set(handles.checkbox_samecolumns,'enable','off','value',0);
                set(handles.checkbox_external,'enable','on','value',0);
                set(handles.popup_selns,'enable','off','value',1);
                set(handles.push_ok,'enable','off');
            
                % create list selnC
            
                matc=nexp;
                j=2;
                llistannc(1)={'select...'};
            
                for i=1:1:matc;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
            
                set(handles.popup_selnc,'string',llistannc,'enable','on','value',1);
                matc=nexp;
                nrsol=zeros(1,matc);
                assignin('base','nrsol',nrsol);
                evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
                evalin('base','clear nrsol ');
                assignin('base','matc',matc);
                evalin('base','mcr_als.alsOptions.multi.matc=matc;');
                evalin('base','clear matc ');
                matr=1;
                assignin('base','matr',matr);
                evalin('base','mcr_als.alsOptions.multi.matr=matr;');
                evalin('base','clear matr');
            
            elseif datamod==2
            
                set(handles.text_numS,'enable','on');
                set(handles.text_numC,'enable','off');
                set(handles.text_Csub,'enable','off');
                set(handles.text_selnc,'enable','off');
                set(handles.text_nr,'enable','off');
                set(handles.text_Ssub,'enable','on');
                set(handles.text_selns,'enable','on');
                set(handles.text_nc,'enable','off');
                set(handles.edit_nr,'enable','off');
                set(handles.edit_nc,'enable','off');
                set(handles.popup_numS,'enable','inactive','value',pop_nexp);
                set(handles.popup_numC,'enable','off','value',1);
                set(handles.popup_selnc,'enable','off','value',1);
                set(handles.checkbox_samerows,'enable','off','value',0);
                set(handles.checkbox_samecolumns,'enable','on','value',0);
                set(handles.checkbox_external,'enable','off','value',0);
                set(handles.popup_selns,'enable','on','value',1);
                set(handles.push_ok,'enable','off');
            
                % create list selnC
                matr=nexp;
                j=2;
                llistannc(1)={'select...'};
                for i=1:1:matr;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
                set(handles.popup_selns,'string',llistannc,'enable','on','value',1);
                matr=nexp;
                ncsol=zeros(1,matr);
                assignin('base','ncsol',ncsol);
                evalin('base','mcr_als.alsOptions.multi.ncsol=ncsol;');
                evalin('base','clear ncsol ');
                assignin('base','matr',matr);
                evalin('base','mcr_als.alsOptions.multi.matr=matr;');
                evalin('base','clear matr ');
                matc=1;
                assignin('base','matc',matc);
                evalin('base','mcr_als.alsOptions.multi.matc=matc;');
                evalin('base','clear matc');
            
            elseif datamod==3
            
                set(handles.text_numC,'enable','on');
                set(handles.text_numS,'enable','on');
                set(handles.text_Csub,'enable','off');
                set(handles.text_selnc,'enable','off');
                set(handles.text_nr,'enable','off');
                set(handles.text_Ssub,'enable','off');
                set(handles.text_selns,'enable','off');
                set(handles.text_nc,'enable','off');
                set(handles.edit_nr,'enable','off');
                set(handles.edit_nc,'enable','off');
                set(handles.popup_numC,'enable','on','value',1);
                set(handles.popup_numS,'enable','on','value',1);
                set(handles.popup_selnc,'enable','off','value',1);
                set(handles.checkbox_samerows,'enable','off','value',0);
                set(handles.checkbox_samecolumns,'enable','off','value',0);
                set(handles.checkbox_external,'enable','off','value',0);
                set(handles.popup_selns,'enable','off','value',1);
                set(handles.push_ok,'enable','off');
            
                %init. matc i matr
                matc=0;matr=0;
                assignin('base','matc',matc);
                evalin('base','mcr_als.alsOptions.multi.matc=matc;');
                evalin('base','clear matc');
                assignin('base','matr',matr);
                evalin('base','mcr_als.alsOptions.multi.matr=matr;');
                evalin('base','clear matr');
            
            
            end
            
            assignin('base','datamod',datamod);
            evalin('base','mcr_als.alsOptions.multi.datamod=datamod;');
            evalin('base','clear datamod');
        end

        % Value changed function: popup_selnc
        function popup_selnc_Callback(app, event)
            % --- Executes on selection change in popup_selnc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_selnc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_selnc contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_selnc
            
            numc=get(handles.popup_selnc,'Value')-1;
            assignin('base','numc',numc);
            evalin('base','mcr_als.alsOptions.multi.numc=numc;');
            
            if numc>0
                set(handles.text_nr,'enable','on');
                set(handles.edit_nr,'enable','on','string',evalin('base','mcr_als.alsOptions.multi.nrsol(numc)'));
            else
                set(handles.text_nr,'enable','off');
                set(handles.edit_nr,'enable','off','string',' ');
            end
            
            evalin('base','clear numc ');
            set(handles.push_ok,'enable','on');
        end

        % Value changed function: popup_selns
        function popup_selns_Callback(app, event)
            % --- Executes on selection change in popup_selns.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_selns (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_selns contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_selns
            
            nums=get(handles.popup_selns,'Value')-1;
            assignin('base','nums',nums);
            evalin('base','mcr_als.alsOptions.multi.nums=nums;');
            
            if nums>0
                set(handles.text_nc,'enable','on');
                set(handles.edit_nc,'enable','on','string',evalin('base','mcr_als.alsOptions.multi.ncsol(nums)'));
            else
                set(handles.text_nc,'enable','off');
                set(handles.edit_nc,'enable','off','string',' ');
            end
            
            evalin('base','clear nums ');
            set(handles.push_ok,'enable','on');
        end

        % Button pushed function: push_can
        function push_can_Callback(app, event)
            % --- Executes on button press in push_can.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_can (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end

        % Button pushed function: push_ok
        function push_ok_Callback(app, event)
            % --- Executes on button press in push_ok.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_ok (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if evalin('base','mcr_als.alsOptions.multi.datamod') ==1
                nrsol=evalin('base','mcr_als.alsOptions.multi.nrsol');
                [nr,nc]=size(evalin('base','mcr_als.alsOptions.matdad'));
                nrfin=cumsum(evalin('base','mcr_als.alsOptions.multi.nrsol'));
                [b,a]=size(nrfin);
                nrinic=[1 1+nrfin(1:(a-1))];
                for i=1:a;
                    ncinic(1,i)=1;
                    ncfin(1,i)=nc;
                end
                ncsol=nc;
                assignin('base','ncsol',ncsol);
                evalin('base','mcr_als.alsOptions.multi.ncsol=ncsol;');
                evalin('base','clear ncsol ');
            elseif evalin('base','mcr_als.alsOptions.multi.datamod')==2
                ncsol=evalin('base','mcr_als.alsOptions.multi.ncsol');
                [nr,nc]=size(evalin('base','mcr_als.alsOptions.matdad'));
                ncfin=cumsum(evalin('base','mcr_als.alsOptions.multi.ncsol'));
                [b,a]=size(ncfin);
                ncinic=[1 1+ncfin(1:(a-1))];
                for i=1:a;
                    nrinic(1,i)=1;
                    nrfin(1,i)=nr;
                end
                nrsol=nr;
                assignin('base','nrsol',nrsol);
                evalin('base','mcr_als.alsOptions.multi.nrsol=nrsol;');
                evalin('base','clear nrsol ');
            elseif evalin('base','mcr_als.alsOptions.multi.datamod')==3
                nrsol=evalin('base','mcr_als.alsOptions.multi.nrsol');
                ncsol=evalin('base','mcr_als.alsOptions.multi.ncsol');
                [nr,nc]=size(evalin('base','mcr_als.alsOptions.matdad'));
                nrfin=cumsum(evalin('base','mcr_als.alsOptions.multi.nrsol'));
                [b,a]=size(nrfin);
                nrinic=[1 1+nrfin(1:(a-1))];
                ncfin=cumsum(evalin('base','mcr_als.alsOptions.multi.ncsol'));
                [b,a]=size(ncfin);
                ncinic=[1 1+ncfin(1:(a-1))];
            end
            
            assignin('base','ncfin',ncfin);
            evalin('base','mcr_als.alsOptions.multi.ncfin=ncfin;');
            evalin('base','clear ncfin ');
            assignin('base','ncinic',ncinic);
            evalin('base','mcr_als.alsOptions.multi.ncinic=ncinic;');
            evalin('base','clear ncinic ');
            assignin('base','nrfin',nrfin);
            evalin('base','mcr_als.alsOptions.multi.nrfin=nrfin;');
            evalin('base','clear nrfin ');
            assignin('base','nrinic',nrinic);
            evalin('base','mcr_als.alsOptions.multi.nrinic=nrinic;');
            evalin('base','clear nrinic ');
            
            % isp
            nesp=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            matr=evalin('base','mcr_als.alsOptions.multi.matr');
            isp=ones(matc,nesp);
            assignin('base','isp',isp);
            evalin('base','mcr_als.alsOptions.multi.isp=isp;');
            evalin('base','clear isp ');
            
            % check dimensions
            
            if evalin('base','mcr_als.alsOptions.multi.datamod')==1
                dimr=sum(evalin('base','mcr_als.alsOptions.multi.nrsol'));
                [row,col]=size(evalin('base','mcr_als.alsOptions.matdad'));
                if dimr ~= row
                    errordlg('check dimensionsof the c submatrices');
                else
                    close;
                    rowMultiConstraints_App;
                end
            
            elseif evalin('base','mcr_als.alsOptions.multi.datamod')==2
                dimc=sum(evalin('base','mcr_als.alsOptions.multi.ncsol'));
                [row,col]=size(evalin('base','mcr_als.alsOptions.matdad'));
                if dimc ~= col
                    errordlg('check dimensions of the s submatrices');
                else
                    close;
                    rowModeConstraints_App;
                end
            
            elseif evalin('base','mcr_als.alsOptions.multi.datamod')==3
                dimr=sum(evalin('base','mcr_als.alsOptions.multi.nrsol'));
                dimc=sum(evalin('base','mcr_als.alsOptions.multi.ncsol'));
                [row,col]=size(evalin('base','mcr_als.alsOptions.matdad'));
                if dimr ~= row
                    errordlg('check dimensionsof the c submatrices');
                elseif dimc ~= col
                    errordlg('check dimensions of the s submatrices');
                else
                    close;
                    rowMultiConstraints_App;
                end
            
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Definition of the 3-way data set';
            app.figure1.Tag = 'figure1';
            app.text_def = uilabel(app.figure1);
            app.text_def.Tag = 'text_def';
            app.text_def.Text = 'Definition of the data set';
            app.text_seldat = uilabel(app.figure1);
            app.text_seldat.Tag = 'text_seldat';
            app.text_seldat.Text = 'Define your data set:';
            app.text_numC = uilabel(app.figure1);
            app.text_numC.Tag = 'text_numC';
            app.text_numC.Enable = 'off';
            app.text_numC.Text = 'How many submatrices has the C matrix?';
            app.text_numS = uilabel(app.figure1);
            app.text_numS.Tag = 'text_numS';
            app.text_numS.Enable = 'off';
            app.text_numS.Text = 'How many submatrices has the S matrix?';
            app.text_Csub = uilabel(app.figure1);
            app.text_Csub.Tag = 'text_Csub';
            app.text_Csub.Enable = 'off';
            app.text_Csub.Text = 'C submatrix';
            app.text_Ssub = uilabel(app.figure1);
            app.text_Ssub.Tag = 'text_Ssub';
            app.text_Ssub.Enable = 'off';
            app.text_Ssub.Text = 'S submatrix';
            app.text_selnc = uilabel(app.figure1);
            app.text_selnc.Tag = 'text_selnc';
            app.text_selnc.Enable = 'off';
            app.text_selnc.Text = 'Submatrix Nr.';
            app.text_selns = uilabel(app.figure1);
            app.text_selns.Tag = 'text_selns';
            app.text_selns.Enable = 'off';
            app.text_selns.Text = 'Submatrix Nr.';
            app.text_nc = uilabel(app.figure1);
            app.text_nc.Tag = 'text_nc';
            app.text_nc.Enable = 'off';
            app.text_nc.Text = 'Nr. of columns';
            app.text_nr = uilabel(app.figure1);
            app.text_nr.Tag = 'text_nr';
            app.text_nr.Enable = 'off';
            app.text_nr.Text = 'Nr. of rows';
            app.edit_nr = uieditfield(app.figure1, 'text');
            app.edit_nr.ValueChangedFcn = createCallbackFcn(app, @edit_nr_Callback, true);
            app.edit_nr.Tag = 'edit_nr';
            app.edit_nr.Enable = 'off';
            app.edit_nc = uieditfield(app.figure1, 'text');
            app.edit_nc.ValueChangedFcn = createCallbackFcn(app, @edit_nc_Callback, true);
            app.edit_nc.Tag = 'edit_nc';
            app.edit_nc.Enable = 'off';
            app.push_ok = uibutton(app.figure1, 'push');
            app.push_ok.ButtonPushedFcn = createCallbackFcn(app, @push_ok_Callback, true);
            app.push_ok.Tag = 'push_ok';
            app.push_ok.Enable = 'off';
            app.push_ok.Text = 'OK';
            app.push_can = uibutton(app.figure1, 'push');
            app.push_can.ButtonPushedFcn = createCallbackFcn(app, @push_can_Callback, true);
            app.push_can.Tag = 'push_can';
            app.push_can.Text = 'Cancel';
            app.checkbox_samerows = uicheckbox(app.figure1);
            app.checkbox_samerows.ValueChangedFcn = createCallbackFcn(app, @checkbox_samerows_Callback, true);
            app.checkbox_samerows.Tag = 'checkbox_samerows';
            app.checkbox_samerows.Enable = 'off';
            app.checkbox_samerows.Text = 'All matrices have the same Nr. of rows?';
            app.checkbox_external = uicheckbox(app.figure1);
            app.checkbox_external.ValueChangedFcn = createCallbackFcn(app, @checkbox_external_Callback, true);
            app.checkbox_external.Tag = 'checkbox_external';
            app.checkbox_external.Enable = 'off';
            app.checkbox_external.Text = 'Input rows frrom external variable?';
            app.edit_external = uieditfield(app.figure1, 'text');
            app.edit_external.ValueChangedFcn = createCallbackFcn(app, @edit_external_Callback, true);
            app.edit_external.Tag = 'edit_external';
            app.edit_external.Enable = 'off';
            app.popup_selns = uidropdown(app.figure1);
            app.popup_selns.Items = {'select...'};
            app.popup_selns.ValueChangedFcn = createCallbackFcn(app, @popup_selns_Callback, true);
            app.popup_selns.Tag = 'popup_selns';
            app.popup_selns.Enable = 'off';
            app.popup_selns.Value = 'select...';
            app.popup_selnc = uidropdown(app.figure1);
            app.popup_selnc.Items = {'select...'};
            app.popup_selnc.ValueChangedFcn = createCallbackFcn(app, @popup_selnc_Callback, true);
            app.popup_selnc.Tag = 'popup_selnc';
            app.popup_selnc.Enable = 'off';
            app.popup_selnc.Value = 'select...';
            app.popup_numS = uidropdown(app.figure1);
            app.popup_numS.Items = {'select...', '1'};
            app.popup_numS.ValueChangedFcn = createCallbackFcn(app, @popup_numS_Callback, true);
            app.popup_numS.Tag = 'popup_numS';
            app.popup_numS.Enable = 'off';
            app.popup_numS.Value = 'select...';
            app.popup_numC = uidropdown(app.figure1);
            app.popup_numC.Items = {'select...', '1'};
            app.popup_numC.ValueChangedFcn = createCallbackFcn(app, @popup_numC_Callback, true);
            app.popup_numC.Tag = 'popup_numC';
            app.popup_numC.Enable = 'off';
            app.popup_numC.Value = 'select...';
            app.popup_seldat = uidropdown(app.figure1);
            app.popup_seldat.Items = {'select...', 'Column-wise augmented data matrix (C direction)', 'Row-wise augmented data matrix (S direction)', 'Column- and Row-wise augmented data matrix (C&S directions)'};
            app.popup_seldat.ValueChangedFcn = createCallbackFcn(app, @popup_seldat_Callback, true);
            app.popup_seldat.Tag = 'popup_seldat';
            app.popup_seldat.Value = 'select...';
            app.checkbox_samecolumns = uicheckbox(app.figure1);
            app.checkbox_samecolumns.ValueChangedFcn = createCallbackFcn(app, @checkbox_samecolumns_Callback, true);
            app.checkbox_samecolumns.Tag = 'checkbox_samecolumns';
            app.checkbox_samecolumns.Enable = 'off';
            app.checkbox_samecolumns.Text = 'All matrices have the same Nr. of columns?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = clas3way_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)clas3way_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end