classdef closure_no_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        push_tsum    matlab.ui.control.Button
        text3        matlab.ui.control.Label
        push_length  matlab.ui.control.Button
        push_height  matlab.ui.control.Button
        text2        matlab.ui.control.Label
        text1        matlab.ui.control.Label
        push_none    matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
%         function figure1_CloseRequestFcn(app, hObject, eventdata, handles)
%             % --- Executes when user attempts to close figure1.
%             
%             % hObject    handle to figure1 (see GCBO)
%             % eventdata  reserved - to be defined in a future version of MATLAB
%             % handles    structure with handles and user data (see GUIDATA)
%             
%             if isequal(get(handles.figure1, 'waitstatus'), 'waiting')
%                 % The GUI is still in UIWAIT, us UIRESUME
%                 uiresume(handles.figure1);
%             else
%                 % The GUI is no longer waiting, just close it
%                 delete(handles.figure1);
%             end
%         end
        
        function pushbutton1_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton1.
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.output = get(hObject,'String');
            
            % Update handles structure
            guidata(hObject, handles);
            
            % Use UIRESUME instead of delete because the OutputFcn needs
            % to get the updated handles structure.
            uiresume(handles.figure1);
        end
        
        function pushbutton2_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in pushbutton2.
            
            % hObject    handle to pushbutton2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            handles.output = get(hObject,'String');
            
            % Update handles structure
            guidata(hObject, handles);
            
            % Use UIRESUME instead of delete because the OutputFcn needs
            % to get the updated handles structure.
            uiresume(handles.figure1);
        end
        
        % Update components that require runtime configuration
%         function addRuntimeConfigurations(app)
%             
%             % Set component properties that require runtime configuration
%             app.figure1.CloseRequestFcn = 'closure_no(''figure1_CloseRequestFcn'',gcf,[],guidata(gcf))';
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function closure_no_OpeningFcn(app, varargin)
            % --- Executes just before closure_no is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
%             addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to closure_no (see VARARGIN)
            
            % Choose default command line output for closure_no
            handles.output = 'Yes';
            
            % Update handles structure
            guidata(hObject, handles);
            
            % Insert custom Title and Text if specified by the user
            % Hint: when choosing keywords, be sure they are not easily confused
            % with existing figure properties.  See the output of set(figure) for
            % a list of figure properties.
            if(nargin > 3)
                for index = 1:2:(nargin-3),
                    if nargin-3==index break, end
                    switch lower(varargin{index})
                     case 'title'
                      set(hObject, 'Name', varargin{index+1});
                     case 'string'
                      set(handles.text1, 'String', varargin{index+1});
                    end
                end
            end
            
            % Determine the position of the dialog - centered on the callback figure
            % if available, else, centered on the screen
            FigPos=get(0,'DefaultFigurePosition');
            OldUnits = get(hObject, 'Units');
            set(hObject, 'Units', 'pixels');
            OldPos = get(hObject,'Position');
            FigWidth = OldPos(3);
            FigHeight = OldPos(4);
            if isempty(gcbf)
                ScreenUnits=get(0,'Units');
                set(0,'Units','pixels');
                ScreenSize=get(0,'ScreenSize');
                set(0,'Units',ScreenUnits);
            
                FigPos(1)=1/2*(ScreenSize(3)-FigWidth);
                FigPos(2)=2/3*(ScreenSize(4)-FigHeight);
            else
                GCBFOldUnits = get(gcbf,'Units');
                set(gcbf,'Units','pixels');
                GCBFPos = get(gcbf,'Position');
                set(gcbf,'Units',GCBFOldUnits);
                FigPos(1:2) = [(GCBFPos(1) + GCBFPos(3) / 2) - FigWidth / 2, ...
                               (GCBFPos(2) + GCBFPos(4) / 2) - FigHeight / 2];
            end
            FigPos(3:4)=[FigWidth FigHeight];
            set(hObject, 'Position', FigPos);
            set(hObject, 'Units', OldUnits);
            
            % Show a question icon from dialogicons.mat - variables questIconData
            % and questIconMap
            load dialogicons.mat
            
            IconData=questIconData;
            questIconMap(256,:) = get(handles.figure1, 'Color');
            IconCMap=questIconMap;
            
            Img=image(IconData, 'Parent', handles.axes1);
            set(handles.figure1, 'Colormap', IconCMap);
            
            set(handles.axes1, ...
                'Visible', 'off', ...
                'YDir'   , 'reverse'       , ...
                'XLim'   , get(Img,'XData'), ...
                'YLim'   , get(Img,'YData')  ...
                );
            
            % Make the GUI modal
            set(handles.figure1,'WindowStyle','modal')
            
            % UIWAIT makes closure_no wait for user response (see UIRESUME)
            uiwait(handles.figure1);
        end

        % Key press function: figure1
        function figure1_KeyPressFcn(app, event)
            % --- Executes on key press over figure1 with no controls selected.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Check for "enter" or "escape"
            if isequal(get(hObject,'CurrentKey'),'escape')
                % User said no by hitting escape
                handles.output = 'No';
            
                % Update handles structure
                guidata(hObject, handles);
            
                uiresume(handles.figure1);
            end
            
            if isequal(get(hObject,'CurrentKey'),'return')
                uiresume(handles.figure1);
            end
        end

        % Button pushed function: push_height
        function push_height_Callback(app, event)
            % --- Executes on button press in push_height.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_height (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            inorm=1;
            assignin('base','inorm',inorm);
            evalin('base','mcr_als.alsOptions.closure.inorm=inorm;');
            evalin('base','clear inorm');
            close;
            closX='Spectra equal height';
            assignin('base','closX',closX);
            evalin('base','mcr_als.alsOptions.closure.type=closX;');
            evalin('base','clear closX');
            als_parameters_App;
        end

        % Button pushed function: push_length
        function push_length_Callback(app, event)
            % --- Executes on button press in push_length.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_length (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            inorm=2;
            assignin('base','inorm',inorm);
            evalin('base','mcr_als.alsOptions.closure.inorm=inorm;');
            evalin('base','clear inorm');
            close;
            closX='Spectra equal lenght - divided by Frobenius norm';
            assignin('base','closX',closX);
            evalin('base','mcr_als.alsOptions.closure.type=closX;');
            evalin('base','clear closX');
            als_parameters_App;
        end

        % Button pushed function: push_none
        function push_none_Callback(app, event)
            % --- Executes on button press in push_none.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_none (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            inorm=0;
            assignin('base','inorm',inorm);
            evalin('base','mcr_als.alsOptions.closure.inorm=inorm;');
            evalin('base','clear inorm');
            close;
            closX='None';
            assignin('base','closX',closX);
            evalin('base','mcr_als.alsOptions.closure.type=closX;');
            evalin('base','clear closX');
            als_parameters_App;
        end

        % Button pushed function: push_tsum
        function push_tsum_Callback(app, event)
            % --- Executes on button press in push_tsum.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_tsum (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            inorm=3;
            assignin('base','inorm',inorm);
            evalin('base','mcr_als.alsOptions.closure.inorm=inorm;');
            evalin('base','clear inorm');
            close;
            closX='Spectra equal lenght - divided by total sum norm';
            assignin('base','closX',closX);
            evalin('base','mcr_als.alsOptions.closure.type=closX;');
            evalin('base','clear closX');
            als_parameters_App;
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            % --- Executes when user attempts to close figure1.
            
            % hObject    handle to figure1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            delete(app);

%             if isequal(get(app, 'waitstatus'), 'waiting')
%                 % The GUI is still in UIWAIT, us UIRESUME
%                 uiresume(app);
%             else
%                 % The GUI is no longer waiting, just close it
%                 delete(app);
%             end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Warning! No closure selected.';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.figure1.KeyPressFcn = createCallbackFcn(app, @figure1_KeyPressFcn, true);
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.XLim = [1 51];
            app.axes1.YLim = [1 51];
            app.axes1.Tag = 'axes1';
            app.axes1.Visible = 'off';
            app.push_none = uibutton(app.figure1, 'push');
            app.push_none.ButtonPushedFcn = createCallbackFcn(app, @push_none_Callback, true);
            app.push_none.Tag = 'push_none';
            app.push_none.Text = 'None';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'WARNING! NO CLOSURE SELECTED:';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'Normalization of the S matrix (pure spectra) '; 'can be recommended'};
            app.push_height = uibutton(app.figure1, 'push');
            app.push_height.ButtonPushedFcn = createCallbackFcn(app, @push_height_Callback, true);
            app.push_height.Tag = 'push_height';
            app.push_height.Text = 'Spectra equal height';
            app.push_length = uibutton(app.figure1, 'push');
            app.push_length.ButtonPushedFcn = createCallbackFcn(app, @push_length_Callback, true);
            app.push_length.Tag = 'push_length';
            app.push_length.Text = 'Spectra divided by Euclidean norm';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Types of normalization :';
            app.push_tsum = uibutton(app.figure1, 'push');
            app.push_tsum.ButtonPushedFcn = createCallbackFcn(app, @push_tsum_Callback, true);
            app.push_tsum.Tag = 'push_tsum';
            app.push_tsum.Text = 'Spectra divided by total sum norm';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = closure_no_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)closure_no_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end