classdef columnModeConstraints_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        push_ConstInfo  matlab.ui.control.Button
        push_back       matlab.ui.control.Button
        push_reset      matlab.ui.control.Button
        uipanel1        matlab.ui.container.ButtonGroup
        text2           matlab.ui.control.Label
        push_continue   matlab.ui.control.Button
        text1           matlab.ui.control.Label
        uipanel3        matlab.ui.container.ButtonGroup
        uipanel4        matlab.ui.container.ButtonGroup
        text_nnCp       matlab.ui.control.Label
        edit_nnCp       matlab.ui.control.EditField
        popup_nnCsp     matlab.ui.control.DropDown
        popup_nnCi      matlab.ui.control.DropDown
        text_nnCsp      matlab.ui.control.Label
        text_nnCi       matlab.ui.control.Label
        check_nnC       matlab.ui.control.CheckBox
        uipanel6        matlab.ui.container.Panel
        check_unimod    matlab.ui.control.CheckBox
        text_Utol       matlab.ui.control.Label
        edit_Utol       matlab.ui.control.EditField
        text_Uv         matlab.ui.control.Label
        edit_Uv         matlab.ui.control.EditField
        popup_Up        matlab.ui.control.DropDown
        text_Up         matlab.ui.control.Label
        popup_Ui        matlab.ui.control.DropDown
        text_Ui         matlab.ui.control.Label
        uipanel9        matlab.ui.container.Panel
        check_C2sp      matlab.ui.control.CheckBox
        edit_C2sp       matlab.ui.control.EditField
        text_C2sp       matlab.ui.control.Label
        edit_C1sp       matlab.ui.control.EditField
        check_C1sp      matlab.ui.control.CheckBox
        text_C1sp       matlab.ui.control.Label
        popup_C2c       matlab.ui.control.DropDown
        popup_C1c       matlab.ui.control.DropDown
        text_C1c        matlab.ui.control.Label
        text_C2c        matlab.ui.control.Label
        edit_C2v        matlab.ui.control.EditField
        text_C2v        matlab.ui.control.Label
        edit_C1v        matlab.ui.control.EditField
        text_C1v        matlab.ui.control.Label
        edit_C2         matlab.ui.control.EditField
        text_C2         matlab.ui.control.Label
        edit_C1         matlab.ui.control.EditField
        text_C1         matlab.ui.control.Label
        popup_Cnr       matlab.ui.control.DropDown
        check_Cvar      matlab.ui.control.CheckBox
        text_Cnr        matlab.ui.control.Label
        Pan_Clos        matlab.ui.container.ButtonGroup
        check_closC     matlab.ui.control.CheckBox
        uipanel8        matlab.ui.container.Panel
        popup_eqCc      matlab.ui.control.DropDown
        popup_eqCs      matlab.ui.control.DropDown
        text_eqCc       matlab.ui.control.Label
        text_eqCs       matlab.ui.control.Label
        check_eqC       matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function columnModeConstraints_OpeningFcn(app, varargin)
            % --- Executes just before columnModeConstraints is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to columnModeConstraints (see VARARGIN)
            
            % Choose default command line output for columnModeConstraints
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            clos_used=evalin('base','mcr_als.alsOptions.closure.closure');
            if clos_used==1
                set(handles.check_closC,'enable','off');
                set(handles.Pan_Clos,'visible','on');
            
                closX='concentration';
                assignin('base','closX',closX);
                evalin('base','mcr_als.alsOptions.closure.type=closX;');
                evalin('base','clear closX');
            end

%             lsvar=evalin('base','whos');
%             [aa,bb]=size(lsvar);
%             j=2;
%             lsv(1)={'select a variable from the WS'};
%             for i=1:aa,
%                 csize=length(lsvar(i).class);
%                 if csize==6,
%                     if lsvar(i).class=='double',
%                         lsb=[lsvar(i).name];
%                         lsv(j)={lsb};
%                         j=j+1;
%                     end;
%                 end;
%             end;
%             
%             set(app.popup_eqCs,'string',lsv)
        end

        % Value changed function: check_C1sp
        function check_C1sp_Callback(app, event)
            % --- Executes on button press in check_C1sp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_C1sp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_C1sp
            
            check1clos=get(hObject,'Value');
            
            if check1clos==0
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                ceros=zeros(1,nsign);
                set(handles.text_C1sp,'enable','on');
                set(handles.edit_C1sp,'enable','on','string',' ');
                assignin('base','sclos1',ceros);
                evalin('base','mcr_als.alsOptions.closure.sclos1=sclos1;');
                evalin('base','clear sclos1');
            
            elseif check1clos==1
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                unos=ones(1,nsign);
                set(handles.text_C1sp,'enable','on');
                set(handles.edit_C1sp,'enable','inactive','string',num2str(unos));
                assignin('base','sclos1',unos);
                evalin('base','mcr_als.alsOptions.closure.sclos1=sclos1;');
                evalin('base','clear sclos1');
            end
        end

        % Value changed function: check_C2sp
        function check_C2sp_Callback(app, event)
            % --- Executes on button press in check_C2sp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_C2sp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_C2sp
            
            check2clos=get(hObject,'Value');
            
            if check2clos==0
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                ceros=zeros(1,nsign);
                set(handles.text_C2sp,'enable','on');
                set(handles.edit_C2sp,'enable','on','string',' ');
                assignin('base','sclos2',ceros);
                evalin('base','mcr_als.alsOptions.closure.sclos2=sclos2;');
                evalin('base','clear sclos2');
            
            elseif check2clos==1
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                unos=ones(1,nsign);
                set(handles.text_C2sp,'enable','on');
                set(handles.edit_C2sp,'enable','inactive','string',num2str(unos));
                assignin('base','sclos2',unos);
                evalin('base','mcr_als.alsOptions.closure.sclos2=sclos2;');
                evalin('base','clear sclos2');
            end
        end

        % Value changed function: check_Cvar
        function check_Cvar_Callback(app, event)
            % --- Executes on button press in check_Cvar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_Cvar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_Cvar
            
            vclos=get(hObject,'Value');
            
            if vclos==1;
                iclos=evalin('base','mcr_als.alsOptions.closure.iclos');
                if iclos==1
                    set(handles.text_C1,'enable','off');
                    set(handles.edit_C1,'enable','off');
                    set(handles.text_C1c,'enable','off');
                    set(handles.popup_C1c,'enable','off','value',1);
                    set(handles.text_C1sp,'enable','on');
                    set(handles.edit_C1sp,'enable','on','string',' ');
                    set(handles.check_C1sp,'enable','on','value',0);
                    set(handles.text_C1v,'enable','on');
                    set(handles.edit_C1v,'enable','on','string',' ');
            
                    set(handles.text_C2,'enable','off');
                    set(handles.edit_C2,'enable','off');
                    set(handles.text_C2c,'enable','off');
                    set(handles.popup_C2c,'enable','off','value',1);
                    set(handles.text_C2sp,'enable','off');
                    set(handles.edit_C2sp,'enable','off','string',' ');
                    set(handles.check_C2sp,'enable','off','value',0);
                    set(handles.text_C2v,'enable','off');
                    set(handles.edit_C2v,'enable','off','string',' ');
            
                    iclos1=1; % equal
                    assignin('base','iclos1',iclos1);
                    evalin('base','mcr_als.alsOptions.closure.iclos1=iclos1;');
                    evalin('base','clear iclos1');
            
                    vc=1; % equal
                    assignin('base','vc',vc);
                    evalin('base','mcr_als.alsOptions.closure.vc=vc;');
                    evalin('base','clear vc');
            
                elseif iclos==2
            
                    set(handles.text_C1,'enable','off');
                    set(handles.edit_C1,'enable','off');
                    set(handles.text_C1c,'enable','off');
                    set(handles.popup_C1c,'enable','off','value',1);
                    set(handles.text_C1sp,'enable','on');
                    set(handles.edit_C1sp,'enable','on','string',' ');
                    set(handles.check_C1sp,'enable','on','value',0);
                    set(handles.text_C1v,'enable','on');
                    set(handles.edit_C1v,'enable','on','string',' ');
                    set(handles.text_C2,'enable','off');
                    set(handles.edit_C2,'enable','off');
                    set(handles.text_C2c,'enable','off');
                    set(handles.popup_C2c,'enable','off','value',1);
                    set(handles.text_C2sp,'enable','on');
                    set(handles.edit_C2sp,'enable','on','string',' ');
                    set(handles.check_C2sp,'enable','on','value',0);
                    set(handles.text_C2v,'enable','on');
                    set(handles.edit_C2v,'enable','on','string',' ');
            
                    iclos1=1; % equal
                    assignin('base','iclos1',iclos1);
                    evalin('base','mcr_als.alsOptions.closure.iclos1=iclos1;');
                    evalin('base','clear iclos1');
            
                    iclos2=1; % equal
                    assignin('base','iclos2',iclos2);
                    evalin('base','mcr_als.alsOptions.closure.iclos2=iclos2;');
                    evalin('base','clear iclos2');
            
                    vc=1; % equal
                    assignin('base','vc',vc);
                    evalin('base','mcr_als.alsOptions.closure.vc=vc;');
                    evalin('base','clear vc');
            
                end
            
            elseif vclos==0
            
                iclos=evalin('base','mcr_als.alsOptions.closure.iclos');
                if iclos==1
                    set(handles.text_C1,'enable','on');
                    set(handles.edit_C1,'enable','on');
                    set(handles.text_C1c,'enable','on');
                    set(handles.popup_C1c,'enable','on','value',1);
                    set(handles.text_C1sp,'enable','on');
                    set(handles.edit_C1sp,'enable','on','string',' ');
                    set(handles.check_C1sp,'enable','on','value',0);
                    set(handles.text_C1v,'enable','off');
                    set(handles.edit_C1v,'enable','off','string',' ');
            
                    set(handles.text_C2,'enable','off');
                    set(handles.edit_C2,'enable','off');
                    set(handles.text_C2c,'enable','off');
                    set(handles.popup_C2c,'enable','off','value',1);
                    set(handles.text_C2sp,'enable','off');
                    set(handles.edit_C2sp,'enable','off','string',' ');
                    set(handles.check_C2sp,'enable','off','value',0);
                    set(handles.text_C2v,'enable','off');
                    set(handles.edit_C2v,'enable','off','string',' ');
            
                    iclos1=1; % equal
                    assignin('base','iclos1',iclos1);
                    evalin('base','mcr_als.alsOptions.closure.iclos1=iclos1;');
                    evalin('base','clear iclos1');
            
                    vc=1; % equal
                    assignin('base','vc',vc);
                    evalin('base','mcr_als.alsOptions.closure.vc=vc;');
                    evalin('base','clear vc');
            
                elseif iclos==2
            
                    set(handles.text_C1,'enable','on');
                    set(handles.edit_C1,'enable','on');
                    set(handles.text_C1c,'enable','on');
                    set(handles.popup_C1c,'enable','on','value',1);
                    set(handles.text_C1sp,'enable','on');
                    set(handles.edit_C1sp,'enable','on','string',' ');
                    set(handles.check_C1sp,'enable','on','value',0);
                    set(handles.text_C1v,'enable','off');
                    set(handles.edit_C1v,'enable','off','string',' ');
            
                    set(handles.text_C2,'enable','on');
                    set(handles.edit_C2,'enable','on');
                    set(handles.text_C2c,'enable','on');
                    set(handles.popup_C2c,'enable','on','value',1);
                    set(handles.text_C2sp,'enable','on');
                    set(handles.edit_C2sp,'enable','on','string',' ');
                    set(handles.check_C2sp,'enable','on','value',0);
                    set(handles.text_C2v,'enable','off');
                    set(handles.edit_C2v,'enable','off','string',' ');
            
                    iclos1=1; % equal
                    assignin('base','iclos1',iclos1);
                    evalin('base','mcr_als.alsOptions.closure.iclos1=iclos1;');
                    evalin('base','clear iclos1');
            
                    iclos2=1; % equal
                    assignin('base','iclos2',iclos2);
                    evalin('base','mcr_als.alsOptions.closure.iclos2=iclos2;');
                    evalin('base','clear iclos2');
            
                    vc=1; % equal
                    assignin('base','vc',vc);
                    evalin('base','mcr_als.alsOptions.closure.vc=vc;');
                    evalin('base','clear vc');
            
                end
            end
        end

        % Value changed function: check_closC
        function check_closC_Callback(app, event)
            % --- Executes on button press in check_closC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_closC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_closC
            
            closure=get(hObject,'Value');
            
            if closure==1;
            
                set(handles.text_Cnr,'enable','on');
                set(handles.popup_Cnr,'enable','on','value',1);
                closX='Spectra';
                assignin('base','closX',closX);
                evalin('base','mcr_als.alsOptions.closure.type=closX;');
                evalin('base','clear closX');
                assignin('base','dc',2);
                evalin('base','mcr_als.alsOptions.closure.dc=dc;');
                evalin('base','clear dc');
            
            else
            
                set(handles.text_Cnr,'enable','off');
                set(handles.popup_Cnr,'enable','off','value',1);
                set(handles.check_Cvar,'enable','off','value',0);
                set(handles.text_C1,'enable','off');
                set(handles.text_C1v,'enable','off');
                set(handles.text_C1c,'enable','off');
                set(handles.text_C1sp,'enable','off');
                set(handles.check_C1sp,'enable','off','value',0);
                set(handles.popup_C1c,'enable','off','value',1);
                set(handles.edit_C1,'enable','off','string',' ');
                set(handles.edit_C1v,'enable','off','string',' ');
                set(handles.edit_C1sp,'enable','off','string',' ');
                set(handles.text_C2,'enable','off');
                set(handles.text_C2v,'enable','off');
                set(handles.text_C2c,'enable','off');
                set(handles.text_C2sp,'enable','off');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.edit_C2,'enable','off','string',' ');
                set(handles.edit_C2v,'enable','off','string',' ');
                set(handles.edit_C2sp,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''closure'');');
                closX='None';
                assignin('base','closX',closX);
                evalin('base','mcr_als.alsOptions.closure.type=closX;');
                evalin('base','clear closX');
            end;
            
            assignin('base','closure',closure);
            evalin('base','mcr_als.alsOptions.closure.closure=closure;');
            evalin('base','clear closure');
            vc=0;
            assignin('base','vc',vc);
            evalin('base','mcr_als.alsOptions.closure.vc=vc;');
            evalin('base','clear vc');
        end

        % Value changed function: check_eqC
        function check_eqC_Callback(app, event)
            % --- Executes on button press in check_eqC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_eqC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_eqC
            
            sselcon=get(hObject,'Value');
            
            if sselcon==1;
                set(handles.text_eqCs,'enable','on');
                set(handles.popup_eqCs,'enable','on','value',1);
                set(handles.text_eqCc,'enable','on');
                set(handles.popup_eqCc,'enable','on','value',1);
            
            
            else
                set(handles.text_eqCs,'enable','off');
                set(handles.popup_eqCs,'enable','off','value',1);
                set(handles.text_eqCc,'enable','off');
                set(handles.popup_eqCc,'enable','off','value',1);
            
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''sselcS'');');
            
            end;
            assignin('base','sselcon',sselcon);
            evalin('base','mcr_als.alsOptions.sselcS.sselcon=sselcon;');
            evalin('base','clear sselcon');
        end

        % Value changed function: check_nnC
        function check_nnC_Callback(app, event)
            % --- Executes on button press in check_nnC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_nnC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_nnC
            
            noneg=get(hObject,'Value');
            
            if noneg==0;
                set(handles.text_nnCi,'enable','off');
                set(handles.popup_nnCi,'enable','off','value',1);
                set(handles.text_nnCsp,'enable','off');
                set(handles.popup_nnCsp,'enable','off','value',1);
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''nonegS'');');
            else
                set(handles.text_nnCi,'enable','on');
                set(handles.popup_nnCi,'enable','on','value',1);
                set(handles.text_nnCsp,'enable','off');
                set(handles.popup_nnCsp,'enable','off','value',1);
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string',' ');
            end;
            
            assignin('base','noneg',noneg);
            evalin('base','mcr_als.alsOptions.nonegS.noneg=noneg;');
            evalin('base','clear noneg');
        end

        % Value changed function: check_unimod
        function check_unimod_Callback(app, event)
            % --- Executes on button press in check_unimod.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_unimod (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_unimod
            
            unimodal=get(hObject,'Value');
            
            if unimodal==1;
                set(handles.text_Ui,'enable','on');
                set(handles.popup_Ui,'enable','on','value',1);
                set(handles.text_Up,'enable','on');
                set(handles.popup_Up,'enable','on','value',1);
                set(handles.text_Utol,'enable','on');
                set(handles.edit_Utol,'enable','on','string',' 1.1');
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string',' ');
                % default value of Utol
                smod=1.1;
                assignin('base','smod',smod);
                evalin('base','mcr_als.alsOptions.unimodS.smod=smod;');
                evalin('base','clear smod');
            else
                set(handles.text_Ui,'enable','off');
                set(handles.popup_Ui,'enable','off','value',1);
                set(handles.text_Up,'enable','off');
                set(handles.popup_Up,'enable','off','value',1);
                set(handles.text_Utol,'enable','off');
                set(handles.edit_Utol,'enable','off','string',' ');
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''unimodS'');');
            end;
            
            assignin('base','unimodal',unimodal);
            evalin('base','mcr_als.alsOptions.unimodS.unimodal=unimodal;');
            evalin('base','clear unimodal');
        end

        % Value changed function: edit_C1
        function edit_C1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C1 as text
            %        str2double(get(hObject,'String')) returns contents of edit_C1 as a double
            
            tclos1= str2num(get(hObject,'String')); % First closure constraint equal to...
            assignin('base','tclos1',tclos1);
            evalin('base','mcr_als.alsOptions.closure.tclos1=tclos1;');
            evalin('base','clear tclos1');
        end

        % Value changed function: edit_C1sp
        function edit_C1sp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C1sp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C1sp as text
            %        str2double(get(hObject,'String')) returns contents of edit_C1sp as a double
            
            sclos1= str2num(get(hObject,'String'));
            assignin('base','sclos1',sclos1);
            evalin('base','mcr_als.alsOptions.closure.sclos1=sclos1;');
            evalin('base','clear sclos1');
        end

        % Value changed function: edit_C1v
        function edit_C1v_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C1v (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C1v as text
            %        str2double(get(hObject,'String')) returns contents of edit_C1v as a double
            
            
            vclos1n=get(hObject,'String');
            
            vclos1=evalin('base',vclos1n); % First Variable closure
            assignin('base','vclos1',vclos1);
            assignin('base','vclos1n',vclos1n);
            evalin('base','mcr_als.alsOptions.closure.vclos1=vclos1;');
            evalin('base','mcr_als.alsOptions.closure.vclos1n=vclos1n;');
            evalin('base','clear vclos1 vclos1n');
        end

        % Value changed function: edit_C2
        function edit_C2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C2 as text
            %        str2double(get(hObject,'String')) returns contents of edit_C2 as a double
            
            tclos2= str2num(get(hObject,'String'));
            assignin('base','tclos2',tclos2);
            evalin('base','mcr_als.alsOptions.closure.tclos2=tclos2;');
            evalin('base','clear tclos2');
        end

        % Value changed function: edit_C2sp
        function edit_C2sp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C2sp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C2sp as text
            %        str2double(get(hObject,'String')) returns contents of edit_C2sp as a double
            
            sclos2= str2num(get(hObject,'String'));
            
            assignin('base','sclos2',sclos2);
            evalin('base','mcr_als.alsOptions.closure.sclos2=sclos2;');
            evalin('base','clear sclos2');
        end

        % Value changed function: edit_C2v
        function edit_C2v_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C2v (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C2v as text
            %        str2double(get(hObject,'String')) returns contents of edit_C2v as a double
            
            vclos2n=get(hObject,'String');
            
            vclos2=evalin('base',vclos2n);
            assignin('base','vclos2',vclos2);
            assignin('base','vclos2n',vclos2n);
            evalin('base','mcr_als.alsOptions.closure.vclos2=vclos2;');
            evalin('base','mcr_als.alsOptions.closure.vclos2n=vclos2n;');
            evalin('base','clear vclos2 vclos2n');
        end

        % Value changed function: edit_Utol
        function edit_Utol_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_Utol (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_Utol as text
            %        str2double(get(hObject,'String')) returns contents of edit_Utol as a double
            
            smod= str2num(get(hObject,'String'));
            
            if smod==1,
                smod=1.0001;
            end
            
            assignin('base','smod',smod);
            evalin('base','mcr_als.alsOptions.unimodS.smod=smod;');
            evalin('base','clear smod');
        end

        % Value changed function: edit_Uv
        function edit_Uv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_Uv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_Uv as text
            %        str2double(get(hObject,'String')) returns contents of edit_Uv as a double
            
            spsmod= str2num(get(hObject,'String'));
            assignin('base','spsmod',spsmod);
            evalin('base','mcr_als.alsOptions.unimodS.spsmod=spsmod;');
            evalin('base','clear spsmod');
        end

        % Value changed function: edit_nnCp
        function edit_nnCp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_nnCp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_nnCp as text
            %        str2double(get(hObject,'String')) returns contents of edit_nnCp as a double
            
            spnegr=str2num(get(hObject,'String'));
            spneg = ones(evalin('base','mcr_als.alsOptions.nexp'),1)*spnegr;
            assignin('base','spneg',spneg);
            evalin('base','mcr_als.alsOptions.nonegS.spneg=spneg;');
            evalin('base','clear spneg');
        end

        % Value changed function: popup_C1c
        function popup_C1c_Callback(app, event)
            % --- Executes on selection change in popup_C1c.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_C1c (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_C1c contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_C1c
            
            iclos1=get(hObject,'Value')-1; % Closure condition
            assignin('base','iclos1',iclos1);
            evalin('base','mcr_als.alsOptions.closure.iclos1=iclos1;');
            evalin('base','clear iclos1');
        end

        % Value changed function: popup_C2c
        function popup_C2c_Callback(app, event)
            % --- Executes on selection change in popup_C2c.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_C2c (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_C2c contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_C2c
            
            iclos2=get(hObject,'Value')-1;
            assignin('base','iclos2',iclos2);
            evalin('base','mcr_als.alsOptions.closure.iclos2=iclos2;');
            evalin('base','clear iclos2');
        end

        % Value changed function: popup_Cnr
        function popup_Cnr_Callback(app, event)
            % --- Executes on selection change in popup_Cnr.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_Cnr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_Cnr contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_Cnr
            
            iclos=get(hObject,'Value')-1;
            
            if iclos == 1;
                set(handles.check_Cvar,'enable','on','value',0);
                set(handles.text_C1,'enable','on');
                set(handles.edit_C1,'enable','on');
                set(handles.text_C1c,'enable','on');
                set(handles.popup_C1c,'enable','on','value',1);
                set(handles.text_C1sp,'enable','on');
                set(handles.edit_C1sp,'enable','on');
                set(handles.check_C1sp,'enable','on','value',0);
                set(handles.text_C1v,'enable','off');
                set(handles.edit_C1v,'enable','off','string',' ');
                set(handles.text_C2,'enable','off');
                set(handles.edit_C2,'enable','off','string',' ');
                set(handles.text_C2c,'enable','off');
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.text_C2sp,'enable','off');
                set(handles.edit_C2sp,'enable','off','string',' ');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.text_C2v,'enable','off');
                set(handles.edit_C2v,'enable','off','string',' ');
            
            elseif  iclos==2
                warndlg('Warning: there should not be common species to the two closures','WARNING!!');
            
                set(handles.check_Cvar,'enable','on','value',0);
                set(handles.text_C1,'enable','on');
                set(handles.edit_C1,'enable','on');
                set(handles.text_C1c,'enable','on');
                set(handles.popup_C1c,'enable','on','value',1);
                set(handles.text_C1sp,'enable','on');
                set(handles.edit_C1sp,'enable','on');
                set(handles.check_C1sp,'enable','on','value',0);
                set(handles.text_C1v,'enable','off');
                set(handles.edit_C1v,'enable','off','string',' ');
                set(handles.text_C2,'enable','on');
                set(handles.edit_C2,'enable','on');
                set(handles.text_C2c,'enable','on');
                set(handles.popup_C2c,'enable','on','value',1);
                set(handles.text_C2sp,'enable','on');
                set(handles.edit_C2sp,'enable','on');
                set(handles.check_C2sp,'enable','on','value',0);
                set(handles.text_C2v,'enable','off');
                set(handles.edit_C2v,'enable','off','string',' ');
            else
                set(handles.check_Cvar,'enable','off','value',0);
                set(handles.text_C1,'enable','off');
                set(handles.edit_C1,'enable','off');
                set(handles.text_C1c,'enable','off');
                set(handles.popup_C1c,'enable','off','value',1);
                set(handles.text_C1sp,'enable','off');
                set(handles.edit_C1sp,'enable','off');
                set(handles.check_C1sp,'enable','off','value',0);
                set(handles.text_C1v,'enable','off');
                set(handles.edit_C1v,'enable','off','string',' ');
                set(handles.text_C2,'enable','off');
                set(handles.edit_C2,'enable','off');
                set(handles.text_C2c,'enable','off');
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.text_C2sp,'enable','off');
                set(handles.edit_C2sp,'enable','off');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.text_C2v,'enable','off');
                set(handles.edit_C2v,'enable','off','string',' ');
            end
            
            assignin('base','iclos',iclos);
            evalin('base','mcr_als.alsOptions.closure.iclos=iclos;');
            evalin('base','clear iclos');
        end

        % Value changed function: popup_Ui
        function popup_Ui_Callback(app, event)
            % --- Executes on selection change in popup_Ui.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_Ui (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_Ui contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_Ui
            
            cmod=get(hObject,'Value')-2;
            assignin('base','cmod',cmod);
            evalin('base','mcr_als.alsOptions.unimodS.cmod=cmod;');
            evalin('base','clear cmod');
        end

        % Value changed function: popup_Up
        function popup_Up_Callback(app, event)
            % --- Executes on selection change in popup_Up.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_Up (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_Up contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_Up
            
            nsmod=get(hObject,'Value')-2;
            dim=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
            nexp=evalin('base','mcr_als.alsOptions.nexp');
            
            if nsmod == dim
                spsmod=ones(1,dim);
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string','');
            elseif  nsmod == 0
                spsmod=zeros(1,dim);
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string','');
            elseif  nsmod == -1
                spsmod=[];
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string','');
            else
                spsmod=[];
                set(handles.text_Uv,'enable','on');
                set(handles.edit_Uv,'enable','on');
            end
            
            assignin('base','spsmod',spsmod);
            evalin('base','mcr_als.alsOptions.unimodS.spsmod=spsmod;');
            evalin('base','clear spsmod');
        end

        % Value changed function: popup_eqCc
        function popup_eqCc_Callback(app, event)
            % --- Executes on selection change in popup_eqCc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_eqCc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_eqCc contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_eqCc
            
            type_ssel=get(hObject,'Value')-2;
            assignin('base','type_ssel',type_ssel);
            evalin('base','mcr_als.alsOptions.sselcS.type_ssel=type_ssel;');
            evalin('base','clear type_ssel');
        end

        % Value changed function: popup_eqCs
        function popup_eqCs_Callback(app, event)
            % --- Executes on selection change in popup_eqCs.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_eqCs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_eqCs contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_eqCs
            
            popmenu3=get(handles.popup_eqCs,'String');
            pm3=get(handles.popup_eqCs,'Value');
            
            if pm3==1
            
            else
                selssel_char=char([popmenu3(pm3)]);
                selssel=evalin('base',selssel_char); 
                jjsel=find(isfinite(selssel));
            
                assignin('base','selssel',selssel); % Selectivity matrix
                assignin('base','jjsel',jjsel); % Finite vals in selectivity mat
                assignin('base','selssel_char',selssel_char);
            
                evalin('base','mcr_als.alsOptions.sselcS.jjsel=jjsel;');
                evalin('base','mcr_als.alsOptions.sselcS.ssel=selssel;');
                evalin('base','mcr_als.alsOptions.sselcS.selssel_char=selssel_char;');
            
                evalin('base','clear selssel jjsel selssel_char');
            
            end
        end

        % Value changed function: popup_nnCi
        function popup_nnCi_Callback(app, event)
            % --- Executes on selection change in popup_nnCi.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_nnCi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_nnCi contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_nnCi
            
            ialgs=get(hObject,'Value')-2;
            assignin('base','ialgs',ialgs);
            evalin('base','mcr_als.alsOptions.nonegS.ialgs=ialgs;');
            
            if ialgs==-1
            
                set(handles.text_nnCsp,'enable','off');
                set(handles.popup_nnCsp,'enable','off'),
                set(handles.popup_nnCsp,'string','select...');
            
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string','');
            
            elseif ialgs==0
            
                dim=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                j=2;
                llistannc(1)={'select...'};
                for i=0:1:dim;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
            
                set(handles.text_nnCsp,'enable','on');
                set(handles.popup_nnCsp,'enable','on','value',1)
                set(handles.popup_nnCsp,'string',llistannc)
            
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string','');
            
            elseif (ialgs==1 | ialgs==2)
                dim=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                llistannc(1)={'select...'};
                llistnnc=[dim];
                llistannc(2)={llistnnc};
            
                set(handles.text_nnCsp,'enable','on');
                set(handles.popup_nnCsp,'enable','on','value',1)
                set(handles.popup_nnCsp,'string',llistannc)
            
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string','');
            
            end
            
            evalin('base','clear ialgs');
        end

        % Value changed function: popup_nnCsp
        function popup_nnCsp_Callback(app, event)
            % --- Executes on selection change in popup_nnCsp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_nnCsp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_nnCsp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_nnCsp
            
            nspneg=get(hObject,'Value')-2;
            
            assignin('base','nspneg',nspneg);
            evalin('base','mcr_als.alsOptions.nonegS.nspneg=nspneg;');
            
            dim=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
            nexp=evalin('base','mcr_als.alsOptions.nexp');
            ialgs=evalin('base','mcr_als.alsOptions.nonegS.ialgs');
            
            if ialgs == 0
                if nspneg == dim;
                    spneg = ones(dim,nexp);
                    set(handles.text_nnCp,'enable','off');
                    set(handles.edit_nnCp,'enable','off','string','');
                elseif  nspneg == -1
                    spneg=zeros(dim,nexp);
                    set(handles.text_nnCp,'enable','off');
                    set(handles.edit_nnCp,'enable','off','string','');
                elseif  nspneg == 0
                    spneg = zeros(dim,nexp);
                    set(handles.text_nnCp,'enable','off');
                    set(handles.edit_nnCp,'enable','off','string','');
                else
                    spneg=[];
                    set(handles.text_nnCp,'enable','on');
                    set(handles.edit_nnCp,'enable','on');
                end
            else
            %     spneg = ones(nexp,dim);
                    if nspneg<0;
                        form_spneg = zeros(dim,1);
                        spneg(:,1)=form_spneg;
                    else;
                        form_spneg= ones(dim,1);
                        spneg(:,1)=form_spneg;
                    end
            end
            
            assignin('base','spneg',spneg);
            evalin('base','mcr_als.alsOptions.nonegS.spneg=spneg;');
            evalin('base','clear spneg nspneg');
        end

        % Button pushed function: push_back
        function push_back_Callback(app, event)
            % --- Executes on button press in push_back.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_back (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
            rowModeConstraints_App;
        end

        % Button pushed function: push_continue
        function push_continue_Callback(app, event)
            % --- Executes on button press in push_continue.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_continue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
            
            if evalin('base','mcr_als.alsOptions.closure.closure')==0;
                if evalin('base','mcr_als.alsOptions.correlation.checkSNorm')==0;
                    closure_no_App;
                else
                    als_parameters_App;
                end
            else
                als_parameters_App;
            end
        end

        % Button pushed function: push_ConstInfo
        function ConstInfo(app, event)
            ConstraintInfo
        end

        % Clicked callback: popup_eqCs
        function popup_eqCs_ClickedFcn(app, event)
            lsvar=evalin('base','whos');
            [aa,~]=size(lsvar);
            j=2;
            lsv(1)={'select a variable...'};
            for i=1:aa
                csize=length(lsvar(i).class);
                if csize==6
                    if lsvar(i).class=='double'
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end
                end
            end
            app.popup_eqCs.Items = lsv;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Constraints: column mode (spectra)';
            app.figure1.Tag = 'figure1';
            app.uipanel3 = uibuttongroup(app.figure1);
            app.uipanel3.Title = 'Constraints';
            app.uipanel3.Tag = 'uipanel3';
            app.uipanel8 = uipanel(app.uipanel3);
            app.uipanel8.Title = 'Equality constraints';
            app.uipanel8.Tag = 'uipanel8';
            app.check_eqC = uicheckbox(app.uipanel8);
            app.check_eqC.ValueChangedFcn = createCallbackFcn(app, @check_eqC_Callback, true);
            app.check_eqC.Tag = 'check_eqC';
            app.check_eqC.Text = 'Apply?';
            app.text_eqCs = uilabel(app.uipanel8);
            app.text_eqCs.Tag = 'text_eqCs';
            app.text_eqCs.Enable = 'off';
            app.text_eqCs.Text = 'Select csel matrix:';
            app.text_eqCc = uilabel(app.uipanel8);
            app.text_eqCc.Tag = 'text_eqCc';
            app.text_eqCc.Enable = 'off';
            app.text_eqCc.Text = 'Constraints are:';
            app.popup_eqCs = uidropdown(app.uipanel8);
            app.popup_eqCs.Items = {'select a variable from the WS', 'DataDC', 'DataTime', 'Log10HistData', 'currentT50Times', 'exportT0_intensities', 'f', 'imageData', 'k', 'len', 'p', 'r'};
            app.popup_eqCs.ValueChangedFcn = createCallbackFcn(app, @popup_eqCs_Callback, true);
            app.popup_eqCs.Tag = 'popup_eqCs';
            app.popup_eqCs.Enable = 'off';
            app.popup_eqCs.ClickedFcn = createCallbackFcn(app, @popup_eqCs_ClickedFcn, true);
            app.popup_eqCs.Value = 'select a variable from the WS';
            app.popup_eqCc = uidropdown(app.uipanel8);
            app.popup_eqCc.Items = {'select...', 'equal to', 'lower than or equal to', 'greater than'};
            app.popup_eqCc.ValueChangedFcn = createCallbackFcn(app, @popup_eqCc_Callback, true);
            app.popup_eqCc.Tag = 'popup_eqCc';
            app.popup_eqCc.Enable = 'off';
            app.popup_eqCc.Value = 'select...';
            app.uipanel9 = uipanel(app.uipanel3);
            app.uipanel9.Title = 'Closure';
            app.uipanel9.Tag = 'uipanel9';
            app.check_closC = uicheckbox(app.uipanel9);
            app.check_closC.ValueChangedFcn = createCallbackFcn(app, @check_closC_Callback, true);
            app.check_closC.Tag = 'check_closC';
            app.check_closC.Text = 'Apply?';
            app.Pan_Clos = uibuttongroup(app.uipanel9);
            app.Pan_Clos.Title = 'Row closure is active';
            app.Pan_Clos.Visible = 'off';
            app.Pan_Clos.Tag = 'Pan_Clos';
            app.text_Cnr = uilabel(app.uipanel9);
            app.text_Cnr.Tag = 'text_Cnr';
            app.text_Cnr.Enable = 'off';
            app.text_Cnr.Text = 'Nr. of closure constraints to be included?';
            app.check_Cvar = uicheckbox(app.uipanel9);
            app.check_Cvar.ValueChangedFcn = createCallbackFcn(app, @check_Cvar_Callback, true);
            app.check_Cvar.Tag = 'check_Cvar';
            app.check_Cvar.Enable = 'off';
            app.check_Cvar.Text = 'Closure variable?';
            app.popup_Cnr = uidropdown(app.uipanel9);
            app.popup_Cnr.Items = {'select...', '1', '2'};
            app.popup_Cnr.ValueChangedFcn = createCallbackFcn(app, @popup_Cnr_Callback, true);
            app.popup_Cnr.Tag = 'popup_Cnr';
            app.popup_Cnr.Enable = 'off';
            app.popup_Cnr.Value = 'select...';
            app.text_C1 = uilabel(app.uipanel9);
            app.text_C1.Tag = 'text_C1';
            app.text_C1.Enable = 'off';
            app.text_C1.Text = 'First closure constraint equal to:';
            app.edit_C1 = uieditfield(app.uipanel9, 'text');
            app.edit_C1.ValueChangedFcn = createCallbackFcn(app, @edit_C1_Callback, true);
            app.edit_C1.Tag = 'edit_C1';
            app.edit_C1.Enable = 'off';
            app.text_C2 = uilabel(app.uipanel9);
            app.text_C2.Tag = 'text_C2';
            app.text_C2.Enable = 'off';
            app.text_C2.Text = 'Second closure constraint equal to:';
            app.edit_C2 = uieditfield(app.uipanel9, 'text');
            app.edit_C2.ValueChangedFcn = createCallbackFcn(app, @edit_C2_Callback, true);
            app.edit_C2.Tag = 'edit_C2';
            app.edit_C2.Enable = 'off';
            app.text_C1v = uilabel(app.uipanel9);
            app.text_C1v.Tag = 'text_C1v';
            app.text_C1v.Enable = 'off';
            app.text_C1v.Text = 'First variable closure:';
            app.edit_C1v = uieditfield(app.uipanel9, 'text');
            app.edit_C1v.ValueChangedFcn = createCallbackFcn(app, @edit_C1v_Callback, true);
            app.edit_C1v.Tag = 'edit_C1v';
            app.edit_C1v.Enable = 'off';
            app.text_C2v = uilabel(app.uipanel9);
            app.text_C2v.Tag = 'text_C2v';
            app.text_C2v.Enable = 'off';
            app.text_C2v.Text = 'Second variable closure:';
            app.edit_C2v = uieditfield(app.uipanel9, 'text');
            app.edit_C2v.ValueChangedFcn = createCallbackFcn(app, @edit_C2v_Callback, true);
            app.edit_C2v.Tag = 'edit_C2v';
            app.edit_C2v.Enable = 'off';
            app.text_C2c = uilabel(app.uipanel9);
            app.text_C2c.Tag = 'text_C2c';
            app.text_C2c.Enable = 'off';
            app.text_C2c.Text = 'Closure condition:';
            app.text_C1c = uilabel(app.uipanel9);
            app.text_C1c.Tag = 'text_C1c';
            app.text_C1c.Enable = 'off';
            app.text_C1c.Text = 'Closure condition:';
            app.popup_C1c = uidropdown(app.uipanel9);
            app.popup_C1c.Items = {'select...', 'equal to', 'least-squares closure', 'lower than or equal to'};
            app.popup_C1c.ValueChangedFcn = createCallbackFcn(app, @popup_C1c_Callback, true);
            app.popup_C1c.Tag = 'popup_C1c';
            app.popup_C1c.Enable = 'off';
            app.popup_C1c.Value = 'select...';
            app.popup_C2c = uidropdown(app.uipanel9);
            app.popup_C2c.Items = {'select...', 'equal to', 'least-squares closure', 'lower than or equal to'};
            app.popup_C2c.ValueChangedFcn = createCallbackFcn(app, @popup_C2c_Callback, true);
            app.popup_C2c.Tag = 'popup_C2c';
            app.popup_C2c.Enable = 'off';
            app.popup_C2c.Value = 'select...';
            app.text_C1sp = uilabel(app.uipanel9);
            app.text_C1sp.Tag = 'text_C1sp';
            app.text_C1sp.Enable = 'off';
            app.text_C1sp.Text = 'Which species are in 1st closure?';
            app.check_C1sp = uicheckbox(app.uipanel9);
            app.check_C1sp.ValueChangedFcn = createCallbackFcn(app, @check_C1sp_Callback, true);
            app.check_C1sp.Tag = 'check_C1sp';
            app.check_C1sp.Enable = 'off';
            app.check_C1sp.Text = 'All';
            app.edit_C1sp = uieditfield(app.uipanel9, 'text');
            app.edit_C1sp.ValueChangedFcn = createCallbackFcn(app, @edit_C1sp_Callback, true);
            app.edit_C1sp.Tag = 'edit_C1sp';
            app.edit_C1sp.Enable = 'off';
            app.text_C2sp = uilabel(app.uipanel9);
            app.text_C2sp.Tag = 'text_C2sp';
            app.text_C2sp.Enable = 'off';
            app.text_C2sp.Text = 'Which species are in 2nd closure?';
            app.edit_C2sp = uieditfield(app.uipanel9, 'text');
            app.edit_C2sp.ValueChangedFcn = createCallbackFcn(app, @edit_C2sp_Callback, true);
            app.edit_C2sp.Tag = 'edit_C2sp';
            app.edit_C2sp.Enable = 'off';
            app.check_C2sp = uicheckbox(app.uipanel9);
            app.check_C2sp.ValueChangedFcn = createCallbackFcn(app, @check_C2sp_Callback, true);
            app.check_C2sp.Tag = 'check_C2sp';
            app.check_C2sp.Enable = 'off';
            app.check_C2sp.Text = 'All';
            app.uipanel6 = uipanel(app.uipanel3);
            app.uipanel6.Title = 'Unimodality';
            app.uipanel6.Tag = 'uipanel6';
            app.text_Ui = uilabel(app.uipanel6);
            app.text_Ui.Tag = 'text_Ui';
            app.text_Ui.Enable = 'off';
            app.text_Ui.Text = 'Implementation';
            app.popup_Ui = uidropdown(app.uipanel6);
            app.popup_Ui.Items = {'select...', 'vertical', 'horizontal', 'average'};
            app.popup_Ui.ValueChangedFcn = createCallbackFcn(app, @popup_Ui_Callback, true);
            app.popup_Ui.Tag = 'popup_Ui';
            app.popup_Ui.Enable = 'off';
            app.popup_Ui.Value = 'select...';
            app.text_Up = uilabel(app.uipanel6);
            app.text_Up.Tag = 'text_Up';
            app.text_Up.Enable = 'off';
            app.text_Up.Text = 'Nr. of species with unimodal profiles?';
            app.popup_Up = uidropdown(app.uipanel6);
            app.popup_Up.Items = {'select...', '0', '1', '2', '3'};
            app.popup_Up.ValueChangedFcn = createCallbackFcn(app, @popup_Up_Callback, true);
            app.popup_Up.Tag = 'popup_Up';
            app.popup_Up.Enable = 'off';
            app.popup_Up.Value = 'select...';
            app.edit_Uv = uieditfield(app.uipanel6, 'text');
            app.edit_Uv.ValueChangedFcn = createCallbackFcn(app, @edit_Uv_Callback, true);
            app.edit_Uv.Tag = 'edit_Uv';
            app.edit_Uv.Enable = 'off';
            app.text_Uv = uilabel(app.uipanel6);
            app.text_Uv.Tag = 'text_Uv';
            app.text_Uv.Enable = 'off';
            app.text_Uv.Text = 'Enter a vector of unimodal profiles:';
            app.edit_Utol = uieditfield(app.uipanel6, 'text');
            app.edit_Utol.ValueChangedFcn = createCallbackFcn(app, @edit_Utol_Callback, true);
            app.edit_Utol.Tag = 'edit_Utol';
            app.edit_Utol.Enable = 'off';
            app.text_Utol = uilabel(app.uipanel6);
            app.text_Utol.Tag = 'text_Utol';
            app.text_Utol.Enable = 'off';
            app.text_Utol.Text = 'Constraint tolerance:';
            app.check_unimod = uicheckbox(app.uipanel6);
            app.check_unimod.ValueChangedFcn = createCallbackFcn(app, @check_unimod_Callback, true);
            app.check_unimod.Tag = 'check_unimod';
            app.check_unimod.Text = 'Apply?';
            app.uipanel4 = uibuttongroup(app.uipanel3);
            app.uipanel4.Title = 'Non-negativity';
            app.uipanel4.Tag = 'uipanel4';
            app.check_nnC = uicheckbox(app.uipanel4);
            app.check_nnC.ValueChangedFcn = createCallbackFcn(app, @check_nnC_Callback, true);
            app.check_nnC.Tag = 'check_nnC';
            app.check_nnC.Text = 'Apply?';
            app.text_nnCi = uilabel(app.uipanel4);
            app.text_nnCi.Tag = 'text_nnCi';
            app.text_nnCi.Enable = 'off';
            app.text_nnCi.Text = 'Implementation';
            app.text_nnCsp = uilabel(app.uipanel4);
            app.text_nnCsp.Tag = 'text_nnCsp';
            app.text_nnCsp.Enable = 'off';
            app.text_nnCsp.Text = 'Nr. of species with non-negative profiles?';
            app.popup_nnCi = uidropdown(app.uipanel4);
            app.popup_nnCi.Items = {'select...', 'forced to zero', 'nnls', 'fnnls'};
            app.popup_nnCi.ValueChangedFcn = createCallbackFcn(app, @popup_nnCi_Callback, true);
            app.popup_nnCi.Tag = 'popup_nnCi';
            app.popup_nnCi.Enable = 'off';
            app.popup_nnCi.Value = 'select...';
            app.popup_nnCsp = uidropdown(app.uipanel4);
            app.popup_nnCsp.Items = {'select...'};
            app.popup_nnCsp.ValueChangedFcn = createCallbackFcn(app, @popup_nnCsp_Callback, true);
            app.popup_nnCsp.Tag = 'popup_nnCsp';
            app.popup_nnCsp.Enable = 'off';
            app.popup_nnCsp.Value = 'select...';
            app.edit_nnCp = uieditfield(app.uipanel4, 'text');
            app.edit_nnCp.ValueChangedFcn = createCallbackFcn(app, @edit_nnCp_Callback, true);
            app.edit_nnCp.Tag = 'edit_nnCp';
            app.edit_nnCp.Enable = 'off';
            app.text_nnCp = uilabel(app.uipanel4);
            app.text_nnCp.Tag = 'text_nnCp';
            app.text_nnCp.Enable = 'off';
            app.text_nnCp.Text = 'Enter a vector of positive profiles:';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Constraints: column mode (spectra and single technique)';
            app.push_continue = uibutton(app.figure1, 'push');
            app.push_continue.ButtonPushedFcn = createCallbackFcn(app, @push_continue_Callback, true);
            app.push_continue.Tag = 'push_continue';
            app.push_continue.Text = 'Continue';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'Multimatriu';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'De moment, una matriu';
            app.push_reset = uibutton(app.figure1, 'push');
            app.push_reset.Tag = 'push_reset';
            app.push_reset.Text = 'Reset';
            app.push_back = uibutton(app.figure1, 'push');
            app.push_back.ButtonPushedFcn = createCallbackFcn(app, @push_back_Callback, true);
            app.push_back.Tag = 'push_back';
            app.push_back.Text = 'Back';
            app.push_ConstInfo = uibutton(app.figure1, 'push');
            app.push_ConstInfo.ButtonPushedFcn = createCallbackFcn(app, @ConstInfo, true);
            app.push_ConstInfo.Tag = 'push_alsInfo';
            app.push_ConstInfo.Icon = fullfile(pathToMLAPP, 'info-circle-regular-24.png');
            app.push_ConstInfo.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = columnModeConstraints_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)columnModeConstraints_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end