classdef correlCons_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        check_Snorm   matlab.ui.control.CheckBox
        push_cancel   matlab.ui.control.Button
        push_done     matlab.ui.control.Button
        push_reset    matlab.ui.control.Button
        text1         matlab.ui.control.Label
        uipanel2      matlab.ui.container.Panel
        text2         matlab.ui.control.Label
        check_all     matlab.ui.control.CheckBox
        edit_species  matlab.ui.control.EditField
        text_species  matlab.ui.control.Label
        popup_select  matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        function radio_global_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in radio_global.
            
            % hObject    handle to radio_global (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of radio_global
            
            type=0;% global
            set(handles.radio_global,'enable','on','value',1);
            set(handles.radio_local,'enable','on','value',0);
            set(handles.radio_yes,'enable','off','value',0);
            set(handles.radio_no,'enable','off','value',0);
            
            assignin('base','typeCorrel',type);
            evalin('base','mcr_als.alsOptions.correlation.regmodel=typeCorrel;');
            evalin('base','mcr_als.alsOptions.correlation.mateffect=0;');
            evalin('base','clear typeCorrel');
        end
        
        function radio_local_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in radio_local.
            
            % hObject    handle to radio_local (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of radio_local
            type=1;% local
            set(handles.radio_global,'enable','on','value',0);
            set(handles.radio_local,'enable','on','value',1);
            set(handles.radio_yes,'enable','on','value',0);
            set(handles.radio_no,'enable','on','value',1);
            
            assignin('base','typeCorrel',type);
            evalin('base','mcr_als.alsOptions.correlation.regmodel=typeCorrel;');
            evalin('base','mcr_als.alsOptions.correlation.mateffect=0;');
            evalin('base','clear typeCorrel');
        end
        
        function radio_no_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in radio_no.
            
            % hObject    handle to radio_no (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of radio_no
            mEffect=0;% yes
            set(handles.radio_yes,'enable','on','value',0);
            set(handles.radio_no,'enable','on','value',1);
            
            assignin('base','matEffect',mEffect);
            evalin('base','mcr_als.alsOptions.correlation.mateffect=matEffect;');
            evalin('base','clear matEffect');
        end
        
        function radio_yes_Callback(app, hObject, eventdata, handles)
            % --- Executes on button press in radio_yes.
            
            % hObject    handle to radio_yes (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of radio_yes
            mEffect=1;% yes
            set(handles.radio_yes,'enable','on','value',1);
            set(handles.radio_no,'enable','on','value',0);
            
            assignin('base','matEffect',mEffect);
            evalin('base','mcr_als.alsOptions.correlation.mateffect=matEffect;');
            evalin('base','clear matEffect');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function correlCons_OpeningFcn(app, varargin)
            % --- Executes just before correlCons is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to correlCons (see VARARGIN)
            
            % Choose default command line output for correlCons
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: check_Snorm
        function check_Snorm_Callback(app, event)
            % --- Executes on button press in check_Snorm.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_Snorm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_Snorm
            
            checkSNorm = get(hObject,'Value');
            assignin('base','checkSNorm',checkSNorm);
            evalin('base','mcr_als.alsOptions.correlation.checkSNorm=checkSNorm;');
            evalin('base','clear checkSNorm');
        end

        % Value changed function: check_all
        function check_all_Callback(app, event)
            % --- Executes on button press in check_all.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_all (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_all
            
            check1clos=get(hObject,'Value');
            
            if check1clos==0
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                ceros=zeros(1,nsign);
            
                set(handles.edit_species,'enable','on','string',num2str(ceros));
                assignin('base','valorCorrel',ceros);
                evalin('base','mcr_als.alsOptions.correlation.compreg=valorCorrel;');
                evalin('base','clear valorCorrel');
            
            elseif check1clos==1
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                unos=ones(1,nsign);
                set(handles.edit_species,'enable','inactive','string',num2str(unos));
                assignin('base','valorCorrel',unos);
                evalin('base','mcr_als.alsOptions.correlation.compreg=valorCorrel;');
                evalin('base','clear valorCorrel');
            end
        end

        % Value changed function: edit_species
        function edit_species_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_species (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_species as text
            %        str2double(get(hObject,'String')) returns contents of edit_species as a double
            
            valorCorrel= str2num(get(hObject,'String'));
            assignin('base','valorCorrel',valorCorrel);
            evalin('base','mcr_als.alsOptions.correlation.compreg=valorCorrel;');
            evalin('base','clear valorCorrel');
        end

        % Value changed function: popup_select
        function popup_select_Callback(app, event)
            % --- Executes on selection change in popup_select.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_select (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_select contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_select
            
            popmenu2=get(handles.popup_select,'String');
            pm2=get(handles.popup_select,'Value');
            
            if pm2==1
            
                % Deactivate options
                set(handles.text_species,'enable','off');
                set(handles.check_all,'enable','off','value',0);
                set(handles.edit_species,'enable','off','string',' ');
            %     set(handles.text_type,'enable','off');
            %     set(handles.radio_global,'enable','off','value',0);
            %     set(handles.radio_local,'enable','off','value',0);
            %     set(handles.text_matrix,'enable','off');
            %     set(handles.radio_yes,'enable','off','value',0');
            %     set(handles.radio_no,'enable','off','value',0);
            
                evalin('base','mcr_als.alsOptions.correlation.csel_variable=[];');
                evalin('base','mcr_als.alsOptions.correlation.csel_matrix_name=[];');
                evalin('base','mcr_als.alsOptions.correlation.compreg=[];');
                evalin('base','mcr_als.alsOptions.correlation.iisel=[];');
                evalin('base','mcr_als.alsOptions.correlation.regmodel=[];');
                evalin('base','mcr_als.alsOptions.correlation.mateffect=[];');
            
            else
            
                selec2=char([popmenu2(pm2)]);
                var_name=evalin('base',selec2);
                iisel=find(isfinite(var_name));
                assignin('base','var_name',var_name);
                assignin('base','iisel',iisel);
                evalin('base','mcr_als.alsOptions.correlation.csel_variable=var_name;');
                evalin('base','mcr_als.alsOptions.correlation.iisel=iisel;');
                evalin('base','clear var_name iisel');
                assignin('base','csel_matrix_name',selec2);
                evalin('base','mcr_als.alsOptions.correlation.csel_matrix_name=csel_matrix_name;');
                evalin('base','clear csel_matrix_name');
            
                % Activate options
                set(handles.text_species,'enable','on');
                set(handles.check_all,'enable','on');
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                ceros=zeros(1,nsign);
                set(handles.edit_species,'enable','on','string',num2str(ceros));
                nexp=evalin('base','mcr_als.alsOptions.nexp');
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                ceros=zeros(1,nsign);
                assignin('base','valorCorrel',ceros);
                evalin('base','mcr_als.alsOptions.correlation.compreg=valorCorrel;');
                evalin('base','mcr_als.alsOptions.correlation.regmodel=0;');
                evalin('base','mcr_als.alsOptions.correlation.mateffect=0;');
                evalin('base','clear valorCorrel');
            
            %     if nexp>1
            %         nrMatC=evalin('base','mcr_als.alsOptions.multi.matc');
            %         if nrMatC>1
            %             set(handles.text_type,'enable','on');
            %             set(handles.radio_global,'enable','on','value',1);
            %             set(handles.radio_local,'enable','on','value',0);
            %             set(handles.radio_yes,'enable','off','value',0);
            %             set(handles.radio_no,'enable','off','value',0);
            %             set(handles.text_matrix,'enable','on');
            %         end
            %     else
            %         set(handles.text_type,'enable','off');
            %         set(handles.radio_global,'enable','off','value',0);
            %         set(handles.radio_local,'enable','off','value',0);
            %         set(handles.radio_yes,'enable','off','value',0);
            %         set(handles.radio_no,'enable','off','value',0);
            %         set(handles.text_matrix,'enable','off');
            %     end
            
            end
        end

        % Button pushed function: push_cancel
        function push_cancel_Callback(app, event)
            % --- Executes on button press in push_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''correlation'');');
            evalin('base','mcr_als.alsOptions.correlation.appCorrelation=0;');
            close;
        end

        % Button pushed function: push_done
        function push_done_Callback(app, event)
            % --- Executes on button press in push_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_done (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions.correlation.appCorrelation=1;');
            close;
        end

        % Button pushed function: push_reset
        function push_reset_Callback(app, event)
            % --- Executes on button press in push_reset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''correlation'');');
            close;
            correlCons;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Parameters of the Correlation Constraint';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.popup_select = uidropdown(app.uipanel2);
            app.popup_select.Items = {'select a variable', 'DataDC', 'DataTime', 'Log10HistData', 'currentT50Times', 'exportT0_intensities', 'f', 'imageData', 'k', 'len', 'p', 'r'};
            app.popup_select.ValueChangedFcn = createCallbackFcn(app, @popup_select_Callback, true);
            app.popup_select.Tag = 'popup_select';
            app.popup_select.Value = 'select a variable';
            app.text_species = uilabel(app.uipanel2);
            app.text_species.Tag = 'text_species';
            app.text_species.Enable = 'off';
            app.text_species.Text = 'Compounds to perform regression';
            app.edit_species = uieditfield(app.uipanel2, 'text');
            app.edit_species.ValueChangedFcn = createCallbackFcn(app, @edit_species_Callback, true);
            app.edit_species.Tag = 'edit_species';
            app.edit_species.Enable = 'off';
            app.check_all = uicheckbox(app.uipanel2);
            app.check_all.ValueChangedFcn = createCallbackFcn(app, @check_all_Callback, true);
            app.check_all.Tag = 'check_all';
            app.check_all.Enable = 'off';
            app.check_all.Text = 'All?';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select matrix with the C information';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Correlation Constraint Options';
            app.push_reset = uibutton(app.figure1, 'push');
            app.push_reset.ButtonPushedFcn = createCallbackFcn(app, @push_reset_Callback, true);
            app.push_reset.Tag = 'push_reset';
            app.push_reset.Text = 'Reset';
            app.push_done = uibutton(app.figure1, 'push');
            app.push_done.ButtonPushedFcn = createCallbackFcn(app, @push_done_Callback, true);
            app.push_done.Tag = 'push_done';
            app.push_done.Text = 'Done';
            app.push_cancel = uibutton(app.figure1, 'push');
            app.push_cancel.ButtonPushedFcn = createCallbackFcn(app, @push_cancel_Callback, true);
            app.push_cancel.Tag = 'push_cancel';
            app.push_cancel.Text = 'Cancel';
            app.check_Snorm = uicheckbox(app.figure1);
            app.check_Snorm.ValueChangedFcn = createCallbackFcn(app, @check_Snorm_Callback, true);
            app.check_Snorm.Tag = 'check_Snorm';
            app.check_Snorm.Text = 'Spectra in the same scale?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = correlCons_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)correlCons_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end