classdef correlCons_reload_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        push_cancel    matlab.ui.control.Button
        push_done      matlab.ui.control.Button
        push_reset     matlab.ui.control.Button
        uipanel1       matlab.ui.container.Panel
        ButtonGroup_2  matlab.ui.container.ButtonGroup
        NoButton       matlab.ui.control.RadioButton
        YesButton      matlab.ui.control.RadioButton
        ButtonGroup    matlab.ui.container.ButtonGroup
        LocalButton    matlab.ui.control.RadioButton
        GlobalButton   matlab.ui.control.RadioButton
        text_matrix    matlab.ui.control.Label
        text_type      matlab.ui.control.Label
        check_all      matlab.ui.control.CheckBox
        edit_species   matlab.ui.control.EditField
        text_species   matlab.ui.control.Label
        popup_select   matlab.ui.control.DropDown
        text2          matlab.ui.control.Label
        text1          matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function correlCons_reload_OpeningFcn(app, varargin)
            % --- Executes just before correlCons_reload is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to correlCons_reload (see VARARGIN)
            
            % Choose default command line output for correlCons_reload
            handles.output = hObject;
            
            
            % Update the figure with old values
            % *******************************************************************
            
            % texts
                set(handles.text_species,'enable','inactive');
            %     set(handles.text_type,'enable','inactive');
            %     set(handles.text_matrix,'enable','inactive');
            
            % check all
            compreg=evalin('base','mcr_als.alsOptions.correlation.compreg');
            total=sum(compreg);
            nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
            if total==nsign;
                set(handles.check_all,'value',1,'enable','inactive');
            else
                set(handles.check_all,'value',0,'enable','inactive');
            end
            
            % multiexperiments
            nexp=evalin('base','mcr_als.alsOptions.nexp');
            
            if nexp==1
            %     set(handles.radio_global,'enable','inactive','value',0);
            %     set(handles.radio_local,'enable','inactive','value',0);
            %     set(handles.radio_no,'enable','inactive','value',0);
            %     set(handles.radio_yes,'enable','inactive','value',0);
            else
            %     % regmodel
            %     regmodel=evalin('base','mcr_als.alsOptions.correlation.regmodel');
            %     % mateffect
            %     mateffect=evalin('base','mcr_als.alsOptions.correlation.mateffect');
            %
            %     if regmodel==0
            %         set(handles.radio_global,'enable','inactive','value',1);
            %         set(handles.radio_local,'enable','inactive','value',0);
            %         set(handles.radio_no,'enable','inactive','value',0);
            %         set(handles.radio_yes,'enable','inactive','value',0);
            %     else
            %         set(handles.radio_global,'enable','inactive','value',0);
            %         set(handles.radio_local,'enable','inactive','value',1);
            %         if mateffect==0
            %             set(handles.radio_no,'enable','inactive','value',1);
            %             set(handles.radio_yes,'enable','inactive','value',0);
            %         else
            %             set(handles.radio_no,'enable','inactive','value',0);
            %             set(handles.radio_yes,'enable','inactive','value',1);
            %         end
            %     end
            %
            
            
            end
            
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: push_cancel
        function push_cancel_Callback(app, event)
            % --- Executes on button press in push_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''correlation'');');
            evalin('base','mcr_als.alsOptions.correlation.appCorrelation=0;');
            close;
        end

        % Button pushed function: push_done
        function push_done_Callback(app, event)
            % --- Executes on button press in push_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_done (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions.correlation.appCorrelation=1;');
            close;
        end

        % Button pushed function: push_reset
        function push_reset_Callback(app, event)
            % --- Executes on button press in push_reset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''correlation'');');
            close;
            correlCons;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Parameters of the Correlation Constraint';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Correlation Constraint Options';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select matrix with the C information';
            app.popup_select = uidropdown(app.figure1);
            app.popup_select.Items = {'select a variable', 'WLaxis', 'ans', 'areaxVal_APAP', 'copt', 'csel_ValAPAP', 'matrix', 'msc_d11', 'msc_d13', 'msc_d14', 'msc_d15', 'msc_d2', 'msc_d7', 'msc_d9', 'msc_rR1', 'msc_rR2', 'msc_rR3', 'msc_rR4', 'rtxVal_APAP', 'rxVal_APAP', 'sopt', 'sp_iniesta', 'spinicial', 'sxVal_APAP', 'total_niter', 'ycalx_APAP'};
            app.popup_select.Tag = 'popup_select';
            app.popup_select.Value = 'select a variable';
            app.text_species = uilabel(app.figure1);
            app.text_species.Tag = 'text_species';
            app.text_species.Enable = 'off';
            app.text_species.Text = 'Compounds to perform regression';
            app.edit_species = uieditfield(app.figure1, 'text');
            app.edit_species.Tag = 'edit_species';
            app.edit_species.Enable = 'off';
            app.check_all = uicheckbox(app.figure1);
            app.check_all.Tag = 'check_all';
            app.check_all.Enable = 'off';
            app.check_all.Text = 'All?';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Multiple experiments';
            app.uipanel1.Tag = 'uipanel1';
            app.text_type = uilabel(app.uipanel1);
            app.text_type.Tag = 'text_type';
            app.text_type.Enable = 'off';
            app.text_type.Text = 'Type of regression model:';
            app.text_matrix = uilabel(app.uipanel1);
            app.text_matrix.Tag = 'text_matrix';
            app.text_matrix.Enable = 'off';
            app.text_matrix.Text = 'Is there matrix effect among models?';
            app.ButtonGroup = uibuttongroup(app.uipanel1);
            app.GlobalButton = uiradiobutton(app.ButtonGroup);
            app.GlobalButton.Text = 'Global';
            app.GlobalButton.Value = true;
            app.LocalButton = uiradiobutton(app.ButtonGroup);
            app.LocalButton.Text = 'Local';
            app.ButtonGroup_2 = uibuttongroup(app.uipanel1);
            app.YesButton = uiradiobutton(app.ButtonGroup_2);
            app.YesButton.Text = 'Yes';
            app.YesButton.Value = true;
            app.NoButton = uiradiobutton(app.ButtonGroup_2);
            app.NoButton.Text = 'No';
            app.push_reset = uibutton(app.figure1, 'push');
            app.push_reset.ButtonPushedFcn = createCallbackFcn(app, @push_reset_Callback, true);
            app.push_reset.Tag = 'push_reset';
            app.push_reset.Text = 'Reset';
            app.push_done = uibutton(app.figure1, 'push');
            app.push_done.ButtonPushedFcn = createCallbackFcn(app, @push_done_Callback, true);
            app.push_done.Tag = 'push_done';
            app.push_done.Text = 'Done';
            app.push_cancel = uibutton(app.figure1, 'push');
            app.push_cancel.ButtonPushedFcn = createCallbackFcn(app, @push_cancel_Callback, true);
            app.push_cancel.Tag = 'push_cancel';
            app.push_cancel.Text = 'Cancel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = correlCons_reload_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)correlCons_reload_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end