classdef correlation_info_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        uitable1     matlab.ui.control.Table
        popu_select  matlab.ui.control.DropDown
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        push_close   matlab.ui.control.Button
        text1        matlab.ui.control.Label
        axes1        matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.uitable1.ColumnFormat = {[] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function correlation_info_OpeningFcn(app, varargin)
            % --- Executes just before correlation_info is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to correlation_info (see VARARGIN)
            
            % Choose default command line output for correlation_info
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: popu_select
        function popu_select_Callback(app, event)
            % --- Executes on selection change in popu_select.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popu_select (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popu_select contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popu_select
            
            valorpop=get(hObject,'Value')-1;
            
            if valorpop>0
                compreg=evalin('base','mcr_als.alsOptions.correlation.compreg');
                indices=find(compreg);
                num=indices(valorpop);
            
                % plot
                axes(handles.axes1);
            
                % optimal iteration
                optim_niter=evalin('base','mcr_als.alsOptions.resultats.optim_niter;');
                matdad=evalin('base','mcr_als.data');
                [rows,cols]=size(matdad);
                ycorrelP=evalin('base','mcr_als.alsOptions.correlation.results.save_ycal');
                yinp=ycorrelP((rows*(optim_niter-1))+1:(rows*optim_niter),:);
            
                ysel=evalin('base','mcr_als.alsOptions.correlation.csel_variable');
                [nr,nc]=size(yinp);
                yout=yinp;
                ycal=yinp;
                j=num;
                isel=find(isfinite(ysel(:,j)));
            
                if isfinite(isel) & length(ysel(isel,j))>=2
                    x=ysel(isel,j);
                    y=yinp(isel,j);
                    [p,S]=polyfit(x,y,1);
                    ycalc(:,j)=(yinp(:,j)-p(2))/p(1);
                    plot(ysel(isel,j),ycalc(isel,j),'r*',ysel(isel,j),ysel(isel,j));
                    xlabel('Cknown values');
                    ylabel('Cpred values');
                end
            
                % table
                data=[];
                optim_niter=evalin('base','mcr_als.alsOptions.resultats.optim_niter;');
                stats=evalin('base',['mcr_als.alsOptions.correlation.results.save_stats(',num2str(optim_niter),',:);']);
            
                data{1,1}=[];
            
                % slope
                SLOPE=stats{1,num}.slope;
                data{2,1}=num2str(SLOPE);
            
                % offset
                OFFSET=stats{1,num}.offset;
                data{3,1}=num2str(OFFSET);
            
                % r
                RCOEF=stats{1,num}.r;
                data{4,1}=num2str(RCOEF);
            
                % RMSEC
                RMSEC=stats{1,num}.RMSEC;
                data{5,1}=num2str(RMSEC);
                set(handles.uitable1,'Data',data);
            
            else
            
                data=[];
                set(handles.uitable1,'Data',data);
                axes(handles.axes1);
                cla;
            
            end
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Information about the Correlation Constraint';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Information about the Correlation Constraint';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select modelled species:';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Calibration Curve';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Calibration Statistics';
            app.popu_select = uidropdown(app.figure1);
            app.popu_select.Items = {'select a species...', 'Species nr. 1'};
            app.popu_select.ValueChangedFcn = createCallbackFcn(app, @popu_select_Callback, true);
            app.popu_select.Tag = 'popu_select';
            app.popu_select.Value = 'select a species...';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = '';
            app.uitable1.RowName = {''; 'Slope'; 'Offset'; 'R'; 'RMSEC'; ''};
            app.uitable1.ColumnEditable = [false false];
            app.uitable1.Tag = 'uitable1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = correlation_info_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)correlation_info_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end