classdef efagui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1        matlab.ui.Figure
        edit_minvalue  matlab.ui.control.EditField
        text_minvalue  matlab.ui.control.Label
        push_go        matlab.ui.control.Button
        push_print     matlab.ui.control.Button
        push_copy      matlab.ui.control.Button
        push_canc      matlab.ui.control.Button
        push_OK        matlab.ui.control.Button
        uipanel3       matlab.ui.container.Panel
        text1          matlab.ui.control.Label
        text_start     matlab.ui.control.Label
        axes_efa       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function efagui_OpeningFcn(app, varargin)
            % --- Executes just before efagui is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to efagui (see VARARGIN)
            
            % Choose default command line output for efagui
            handles.output = hObject;

            % initialization
            evalin('base','mcr_als.InitEstim.Sort=0;');
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_minvalue
        function edit_minvalue_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_minvalue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_minvalue as text
            %        str2double(get(hObject,'String')) returns contents of edit_minvalue as a double
            
            EFAminvalue=str2num(get(hObject,'String'));
            
            assignin('base','EFAminvalue',EFAminvalue);
            evalin('base','mcr_als.InitEstim.aux.EFAminvalue=EFAminvalue;');
            evalin('base','clear EFAminvalue');
            
            evalin('base','mcr_als.aux.efamin=0;');
        end

        % Button pushed function: push_OK
        function push_OK_Callback(app, event)
            % --- Executes on button press in push_OK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_OK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=2;');
            evalin('base','mcr_als.InitEstim.method=''EFA'';');
            close;
        end

        % Button pushed function: push_canc
        function push_canc_Callback(app, event)
            % --- Executes on button press in push_canc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_canc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=3;');
            evalin('base','mcr_als=rmfield(mcr_als,''InitEstim'');');
            close;
        end

        % Button pushed function: push_copy
        function push_copy_Callback(app, event)
            % --- Executes on button press in push_copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            print -dbitmap;
        end

        % Button pushed function: push_go
        function push_go_Callback(app, event)
            % --- Executes on button press in push_go.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_go (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            comptador=evalin('base','mcr_als.aux.counter;')+1;
            
               d=evalin('base','mcr_als.data;');
               [ns,nc]=size(d); % required?
               x=d(1:ns,:);
               [nsoln,nwave]=size(x);
               minn=min(nsoln,nwave);
            
            if comptador==1; % first time. start analysis
               set(handles.text_start,'visible','off');
            
               % ****************
               % forward analysis
               % ****************
            
               n=2;
            
               while n<=nsoln % for 2:height of d matrix
            %         disp('processing row number: '),disp(n)
                    svf=svd(x(1:n,:)); % Uses SVD for first n rows - returns a vector containing the singular values
                    l=svf.*svf; % Square singular values thes creates the S matrix
                    nl=size(l);
            
                    ef(1:nl,n-1)=l(1:nl,1); % Create matricy of first col of Singular matricy
                    efl(1:nl,n-1)=log10(l(1:nl,1)); % Log of Singular matrix first col
                    n=n+1;
               end
               ef=ef'; % 1 by height of S
               efl=efl';
               eforward=ef;

            
               % set appropriately the zero log values
                minvalue=min(min(efl));
                minvalue=round(minvalue);
                [ns1,ns2]=size(efl);
                for i=1:ns1,
                    for j=1:ns2,
                        if efl(i,j)==0,
                            efl(i,j)=minvalue; % replace 0 in log matrix with lowest value
                        end
                    end
                end
            
                xforward=[2:nsoln]; % Vector from 2 to heigh of D matrix
            
                axes(handles.axes_efa);
                plot(xforward,efl); % Plot log matrix
                title('Forward EFA');
                xlabel('Row number');
                ylabel('log(eigenvalues)');
            
                assignin('base','xforward',xforward);
                evalin('base','mcr_als.InitEstim.aux.xforward=xforward;');
                assignin('base','efl',efl);
                evalin('base','mcr_als.InitEstim.aux.efl=efl;');
                assignin('base','ef',ef);
                evalin('base','mcr_als.InitEstim.ef=ef;');
                evalin('base','clear xforward efl ef');
            
                evalin('base','mcr_als.aux.counter=1;');
            end
            
            if comptador==2; % Second time is backwards
            
                % *****************
                % backward analysis
                % *****************
            
            %     disp('backward analysis')
                x=x(nsoln:-1:1,:);
                n=2;
                while n<=nsoln % I cant find the difference
            %         disp('processing row number (backward): '),disp(n)
                    svb=svd(x(1:n,:)); 
                    nl=size(svb);
                    l=svb.*svb;
                    eb(1:nl,n-1)=l(1:nl,1);
                    ebl(1:nl,n-1)=log10(l(1:nl,1));
                    n=n+1;
                end
                eb=eb';
                ebl=ebl';
                ebackward=eb;
            
                % set appropriately the zero log values
                minvalue=min(min(ebl));
                minvalue=round(minvalue);
                [ns1,ns2]=size(ebl);
                for i=1:ns1,
                    for j=1:ns2,
                        if ebl(i,j)==0,
                            ebl(i,j)=minvalue;
                        end
                    end
                end
            
                xbackward=[nsoln:-1:2]; % This is backwards of xforward
                axes(handles.axes_efa);
                plot(xbackward,ebl)
                title('Backward EFA')
                xlabel('Row number')
                ylabel('log(eigenvalues)')
            
                assignin('base','xbackward',xbackward);
                evalin('base','mcr_als.InitEstim.aux.xbackward=xbackward;');
                assignin('base','ebl',ebl);
                evalin('base','mcr_als.InitEstim.aux.ebl=ebl;');
            
                assignin('base','eb',eb);
                evalin('base','mcr_als.InitEstim.eb=eb;');
                evalin('base','clear xbackward ebl eb');
                evalin('base','mcr_als.aux.counter=2;');
            end
            
            if comptador==3; %
            
                   d=evalin('base','mcr_als.data;');
                   [ns,nc]=size(d); % necessari?
                   x=d(1:ns,:);
                   [nsoln,nwave]=size(x);
                   minn=min(nsoln,nwave);
            
            
                % ******************************
                % evolving factor analysis plots
                % ******************************
            
                % 1) plot the log(eigenvalues) and singular values
            
                xforward=evalin('base','mcr_als.InitEstim.aux.xforward;');
                xbackward=evalin('base','mcr_als.InitEstim.aux.xbackward;');
                efl=evalin('base','mcr_als.InitEstim.aux.efl;');
                ebl=evalin('base','mcr_als.InitEstim.aux.ebl;');
            
                xforward=[2:nsoln];
                xbackward=[nsoln:-1:2];
            
                axes(handles.axes_efa);
                plot(xforward,efl,'k',xbackward,ebl,'r')
                title('EVOLVING FACTOR ANALYSIS')
                xlabel('Row number')
                ylabel('log(eigenvalues)')
            
                evalin('base','mcr_als.aux.counter=3;');
            end
            
            
            if comptador==4; %
            
            % 2) rescaling the log of eigenvalues plot
            
                d=evalin('base','mcr_als.data;');
                [ns,nc]=size(d);
                x=d(1:ns,:);
                [nsoln,nwave]=size(x);
                minn=min(nsoln,nwave);
                xforward=evalin('base','mcr_als.InitEstim.aux.xforward;');
                xbackward=evalin('base','mcr_als.InitEstim.aux.xbackward;');
                efl=evalin('base','mcr_als.InitEstim.aux.efl;');
                ebl=evalin('base','mcr_als.InitEstim.aux.ebl;');
            
                maxvalue=max(max(ebl));
                maxvalue=round(maxvalue+1);
                %minvalue=input(' min. value of log efa plots ? ');
                set(handles.text_minvalue,'visible','on','enable','on');
                set(handles.edit_minvalue,'visible','on','enable','on');
            
                set(handles.push_print,'enable','off');
                set(handles.push_copy,'enable','off');
                set(handles.push_canc,'enable','off');
                set(handles.push_go,'enable','off');
                evalin('base','mcr_als.aux.efamin=1;');
            
                while(evalin('base','mcr_als.aux.efamin')==1)
                     pause(0.3);
                end
                set(handles.push_go,'enable','on');
                set(handles.push_print,'enable','on');
                set(handles.push_copy,'enable','on');
                set(handles.push_canc,'enable','on');
                set(handles.text_minvalue,'visible','on','enable','inactive');
                set(handles.edit_minvalue,'visible','on','enable','inactive');
                minvalue=evalin('base','mcr_als.InitEstim.aux.EFAminvalue;');
            
                axes(handles.axes_efa);
                plot(xforward,efl,'k',xbackward,ebl,'r')
                %axis([2 nsoln minvalue maxvalue])
                title('EVOLVING FACTOR ANALYSIS')
                xlabel('Row number')
                ylabel('log(eigenvalues)')
                evalin('base','mcr_als.aux.counter=4;');
            
            end
            
            
            if comptador==5; %
            
                d=evalin('base','mcr_als.data;');
                [ns,nc]=size(d);
                x=d(1:ns,:);
                [nsoln,nwave]=size(x);
                minn=min(nsoln,nwave);
                xforward=evalin('base','mcr_als.InitEstim.aux.xforward;');
                xbackward=evalin('base','mcr_als.InitEstim.aux.xbackward;');
                ef=evalin('base','mcr_als.InitEstim.ef;');
                eb=evalin('base','mcr_als.InitEstim.eb;');
            
                % 3) plot the arranged conc. profiles for the num. of factors
            
                nf=evalin('base','mcr_als.CompNumb.nc;');
            
                clear e;
            
                    for j=1:nf
                        jj=nf+1-j;
                        for i=1:nsoln-1,
                            ii=nsoln-i;
                            if ef(i,j)<=eb(ii,jj),
                                e(i,j)=ef(i,j);
                            else,
                                e(i,j)=eb(ii,jj);
                            end
                        if e(i,j)==0.0, e(i,j)=1.0e-30; end
                        end
                    end
            
                    axes(handles.axes_efa);
                    plot(xforward,e)
                    title ('Initial estimates from EFA analysis')
            
                e(2:nsoln,:)=e(1:nsoln-1,:);
                assignin('base','e',e);
                evalin('base','mcr_als.InitEstim.iniesta=e;');
                evalin('base','clear e');
            
                evalin('base','mcr_als.aux.counter=5;');
                set(handles.push_go,'enable','off');
                set(handles.push_OK,'enable','on');
            
            
            end
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf,'PaperPositionMode','auto');
            print('EFAGUI','-dpdf');
        end

        % Callback function: not associated with a component
        function Info(app, event)
            text = sprintf(['Evolving Factor Analysis is used for sequential processes only and produces a concentration/column estimate. \n' ...
                ' - By detecting major increases in insensity as forward and backwards passes are made on the matrix, the concentration windows are found. \n' ...
                ' - Using SVD, the concentration profile can be derived \n' ...
                ' - The gui will walk through both forward and backwards passes \n' ...
                ' - You will be prompted to enter the number of prominant elements eluted, Ussually 3 or 4.']);
            msgbox(text, 'EFA Info')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Multivariate Curve Resolution Toolbox - EFA analysis';
            app.figure1.Tag = 'figure1';
            app.axes_efa = uiaxes(app.figure1);
            app.axes_efa.Tag = 'axes_efa';
            app.text_start = uilabel(app.figure1);
            app.text_start.Tag = 'text_start';
            app.text_start.Text = 'Click continue to start';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Tag = 'uipanel3';
            app.text1 = uilabel(app.uipanel3);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Evolving Factor Analysis Method';
            app.push_OK = uibutton(app.figure1, 'push');
            app.push_OK.ButtonPushedFcn = createCallbackFcn(app, @push_OK_Callback, true);
            app.push_OK.Tag = 'push_OK';
            app.push_OK.Enable = 'off';
            app.push_OK.Text = 'OK';
            app.push_canc = uibutton(app.figure1, 'push');
            app.push_canc.ButtonPushedFcn = createCallbackFcn(app, @push_canc_Callback, true);
            app.push_canc.Tag = 'push_canc';
            app.push_canc.Text = 'Cancel';
            app.push_copy = uibutton(app.figure1, 'push');
            app.push_copy.ButtonPushedFcn = createCallbackFcn(app, @push_copy_Callback, true);
            app.push_copy.Tag = 'push_copy';
            app.push_copy.Text = 'Copy';
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Text = 'Print';
            app.push_go = uibutton(app.figure1, 'push');
            app.push_go.ButtonPushedFcn = createCallbackFcn(app, @push_go_Callback, true);
            app.push_go.Tag = 'push_go';
            app.push_go.Text = 'Continue';
            app.text_minvalue = uilabel(app.figure1);
            app.text_minvalue.Tag = 'text_minvalue';
            app.text_minvalue.Enable = 'off';
            app.text_minvalue.Visible = 'off';
            app.text_minvalue.Text = {'Write the minimum '; 'log(eigenvalue)'};
            app.edit_minvalue = uieditfield(app.figure1, 'text');
            app.edit_minvalue.ValueChangedFcn = createCallbackFcn(app, @edit_minvalue_Callback, true);
            app.edit_minvalue.Tag = 'edit_minvalue';
            app.edit_minvalue.Enable = 'off';
            app.edit_minvalue.Visible = 'off';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = efagui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)efagui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end