classdef images_info_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        push_close   matlab.ui.control.Button
        uipanel2     matlab.ui.container.Panel
        text15       matlab.ui.control.Label
        popup_wave   matlab.ui.control.DropDown
        text14       matlab.ui.control.Label
        text13       matlab.ui.control.Label
        text12       matlab.ui.control.Label
        edit_pback   matlab.ui.control.EditField
        edit_presol  matlab.ui.control.EditField
        text11       matlab.ui.control.Label
        text9        matlab.ui.control.Label
        uipanel1     matlab.ui.container.Panel
        text7        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        text_nr      matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        edit_images  matlab.ui.control.EditField
        edit_xpix    matlab.ui.control.EditField
        edit_ypix    matlab.ui.control.EditField
        push_OK      matlab.ui.control.Button
        text1        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function images_info_OpeningFcn(app, varargin)
            % --- Executes just before images_info is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to images_info (see VARARGIN)
            
            % Choose default command line output for images_info
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_images
        function edit_images_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_images (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_images as text
            %        str2double(get(hObject,'String')) returns contents of edit_images as a double
            
            z_imags=str2num(get(hObject,'String'));
            assignin('base','z_imags',z_imags);
            evalin('base','mcr_als.aux.z=z_imags;');
            evalin('base','clear z_imags');
        end

        % Value changed function: edit_pback
        function edit_pback_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_pback (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_pback as text
            %        str2double(get(hObject,'String')) returns contents of edit_pback as a double
            
            PixOut=str2num(get(hObject,'String'));
            assignin('base','PixOut',PixOut);
            evalin('base','mcr_als.aux.pixout=PixOut;');
            evalin('base','clear PixOut');
        end

        % Value changed function: edit_presol
        function edit_presol_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_presol (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_presol as text
            %        str2double(get(hObject,'String')) returns contents of edit_presol as a double
            
            
            PixIn=str2num(get(hObject,'String'));
            assignin('base','PixIn',PixIn);
            evalin('base','mcr_als.aux.pixin=PixIn;');
            evalin('base','clear PixIn');
        end

        % Value changed function: edit_xpix
        function edit_xpix_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_xpix (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_xpix as text
            %        str2double(get(hObject,'String')) returns contents of edit_xpix as a double
            
            x_pixs=str2num(get(hObject,'String'));
            assignin('base','x_pixs',x_pixs);
            evalin('base','mcr_als.aux.x=x_pixs;');
            evalin('base','clear x_pixs');
        end

        % Value changed function: edit_ypix
        function edit_ypix_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_ypix (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_ypix as text
            %        str2double(get(hObject,'String')) returns contents of edit_ypix as a double
            
            y_pixs=str2num(get(hObject,'String'));
            assignin('base','y_pixs',y_pixs);
            evalin('base','mcr_als.aux.y=y_pixs;');
            evalin('base','clear y_pixs');
        end

        % Value changed function: popup_wave
        function popup_wave_Callback(app, event)
            % --- Executes on selection change in popup_wave.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_wave (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_wave contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_wave
            
            popmenu3=get(handles.popup_wave,'String');
            pm3=get(handles.popup_wave,'Value');
            
            if pm3==1
                % arbitrary channels
                sopt=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
                [rs,cs]=size(sopt);
                longWave=[1:cs];
                assignin('base','longWave',longWave);
                evalin('base','mcr_als.aux.long=longWave;');
                evalin('base','clear longWave');
            else
                selec3=char([popmenu3(pm3)]);
                longWave=evalin('base',selec3);
                assignin('base','longWave',longWave);
                evalin('base','mcr_als.aux.long=longWave;');
                evalin('base','clear longWave');
            end
        end

        % Button pushed function: push_OK
        function push_OK_Callback(app, event)
            % --- Executes on button press in push_OK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_OK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            copt=evalin('base','mcr_als.alsOptions.resultats.optim_concs;');
            sopt=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
            [m,n]=size(copt);
            [rs,cs]=size(sopt);
            
            long=evalin('base','mcr_als.aux.long;');
            
            % x
            x=evalin('base','mcr_als.aux.x;');
            % y
            y=evalin('base','mcr_als.aux.y;');
            % z -> number of images
            z=evalin('base','mcr_als.aux.z;');
            
            % pixin
            pixin=evalin('base','mcr_als.aux.pixin;');
            % pixout
            pixout=evalin('base','mcr_als.aux.pixout;');
            
            % if nargin <=6
            %     pixout=0;
            %     pixin=[1:m];
            % end
            
            mdis=cell(z,n);
            quantc=zeros(z,n);
            ctot=zeros(m,n);
            ctot(pixin,:)=copt;
            
            if pixout~=0
                ctot(pixout,:)=min(min(copt));
            end
            
            % reshaping conc profiles into maps frommultisets with images equally sized
            if length(x)==1 & length(y)==1
                for j=0:z-1
                    clayer=ctot((x*y)*j+1:(x*y)*(j+1),:);
                    for i=1:n
                        quantc(j+1,i)=100*(sum(sum(clayer(:,i)*sopt(i,:))))/(sum(sum(clayer*sopt)));
                        mdis{j+1,i}=reshape(clayer(:,i),x,y);
            %           figure(j+1),subplot(n,1,i),imagesc(mdis{j+1,i},[minc maxc]),axis('square')
                        assignin('base','mdisIMG',mdis);
                        evalin('base','mcr_als.aux.mdis=mdisIMG;');
                        evalin('base','clear mdisIMG');
            
                        assignin('base','quantcIMG',quantc);
                        evalin('base','mcr_als.aux.quantc=quantcIMG;');
                        evalin('base','clear quantcIMG');
            
                    end
                end
            end
            % reshaping conc profiles into maps from multisets with images with different sizes
            if length(x)>1 & length(y)>1
                ptot=0;
                for j=0:z-1
                    clayer=ctot(ptot+1:ptot+x(j+1)*y(j+1),:);
                    ptot=sum([ptot x(j+1)*y(j+1)]);
                    for i=1:n
                        quantc(j+1,i)=100*(sum(sum(clayer(:,i)*sopt(i,:))))/(sum(sum(clayer*sopt)));
                        mdis{j+1,i}=reshape(clayer(:,i),x(j+1),y(j+1));
            %           figure(j+1),subplot(n,1,i),imagesc(mdis{j+1,i},[minc maxc]),axis('square')
                        assignin('base','mdisIMG',mdis);
                        evalin('base','mcr_als.aux.mdis=mdisIMG;');
                        evalin('base','clear mdisIMG');
            
                        assignin('base','quantcIMG',quantc);
                        evalin('base','mcr_als.aux.quantc=quantcIMG;');
                        evalin('base','clear quantcIMG');
            
                    end
                end
            end
            
            images_plots;
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Images scores refolding';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Image scores refolding';
            app.push_OK = uibutton(app.figure1, 'push');
            app.push_OK.ButtonPushedFcn = createCallbackFcn(app, @push_OK_Callback, true);
            app.push_OK.Tag = 'push_OK';
            app.push_OK.Text = 'Plot';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Pixels and Images info';
            app.uipanel1.Tag = 'uipanel1';
            app.edit_ypix = uieditfield(app.uipanel1, 'text');
            app.edit_ypix.ValueChangedFcn = createCallbackFcn(app, @edit_ypix_Callback, true);
            app.edit_ypix.Tag = 'edit_ypix';
            app.edit_xpix = uieditfield(app.uipanel1, 'text');
            app.edit_xpix.ValueChangedFcn = createCallbackFcn(app, @edit_xpix_Callback, true);
            app.edit_xpix.Tag = 'edit_xpix';
            app.edit_images = uieditfield(app.uipanel1, 'text');
            app.edit_images.ValueChangedFcn = createCallbackFcn(app, @edit_images_Callback, true);
            app.edit_images.Tag = 'edit_images';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Pixels in X direction * ';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Pixels in Y direction *';
            app.text_nr = uilabel(app.uipanel1);
            app.text_nr.Tag = 'text_nr';
            app.text_nr.Text = 'Number of images';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'a single number if all images are equally sized,';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'a vector if images have different sizes';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Optional';
            app.uipanel2.Tag = 'uipanel2';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Indexes of pixels in resolution';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Indexes of background pixels';
            app.edit_presol = uieditfield(app.uipanel2, 'text');
            app.edit_presol.ValueChangedFcn = createCallbackFcn(app, @edit_presol_Callback, true);
            app.edit_presol.Tag = 'edit_presol';
            app.edit_pback = uieditfield(app.uipanel2, 'text');
            app.edit_pback.ValueChangedFcn = createCallbackFcn(app, @edit_pback_Callback, true);
            app.edit_pback.Tag = 'edit_pback';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tag = 'text12';
            app.text12.Text = '(all by default)';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tag = 'text13';
            app.text13.Text = '(0 by default)';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Wavelength axis for spectra';
            app.popup_wave = uidropdown(app.uipanel2);
            app.popup_wave.Items = {'arbitrary channels', 'DataDC', 'DataTime', 'colormap', 'copt', 'currentT50Times', 'exportT0_intensities', 'kin_data', 'sopt', 'times', 'waves'};
            app.popup_wave.ValueChangedFcn = createCallbackFcn(app, @popup_wave_Callback, true);
            app.popup_wave.Tag = 'popup_wave';
            app.popup_wave.Value = 'arbitrary channels';
            app.text15 = uilabel(app.uipanel2);
            app.text15.Tag = 'text15';
            app.text15.Text = '(optional, arbitrary channels)';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = images_info_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)images_info_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end