classdef kineticHM_multi_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1             matlab.ui.Figure
        popup_expSim        matlab.ui.control.DropDown
        text19              matlab.ui.control.Label
        push_sim            matlab.ui.control.Button
        uipanel4            matlab.ui.container.Panel
        edit_nexp           matlab.ui.control.EditField
        text15              matlab.ui.control.Label
        text14              matlab.ui.control.Label
        edit_nrModel        matlab.ui.control.EditField
        popup_selectModel   matlab.ui.control.DropDown
        text13              matlab.ui.control.Label
        push_credits        matlab.ui.control.Button
        uipanel2            matlab.ui.container.Panel
        popup_time          matlab.ui.control.DropDown
        text8               matlab.ui.control.Label
        uipanel5            matlab.ui.container.Panel
        listbox_MCRspecies  matlab.ui.control.ListBox
        listbox_KINspecies  matlab.ui.control.ListBox
        push_CALS           matlab.ui.control.Button
        text18              matlab.ui.control.Label
        text17              matlab.ui.control.Label
        text16              matlab.ui.control.Label
        push_Tvalues        matlab.ui.control.Button
        uitable2            matlab.ui.control.Table
        edit_mech           matlab.ui.control.TextArea
        text2               matlab.ui.control.Label
        text4               matlab.ui.control.Label
        check_colour        matlab.ui.control.CheckBox
        listbox_concs       matlab.ui.control.ListBox
        listbox_rates       matlab.ui.control.ListBox
        text7               matlab.ui.control.Label
        edit_rates          matlab.ui.control.EditField
        text6               matlab.ui.control.Label
        text5               matlab.ui.control.Label
        text3               matlab.ui.control.Label
        push_done           matlab.ui.control.Button
        push_cancel         matlab.ui.control.Button
        text1               matlab.ui.control.Label
        axes1               matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable2.BackgroundColor = [1 1 1;0.9608 0.9608 0.9608];
            app.uitable2.ColumnFormat = {[] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function kineticHM_multi_OpeningFcn(app, varargin)
            % --- Executes just before kineticHM_multi is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to kineticHM_multi (see VARARGIN)
            
            % Choose default command line output for kineticHM_multi
            handles.output = hObject;
            
            set(handles.uitable2,'Data',[]);
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: check_colour
        function check_colour_Callback(app, event)
            % --- Executes on button press in check_colour.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_colour (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_colour
            
            valorCol=get(hObject,'Value');
            
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.cactiu;'];
            num=evalin('base',expressionX);
            
            assignin('base','num',num);
            assignin('base','valorCol',valorCol);
            
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.col(1,num)=valorCol;'];
            evalin('base',expressionX);
            
            evalin('base','clear valorCol num');
        end

        % Value changed function: edit_mech
        function edit_mech_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_mech (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_mech as text
            %        str2double(get(hObject,'String')) returns contents of edit_mech as a double
            
            modelEscrit=get(hObject,'String');
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            
            % save expFitted
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.expFitted;'];
            expFitted=evalin('base',expressionX);
            
            hard.model.modelEscrit = cellstr(modelEscrit);
            disp(hard.model.modelEscrit)
            [hard.model.model,hard.model.species_list,hard.model.errstr] = eq2mat_hard(hard.model.modelEscrit);
            assignin('base','modelHard',hard);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'=modelHard;'];
            evalin('base',expressionX);
            evalin('base','clear modelHard');
            
            % load expFitted
            assignin('base','expFittedX',expFitted);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.expFitted=expFittedX;'];
            evalin('base',expressionX);
            evalin('base','clear expFittedX');
            
            % rates
            [hard.model.nrconstants,n]=size(hard.model.model.N);
            j=1;
            for i=1:1:hard.model.nrconstants;
                llistnnc=['k',num2str(i)];
                llistannc(j)={llistnnc};
                j=j+1;
            end;
            set(handles.listbox_rates,'string',llistannc,'value',1);
            set(handles.listbox_rates,'enable','on');
            
            % set active the first and value=0
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.constants.kactiu=1;'];
            evalin('base',expressionX);
            
            set(handles.edit_rates,'string','0');
            
            % all zeros and edit values
            ki=zeros(1,hard.model.nrconstants);
            assignin('base','ki',ki);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.constants.ki=ki;'];
            evalin('base',expressionX);
            evalin('base','clear ki');
            
            % species *****************************************************
            [hard.model.nrspecies,n]=size(hard.model.species_list);
            j=1;
            for i=1:1:hard.model.nrspecies;
                llistnnc=[hard.model.species_list(i)];
                llistannc(j)=llistnnc;
                j=j+1;
            end;
            set(handles.listbox_concs,'string',llistannc,'value',1);
            set(handles.listbox_concs,'enable','on');
            
            % set active the first ones
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.cactiu=1;'];
            evalin('base',expressionX);
            
            % all zeros and edit values
            nexp=evalin('base','mcr_als.alsOptions.nexp;');
            ci=zeros(nexp,hard.model.nrspecies);
            assignin('base','ci',ci);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.ci=ci;'];
            evalin('base',expressionX);
            evalin('base','clear ci');
            
            %  setting the table ******************************************************
            
            dades_taula=[];
            nexp=evalin('base','mcr_als.alsOptions.nexp;');
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.expFitted;'];
            expFitted=evalin('base',expressionX);
            
            for i=1:1:nexp
                dades_taula{i,1}=['Exp ',num2str(i)];
            
                if expFitted(1,i)==1
                    dades_taula{i,2}=[];
                else
                    dades_taula{i,2}=NaN;
                end
            end
            set(handles.uitable2,'Data',dades_taula);
            
            % cused
            Cused=zeros(1,hard.model.nrspecies);
            assignin('base','Cused',Cused);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.Cused=Cused;'];
            evalin('base',expressionX);
            evalin('base','clear Cused');
            
            % coloured
            col=ones(1,hard.model.nrspecies);
            assignin('base','col',col);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.col=col;'];
            evalin('base',expressionX);
            evalin('base','clear col');
            
            % correspondence kinetic and MCR profiles
            llistannc2=['No kinetic' llistannc];
            set(handles.listbox_KINspecies,'string',llistannc2,'value',1);
            set(handles.listbox_KINspecies,'enable','on');
            
            % set active the first ones
            corrMCR0=zeros(evalin('base','mcr_als.alsOptions.nComponents;'),1);
            assignin('base','corrMCR0',corrMCR0);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concMCR.corrMCR=corrMCR0;'];
            evalin('base',expressionX);
            evalin('base','clear corrMCR0');
            
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concMCR.MCRactiu=1;'];
            evalin('base',expressionX);
            evalin('base','clear MCRactiu sortMCR');
        end

        % Value changed function: edit_nexp
        function edit_nexp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_nexp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_nexp as text
            %        str2double(get(hObject,'String')) returns contents of edit_nexp as a double
            
            expFitted=str2num(get(hObject,'String'));
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            assignin('base','expFitted',expFitted);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.expFitted=expFitted;'];
            evalin('base',expressionX);
            evalin('base','clear expFitted');
        end

        % Value changed function: edit_nrModel
        function edit_nrModel_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_nrModel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_nrModel as text
            %        str2double(get(hObject,'String')) returns contents of edit_nrModel as a double
            
            nModels=str2num(get(hObject,'String'));
            assignin('base','nModels',nModels);
            evalin('base','mcr_als.alsOptions.kinetic.nModels=nModels;');
            evalin('base','clear nModels');
            
            if nModels==1
                set(handles.popup_selectModel,'enable','inactive','string',1,'value',1);
                set(handles.edit_mech,'enable','on');
                evalin('base','mcr_als.alsOptions.kinetic.modelActiu=1;');
                nexp=evalin('base','mcr_als.alsOptions.nexp;');
                set(handles.edit_nexp,'string',num2str(ones(1,nexp)),'enable','on');
            
                assignin('base','nexpx3',nexp);
                expressionX=['mcr_als.alsOptions.kinetic.models.model1.expFitted=ones(1,nexpx3);'];
                evalin('base',expressionX);
                evalin('base','clear nexpx3');
            
            elseif nModels>1
                j=2;
                llistannc(1)={'select model nr.'};
                for i=1:nModels;
                    llistnnc=[num2str(i)];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
                set(handles.popup_selectModel,'enable','on','string',llistannc,'value',1);
            end
        end

        % Value changed function: edit_rates
        function edit_rates_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_rates (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_rates as text
            %        str2double(get(hObject,'String')) returns contents of edit_rates as a double
            
            ki=str2num(get(hObject,'String'));
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.constants.kactiu;'];
            num=evalin('base',expressionX);
            assignin('base','ki',ki);
            assignin('base','num',num);
            
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.constants.ki(1,num)=ki;'];
            evalin('base',expressionX);
            evalin('base','clear ki num');
        end

        % Value changed function: listbox_KINspecies
        function listbox_KINspecies_Callback(app, event)
            % --- Executes on selection change in listbox_KINspecies.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_KINspecies (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_KINspecies contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_KINspecies
            
            sortMCR=get(hObject,'Value')-1;
            assignin('base','sortMCR',sortMCR);
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concMCR.MCRactiu;'];
            MCRactiu=evalin('base',expressionX);
            assignin('base','MCRactiu',MCRactiu);
            
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concMCR.corrMCR(MCRactiu,1)=sortMCR;'];
            evalin('base',expressionX);
            
            % change edit
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concMCR.corrMCR'];
            vectorCorr=evalin('base',expressionX);
            evalin('base','clear cactiu sortMCR');
        end

        % Value changed function: listbox_MCRspecies
        function listbox_MCRspecies_Callback(app, event)
            % --- Executes on selection change in listbox_MCRspecies.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_MCRspecies (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_MCRspecies contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_MCRspecies
            
            MCRactiu=get(hObject,'Value');
            assignin('base','MCRactiu',MCRactiu);
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concMCR.MCRactiu=MCRactiu;'];
            evalin('base',expressionX);
            
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concMCR.corrMCR(MCRactiu,1);'];
            corrMCRold=evalin('base',expressionX);
            
            valor=corrMCRold+1;
            set(handles.listbox_KINspecies,'value',valor);
            evalin('base','clear MCRactiu');
        end

        % Value changed function: listbox_concs
        function listbox_concs_Callback(app, event)
            % --- Executes on selection change in listbox_concs.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_concs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_concs contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_concs
            
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            cactiu=get(hObject,'Value');
            assignin('base','cactiu',cactiu);
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.cactiu=cactiu;'];
            evalin('base',expressionX);
            evalin('base','clear cactiu');
            
            % change the edit value for the memory value
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.ci(1,mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.cactiu);'];
            valor=evalin('base',expressionX);
            
            %canvi del valor del check pel de la memo
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.col(1,mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.cactiu);'];
            valor=evalin('base',expressionX);
            set(handles.check_colour,'enable','on','value',valor);
            
            % setting of the table ******************************************************
            
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.Cused;'];
            Cused=evalin('base',expressionX);
            valor=Cused(cactiu);
            
            if valor==0
                dades_taula=[];
                nexp=evalin('base','mcr_als.alsOptions.nexp;');
                expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.expFitted;'];
                expFitted=evalin('base',expressionX);
            
                for i=1:1:nexp
                    dades_taula{i,1}=['Exp ',num2str(i)];
            
                    if expFitted(1,i)==1
                        dades_taula{i,2}=[];
                    else
                        dades_taula{i,2}=NaN;
                    end
                end
                set(handles.uitable2,'Data',dades_taula);
            else
                expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.ci(:,',num2str(cactiu),');'];
                oldCvalues=evalin('base',expressionX);
            
                nexp=evalin('base','mcr_als.alsOptions.nexp;');
                for i=1:1:nexp
                    dades_taula{i,1}=['Exp ',num2str(i)];
                    dades_taula{i,2}=oldCvalues(i,1);
                end
                set(handles.uitable2,'Data',dades_taula);
            end
        end

        % Value changed function: listbox_rates
        function listbox_rates_Callback(app, event)
            % --- Executes on selection change in listbox_rates.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_rates (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_rates contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_rates
            
            kactiu=get(hObject,'Value');
            assignin('base','kactiu',kactiu);
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.constants.kactiu=kactiu;'];
            evalin('base',expressionX);
            evalin('base','clear kactiu');
            
            % change the edit value for the memory value
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.constants.ki(1,mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.constants.kactiu);'];
            valor=evalin('base',expressionX);
            set(handles.edit_rates,'enable','on','string',num2str(valor));
        end

        % Value changed function: popup_expSim
        function popup_expSim_Callback(app, event)
            % --- Executes on selection change in popup_expSim.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_expSim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_expSim contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_expSim
            
            % s'ha el temps d'acord a l'experiment
            
            exp2Sim=get(hObject,'Value')-1;
            
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            nexp=evalin('base','mcr_als.alsOptions.nexp;');
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.expFitted;'];
            expFitted=evalin('base',expressionX);
            time=evalin('base','mcr_als.alsOptions.kinetic.time_axis');
            
            if nexp==sum(expFitted) % cas facil, tots els experiments es modelen
                % cut time
                nrinic=evalin('base','mcr_als.alsOptions.multi.nrinic;');
                nrfin=evalin('base','mcr_als.alsOptions.multi.nrfin;');
                time_sim=time(nrinic(exp2Sim):nrfin(exp2Sim));
            
                % cut concs
                expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.ci;'];
                concs=evalin('base',expressionX);
                concs_sim=concs(exp2Sim,:);
            
            else
                % modification of expsim adding the previos 0 values
                expFitted_tall=expFitted(1:exp2Sim);
                numOf0=numel(expFitted_tall)-sum(expFitted_tall);
                exp2Sim_0=exp2Sim+numOf0;
            
                % cutting concs
                expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.ci;'];
                concs=evalin('base',expressionX);
                concs_sim=concs(exp2Sim_0,:);
            
                % cutting time
                if numOf0>0
                    nrsol=evalin('base','mcr_als.alsOptions.multi.nrsol;');
                    rows=expFitted.*nrsol;
                    time_sim=time(1,1+sum(rows(1:exp2Sim_0-1)) : sum(rows(1:exp2Sim_0)));
                else
                    nrinic=evalin('base','mcr_als.alsOptions.multi.nrinic;');
                    nrfin=evalin('base','mcr_als.alsOptions.multi.nrfin;');
                    time_sim=time(nrinic(exp2Sim_0):nrfin(exp2Sim_0));
                end
            end
            assignin('base','time_sim',time_sim);
            evalin('base','mcr_als.alsOptions.kinetic.sim.time_sim=time_sim');
            assignin('base','concs_sim',concs_sim);
            evalin('base','mcr_als.alsOptions.kinetic.sim.concs_sim=concs_sim');
            evalin('base','clear time_sim concs_sim');
            set(handles.push_sim,'enable','on');
        end

        % Value changed function: popup_selectModel
        function popup_selectModel_Callback(app, event)
            % --- Executes on selection change in popup_selectModel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_selectModel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_selectModel contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_selectModel
            
            modelActiu=get(hObject,'Value')-1;
            assignin('base','modelActiu',modelActiu);
            evalin('base','mcr_als.alsOptions.kinetic.modelActiu=modelActiu;');
            evalin('base','clear modelActiu ');
            
            % reset (by now)
            
            nexp=evalin('base','mcr_als.alsOptions.nexp;');
            set(handles.edit_nexp,'string',num2str(ones(1,nexp)),'enable','on');
            assignin('base','nexpx3',ones(1,nexp));
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.expFitted=nexpx3;'];
            evalin('base',expressionX);
            evalin('base','clear nexpx3');
            
            set(handles.edit_mech,'enable','on','string',' ');
            set(handles.listbox_rates,'string',' ','value',1,'enable','inactive');
            set(handles.edit_rates,'string',' ');
            set(handles.listbox_concs,'string',' ','value',1,'enable','inactive');
            set(handles.listbox_KINspecies,'string',' ','value',1,'enable','inactive');
            % set(handles.listbox_MCRspecies,'string',' ','value',1,'enable','inactive');
            set(handles.popup_time,'value',1');
            set(handles.check_colour,'enable','inactive','value',1);
            set(handles.uitable2,'Data',[]);
            axes(handles.axes1);cla;
        end

        % Value changed function: popup_time
        function popup_time_Callback(app, event)
            % --- Executes on selection change in popup_time.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_time (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_time contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_time
            
            
            popmenu2=get(handles.popup_time,'String');
            pm2=get(handles.popup_time,'Value');
            
            if pm2==1
            
            else
                selec2=char([popmenu2(pm2)]);
                time=evalin('base',selec2);
                assignin('base','time_axis',time);
                evalin('base','mcr_als.alsOptions.kinetic.time_axis=time_axis;');
                evalin('base','clear time_axis');
            end
            
            % activate popup of setting experiment
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            nexp=evalin('base','mcr_als.alsOptions.nexp;');
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.expFitted;'];
            expFitted=evalin('base',expressionX);
            
            j=2;
            llistannc(1)={'select Exp nr.'};
            for i=1:1:nexp
                if expFitted(i)==1
                    llistnnc=['Exp nr. ',num2str(i)];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end
            end
            set(handles.popup_expSim,'enable','on','string',llistannc,'value',1);
            
            set(handles.push_sim,'enable','off');
            set(handles.push_done,'enable','on');
        end

        % Button pushed function: push_CALS
        function push_CALS_Callback(app, event)
            % --- Executes on button press in push_CALS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_CALS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            iniesta=evalin('base','mcr_als.alsOptions.iniesta;');
            if evalin('base','mcr_als.InitEstim.method')=='Pur';
                if evalin('base','mcr_als.InitEstim.pureDirection')==1;
                    iniesta=iniesta';
                end
            end
            [rows,columns]=size(iniesta);
            nComponents=evalin('base','mcr_als.alsOptions.nComponents;');
            matdad=evalin('base','mcr_als.alsOptions.matdad;');
            perfils=evalin('base','mcr_als.alsOptions.kinetic.sim.simula.perfilc');
            
            
            if nComponents==rows % IE = spectra
            
                axes(handles.axes1);
                hold on;
                time=evalin('base','mcr_als.alsOptions.kinetic.time_axis');
                ccc=matdad/iniesta;
                valorNorm=max(max(ccc))/max(max(perfils));
                if verLessThan('matlab', '8.4')
                    %  nothing
                else
                    set(handles.axes1,'ColorOrderIndex',1);
                end
                plot(time,ccc/valorNorm,'*');
            
            
            elseif nComponents==columns % IE = concs
            
                axes(handles.axes1);
                hold on;
                if verLessThan('matlab', '8.4')
                    %  nothing
                else
                    set(handles.axes1,'ColorOrderIndex',1);
                end
                time=evalin('base','mcr_als.alsOptions.kinetic.time_axis');
                valorNorm=max(max(iniesta))/max(max(perfils));
                plot(time,iniesta/valorNorm,'*');
                hold off;
            
            end
        end

        % Button pushed function: push_Tvalues
        function push_Tvalues_Callback(app, event)
            % --- Executes on button press in push_Tvalues.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_Tvalues (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            % save species and the table values
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.cactiu;'];
            cactiu=evalin('base',expressionX);
            % backup taula
            info = get(handles.uitable2,'Data');
            oldCvalues=cell2mat(info(:,2))';
            assignin('base','oldCvalues',oldCvalues');
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.ci(:,',num2str(cactiu),')=str2num(oldCvalues);'];
            evalin('base',expressionX);
            
            % seting used=yes
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.Cused(1,mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.cactiu)=1;'];
            evalin('base',expressionX);
        end

        % Button pushed function: push_cancel
        function push_cancel_Callback(app, event)
            % --- Executes on button press in push_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end

        % Button pushed function: push_credits
        function push_credits_Callback(app, event)
            % --- Executes on button press in push_credits.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_credits (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            KineticCred;
        end

        % Button pushed function: push_done
        function push_done_Callback(app, event)
            % --- Executes on button press in push_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_done (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions.kinetic.appKinetic=1');
            close;
        end

        % Button pushed function: push_sim
        function push_sim_Callback(app, event)
            % --- Executes on button press in push_sim.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_sim (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.modelActiu;');
            expressionX=['[mcr_als.alsOptions.kinetic.sim.simula.eixx,mcr_als.alsOptions.kinetic.sim.simula.perfilsc]=ode15s(@gui_conc,[0 max(mcr_als.alsOptions.kinetic.sim.time_sim)],mcr_als.alsOptions.kinetic.sim.concs_sim,[],mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.constants.ki,transpose(mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.model.model.N),mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.model.model.R);'];
            evalin('base',expressionX);
            axes(handles.axes1);
            
            evalin('base','mcr_als.alsOptions.kinetic.sim.simula.perfilc=interp1(mcr_als.alsOptions.kinetic.sim.simula.eixx,mcr_als.alsOptions.kinetic.sim.simula.perfilsc,mcr_als.alsOptions.kinetic.sim.time_sim);');
            
            time=evalin('base','mcr_als.alsOptions.kinetic.sim.time_sim');
            perfils=evalin('base','mcr_als.alsOptions.kinetic.sim.simula.perfilc');
            expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.col;'];
            coloured=evalin('base',expressionX);
            
            perfilsCol=perfils*diag(coloured);
            plot(time,perfils,':');
            hold on;
            if verLessThan('matlab', '8.4')
                %  nothing
            else
                set(handles.axes1,'ColorOrderIndex',1);
            end
            plot(time,perfilsCol);
            hold off;
            axis([0 max(time) 0 max(max(perfils))]);
            
            set(handles.push_done,'enable','on');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Parameters of the Kinetic Constraint';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Parameters of the Kinetic Constraint';
            app.push_cancel = uibutton(app.figure1, 'push');
            app.push_cancel.ButtonPushedFcn = createCallbackFcn(app, @push_cancel_Callback, true);
            app.push_cancel.Tag = 'push_cancel';
            app.push_cancel.Text = 'Cancel';
            app.push_done = uibutton(app.figure1, 'push');
            app.push_done.ButtonPushedFcn = createCallbackFcn(app, @push_done_Callback, true);
            app.push_done.Tag = 'push_done';
            app.push_done.Enable = 'off';
            app.push_done.Text = 'Done';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Kinetic Model';
            app.uipanel2.Tag = 'uipanel2';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Initial Values';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Initial Concentrations';
            app.text6 = uilabel(app.uipanel2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Value';
            app.edit_rates = uieditfield(app.uipanel2, 'text');
            app.edit_rates.ValueChangedFcn = createCallbackFcn(app, @edit_rates_Callback, true);
            app.edit_rates.Tag = 'edit_rates';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Value';
            app.listbox_rates = uilistbox(app.uipanel2);
            app.listbox_rates.Items = {};
            app.listbox_rates.ValueChangedFcn = createCallbackFcn(app, @listbox_rates_Callback, true);
            app.listbox_rates.Tag = 'listbox_rates';
            app.listbox_rates.Value = {};
            app.listbox_concs = uilistbox(app.uipanel2);
            app.listbox_concs.Items = {};
            app.listbox_concs.ValueChangedFcn = createCallbackFcn(app, @listbox_concs_Callback, true);
            app.listbox_concs.Tag = 'listbox_concs';
            app.listbox_concs.Value = {};
            app.check_colour = uicheckbox(app.uipanel2);
            app.check_colour.ValueChangedFcn = createCallbackFcn(app, @check_colour_Callback, true);
            app.check_colour.Tag = 'check_colour';
            app.check_colour.Text = 'Coloured?';
            app.check_colour.Value = true;
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Reaction Rates';
            app.text2 = uilabel(app.uipanel2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Mechanism';
            app.edit_mech = uitextarea(app.uipanel2);
            app.edit_mech.ValueChangedFcn = createCallbackFcn(app, @edit_mech_Callback, true);
            app.edit_mech.Tag = 'edit_mech';
            app.edit_mech.Enable = 'off';
            app.uitable2 = uitable(app.uipanel2);
            app.uitable2.ColumnName = {'Exps'; 'Concs'};
            app.uitable2.ColumnRearrangeable = 'on';
            app.uitable2.RowName = '';
            app.uitable2.ColumnEditable = [true true];
            app.uitable2.Tag = 'uitable2';
            app.push_Tvalues = uibutton(app.uipanel2, 'push');
            app.push_Tvalues.ButtonPushedFcn = createCallbackFcn(app, @push_Tvalues_Callback, true);
            app.push_Tvalues.Tag = 'push_Tvalues';
            app.push_Tvalues.Tooltip = 'double click';
            app.push_Tvalues.Text = 'Send values';
            app.uipanel5 = uipanel(app.uipanel2);
            app.uipanel5.Title = 'Kinetic-MCR species relationship';
            app.uipanel5.Tag = 'uipanel5';
            app.text16 = uilabel(app.uipanel5);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Corresponding HM Species';
            app.text17 = uilabel(app.uipanel5);
            app.text17.Tag = 'text17';
            app.text17.Text = 'MCR species';
            app.text18 = uilabel(app.uipanel5);
            app.text18.Tag = 'text18';
            app.text18.Text = '* By default there is no correspondence';
            app.push_CALS = uibutton(app.uipanel5, 'push');
            app.push_CALS.ButtonPushedFcn = createCallbackFcn(app, @push_CALS_Callback, true);
            app.push_CALS.Tag = 'push_CALS';
            app.push_CALS.Text = 'ALS profiles';
            app.listbox_KINspecies = uilistbox(app.uipanel5);
            app.listbox_KINspecies.Items = {};
            app.listbox_KINspecies.ValueChangedFcn = createCallbackFcn(app, @listbox_KINspecies_Callback, true);
            app.listbox_KINspecies.Tag = 'listbox_KINspecies';
            app.listbox_KINspecies.Value = {};
            app.listbox_MCRspecies = uilistbox(app.uipanel5);
            app.listbox_MCRspecies.Items = {'MCR species nr. 1', 'MCR species nr. 2', 'MCR species nr. 3'};
            app.listbox_MCRspecies.ValueChangedFcn = createCallbackFcn(app, @listbox_MCRspecies_Callback, true);
            app.listbox_MCRspecies.Tag = 'listbox_MCRspecies';
            app.listbox_MCRspecies.Value = 'MCR species nr. 1';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Time axis';
            app.popup_time = uidropdown(app.uipanel2);
            app.popup_time.Items = {'select a variable', 'DataDC', 'DataTime', 'Log10HistData', 'currentT50Times', 'exportT0_intensities', 'f', 'imageData', 'k', 'len', 'p', 'r'};
            app.popup_time.ValueChangedFcn = createCallbackFcn(app, @popup_time_Callback, true);
            app.popup_time.Tag = 'popup_time';
            app.popup_time.Value = 'select a variable';
            app.push_credits = uibutton(app.figure1, 'push');
            app.push_credits.ButtonPushedFcn = createCallbackFcn(app, @push_credits_Callback, true);
            app.push_credits.Tag = 'push_credits';
            app.push_credits.Text = 'Credits';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Tag = 'uipanel4';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Tag = 'text13';
            app.text13.Text = 'How many Kinetic Models you need to use?';
            app.popup_selectModel = uidropdown(app.uipanel4);
            app.popup_selectModel.Items = {};
            app.popup_selectModel.ValueChangedFcn = createCallbackFcn(app, @popup_selectModel_Callback, true);
            app.popup_selectModel.Tag = 'popup_selectModel';
            app.popup_selectModel.Enable = 'off';
            app.popup_selectModel.Value = {};
            app.edit_nrModel = uieditfield(app.uipanel4, 'text');
            app.edit_nrModel.ValueChangedFcn = createCallbackFcn(app, @edit_nrModel_Callback, true);
            app.edit_nrModel.Tag = 'edit_nrModel';
            app.text14 = uilabel(app.uipanel4);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Select model nr.';
            app.text15 = uilabel(app.uipanel4);
            app.text15.Tag = 'text15';
            app.text15.Text = 'Which experiments are fitted to this model?';
            app.edit_nexp = uieditfield(app.uipanel4, 'text');
            app.edit_nexp.ValueChangedFcn = createCallbackFcn(app, @edit_nexp_Callback, true);
            app.edit_nexp.Tag = 'edit_nexp';
            app.edit_nexp.Enable = 'off';
            app.push_sim = uibutton(app.figure1, 'push');
            app.push_sim.ButtonPushedFcn = createCallbackFcn(app, @push_sim_Callback, true);
            app.push_sim.Tag = 'push_sim';
            app.push_sim.Enable = 'off';
            app.push_sim.Text = 'Simulate';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Experiment';
            app.popup_expSim = uidropdown(app.figure1);
            app.popup_expSim.Items = {'select...'};
            app.popup_expSim.ValueChangedFcn = createCallbackFcn(app, @popup_expSim_Callback, true);
            app.popup_expSim.Tag = 'popup_expSim';
            app.popup_expSim.Enable = 'off';
            app.popup_expSim.Value = 'select...';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kineticHM_multi_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)kineticHM_multi_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end