classdef kinetic_info_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        push_saveK         matlab.ui.control.Button
        push_saveProfiles  matlab.ui.control.Button
        push_close         matlab.ui.control.Button
        uitable1           matlab.ui.control.Table
        popup_model        matlab.ui.control.DropDown
        text5              matlab.ui.control.Label
        edit_ssq           matlab.ui.control.EditField
        text4              matlab.ui.control.Label
        text3              matlab.ui.control.Label
        text2              matlab.ui.control.Label
        text1              matlab.ui.control.Label
        axes_perfils       matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.uitable1.ColumnFormat = {[] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function kinetic_info_OpeningFcn(app, varargin)
            % --- Executes just before kinetic_info is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to kinetic_info (see VARARGIN)
            
            % Choose default command line output for kinetic_info
            handles.output = hObject;
            
            nModels=evalin('base','mcr_als.alsOptions.kinetic.nModels;');
            
            if nModels==1
                modelActiu=1;
                assignin('base','modelActiu',modelActiu);
                evalin('base','mcr_als.alsOptions.kinetic.results.aux.modelActiu=modelActiu;');
                evalin('base','clear modelActiu ');
            
            
                % ssq residuals
                optim_niter=evalin('base','mcr_als.alsOptions.resultats.optim_niter;');
                ssqr=evalin('base',['mcr_als.alsOptions.kinetic.results.save_ssq{',num2str(optim_niter),'};']);
                set(handles.edit_ssq,'string',num2str(ssqr),'enable','inactive');
            
                % plot perfils
                nexp=evalin('base','mcr_als.alsOptions.nexp;');
                if nexp==1
                    perfils=cell2mat(evalin('base','mcr_als.alsOptions.kinetic.results.conckout{1};'));
                else
                    perfils=cell2mat(evalin('base',['mcr_als.alsOptions.kinetic.results.conckout{',num2str(modelActiu),'};']));
                end
            
                time=evalin('base','mcr_als.alsOptions.kinetic.time_axis');
                expressionX=['mcr_als.alsOptions.kinetic.models.model1.concs.col;'];
                coloured=evalin('base',expressionX);
                perfilsCol=perfils*diag(coloured);
                plot(time,perfils,'.');
                hold on;
                plot(time,perfilsCol,'o');
                hold off;
                axis([0 max(time) 0 max(max(perfils))]);
            
                % dades taula
                constants=evalin('base','length(mcr_als.alsOptions.kinetic.results.kopt{1});');
                optim_niter=evalin('base','mcr_als.alsOptions.resultats.optim_niter;');
                for i=1:1:constants
                    expressionX=['mcr_als.alsOptions.kinetic.results.save_kopt{',num2str(optim_niter),'};'];
                    ki=evalin('base',expressionX);
                    expressionX=['mcr_als.alsOptions.kinetic.results.save_sigK{',num2str(optim_niter),'};'];
                    error_ki=evalin('base',expressionX);
                    dades_taula{i,1}=[num2str(ki(i))];
                    dades_taula{i,2}=[num2str(error_ki(i))];
                end
                set(handles.uitable1,'Data',dades_taula);
            
            else
            end
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: popup_model
        function popup_model_Callback(app, event)
            % --- Executes on selection change in popup_model.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_model (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_model contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_model
            
            modelActiu=get(hObject,'Value')-1;
            assignin('base','modelActiu',modelActiu);
            evalin('base','mcr_als.alsOptions.kinetic.results.aux.modelActiu=modelActiu;');
            evalin('base','clear modelActiu ');
            
            if modelActiu==0
                set(handles.edit_ssq,'string','  ','enable','inactive');
                set(handles.uitable1,'Data',[]);
                set(handles.push_saveK,'enable','off');
                set(handles.push_saveProfiles,'enable','off');
                % nothing happen
                axes(handles.axes_perfils);
                cla;
                axis([0 1 0 1]);
                title('');
                xlabel('');
                ylabel('');
            else
                % ssq residuals
                ssqr=evalin('base',['mcr_als.alsOptions.kinetic.results.ssq{',num2str(modelActiu),'};']);
                set(handles.edit_ssq,'string',num2str(ssqr),'enable','inactive');
            
                % plot perfiles
                expressionX=['mcr_als.alsOptions.kinetic.results.conckout{',num2str(modelActiu),'};'];
                perfils=cell2mat(evalin('base',expressionX));
            
                time=evalin('base','mcr_als.alsOptions.kinetic.time_axis');
            
                expressionX=['mcr_als.alsOptions.kinetic.models.model',num2str(modelActiu),'.concs.col;'];
                coloured=evalin('base',expressionX);
                perfilsCol=perfils*diag(coloured);
                plot(time,perfils,':');
                hold on;
                plot(time,perfilsCol);
                hold off;
                axis([0 max(time) 0 max(max(perfils))]);
            
                % data table
                expressionX=['length(mcr_als.alsOptions.kinetic.results.kopt{',num2str(modelActiu),'});'];
                constants=evalin('base',expressionX);
            
                for i=1:1:constants
                    expressionX=['mcr_als.alsOptions.kinetic.results.kopt{',num2str(modelActiu),'};'];
                    ki=evalin('base',expressionX);
                    expressionX=['mcr_als.alsOptions.kinetic.results.sig_knglm{',num2str(modelActiu),'};'];
                    error_ki=evalin('base',expressionX);
                    dades_taula{i,1}=[num2str(ki(i))];
                    dades_taula{i,2}=[num2str(error_ki(i))];
                end
                set(handles.uitable1,'Data',dades_taula);
                set(handles.push_saveK,'enable','on');
                set(handles.push_saveProfiles,'enable','on');
            end
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end

        % Button pushed function: push_saveK
        function push_saveK_Callback(app, event)
            % --- Executes on button press in push_saveK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_saveK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.results.aux.modelActiu;');
            
            expressionX=['mcr_als.alsOptions.kinetic.results.kopt{',num2str(modelActiu),'};'];
            ki=evalin('base',expressionX);
            assignin('base',['k_Kinetic_',num2str(modelActiu)],ki);
        end

        % Button pushed function: push_saveProfiles
        function push_saveProfiles_Callback(app, event)
            % --- Executes on button press in push_saveProfiles.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_saveProfiles (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            modelActiu=evalin('base','mcr_als.alsOptions.kinetic.results.aux.modelActiu;');
            
            expressionX=['mcr_als.alsOptions.kinetic.results.conckout{',num2str(modelActiu),'};'];
            perfils=cell2mat(evalin('base',expressionX));
            assignin('base',['C_Kinetic_',num2str(modelActiu)],perfils);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Information about the Kinetic Constraint';
            app.figure1.Tag = 'figure1';
            app.axes_perfils = uiaxes(app.figure1);
            app.axes_perfils.Tag = 'axes_perfils';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Kinetic Info';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Concentration Profiles';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Rates';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'SSQ of the residuals';
            app.edit_ssq = uieditfield(app.figure1, 'text');
            app.edit_ssq.Tag = 'edit_ssq';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Model Nr.';
            app.popup_model = uidropdown(app.figure1);
            app.popup_model.Items = {'1'};
            app.popup_model.ValueChangedFcn = createCallbackFcn(app, @popup_model_Callback, true);
            app.popup_model.Tag = 'popup_model';
            app.popup_model.Enable = 'off';
            app.popup_model.Value = '1';
            app.uitable1 = uitable(app.figure1);
            app.uitable1.ColumnName = {'k'; 'Sig k'};
            app.uitable1.ColumnEditable = [false false];
            app.uitable1.Tag = 'uitable1';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            app.push_saveProfiles = uibutton(app.figure1, 'push');
            app.push_saveProfiles.ButtonPushedFcn = createCallbackFcn(app, @push_saveProfiles_Callback, true);
            app.push_saveProfiles.Tag = 'push_saveProfiles';
            app.push_saveProfiles.Text = 'Save profiles to WS';
            app.push_saveK = uibutton(app.figure1, 'push');
            app.push_saveK.ButtonPushedFcn = createCallbackFcn(app, @push_saveK_Callback, true);
            app.push_saveK.Tag = 'push_saveK';
            app.push_saveK.Text = 'Save k to WS';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = kinetic_info_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)kinetic_info_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end