classdef manComp_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1    matlab.ui.Figure
        push_Quit  matlab.ui.control.Button
        push_Cont  matlab.ui.control.Button
        edit_NC    matlab.ui.control.EditField
        text1      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function manComp_OpeningFcn(app, varargin)
            % --- Executes just before manComp is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to manComp (see VARARGIN)
            
            % Choose default command line output for manComp
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_NC
        function edit_NC_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_NC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_NC as text
            %        str2double(get(hObject,'String')) returns contents of edit_NC as a double
            
            % @post. the user writes the NC -> continue -> mcr_als and election
            
            nc=str2num(get(hObject,'String'));
            
            if nc>0
            assignin('base','nc',nc);
            evalin('base','mcr_als.CompNumb.nc=nc;');
            evalin('base','mcr_als.CompNumb.method=''manual'';');
            evalin('base','clear nc');
            set(handles.push_Cont,'enable','on');
            else
            set(handles.push_Cont,'enable','off');
            if ((evalin('base','exist(''mcr_als.CompNumb'')'))==1);
                evalin('base','mcr_als=rmfield(mcr_als,''CompNumb'');');end;
            end
        end

        % Button pushed function: push_Cont
        function push_Cont_Callback(app, event)
            % --- Executes on button press in push_Cont.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_Cont (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=2;');
            close;
        end

        % Button pushed function: push_Quit
        function push_Quit_Callback(app, event)
            % --- Executes on button press in push_Quit.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_Quit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=3;');
            evalin('base','mcr_als=rmfield(mcr_als,''CompNumb'');');
            close;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Manual selection of the number of components';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Write the number of components:';
            app.edit_NC = uieditfield(app.figure1, 'text');
            app.edit_NC.ValueChangedFcn = createCallbackFcn(app, @edit_NC_Callback, true);
            app.edit_NC.Tag = 'edit_NC';
            app.edit_NC.Value = '0';
            app.push_Cont = uibutton(app.figure1, 'push');
            app.push_Cont.ButtonPushedFcn = createCallbackFcn(app, @push_Cont_Callback, true);
            app.push_Cont.Tag = 'push_Cont';
            app.push_Cont.Enable = 'off';
            app.push_Cont.Text = 'Continue';
            app.push_Quit = uibutton(app.figure1, 'push');
            app.push_Quit.ButtonPushedFcn = createCallbackFcn(app, @push_Quit_Callback, true);
            app.push_Quit.Tag = 'push_Quit';
            app.push_Quit.Text = 'Cancel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manComp_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)manComp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end