classdef manEst_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        ButtonGroup               matlab.ui.container.ButtonGroup
        FixedConcentrationButton  matlab.ui.control.RadioButton
        FixedSpectraButton        matlab.ui.control.RadioButton
        EstimateOnlyButton        matlab.ui.control.RadioButton
        pop_llista                matlab.ui.control.DropDown
        but_surt                  matlab.ui.control.Button
        but_confi                 matlab.ui.control.Button
        text2                     matlab.ui.control.Label
        text1                     matlab.ui.control.Label
        axes_plot                 matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function manEst_OpeningFcn(app, varargin)
            % --- Executes just before manEst is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to manEst (see VARARGIN)
            
            % Choose default command line output for manEst
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            % initialization
            evalin('base','mcr_als.InitEstim.Sort=0;');

            % Set starting value of button group
            app.ButtonGroup.SelectedObject.Value = 1;

            % pop_llista_CreateFcn
            lsvar=evalin('base','whos');
            [aa,~]=size(lsvar);
            j=2;
            lsv(1)={'select a variable...'};
            for i=1:aa
                csize=length(lsvar(i).class);
                if csize==6
                    if lsvar(i).class=='double'
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end
                end
            end
            app.pop_llista.Items = lsv;
        end

        % Button pushed function: but_confi
        function but_confi_Callback(app, event)
            % --- Executes on button press in but_confi.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_confi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            NC=evalin('base','mcr_als.CompNumb.nc;');
            NCest=min(size(evalin('base','mcr_als.InitEstim.iniesta')));

            if app.ButtonGroup.SelectedObject.Text == "Estimate Only"
                Fixed = 0;
            elseif app.ButtonGroup.SelectedObject.Text == "Fixed Spectra"
                Fixed = 1;
            elseif app.ButtonGroup.SelectedObject.Text == "Fixed Concentration"
                Fixed = 2;
            end

            assignin('base','Fixed',Fixed);
            evalin('base','mcr_als.InitEstim.Fixed=Fixed;');
            
            if NC==NCest
                evalin('base','mcr_als.aux.estat=2;');
                evalin('base','mcr_als.InitEstim.method=''man'';');
                close;
            else
                warndlg('The number of components of the initial estimation and the previously selected does not match');
            end
        end

        % Button pushed function: but_surt
        function but_surt_Callback(app, event)
            % --- Executes on button press in but_surt.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_surt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=3;');
            evalin('base','mcr_als=rmfield(mcr_als,''InitEstim'');');
            close;
        end

        % Value changed function: pop_llista
        function pop_llista_Callback(app, event)
            % --- Executes on selection change in pop_llista.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pop_llista (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns pop_llista contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from pop_llista
            
            popmenu2=get(handles.pop_llista,'String');
            pm2=get(handles.pop_llista,'Value');
            
            if pm2==1
            
            else
                selec2=char([popmenu2(pm2)]);
                iniesta=evalin('base',selec2);
            
                axes(handles.axes_plot);
                [xi,yi]=size(iniesta);
            
                maxim=max(max(iniesta));
                minim=min(min(iniesta));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim);
                end
            
                if minim >= 0
                    minim1=minim+0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
            
                data=evalin('base','mcr_als.data;');
                [md,nd]=size(data);
            
                if nd==yi
                    plot(iniesta');
                    axis([1 yi minim1 maxim1]);
                elseif md==xi
                    plot(iniesta);
                    axis([1 xi minim1 maxim1]);
                else
                    uialert(app.figure1, 'Check Matrix Sizing', 'Error')
                end
            
            
                zoom on;
            
                assignin('base','iniesta',iniesta);
                evalin('base','mcr_als.InitEstim.iniesta=iniesta;');
                evalin('base','clear iniesta');
            
            end
        end

        % Clicked callback: pop_llista
        function Update_Files(app, event)
            lsvar=evalin('base','whos');
            [aa,~]=size(lsvar);
            j=2;
            lsv(1)={'select a variable...'};
            for i=1:aa
                csize=length(lsvar(i).class);
                if csize==6
                    if lsvar(i).class=='double'
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end
                end
            end
            app.pop_llista.Items = lsv;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Manual selection of the initial estimation';
            app.figure1.Tag = 'figure1';
            app.axes_plot = uiaxes(app.figure1);
            app.axes_plot.Tag = 'axes_plot';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Selection of a Initial Estimation';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select a variable as initial estimation:';
            app.but_confi = uibutton(app.figure1, 'push');
            app.but_confi.ButtonPushedFcn = createCallbackFcn(app, @but_confi_Callback, true);
            app.but_confi.Tag = 'but_confi';
            app.but_confi.Text = 'Confirm';
            app.but_surt = uibutton(app.figure1, 'push');
            app.but_surt.ButtonPushedFcn = createCallbackFcn(app, @but_surt_Callback, true);
            app.but_surt.Tag = 'but_surt';
            app.but_surt.Text = 'Quit';
            app.pop_llista = uidropdown(app.figure1);
            app.pop_llista.Items = {'select a variable...', 'DataDC', 'DataTime', 'colormap', 'copt', 'currentT50Times', 'exportT0_intensities', 'kin_data', 'sopt', 'times', 'waves'};
            app.pop_llista.ValueChangedFcn = createCallbackFcn(app, @pop_llista_Callback, true);
            app.pop_llista.Tag = 'pop_llista';
            app.pop_llista.ClickedFcn = createCallbackFcn(app, @Update_Files, true);
            app.pop_llista.Value = 'select a variable...';
            app.ButtonGroup = uibuttongroup(app.figure1);
            app.EstimateOnlyButton = uiradiobutton(app.ButtonGroup);
            app.EstimateOnlyButton.Text = 'Estimate Only';
            app.EstimateOnlyButton.Value = true;
            app.FixedSpectraButton = uiradiobutton(app.ButtonGroup);
            app.FixedSpectraButton.Text = 'Fixed Spectra';
            app.FixedConcentrationButton = uiradiobutton(app.ButtonGroup);
            app.FixedConcentrationButton.Text = 'Fixed Concentration';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = manEst_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)manEst_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end