classdef mcr_main_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1              matlab.ui.Figure
        UncertaintyEstimate  matlab.ui.container.Panel
        check_weight         matlab.ui.control.CheckBox
        push_weight          matlab.ui.control.Button
        uipanel1             matlab.ui.container.Panel
        push_alsInfo         matlab.ui.control.Button
        push_alshelp         matlab.ui.control.Button
        text1                matlab.ui.control.Label
        but_quit             matlab.ui.control.Button
        DetermineNumComp     matlab.ui.container.Panel
        but_manComp          matlab.ui.control.Button
        but_SVD              matlab.ui.control.Button
        DataSelection        matlab.ui.container.Panel
        check_dataType       matlab.ui.control.CheckBox
        push_dades           matlab.ui.control.Button
        popupmenu3           matlab.ui.control.DropDown
        text2                matlab.ui.control.Label
        Optimization         matlab.ui.container.Panel
        text_ready           matlab.ui.control.Label
        text15               matlab.ui.control.Label
        text_weight          matlab.ui.control.Label
        text12               matlab.ui.control.Label
        text_ncmet           matlab.ui.control.Label
        text_iniesta         matlab.ui.control.Label
        text_nc              matlab.ui.control.Label
        text_nom             matlab.ui.control.Label
        text6                matlab.ui.control.Label
        text5                matlab.ui.control.Label
        text4                matlab.ui.control.Label
        text3                matlab.ui.control.Label
        but_ALS              matlab.ui.control.Button
        InitialEstimate      matlab.ui.container.Panel
        but_ManEst           matlab.ui.control.Button
        but_EFA              matlab.ui.control.Button
        but_Pure             matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mcr_main_OpeningFcn(app, varargin)
            % --- Executes just before mcr_main is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to mcr_main (see VARARGIN)
            
            % Choose default command line output for mcr_main
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes mcr_main wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            % --- Outputs from this function are returned to the command line.
            clc;
            disp ('   ')
            disp ('   ')
            disp ('   ************************************************************************************')
            disp ('   *                           MATLAB program MCR-ALS:                                *')
            disp ('   *                           Multivariate Curve Resolution (MCR)                    *')
            disp ('   *                           Alternating Least Squares (ALS)                        *')
            disp ('   ************************************************************************************')
            disp ('   ')
            disp ('                                                                 MCR 2.0     version   ')
            disp ('   ')
            disp ('   ')
            
            % initializations
            if ((evalin('base','exist(''mcr_als'')'))==1);evalin('base','clear(''mcr_als'')');end
            evalin('base','mcr_als.alsOptions.weighted.constrained=0;');

            %Set popup values
            ValueChangedData = struct('Value', 0, 'PreviousValue', 0, 'Source', app.popupmenu3, 'EventName', 'ValueChanged');
            check_dataType_Callback(app, ValueChangedData)
%             lsvar=evalin('base','whos');
%             [aa,bb]=size(lsvar);
%             j=2;
%             lsv(1)={'select a variable from the WS'};
%             for i=1:aa,
%                 csize=length(lsvar(i).class);
%                 if csize==6,
%                     if lsvar(i).class=='double',
%                         lsb=[lsvar(i).name];
%                         lsv(j)={lsb};
%                         j=j+1;
%                     end;
%                 end;
%             end;
%             
%             set(handles.popupmenu3,'string',lsv)
        end

        % Button pushed function: but_ALS
        function but_ALS_Callback(app, event)
            % --- Executes on button press in but_ALS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_ALS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of but_ALS
            close;
            
            % evalin('base','initEstim=mcr_als.InitEstim.iniesta;');
            
            matdad=evalin('base','mcr_als.data;');
            mm=max(size(matdad));
            
            if mm>10000
                answer = questdlg('Warning: large dataset. Graphics?', ...
                    'Large dataset warning', ...
                    'Yes','No','No');
                % Handle response
            
                switch answer
                    case 'Yes'
                        als2013_App;
                    case 'No'
                        als2013fast_App;
                end
            
            else
                als2013_App;
            end
        end

        % Button pushed function: but_EFA
        function but_EFA_Callback(app, event)
            % --- Executes on button press in but_EFA.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_EFA (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of but_EFA
            
            set(handles.push_dades,'enable','off');
            set(handles.push_weight,'enable','off');
            set(handles.but_SVD,'enable','off');
            set(handles.but_manComp,'enable','off');
            set(handles.but_Pure,'enable','off');
            set(handles.but_EFA,'enable','off');
            set(handles.but_ManEst,'enable','off');
            set(handles.but_ALS,'enable','off');
            set(handles.but_quit,'enable','off');
            
            evalin('base','mcr_als.aux.estat=1;');
            evalin('base','mcr_als.aux.counter=0;');
            assignin('base','Fixed',0);
            evalin('base','mcr_als.InitEstim.Fixed=Fixed;');
            efagui_App;
            
            while(evalin('base','mcr_als.aux.estat')==1)
                pause(0.3);
            end
            
            if (evalin('base','mcr_als.aux.estat')==2)
                set(handles.push_dades,'enable','off');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','on');
                set(handles.but_quit,'enable','on');
                set(handles.text_iniesta,'string',evalin('base','mcr_als.InitEstim.method'));
            elseif (evalin('base','mcr_als.aux.estat')==3)
                set(handles.push_dades,'enable','off');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
            end
            
            evalin('base','mcr_als.aux.estat=0;');
        end

        % Button pushed function: but_ManEst
        function but_ManEst_Callback(app, event)
            % --- Executes on button press in but_ManEst.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_ManEst (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of but_ManEst
            
            set(handles.push_dades,'enable','off');
            set(handles.push_weight,'enable','off');
            set(handles.but_SVD,'enable','off');
            set(handles.but_manComp,'enable','off');
            set(handles.but_Pure,'enable','off');
            set(handles.but_EFA,'enable','off');
            set(handles.but_ManEst,'enable','off');
            set(handles.but_ALS,'enable','off');
            set(handles.but_quit,'enable','off');
            
            evalin('base','mcr_als.aux.estat=1;');
            
            manEst_App;
            
            while(evalin('base','mcr_als.aux.estat')==1)
                pause(0.3);
            end
            
            if (evalin('base','mcr_als.aux.estat')==2)
            
                set(handles.push_dades,'enable','off');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','on');
                set(handles.but_quit,'enable','on');
                set(handles.text_iniesta,'string',evalin('base','mcr_als.InitEstim.method'));
            elseif (evalin('base','mcr_als.aux.estat')==3)
            
                set(handles.push_dades,'enable','off');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
            end
            
            evalin('base','mcr_als.aux.estat=0;');
        end

        % Button pushed function: but_Pure
        function but_Pure_Callback(app, event)
            % --- Executes on button press in but_Pure.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_Pure (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of but_Pure
            
            set(handles.push_dades,'enable','off');
            set(handles.push_weight,'enable','off');
            set(handles.but_SVD,'enable','off');
            set(handles.but_manComp,'enable','off');
            set(handles.but_Pure,'enable','off');
            set(handles.but_EFA,'enable','off');
            set(handles.but_ManEst,'enable','off');
            set(handles.but_ALS,'enable','off');
            set(handles.but_quit,'enable','off');
            
            evalin('base','mcr_als.aux.estat=1;');
            assignin('base','Fixed',0);
            evalin('base','mcr_als.InitEstim.Fixed=Fixed;');
            
            puregui_App;
            
            while(evalin('base','mcr_als.aux.estat')==1)
                pause(0.3);
            end
            
            if (evalin('base','mcr_als.aux.estat')==2)
                set(handles.push_dades,'enable','off');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','on');
                set(handles.but_quit,'enable','on');
                set(handles.text_iniesta,'string',evalin('base','mcr_als.InitEstim.method'));
            elseif (evalin('base','mcr_als.aux.estat')==3)
                set(handles.push_dades,'enable','off');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
            end
            
            evalin('base','mcr_als.aux.estat=0;');
        end

        % Button pushed function: but_SVD
        function but_SVD_Callback(app, event)
            % --- Executes on button press in but_SVD.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_SVD (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of but_SVD
            
            set(handles.push_dades,'enable','off');
            set(handles.check_weight,'enable','off');
            set(handles.push_weight,'enable','off');
            set(handles.but_SVD,'enable','off');
            set(handles.but_manComp,'enable','off');
            set(handles.but_Pure,'enable','off');
            set(handles.but_EFA,'enable','off');
            set(handles.but_ManEst,'enable','off');
            set(handles.but_ALS,'enable','off');
            set(handles.but_quit,'enable','off');
            evalin('base','mcr_als.aux.estat=1;');
            
            matriu_svd=evalin('base','mcr_als.data');
            [a,b]=size(matriu_svd);
            evalin('base','mcr_als.aux.estat_svd=1;');
            
            if (a>10000 | b>10000) % avoid problems with big data
                svd_selection;
                while evalin('base','mcr_als.aux.estat_svd;')==1
                    pause(0.3);
                end
                nrsvd=evalin('base','mcr_als.aux.nrsvd;');
                [U,S,V]=svds(matriu_svd,nrsvd);
            else
                [U,S,V]=svd(matriu_svd,'econ');
            end
            
            assignin('base','U',U);
            assignin('base','S',S);
            assignin('base','V',V);
            evalin('base','mcr_als.CompNumb.U=U;');
            evalin('base','mcr_als.CompNumb.S=S;');
            evalin('base','mcr_als.CompNumb.V=V;');
            evalin('base','clear U S V');
            svdgui_App;
            
            while(evalin('base','mcr_als.aux.estat')==1)
                pause(0.3);
            end
            
            if (evalin('base','mcr_als.aux.estat')==2)
                set(handles.push_dades,'enable','off');
                set(handles.check_weight,'enable','on');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
                set(handles.text_nc,'string',num2str(evalin('base','mcr_als.CompNumb.nc')));
                set(handles.text_ncmet,'string',evalin('base','mcr_als.CompNumb.method'));
            
            elseif (evalin('base','mcr_als.aux.estat')==3)
                set(handles.push_dades,'enable','off');
                set(handles.check_weight,'enable','off');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','off');
                set(handles.but_EFA,'enable','off');
                set(handles.but_ManEst,'enable','off');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
            end
            
            evalin('base','mcr_als.aux.estat=0;');
        end

        % Button pushed function: but_manComp
        function but_manComp_Callback(app, event)
            % --- Executes on button press in but_manComp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_manComp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of but_manComp
            
            set(handles.push_dades,'enable','off');
            set(handles.check_weight,'enable','on');
            set(handles.push_weight,'enable','off');
            set(handles.but_SVD,'enable','off');
            set(handles.but_manComp,'enable','off');
            set(handles.but_Pure,'enable','off');
            set(handles.but_EFA,'enable','off');
            set(handles.but_ManEst,'enable','off');
            set(handles.but_ALS,'enable','off');
            set(handles.but_quit,'enable','off');
            evalin('base','mcr_als.aux.estat=1;');
            manComp_App;
            
            while(evalin('base','mcr_als.aux.estat')==1)
                pause(0.3);
            end
            
            if (evalin('base','mcr_als.aux.estat')==2)
                set(handles.push_dades,'enable','off');
                set(handles.check_weight,'enable','on');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
                set(handles.text_nc,'string',num2str(evalin('base','mcr_als.CompNumb.nc')));
                set(handles.text_ncmet,'string',evalin('base','mcr_als.CompNumb.method'));
            elseif (evalin('base','mcr_als.aux.estat')==3)
                set(handles.push_dades,'enable','off');
                set(handles.check_weight,'enable','on');
                set(handles.push_weight,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','off');
                set(handles.but_EFA,'enable','off');
                set(handles.but_ManEst,'enable','off');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
            end
            
            evalin('base','mcr_als.aux.estat=0;');
        end

        % Button pushed function: but_quit
        function but_quit_Callback(app, event)
            % --- Executes on button press in but_quit.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to but_quit (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of but_quit
            
            if ((evalin('base','exist(''mcr_als'')'))==1);evalin('base','clear(''mcr_als'')');end
            close;
        end

        % Callback function: check_dataType, popupmenu3
        function check_dataType_Callback(app, event)
            % --- Executes on button press in check_dataType.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_dataType (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_dataType
            
            valorDT=get(hObject,'Value');
            
            if valorDT==0
                lsvar=evalin('base','whos');
                [aa,~]=size(lsvar);
                j=2;
                lsv(1)={'select a variable from the WS'};
                for i=1:aa
                    csize=length(lsvar(i).class);
                    if csize==6
                        if lsvar(i).class=='double'
                            lsb=[lsvar(i).name];
                            lsv(j)={lsb};
                            j=j+1;
                        end
                    end
                end
            
                set(handles.popupmenu3,'string',lsv)
            
            else
                lsvar=evalin('base','whos');
                [aa,~]=size(lsvar);
                j=2;
                lsv(1)={'select a variable from the WS'};
                for i=1:aa
                    csize=length(lsvar(i).class);
                    if csize==6
                        if (lsvar(i).class==('double')|lsvar(i).class==('single')|lsvar(i).class==('int8  ')|lsvar(i).class==('int16 ')|lsvar(i).class==('int32 ')|lsvar(i).class==('int64 ')|lsvar(i).class==('uint8 ')|lsvar(i).class==('uint16')|lsvar(i).class==('uint32')|lsvar(i).class==('uint64'));
                            lsb=[lsvar(i).name];
                            lsv(j)={lsb};
                            j=j+1;
                        end
                    end
                end
            
                set(handles.popupmenu3,'string',lsv)
            
            end
        end

        % Value changed function: check_weight
        function check_weight_Callback(app, event)
            % --- Executes on button press in check_weight.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_weight (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_weight
            
            valorW=get(hObject,'Value');
            
            if valorW==0
                set(handles.push_weight,'enable','off');
                set(handles.text_weight,'string','No');
                set(handles.text_ready,'string','-','ForegroundColor','Black');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
            
                evalin('base','mcr_als.data=mcr_als.OriginalData;'); % reset original data without weighting
                selec3=evalin('base','mcr_als.aux.data_name;');
                set(handles.text_nom,'string',selec3);
            else
                set(handles.push_weight,'enable','on');
                set(handles.text_weight,'string','Yes');
                set(handles.text_ready,'string','Not Ready','ForegroundColor','Red');
                set(handles.but_Pure,'enable','off');
                set(handles.but_EFA,'enable','off');
                set(handles.but_ManEst,'enable','off');
            end
            
            evalin('base','mcr_als.alsOptions.weighted.appWeight=0;');
            assignin('base','valorW',valorW);
            evalin('base','mcr_als.alsOptions.weighted.check_application=valorW;');
            evalin('base','clear valorW');
        end

        % Value changed function: popupmenu3
        function popupmenu3_Callback(app, event)
            % --- Executes on selection change in popupmenu3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu3 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu3
            
            popmenu3=get(handles.popupmenu3,'String');
            pm3=get(handles.popupmenu3,'Value');
%             evalin('base','whos');
            if pm3==1
            
            else
                selec3=char([popmenu3(pm3)]);
                matdad=evalin('base',selec3);
                assignin('base','matdad',matdad);
                assignin('base','selec3',selec3);
                evalin('base','mcr_als.OriginalData=matdad;');
                evalin('base','mcr_als.data=matdad;');
                evalin('base','mcr_als.aux.data_name=selec3;');
                set(handles.text_nom,'string',selec3);
                evalin('base','clear matdad selec3');
            
                % activate: number of components
                set(handles.push_dades,'enable','on');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                evalin('base','mcr_als.aux.estat=0;');
            
                % activate: weight text
                set(handles.text_weight,'string','No');
            end
        end

        % Button pushed function: push_alsInfo
        function push_alsInfo_Callback(app, event)
            % --- Executes on button press in push_alsInfo.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_alsInfo (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            als_info_App;
        end

        % Button pushed function: push_dades
        function push_dades_Callback(app, event)
            % --- Executes on button press in push_dades.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_dades (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            %
            %
            % figure;
            % matriu=evalin('base','mcr_als.data');
            % plot(matriu');
            
            plot_dades_App;
        end

        % Button pushed function: push_weight
        function push_weight_Callback(app, event)
            % --- Executes on button press in push_weight.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_weight (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(handles.push_dades,'enable','inactive');
            set(handles.push_weight,'enable','off');
            set(handles.but_SVD,'enable','off');
            set(handles.but_manComp,'enable','off');
            set(handles.but_Pure,'enable','off');
            set(handles.but_EFA,'enable','off');
            set(handles.but_ManEst,'enable','off');
            set(handles.but_ALS,'enable','off');
            set(handles.but_quit,'enable','off');
            
            evalin('base','mcr_als.aux.estat=1;');
            
            appWeight=evalin('base','mcr_als.alsOptions.weighted.appWeight;');
            if appWeight==0
                evalin('base','mcr_als.alsOptions.weighted.application=0;');
                evalin('base','mcr_als.alsOptions.weighted.max_iterations=200000;');
                evalin('base','mcr_als.alsOptions.weighted.conv_limit=1e-10;');
                evalin('base','mcr_als.alsOptions.weighted.constrained=0;');
                weighted_App;
            else
                weighted_App;
            end
            
            while(evalin('base','mcr_als.aux.estat')==1)
                pause(0.3);
            end
            
            if (evalin('base','mcr_als.aux.estat')==2)
                set(handles.push_dades,'enable','off');
                set(handles.push_weight,'enable','on');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
                if evalin('base','mcr_als.alsOptions.weighted.constrained;')==1
                    set(handles.text_ready,'string','Using & OK','ForegroundColor','Green');
                    evalin('base','mcr_als.data=mcr_als.alsOptions.weighted.Xmat_weighted;'); % reset original data without weighting
                    selec3=evalin('base','mcr_als.aux.data_name;');
                    set(handles.text_nom,'string',[selec3,' - weighted']);
            
                else
                    set(handles.text_ready,'string','Done, not OK','ForegroundColor','Blue');
                    selec3=evalin('base','mcr_als.aux.data_name;');
                    set(handles.text_nom,'string',selec3);
                end
            elseif (evalin('base','mcr_als.aux.estat')==3)
                set(handles.push_dades,'enable','off');
                set(handles.but_SVD,'enable','on');
                set(handles.but_manComp,'enable','on');
                set(handles.but_Pure,'enable','on');
                set(handles.but_EFA,'enable','on');
                set(handles.but_ManEst,'enable','on');
                set(handles.but_ALS,'enable','off');
                set(handles.but_quit,'enable','on');
                set(handles.text_ready,'string','Cancelled','ForegroundColor','Black');
            end
            
            evalin('base','mcr_als.aux.estat=0;');
        end

        % Button pushed function: push_alshelp
        function alshelpbutt(app, event)
            mcr_help
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Multivariate Curve Resolution Toolbox - Main window';
            app.figure1.Tag = 'figure1';
            app.InitialEstimate = uipanel(app.figure1);
            app.InitialEstimate.Title = 'Initial Estimation';
            app.InitialEstimate.Tag = 'uipanel2';
            app.but_Pure = uibutton(app.InitialEstimate, 'push');
            app.but_Pure.ButtonPushedFcn = createCallbackFcn(app, @but_Pure_Callback, true);
            app.but_Pure.Tag = 'but_Pure';
            app.but_Pure.Enable = 'off';
            app.but_Pure.Text = 'Pure';
            app.but_EFA = uibutton(app.InitialEstimate, 'push');
            app.but_EFA.ButtonPushedFcn = createCallbackFcn(app, @but_EFA_Callback, true);
            app.but_EFA.Tag = 'but_EFA';
            app.but_EFA.Enable = 'off';
            app.but_EFA.Text = 'EFA';
            app.but_ManEst = uibutton(app.InitialEstimate, 'push');
            app.but_ManEst.ButtonPushedFcn = createCallbackFcn(app, @but_ManEst_Callback, true);
            app.but_ManEst.Tag = 'but_ManEst';
            app.but_ManEst.Enable = 'off';
            app.but_ManEst.Text = 'Manual';
            app.Optimization = uipanel(app.figure1);
            app.Optimization.Title = 'Optimization';
            app.Optimization.Tag = 'uipanel5';
            app.but_ALS = uibutton(app.Optimization, 'push');
            app.but_ALS.ButtonPushedFcn = createCallbackFcn(app, @but_ALS_Callback, true);
            app.but_ALS.Tag = 'but_ALS';
            app.but_ALS.Enable = 'off';
            app.but_ALS.Text = 'Continue';
            app.text3 = uilabel(app.Optimization);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Data matrix:';
            app.text4 = uilabel(app.Optimization);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Number of components:';
            app.text5 = uilabel(app.Optimization);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Type of initial estimation:';
            app.text6 = uilabel(app.Optimization);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Method:';
            app.text_nom = uilabel(app.Optimization);
            app.text_nom.Tag = 'text_nom';
            app.text_nom.Text = '-';
            app.text_nc = uilabel(app.Optimization);
            app.text_nc.Tag = 'text_nc';
            app.text_nc.Text = '-';
            app.text_iniesta = uilabel(app.Optimization);
            app.text_iniesta.Tag = 'text_iniesta';
            app.text_iniesta.Text = '-';
            app.text_ncmet = uilabel(app.Optimization);
            app.text_ncmet.Tag = 'text_ncmet';
            app.text_ncmet.Text = '-';
            app.text12 = uilabel(app.Optimization);
            app.text12.Tag = 'text12';
            app.text12.Text = 'Weighted:';
            app.text_weight = uilabel(app.Optimization);
            app.text_weight.Tag = 'text_weight';
            app.text_weight.Text = '-';
            app.text15 = uilabel(app.Optimization);
            app.text15.Tag = 'text15';
            app.text15.Text = 'OK?';
            app.text_ready = uilabel(app.Optimization);
            app.text_ready.Tag = 'text_ready';
            app.text_ready.Text = '-';
            app.DataSelection = uipanel(app.figure1);
            app.DataSelection.Title = 'Data selection';
            app.DataSelection.Tag = 'uipanel6';
            app.text2 = uilabel(app.DataSelection);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select a data matrix ';
            app.popupmenu3 = uidropdown(app.DataSelection);
            app.popupmenu3.Items = {'select a variable from the WS', 'kin_data', 'times', 'waves'};
            app.popupmenu3.ValueChangedFcn = createCallbackFcn(app, @popupmenu3_Callback, true);
            app.popupmenu3.Tag = 'popupmenu3';
            app.popupmenu3.ClickedFcn = createCallbackFcn(app, @check_dataType_Callback, true);
            app.popupmenu3.Value = 'select a variable from the WS';
            app.push_dades = uibutton(app.DataSelection, 'push');
            app.push_dades.ButtonPushedFcn = createCallbackFcn(app, @push_dades_Callback, true);
            app.push_dades.Tag = 'push_dades';
            app.push_dades.Enable = 'off';
            app.push_dades.Text = 'Plot';
            app.check_dataType = uicheckbox(app.DataSelection);
            app.check_dataType.ValueChangedFcn = createCallbackFcn(app, @check_dataType_Callback, true);
            app.check_dataType.Tag = 'check_dataType';
            app.check_dataType.Text = 'Show all numeric Matlab data types (default: only "double")';
            app.DetermineNumComp = uipanel(app.figure1);
            app.DetermineNumComp.Title = 'Determination of the number of components';
            app.DetermineNumComp.Tag = 'uipanel8';
            app.but_SVD = uibutton(app.DetermineNumComp, 'push');
            app.but_SVD.ButtonPushedFcn = createCallbackFcn(app, @but_SVD_Callback, true);
            app.but_SVD.Tag = 'but_SVD';
            app.but_SVD.Enable = 'off';
            app.but_SVD.Text = 'SVD';
            app.but_manComp = uibutton(app.DetermineNumComp, 'push');
            app.but_manComp.ButtonPushedFcn = createCallbackFcn(app, @but_manComp_Callback, true);
            app.but_manComp.Tag = 'but_manComp';
            app.but_manComp.Enable = 'off';
            app.but_manComp.Text = 'Manual';
            app.but_quit = uibutton(app.figure1, 'push');
            app.but_quit.ButtonPushedFcn = createCallbackFcn(app, @but_quit_Callback, true);
            app.but_quit.Tag = 'but_quit';
            app.but_quit.Text = 'Quit';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Multivariate Curve Resolution Toolbox';
            app.push_alshelp = uibutton(app.uipanel1, 'push');
            app.push_alshelp.ButtonPushedFcn = createCallbackFcn(app, @alshelpbutt, true);
            app.push_alshelp.Tag = 'push_alsInfo';
            app.push_alshelp.Icon = fullfile(pathToMLAPP, 'help-circle-regular-24.png');
            app.push_alshelp.Text = '';
            app.push_alsInfo = uibutton(app.uipanel1, 'push');
            app.push_alsInfo.ButtonPushedFcn = createCallbackFcn(app, @push_alsInfo_Callback, true);
            app.push_alsInfo.Tag = 'push_alsInfo';
            app.push_alsInfo.Icon = fullfile(pathToMLAPP, 'info-circle-regular-24.png');
            app.push_alsInfo.Text = '';
            app.UncertaintyEstimate = uipanel(app.figure1);
            app.UncertaintyEstimate.Title = 'Uncertainties Estimation';
            app.UncertaintyEstimate.Tag = 'uipanel11';
            app.push_weight = uibutton(app.UncertaintyEstimate, 'push');
            app.push_weight.ButtonPushedFcn = createCallbackFcn(app, @push_weight_Callback, true);
            app.push_weight.Tag = 'push_weight';
            app.push_weight.Enable = 'off';
            app.push_weight.Text = 'Proceed';
            app.check_weight = uicheckbox(app.UncertaintyEstimate);
            app.check_weight.ValueChangedFcn = createCallbackFcn(app, @check_weight_Callback, true);
            app.check_weight.Tag = 'check_weight';
            app.check_weight.Enable = 'off';
            app.check_weight.Text = 'Data matrix weighting?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mcr_main_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)mcr_main_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end