classdef applicons_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        push_close  matlab.ui.control.Button
        push_print  matlab.ui.control.Button
        push_copy   matlab.ui.control.Button
        edit9       matlab.ui.control.EditField
        edit8       matlab.ui.control.EditField
        edit7       matlab.ui.control.EditField
        edit6       matlab.ui.control.EditField
        edit5       matlab.ui.control.EditField
        edit4       matlab.ui.control.EditField
        edit3       matlab.ui.control.EditField
        edit2       matlab.ui.control.EditField
        edit1       matlab.ui.control.EditField
        text10      matlab.ui.control.Label
        text9       matlab.ui.control.Label
        text8       matlab.ui.control.Label
        text7       matlab.ui.control.Label
        text6       matlab.ui.control.Label
        text5       matlab.ui.control.Label
        text4       matlab.ui.control.Label
        text3       matlab.ui.control.Label
        text2       matlab.ui.control.Label
        uipanel1    matlab.ui.container.Panel
        text1       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function applicons_OpeningFcn(app, varargin)
            % --- Executes just before applicons is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to applicons (see VARARGIN)
            
            % Choose default command line output for applicons
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            % edit1
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,1)'));
            set(app.edit1,'String',valor);
            % edit2
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,2)'));
            set(app.edit2,'String',valor);
            % edit3
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,3)'));
            set(app.edit3,'String',valor);
            % edit4
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,4)'));
            set(app.edit4,'String',valor);
            % edit5
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,5)'));
            set(app.edit5,'String',valor);
            % edit6
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,6)'));
            set(app.edit6,'String',valor);
            % edit7
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,7)'));
            set(app.edit7,'String',valor);
            % edit8
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,8)'));
            set(app.edit8,'String',valor);
            % edit 9
            valor=num2str(evalin('base','mcr_bands.temp.ncosntr(1,9)'));
            set(app.edit9,'String',valor);

        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            delete(handles.figure1);
        end

        % Button pushed function: push_copy
        function push_copy_Callback(app, event)
            % --- Executes on button press in push_copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            print -dbitmap;
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf,'PaperPositionMode','auto');
            print('MCRBANDS_APLCONS','-dpdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Constraints applied ';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Applied constraints';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Total';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Normalization';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Known values';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Non-negativity (concentration)';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Non-negativity (spectra)';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Unimodality';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Selectivity';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Trilinearity';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Closure';
            app.edit1 = uieditfield(app.figure1, 'text');
            app.edit1.Tag = 'edit1';
            app.edit1.Enable = 'off';
            app.edit1.Value = '2';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.Tag = 'edit2';
            app.edit2.Enable = 'off';
            app.edit2.Value = '0';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.Tag = 'edit3';
            app.edit3.Enable = 'off';
            app.edit3.Value = '2002';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.Tag = 'edit4';
            app.edit4.Enable = 'off';
            app.edit4.Value = '200';
            app.edit5 = uieditfield(app.figure1, 'text');
            app.edit5.Tag = 'edit5';
            app.edit5.Enable = 'off';
            app.edit5.Value = '0';
            app.edit6 = uieditfield(app.figure1, 'text');
            app.edit6.Tag = 'edit6';
            app.edit6.Enable = 'off';
            app.edit6.Value = '0';
            app.edit7 = uieditfield(app.figure1, 'text');
            app.edit7.Tag = 'edit7';
            app.edit7.Enable = 'off';
            app.edit7.Value = '0';
            app.edit8 = uieditfield(app.figure1, 'text');
            app.edit8.Tag = 'edit8';
            app.edit8.Enable = 'off';
            app.edit8.Value = '0';
            app.edit9 = uieditfield(app.figure1, 'text');
            app.edit9.Tag = 'edit9';
            app.edit9.Value = '2204';
            app.push_copy = uibutton(app.figure1, 'push');
            app.push_copy.ButtonPushedFcn = createCallbackFcn(app, @push_copy_Callback, true);
            app.push_copy.Tag = 'push_copy';
            app.push_copy.Text = 'Copy';
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Text = 'Print';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = applicons_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)applicons_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end