classdef change_opt_param_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        popup_algorithm   matlab.ui.control.DropDown
        edit7             matlab.ui.control.EditField
        text20            matlab.ui.control.Label
        text19            matlab.ui.control.Label
        pop_leveldisplay  matlab.ui.control.DropDown
        pop_diagn         matlab.ui.control.DropDown
        push_cancel       matlab.ui.control.Button
        pushbutton3       matlab.ui.control.Button
        text18            matlab.ui.control.Label
        push_go           matlab.ui.control.Button
        push_reset        matlab.ui.control.Button
        edit6             matlab.ui.control.EditField
        edit5             matlab.ui.control.EditField
        edit4             matlab.ui.control.EditField
        edit3             matlab.ui.control.EditField
        edit2             matlab.ui.control.EditField
        edit_tolcon       matlab.ui.control.EditField
        text9             matlab.ui.control.Label
        text8             matlab.ui.control.Label
        text7             matlab.ui.control.Label
        text6             matlab.ui.control.Label
        text5             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        text2             matlab.ui.control.Label
        text1             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function change_opt_param_OpeningFcn(app, varargin)
            % --- Executes just before change_opt_param is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to change_opt_param (see VARARGIN)
            
            % Choose default command line output for change_opt_param
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);


            % pop_leveldisplay_CreateFcn
            llista(1)={'default: iter'};
            llista(2)={'off'};
            llista(3)={'final'};
            llista(4)={'notify'};
            if (evalin('base','mcr_bands.Optional.param_flag;'))==0
                set(app.pop_leveldisplay,'string',llista)
            else
                valor=evalin('base','mcr_bands.Optional.Opt_param.levelDisplay;');
                set(app.pop_leveldisplay,'string',llista,'value',valor+1)
            end

            % pop_diagn_CreateFcn
            llista(1)={'default: on'};
            llista(2)={'off'};
            
            if (evalin('base','mcr_bands.Optional.param_flag;'))==0
                set(app.pop_diagn,'string',llista)
            else
                valor=evalin('base','mcr_bands.Optional.Opt_param.valueDiag;');
                set(app.pop_diagn,'string',llista,'value',valor+1)
            end

            % edit_tolcon_CreateFcn
            valor=evalin('base','mcr_bands.Optional.Opt_param.TolCon;');
            set(app.edit_tolcon,'string',valor)

            % edit2_CreateFcn
            valor=evalin('base','mcr_bands.Optional.Opt_param.TolFun;');
            set(app.edit2,'string',valor)

            % edit3_CreateFcn
            valor=evalin('base','mcr_bands.Optional.Opt_param.TolX;');
            set(app.edit3,'string',valor)

            % edit4_CreateFcn
            valor=evalin('base','mcr_bands.Optional.Opt_param.DiffMinChange;');
            set(app.edit4,'string',valor)

            % edit5_CreateFcn
            valor=evalin('base','mcr_bands.Optional.Opt_param.DiffMaxChange;');
            set(app.edit5,'string',valor)

            % edit6_CreateFcn
            valor=evalin('base','mcr_bands.Optional.Opt_param.MaxFunEvals;');
            set(app.edit6,'string',valor)

            % edit7_CreateFcn
            valor=evalin('base','mcr_bands.Optional.Opt_param.MaxIter;');
            set(app.edit7,'string',valor)

            %  popup_algorithm_CreateFcn
            llista(1)={'default: active-set'};
            llista(2)={'interior-point'};
            llista(3)={'sqp'};
            llista(4)={'trust-region-reflective'};
            if (evalin('base','mcr_bands.Optional.param_flag;'))==0
                set(app.popup_algorithm,'string',llista,'value',1)
            else
                valor=evalin('base','mcr_bands.Optional.Opt_param.levelAlgorithm;');
                set(app.popup_algorithm,'string',llista,'value',valor+1)
            end
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit2 as text
            %        str2double(get(hObject,'String')) returns contents of edit2 as a double
            
            
            TolFun= str2num(get(hObject,'String'));
            
            assignin('base','TolFun',TolFun);
            evalin('base','mcr_bands.Optional.Opt_param.TolFun=TolFun;');
            evalin('base','clear TolFun');
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit3 as text
            %        str2double(get(hObject,'String')) returns contents of edit3 as a double
            
            TolX= str2num(get(hObject,'String'));
            
            assignin('base','TolX',TolX);
            evalin('base','mcr_bands.Optional.Opt_param.TolX=TolX;');
            evalin('base','clear TolX');
        end

        % Value changed function: edit4
        function edit4_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit4 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit4 as text
            %        str2double(get(hObject,'String')) returns contents of edit4 as a double
            
            
            DiffMinChange= str2num(get(hObject,'String'));
            
            assignin('base','DiffMinChange',DiffMinChange);
            evalin('base','mcr_bands.Optional.Opt_param.DiffMinChange=DiffMinChange;');
            evalin('base','clear DiffMinChange');
        end

        % Value changed function: edit5
        function edit5_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit5 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit5 as text
            %        str2double(get(hObject,'String')) returns contents of edit5 as a double
            
            DiffMaxChange= str2num(get(hObject,'String'));
            
            assignin('base','DiffMaxChange',DiffMaxChange);
            evalin('base','mcr_bands.Optional.Opt_param.DiffMaxChange=DiffMaxChange;');
            evalin('base','clear DiffMaxChange');
        end

        % Value changed function: edit6
        function edit6_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit6 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit6 as text
            %        str2double(get(hObject,'String')) returns contents of edit6 as a double
            
            MaxFunEvals= str2num(get(hObject,'String'));
            
            assignin('base','MaxFunEvals',MaxFunEvals);
            evalin('base','mcr_bands.Optional.Opt_param.MaxFunEvals=MaxFunEvals;');
            evalin('base','clear MaxFunEvals');
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit7 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit7 as text
            %        str2double(get(hObject,'String')) returns contents of edit7 as a double
            
            MaxIter= str2num(get(hObject,'String'));
            
            assignin('base','MaxIter',MaxIter);
            evalin('base','mcr_bands.Optional.Opt_param.MaxIter=MaxIter;');
            evalin('base','clear MaxIter');
        end

        % Value changed function: edit_tolcon
        function edit_tolcon_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_tolcon (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_tolcon as text
            %        str2double(get(hObject,'String')) returns contents of edit_tolcon as a double
            
            
            TolCon= str2num(get(hObject,'String'));
            
            assignin('base','TolCon',TolCon);
            evalin('base','mcr_bands.Optional.Opt_param.TolCon=TolCon;');
            evalin('base','clear TolCon');
        end

        % Value changed function: pop_diagn
        function pop_diagn_Callback(app, event)
            % --- Executes on selection change in pop_diagn.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pop_diagn (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns pop_diagn contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from pop_diagn
            
            Diagnostics=get(hObject,'Value')-1;
            assignin('base','Diagnostics',Diagnostics);
            
            % list reloaded
            llista(1)={'on'};
            llista(2)={'off'};
            assignin('base','llista',llista);
            
            evalin('base','mcr_bands.Optional.Opt_param.valueDiag=Diagnostics;');
            evalin('base','mcr_bands.Optional.Opt_param.Diagnostics=llista{Diagnostics+1};');
            evalin('base','clear Diagnostics');
        end

        % Value changed function: pop_leveldisplay
        function pop_leveldisplay_Callback(app, event)
            % --- Executes on selection change in pop_leveldisplay.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pop_leveldisplay (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns pop_leveldisplay contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from pop_leveldisplay
            
            levelDisplay=get(hObject,'Value')-1;
            assignin('base','levelDisplay',levelDisplay);
            
            % list reloaded
            llista(1)={'iter'};
            llista(2)={'off'};
            llista(3)={'final'};
            llista(4)={'notify'};
            assignin('base','llista',llista);
            
            % valor=llista{levelDisplay};
            evalin('base','mcr_bands.Optional.Opt_param.Display=llista{levelDisplay+1};');
            evalin('base','mcr_bands.Optional.Opt_param.levelDisplay=levelDisplay;');
            evalin('base','clear llista levelDisplay');
        end

        % Value changed function: popup_algorithm
        function popup_algorithm_Callback(app, event)
            % --- Executes on selection change in popup_algorithm.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_algorithm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_algorithm contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_algorithm
            
            algorithm=get(hObject,'Value')-1;
            assignin('base','algorithm',algorithm);
            
            % list reloaded
            llista(1)={'active-set'};
            llista(2)={'interior-point'};
            llista(3)={'sqp'};
            llista(4)={'trust-region-reflective'};
            assignin('base','llista',llista);
            
            % valor=llista{levelDisplay};
            evalin('base','mcr_bands.Optional.Opt_param.Algorithm=llista{algorithm+1};');
            evalin('base','mcr_bands.Optional.Opt_param.levelAlgorithm=algorithm;');
            evalin('base','clear llista algorithm');
        end

        % Button pushed function: push_cancel
        function push_cancel_Callback(app, event)
            % --- Executes on button press in push_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % tornem a posar els parametres per defecte
            evalin('base','mcr_bands.Optional.Opt_param.Display=''iter'';');
            evalin('base','mcr_bands.Optional.Opt_param.Diagnostics=''on'';');
            evalin('base','mcr_bands.Optional.Opt_param.TolCon=0.0001;');
            evalin('base','mcr_bands.Optional.Opt_param.TolFun=0.00001;');
            evalin('base','mcr_bands.Optional.Opt_param.TolX=0.0001;');
            evalin('base','mcr_bands.Optional.Opt_param.DiffMinChange=0.00001;');
            evalin('base','mcr_bands.Optional.Opt_param.DiffMaxChange=0.1;');
            evalin('base','mcr_bands.Optional.Opt_param.MaxFunEvals=3000;');
            
            evalin('base','mcr_bands.Optional.Opt_param.MaxIter=10000;');
            evalin('base','mcr_bands.Optional.Opt_param.Algorithm=''active-set'';');
            
            
            evalin('base','mcr_bands.Optional.param_flag=0;');
            delete(handles.figure1);
        end

        % Button pushed function: push_go
        function push_go_Callback(app, event)
            % --- Executes on button press in push_go.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_go (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            delete(handles.figure1);
        end

        % Button pushed function: push_reset
        function push_reset_Callback(app, event)
            % --- Executes on button press in push_reset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % default parameters
            evalin('base','mcr_bands.Optional.Opt_param.Display=''iter'';');
            evalin('base','mcr_bands.Optional.Opt_param.Diagnostics=''on'';');
            evalin('base','mcr_bands.Optional.Opt_param.TolCon=0.0001;');
            evalin('base','mcr_bands.Optional.Opt_param.TolFun=0.00001;');
            evalin('base','mcr_bands.Optional.Opt_param.TolX=0.0001;');
            evalin('base','mcr_bands.Optional.Opt_param.DiffMinChange=0.00001;');
            evalin('base','mcr_bands.Optional.Opt_param.DiffMaxChange=0.1;');
            evalin('base','mcr_bands.Optional.Opt_param.MaxFunEvals=3000;');
            
            evalin('base','mcr_bands.Optional.Opt_param.MaxIter=10000;');
            evalin('base','mcr_bands.Optional.Opt_param.Algorithm=''active-set'';');
            
            
            % default parameters
            llista(1)={'default: iter'};
            llista(2)={'off'};
            llista(3)={'final'};
            llista(4)={'notify'};
            set(handles.pop_leveldisplay,'string',llista,'value',1)
            
            llista2(1)={'default: on'};
            llista2(2)={'off'};
            set(handles.pop_diagn,'string',llista2,'value',1)
            
            llista3(1)={'default: active-set'};
            llista3(2)={'interior-point'};
            llista3(3)={'sqp'};
            llista3(4)={'trust-region-reflective'};
            set(handles.pop_algorithm,'string',llista3,'value',1)
            
            
            set(handles.edit_tolcon,'string',0.0001)
            set(handles.edit2,'string',0.00001)
            set(handles.edit3,'string',0.0001)
            set(handles.edit4,'string',0.00001)
            set(handles.edit5,'string',0.1)
            set(handles.edit6,'string',3000)
            set(handles.edit7,'string',10000)
        end

        % Button pushed function: pushbutton3
        function pushbutton3_Callback(app, event)
            % --- Executes on button press in pushbutton3.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton3 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            web('http://www.mathworks.com/access/helpdesk/help/toolbox/optim/index.html?/access/helpdesk/help/toolbox/optim/ug/bqnk0r0.html&http://www.mathworks.com/support/product/product.html?product=OP&x=8&y=7', '-new');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Edit optimization parameters';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Parameters Change';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Level of display';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Diagnostics';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'TolCon';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'TolFun';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'TolX';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'DiffMinChange';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'DiffMaxChange';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'MaxFunEvals';
            app.edit_tolcon = uieditfield(app.figure1, 'text');
            app.edit_tolcon.ValueChangedFcn = createCallbackFcn(app, @edit_tolcon_Callback, true);
            app.edit_tolcon.Tag = 'edit_tolcon';
            app.edit_tolcon.Value = '0.0001';
            app.edit2 = uieditfield(app.figure1, 'text');
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Tag = 'edit2';
            app.edit2.Value = '1e-05';
            app.edit3 = uieditfield(app.figure1, 'text');
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Tag = 'edit3';
            app.edit3.Value = '0.0001';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4_Callback, true);
            app.edit4.Tag = 'edit4';
            app.edit4.Value = '1e-05';
            app.edit5 = uieditfield(app.figure1, 'text');
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit5_Callback, true);
            app.edit5.Tag = 'edit5';
            app.edit5.Value = '0.1';
            app.edit6 = uieditfield(app.figure1, 'text');
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6_Callback, true);
            app.edit6.Tag = 'edit6';
            app.edit6.Value = '3000';
            app.push_reset = uibutton(app.figure1, 'push');
            app.push_reset.ButtonPushedFcn = createCallbackFcn(app, @push_reset_Callback, true);
            app.push_reset.Tag = 'push_reset';
            app.push_reset.Text = 'Reset';
            app.push_go = uibutton(app.figure1, 'push');
            app.push_go.ButtonPushedFcn = createCallbackFcn(app, @push_go_Callback, true);
            app.push_go.Tag = 'push_go';
            app.push_go.Text = 'OK';
            app.text18 = uilabel(app.figure1);
            app.text18.Tag = 'text18';
            app.text18.Text = 'For more information visit';
            app.pushbutton3 = uibutton(app.figure1, 'push');
            app.pushbutton3.ButtonPushedFcn = createCallbackFcn(app, @pushbutton3_Callback, true);
            app.pushbutton3.Tag = 'pushbutton3';
            app.pushbutton3.Text = 'Link';
            app.push_cancel = uibutton(app.figure1, 'push');
            app.push_cancel.ButtonPushedFcn = createCallbackFcn(app, @push_cancel_Callback, true);
            app.push_cancel.Tag = 'push_cancel';
            app.push_cancel.Text = 'Cancel';
            app.pop_diagn = uidropdown(app.figure1);
            app.pop_diagn.Items = {'default: on', 'off'};
            app.pop_diagn.ValueChangedFcn = createCallbackFcn(app, @pop_diagn_Callback, true);
            app.pop_diagn.Tag = 'pop_diagn';
            app.pop_diagn.Value = 'default: on';
            app.pop_leveldisplay = uidropdown(app.figure1);
            app.pop_leveldisplay.Items = {'default: iter', 'off', 'final', 'notify'};
            app.pop_leveldisplay.ValueChangedFcn = createCallbackFcn(app, @pop_leveldisplay_Callback, true);
            app.pop_leveldisplay.Tag = 'pop_leveldisplay';
            app.pop_leveldisplay.Value = 'default: iter';
            app.text19 = uilabel(app.figure1);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Algorithm';
            app.text20 = uilabel(app.figure1);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Max No. Iterations';
            app.edit7 = uieditfield(app.figure1, 'text');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Tag = 'edit7';
            app.edit7.Value = '10000';
            app.popup_algorithm = uidropdown(app.figure1);
            app.popup_algorithm.Items = {'default: active-set', 'interior-point', 'sqp', 'trust-region-reflective'};
            app.popup_algorithm.ValueChangedFcn = createCallbackFcn(app, @popup_algorithm_Callback, true);
            app.popup_algorithm.Tag = 'popup_algorithm';
            app.popup_algorithm.Value = 'sqp';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = change_opt_param_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)change_opt_param_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end