classdef mcrbands_fr_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        push_close  matlab.ui.control.Button
        push_print  matlab.ui.control.Button
        push_copy   matlab.ui.control.Button
        uipanel1    matlab.ui.container.Panel
        push_value  matlab.ui.control.Button
        edit_conv   matlab.ui.control.EditField
        text4       matlab.ui.control.Label
        push_deter  matlab.ui.control.Button
        text3       matlab.ui.control.Label
        push_const  matlab.ui.control.Button
        edit_total  matlab.ui.control.EditField
        text2       matlab.ui.control.Label
        text1       matlab.ui.control.Label
        axesS       matlab.ui.control.UIAxes
        axesC       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mcrbands_fr_OpeningFcn(app, varargin)
            % --- Executes just before mcrbands_fr is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to mcrbands_fr (see VARARGIN)
            
            % Choose default command line output for mcrbands_fr
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            % edit_total_CreateFcn(
            maxim=num2str(evalin('base','mcr_bands.temp.ncosntr(1,9)'));
            set(app.edit_total,'String',maxim);
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end

        % Button pushed function: push_const
        function push_const_Callback(app, event)
            % --- Executes on button press in push_const.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_const (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            applicons_App;
        end

        % Button pushed function: push_copy
        function push_copy_Callback(app, event)
            % --- Executes on button press in push_copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            print -dmeta;
        end

        % Button pushed function: push_deter
        function push_deter_Callback(app, event)
            % --- Executes on button press in push_deter.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_deter (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            vers=version;
            tall=vers(1:3);
            num=str2num(tall);
            
            if num >= 7.6
                normgui_App;
            elseif (num >=7.0 & num < 7.6)
                normgui_old_App;
            else
                norm_old_App;
            end
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf,'PaperPositionMode','auto');
            print('MCRBANDS_RESULTS','-dpdf');
        end

        % Button pushed function: push_value
        function push_value_Callback(app, event)
            % --- Executes on button press in push_value.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_value (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            vers=version;
            tall=vers(1:3);
            num=str2num(tall);
            
            if num >= 7.6
                valueg_App;
            elseif (num >=7.0 & num < 7.6)
                valueg_old_App;
            else
                warndlg('Matlab Release not supported');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Results of the optimization';
            app.figure1.Tag = 'figure1';
            app.axesC = uiaxes(app.figure1);
            app.axesC.Tag = 'axesC';
            app.axesS = uiaxes(app.figure1);
            app.axesS.Tag = 'axesS';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Summary of the optimization results';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Information about the optimization';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Number of applied constraints have been...';
            app.edit_total = uieditfield(app.uipanel1, 'text');
            app.edit_total.Tag = 'edit_total';
            app.edit_total.Enable = 'off';
            app.edit_total.Value = '2204';
            app.push_const = uibutton(app.uipanel1, 'push');
            app.push_const.ButtonPushedFcn = createCallbackFcn(app, @push_const_Callback, true);
            app.push_const.Tag = 'push_const';
            app.push_const.Text = 'Constraints used';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Final evaluation of norms of band solutions';
            app.push_deter = uibutton(app.uipanel1, 'push');
            app.push_deter.ButtonPushedFcn = createCallbackFcn(app, @push_deter_Callback, true);
            app.push_deter.Tag = 'push_deter';
            app.push_deter.Text = 'See information';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Termination of the optimization procedure:';
            app.edit_conv = uieditfield(app.uipanel1, 'text');
            app.edit_conv.Tag = 'edit_conv';
            app.edit_conv.Enable = 'off';
            app.edit_conv.Value = 'Convergence';
            app.push_value = uibutton(app.uipanel1, 'push');
            app.push_value.ButtonPushedFcn = createCallbackFcn(app, @push_value_Callback, true);
            app.push_value.Tag = 'push_value';
            app.push_value.Text = 'Numerical Value';
            app.push_copy = uibutton(app.figure1, 'push');
            app.push_copy.ButtonPushedFcn = createCallbackFcn(app, @push_copy_Callback, true);
            app.push_copy.Tag = 'push_copy';
            app.push_copy.Text = 'Copy';
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Text = 'Print';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mcrbands_fr_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)mcrbands_fr_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end