classdef mcrbandsg_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        push_reset      matlab.ui.control.Button
        edit_results    matlab.ui.control.EditField
        check_results   matlab.ui.control.CheckBox
        text23          matlab.ui.control.Label
        push_opt        matlab.ui.control.Button
        push_cancel     matlab.ui.control.Button
        uipanel3        matlab.ui.container.Panel
        popup_rotation  matlab.ui.control.DropDown
        popup_knownC    matlab.ui.control.DropDown
        popup_knownS    matlab.ui.control.DropDown
        push_isp        matlab.ui.control.Button
        text26          matlab.ui.control.Label
        text9           matlab.ui.control.Label
        text25          matlab.ui.control.Label
        push_optparam   matlab.ui.control.Button
        text24          matlab.ui.control.Label
        check_plots     matlab.ui.control.CheckBox
        edit_nr         matlab.ui.control.EditField
        text22          matlab.ui.control.Label
        text7           matlab.ui.control.Label
        text5           matlab.ui.control.Label
        uipanel2        matlab.ui.container.Panel
        uipanel5        matlab.ui.container.Panel
        popup_trilin    matlab.ui.control.DropDown
        popup_conceq    matlab.ui.control.DropDown
        popup_speceq    matlab.ui.control.DropDown
        popup_unimod    matlab.ui.control.DropDown
        popup_nonneg    matlab.ui.control.DropDown
        popup_norm      matlab.ui.control.DropDown
        text21          matlab.ui.control.Label
        text20          matlab.ui.control.Label
        text19          matlab.ui.control.Label
        text18          matlab.ui.control.Label
        text17          matlab.ui.control.Label
        text16          matlab.ui.control.Label
        popup_conc      matlab.ui.control.DropDown
        popup_spec      matlab.ui.control.DropDown
        text4           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        uipanel1        matlab.ui.container.Panel
        text1           matlab.ui.control.Label
    end

    
    methods (Access = private)
        function popup_data_Callback(app, hObject, eventdata, handles)
            % --- Executes on selection change in popup_data.
            
            % hObject    handle to popup_data (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_data contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_data
            
            popUp=get(handles.popup_data,'String');
            pU=get(handles.popup_data,'Value');
            
            if pU==1
            
            else
                sel=char([popUp(pU)]);
                matdad=evalin('base',sel);
                assignin('base','matdad',matdad);
                evalin('base','mcr_bands.Data.data=matdad;');
                evalin('base','clear matdad');
                set(handles.popup_conc,'enable','on');
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function mcrbandsg_OpeningFcn(app, varargin)
            % --- Executes just before mcrbandsg is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to mcrbandsg (see VARARGIN)
            
            % Choose default command line output for mcrbandsg
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);


            % UIWAIT makes mcrbandsg wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            % Variables to initialize
            evalin('base','mcr_bands.Options.Resultats=0;');
            evalin('base','mcr_bands.Options.flagOptim=0;');
            evalin('base','mcr_bands.Options.knownC=0;');
            evalin('base','mcr_bands.Options.knownS=0;');

            disp ('   ')
            disp ('   ')
            disp ('   ************************************************************************************')
            disp ('   *                           MCR-BANDS GRAPHIC INTERFACE                            *')
            disp ('   *                           MATLAB Optimization Toolbox is required                *')
            disp ('   *                           Apri, 2017                                        *')
            disp ('   ************************************************************************************')
            disp ('   ')
            
            % initial parameters
            evalin('base','mcr_bands.Options.showplots=1;');
            evalin('base','mcr_bands.Optional.param_flag=0;');
            evalin('base','mcr_bands.Options.NExpFlag=0;');
            
            % advanced parameters
            if (evalin('base','mcr_bands.Optional.param_flag;'))==0
                evalin('base','mcr_bands.Optional.Opt_param.Display=''iter'';');
                evalin('base','mcr_bands.Optional.Opt_param.Diagnostics=''on'';');
                evalin('base','mcr_bands.Optional.Opt_param.TolCon=0.0001;');
                evalin('base','mcr_bands.Optional.Opt_param.TolFun=0.00001;');
                evalin('base','mcr_bands.Optional.Opt_param.TolX=0.000001;');
                evalin('base','mcr_bands.Optional.Opt_param.DiffMinChange=0.00001;');
                evalin('base','mcr_bands.Optional.Opt_param.DiffMaxChange=0.1;');
                evalin('base','mcr_bands.Optional.Opt_param.MaxFunEvals=10000;');
                
                evalin('base','mcr_bands.Optional.Opt_param.MaxIter=5000;');
                evalin('base','mcr_bands.Optional.Opt_param.Algorithm=''active-set'';');
            end

            % popup_conc_CreateFcn
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=2;
            lsv(1)={'select a variable from the WS'};
            for i=1:aa,
                csize=length(lsvar(i).class);
                if csize==6,
                    if lsvar(i).class=='double',
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end;
                end;
            end;
            
            set(app.popup_conc,'string',lsv)

            % popup_spec_CreateFcn(
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=2;
            lsv(1)={'select a variable from the WS'};
            for i=1:aa,
                csize=length(lsvar(i).class);
                if csize==6,
                    if lsvar(i).class=='double',
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end;
                end;
            end;
            
            set(app.popup_spec,'string',lsv)

            % popup_norm_CreateFcn
            llista(1)={'default: no closure nor normalization'};
            llista(2)={'apply closure normalization'};
            llista(3)={'spectra normalization'};
            llista(4)={'spectra normalization using only off-diagonal T values'};
            set(app.popup_norm,'string',llista)
            evalin('base','mcr_bands.Constraints.Normalization.iclos=0;'); %defaut

            % popup_nonneg_CreateFcn
            llista(1)={'default: do not apply non-negativity constraints'};
            llista(2)={'apply non-negativity constraints in conc'};
            llista(3)={'non-negativity constraints in spec and conc'};
            llista(4)={'non-negativity constraints only in spec '};
            set(app.popup_nonneg,'string',llista)
            evalin('base','mcr_bands.Constraints.Non_negativity.ineg=0;');%default

            % popup_unimod_CreateFcn
            llista(1)={'default: do not apply unimodality constraints'};
            llista(2)={'apply unimodality in concn'};
            llista(3)={'apply unimodality in spectra (not implemented yet)'};
            llista(4)={'apply unimodality in both (not implemented yet)'};
            set(app.popup_unimod,'string',llista)
            evalin('base','mcr_bands.Constraints.Unimodality.iunimod=0;');%default

            %  popup_trilin_CreateFcn
            llista(1)={'default: single data matrix = no three-way data'};
            llista(2)={'three-way non-trilinear data'};
            llista(3)={'three-way trilinear data'};
            set(app.popup_trilin,'string',llista)
            evalin('base','mcr_bands.Constraints.Trilinearity.itril=0;');%default

            % popup_conceq_CreateFcn
            llista(1)={'default: do not apply equality constraints'};
            llista(2)={'apply equality constraints'};
            llista(3)={'apply selectivity constraints (<=)'};
            set(app.popup_conceq,'string',llista)
            evalin('base','mcr_bands.Constraints.Cequality.iknown=0;');%default

            % popup_speceq_CreateFcn
            llista(1)={'default: do not apply equality constraints'};
            llista(2)={'apply equality constraints'};
            llista(3)={'apply selectivity constraints (<=)'};
            set(app.popup_speceq,'string',llista)
            evalin('base','mcr_bands.Constraints.Sequality.iknown=0;');%default

            % popup_rotation_CreateFcn
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=2;
            lsv(1)={'default is t=eye(nsign) or select a variable'};
            for i=1:aa,
                csize=length(lsvar(i).class);
                if csize==6,
                    if lsvar(i).class=='double',
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end;
                end;
            end;
            
            set(app.popup_rotation,'string',lsv)

            % popup_knownC_CreateFcn
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=3;
            lsv(1)={'select a variable or "use isp matrix"'};
            lsv(2)={'use isp matrix'};
            for i=1:aa,
                csize=length(lsvar(i).class);
                if csize==6,
                    if lsvar(i).class=='double',
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end;
                end;
            end;
            
            set(app.popup_knownC,'string',lsv)
            evalin('base','mcr_bands.Optional.Cequality.cknown=[];'); %defaut

            %  popup_knownS_CreateFcn(
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=2;
            lsv(1)={'select a variable'};
            for i=1:aa,
                csize=length(lsvar(i).class);
                if csize==6,
                    if lsvar(i).class=='double',
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end;
                end;
            end;
            
            set(app.popup_knownS,'string',lsv)
            evalin('base','mcr_bands.Optional.Sequality.sknown=[];'); %defaut
        end

        % Value changed function: check_plots
        function check_plots_Callback(app, event)
            % --- Executes on button press in check_plots.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_plots (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_plots
            
            showplots=get(hObject,'Value');
            assignin('base','showplots',showplots);
            evalin('base','mcr_bands.Options.showplots=showplots;');
            evalin('base','clear showplots');
        end

        % Value changed function: check_results
        function check_results_Callback(app, event)
            % --- Executes on button press in check_results.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_results (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_results
            
            resultats=get(hObject,'Value');
            assignin('base','resultats',resultats);
            if resultats==1;
                evalin('base','mcr_bands.Options.Resultats=1;');
                set(handles.edit_results,'enable','on');
            else
                evalin('base','mcr_bands.Options.Resultats=0;');
                set(handles.edit_results,'enable','off','String',' ');
                evalin('base','mcr_bands.Options.Nom_resultats=[];');
            end;
            evalin('base','clear resultats');
        end

        % Value changed function: edit_nr
        function edit_nr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_nr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_nr as text
            %        str2double(get(hObject,'String')) returns contents of edit_nr as a double
            
            numb_exper= str2num(get(hObject,'String'));
            evalin('base','mcr_bands.Options.NExpFlag=0;');
            
            set(handles.edit_nr,'BackgroundColor',[0.8 1 0.8]);
            
            assignin('base','numb_exper',numb_exper);
            evalin('base','mcr_bands.Optional.NumExp.nexp=numb_exper;');
            evalin('base','clear numb_exper');
        end

        % Value changed function: edit_results
        function edit_results_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_results (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_results as text
            %        str2double(get(hObject,'String')) returns contents of edit_results as a double
            
            nom_resultats= get(hObject,'String');
            
            assignin('base','nom_resultats',nom_resultats);
            evalin('base','mcr_bands.Options.Nom_resultats=nom_resultats;');
            evalin('base','clear nom_resultats');
        end

        % Value changed function: popup_conc
        function popup_conc_Callback(app, event)
            % --- Executes on selection change in popup_conc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_conc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_conc contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_conc
            popUp=get(handles.popup_conc,'String');
            pU=get(handles.popup_conc,'Value');
            
            if pU==1
            
            else
                sel=char([popUp(pU)]);
                concdad=evalin('base',sel);
                assignin('base','concdad',concdad);
                evalin('base','mcr_bands.Data.conc=concdad;');
                [xdim,ydim]=size(concdad);
                assignin('base','ydim',ydim);
                evalin('base','mcr_bands.Optional.Rotation.t0=eye(ydim);');
                %%evalin('base','mcr_bands.Optional.Speciation.ispec=[1:ydim];');
                evalin('base','clear concdad ydim');
                set(handles.popup_spec,'enable','on');
            end
        end

        % Value changed function: popup_conceq
        function popup_conceq_Callback(app, event)
            % --- Executes on selection change in popup_conceq.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_conceq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_conceq contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_conceq
            
            ccknown=get(hObject,'Value')-1;
            assignin('base','ccknown',ccknown);
            evalin('base','mcr_bands.Constraints.Cequality.iknown=ccknown;');
            evalin('base','clear ccknown');
            if ccknown == 0
                set(handles.popup_knownC,'enable','off');
                evalin('base','mcr_bands.Options.knownC=0;');
            else
                set(handles.popup_knownC,'enable','on');
                evalin('base','mcr_bands.Options.knownC=1;');
            end
        end

        % Value changed function: popup_knownC
        function popup_knownC_Callback(app, event)
            % --- Executes on selection change in popup_knownC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_knownC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_knownC contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from
            %        popup_knownC
            
            
            % -------------------------------------------
            popUp=get(handles.popup_knownC,'String');
            pU=get(handles.popup_knownC,'Value');
            
            if pU==1
                evalin('base','mcr_bands.Optional.Cequality.cknown=[];');
                set(handles.push_isp,'enable','off');
            elseif pU==2
                set(handles.push_isp,'enable','on');
                evalin('base','mcr_bands.Options.knownC=0;');
            else
                set(handles.push_isp,'enable','off');
                sel=char([popUp(pU)]);
                ckxx=evalin('base',sel);
                assignin('base','ckxx',ckxx);
                evalin('base','mcr_bands.Optional.Cequality.cknown=ckxx;');
                evalin('base','clear ckxx');
                evalin('base','mcr_bands.Options.knownC=0;');
            end
        end

        % Value changed function: popup_knownS
        function popup_knownS_Callback(app, event)
            % --- Executes on selection change in popup_knownS.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_knownS (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_knownS contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from
            %        popup_knownS
            
            
            % -------------------------------------------
            popUp=get(handles.popup_knownS,'String');
            pU=get(handles.popup_knownS,'Value');
            
            if pU==1
                evalin('base','mcr_bands.Optional.Sequality.sknown=[];');
            else
                sel=char([popUp(pU)]);
                skxx=evalin('base',sel);
                assignin('base','skxx',skxx);
                evalin('base','mcr_bands.Optional.Sequality.sknown=skxx;');
                evalin('base','clear skxx');
                evalin('base','mcr_bands.Options.knownS=0;');
            end
        end

        % Value changed function: popup_nonneg
        function popup_nonneg_Callback(app, event)
            % --- Executes on selection change in popup_nonneg.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_nonneg (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_nonneg contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_nonneg
            
            cnonneg=get(hObject,'Value')-1;
            assignin('base','cnonneg',cnonneg);
            evalin('base','mcr_bands.Constraints.Non_negativity.ineg=cnonneg;');
            evalin('base','clear cnonneg');
        end

        % Value changed function: popup_norm
        function popup_norm_Callback(app, event)
            % --- Executes on selection change in popup_norm.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_norm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_norm contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_norm
            
            cnorm=get(hObject,'Value')-1;
            assignin('base','cnorm',cnorm);
            evalin('base','mcr_bands.Constraints.Normalization.iclos=cnorm;');
            evalin('base','clear cnorm');
        end

        % Value changed function: popup_rotation
        function popup_rotation_Callback(app, event)
            % --- Executes on selection change in popup_rotation.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_rotation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_rotation contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from
            %        popup_rotation


            popUp=get(handles.popup_rotation,'String');
            pU=get(handles.popup_rotation,'Value');
            
            if pU==1
                concdad=evalin('base','mcr_bands.Data.conc;');
                [xdim,ydim]=size(concdad);
                assignin('base','ydim',ydim);
                evalin('base','mcr_bands.Optional.Rotation.t0=eye(ydim);');
                evalin('base','clear ydim');
            else
                sel=char([popUp(pU)]);
                rota=evalin('base',sel);
                assignin('base','rota',rota);
                evalin('base','mcr_bands.Optional.Rotation.t0=rota;');
                evalin('base','clear rota');
            end
        end

        % Value changed function: popup_spec
        function popup_spec_Callback(app, event)
            % --- Executes on selection change in popup_spec.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_spec (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_spec contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_spec
            popUp=get(handles.popup_spec,'String');
            pU=get(handles.popup_spec,'Value');
            
            if pU==1
            
            else
                sel=char([popUp(pU)]);
                specdad=evalin('base',sel);
                assignin('base','specdad',specdad);
                evalin('base','mcr_bands.Data.spec=specdad;');
                evalin('base','clear specdad');
            
                if 1==1;
                    set(handles.popup_norm,'enable','on');
                    set(handles.popup_nonneg,'enable','on');
                    set(handles.popup_unimod,'enable','on');
                    set(handles.popup_trilin,'enable','on');
                    set(handles.popup_conceq,'enable','on');
                    set(handles.popup_speceq,'enable','on');
                end
            end
        end

        % Value changed function: popup_speceq
        function popup_speceq_Callback(app, event)
            % --- Executes on selection change in popup_speceq.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_speceq (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_speceq contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_speceq
            
            scknown=get(hObject,'Value')-1;
            assignin('base','scknown',scknown);
            evalin('base','mcr_bands.Constraints.Sequality.iknown=scknown;');
            evalin('base','clear scknown');
            if scknown == 0
                set(handles.popup_knownS,'enable','off');
                evalin('base','mcr_bands.Options.knownS=0;');
            else
                set(handles.popup_knownS,'enable','on');
                evalin('base','mcr_bands.Options.knownS=1;');
            end
        end

        % Value changed function: popup_trilin
        function popup_trilin_Callback(app, event)
            % --- Executes on selection change in popup_trilin.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_trilin (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_trilin contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_trilin
            
            ctril=get(hObject,'Value')-1;
            assignin('base','ctril',ctril);
            evalin('base','mcr_bands.Constraints.Trilinearity.itril=ctril;');
            evalin('base','clear ctril');
            if ctril == 0
                set(handles.edit_nr,'enable','off','String','1','BackgroundColor',[1 1 1]);
                evalin('base','mcr_bands.Optional.NumExp.nexp=1;');
                evalin('base','mcr_bands.Options.NExpFlag=0;');
            else
                set(handles.edit_nr,'enable','on','String','1','BackgroundColor',[1 0.6 0.6]);
                evalin('base','mcr_bands.Options.NExpFlag=1;');
            end
        end

        % Value changed function: popup_unimod
        function popup_unimod_Callback(app, event)
            % --- Executes on selection change in popup_unimod.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_unimod (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_unimod contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_unimod
            
            cunim=get(hObject,'Value')-1;
            assignin('base','cunim',cunim);
            evalin('base','mcr_bands.Constraints.Unimodality.iunimod=cunim;');
            evalin('base','clear cunim');
        end

        % Button pushed function: push_cancel
        function push_cancel_Callback(app, event)
            % --- Executes on button press in push_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            disp('cancel');
            evalin('base','clear mcr_bands;');
            close;
        end

        % Button pushed function: push_isp
        function push_isp_Callback(app, event)
            % --- Executes on button press in push_isp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_isp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            push_isp;
        end

        % Button pushed function: push_opt
        function push_opt_Callback(app, event)
            % --- Executes on button press in push_opt.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_opt (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % go ahead
            % creation of ig
            %close;
            
            %*************************************************************************
            % Optimization starts
            %*************************************************************************
            
            
            % check flag de NExp
            if (evalin('base','mcr_bands.Options.NExpFlag')==1)
                warndlg('Check the number of experiments');
            else
            
            
                if (evalin('base','mcr_bands.Options.knownC')==1)
                    warndlg('Check the cknown');
                else
            
                    if (evalin('base','mcr_bands.Options.knownS')==1)
                        warndlg('Check the sknown');
                    else
            
                        global g
                        global nconstr
            
                        %    m=evalin('base','mcr_bands.Data.data;');
                        c=evalin('base','mcr_bands.Data.conc;');
                        s=evalin('base','mcr_bands.Data.spec;');
                        t0=evalin('base','mcr_bands.Optional.Rotation.t0;');
                        cknown=evalin('base','mcr_bands.Optional.Cequality.cknown;');
                        sknown=evalin('base','mcr_bands.Optional.Sequality.sknown;');
                        % ispec=evalin('base','mcr_bands.Optional.Speciation.ispec;');
            
            
                        tband=[];tbandsvd=[];sband=[];cband=[];optband=[];lofband=[];fband=[];detcbands=[];detsbands=[];
                        foptim=[];iknown=0;iclos=0;iunimod=0;clos=[];ineg=2;nexp=1;itil=0;
                        % new version
                        nconstr=0;g=[];
                        tnorm=norm(c*s,'fro');normband=[];
            
                        [nrow,nsign]=size(c);
                        [nsign,ncol]=size(s);
            
                        % evaluation of initial determinants
                        detcbands=det(c'*c);
                        detsbands=det(s*s');
            
                     % options=optimset('Display',evalin('base','mcr_bands.Optional.Opt_param.Display'),'Diagnostics',evalin('base','mcr_bands.Optional.Opt_param.Diagnostics=''on'),'TolCon',evalin('base','mcr_bands.Optional.Opt_param.TolCon'),'TolFun',evalin('base','mcr_bands.Optional.Opt_param.TolFun'),'Tolx',evalin('base','mcr_bands.Optional.Opt_param.TolX'),'DiffMinChange',evalin('base','mcr_bands.Optional.Opt_param.DiffMinChange'),'DiffMaxChange',evalin('base','mcr_bands.Optional.Opt_param.DiffMaxChange'),'MaxFunEvals',evalin('base','mcr_bands.Optional.Opt_param.MaxFunEvals'));
            
                        options = optimset('Display',evalin('base','mcr_bands.Optional.Opt_param.Display'),'Diagnostics',evalin('base','mcr_bands.Optional.Opt_param.Diagnostics'),'TolCon',evalin('base','mcr_bands.Optional.Opt_param.TolCon'),'MaxIter',evalin('base','mcr_bands.Optional.Opt_param.MaxIter'),'Algorithm',evalin('base','mcr_bands.Optional.Opt_param.Algorithm'),'Tolx',evalin('base','mcr_bands.Optional.Opt_param.TolX'),'DiffMinChange',evalin('base','mcr_bands.Optional.Opt_param.DiffMinChange'),'DiffMaxChange',evalin('base','mcr_bands.Optional.Opt_param.DiffMaxChange'),'MaxFunEvals',evalin('base','mcr_bands.Optional.Opt_param.MaxFunEvals'));
            
                        % definition of constraints ig
                        % normalization constraints: closure or spectra normalization
                        ig(1,1)=evalin('base','mcr_bands.Constraints.Normalization.iclos;');
            
                        if evalin('base','mcr_bands.Constraints.Normalization.iclos;')==1,
                            clos=sum(c');clos=clos'; 	% closure constants as defined in input concentrations
                        end
            
                        % non-negativity constraints (inequality constraints)
                        ig(1,2)=evalin('base','mcr_bands.Constraints.Non_negativity.ineg;');
            
                        % equality constraints in cknown (implemented)
                        ig(1,3)=evalin('base','mcr_bands.Constraints.Cequality.iknown;');
            
                        % equality constraints in sknown (not implemented for the moment)
                        ig(1,4)=evalin('base','mcr_bands.Constraints.Sequality.iknown;');
            
                        % unimodality constraints in concentrations
                        ig(1,5)=evalin('base','mcr_bands.Constraints.Unimodality.iunimod;');
            
                        % trilinearity constraints
                        ig(1,6)=evalin('base','mcr_bands.Constraints.Trilinearity.itril;');
            
                        if evalin('base','mcr_bands.Constraints.Trilinearity.itril;')>0,
                            nexp=evalin('base','mcr_bands.Optional.NumExp.nexp;');
                        end
            
                        % ************************************************************
                        % constrained maximization/minimization  of resolution bands *
                        % performed separately for each species                      *
                        % ************************************************************
            
                        noptim=nsign;
            
                        % evaluation of t values from svd ---------------> new version
                        disp('evaluation of t values from svd')
                        [u,l,v]=svd(c*s);
                        ul=u(:,1:nsign)*l(1:nsign,1:nsign);
            
                        if (evalin('base','mcr_bands.Options.showplots')==1)
                            figure;
                        else
                        end
            
                        for ioptim=1:noptim;
                            disp('estimation of feasible range for species profiles: '); disp(ioptim);
            
                            %****************************************************************************************
            
                            if ig(1,1)==3,
                                index=0;
                                for ii=1:nsign
                                    for jj=1:nsign
                                        if ii==jj,dummy=t0(ii,jj);else,index=index+1;tt(1,index)=t0(ii,jj);end
                                    end
                                end
                                vlb=[];
                                vub=[];
                                % pause
                            else
                                tt=t0;
                            end
            
                            % calculation of the maximum/outer band
            
                            [tmax,fbandmax,exitflagmax]=fmincon(@fmaxmin,tt,[],[],[],[],[],[],@mycons,options,c,s,1,ioptim,ig,clos,cknown,sknown,nexp);
                            fbandmax=-fbandmax; %---------------------> new version
                            if ig(1,1)==3,tmax=tconv(tmax,nsign);end;
            
            
                            % calculation of the minimum/inner band
            
                            [tmin,fbandmin,exitflagmin]=fmincon(@fmaxmin,tt,[],[],[],[],[],[],@mycons,options,c,s,2,ioptim,ig,clos,cknown,sknown,nexp);
                            if ig(1,1)==3,tmin=tconv(tmin,nsign);end;
            
                            disp('***************************************************')
                            disp('OPTIMAL VALUES FOR MAXIMUM CONTRIBUTION OF SPECIES: ');
                            disp(ioptim);
                            disp('optimal T values (max)');disp(tmax);
                            disp('optimal values of the function f(T) at the maximum');disp(-fbandmax);
                            disp('termination > 0 (convergence), = 0 (max.number of iterations), < 0 (divergence) :');disp(exitflagmax);
                            disp('****************************************************')
                            disp('OPTIMAL VALUES FOR MINIMUM CONTRIBUTION OF SPECIES: ')
                            disp(ioptim);
                            disp('optimal T values (min)');disp(tmin);
                            disp('optimal values of the function f(T) at the minimum');disp(fbandmin);
                            disp('termination > 0 (convergence), = 0 (max.number of iterations), < 0 (divergence) :');disp(exitflagmin);
                            disp('***************************************************')
            
                            %****************************************************************************************
            
            
                            % values to keep for the optimization of each species band
            
                            smax=tmax*s;
                            cmax=c/tmax;
                            smin=tmin*s;
                            cmin=c/tmin;
                            tmaxsvd=smax*v(:,1:nsign);  % -------------------------> new version
                            tminsvd=smin*v(:,1:nsign);
            
                            exitflag(ioptim,:)=[exitflagmax,exitflagmin]; % -----------------------new version
                            for ix=1:nsign
                                tmaxsvd(ix,:)=tmaxsvd(ix,:)./tmaxsvd(ix,ix);
                                tminsvd(ix,:)=tminsvd(ix,:)./tminsvd(ix,ix);
                            end
            
                            tband=[tband;tmax;tmin];
                            tbandsvd=[tbandsvd;tmaxsvd;tminsvd]; % -------------------------> new version
                            sband=[sband;smax(ioptim,:);smin(ioptim,:)];
                            cband=[cband,cmax(:,ioptim),cmin(:,ioptim)];
                            fband=[fband,[fbandmax;fbandmin]];
            
            
                            %****************************************************************************************
                            % Intermediate Figures
                            %**************************************************************************
            
                            if (evalin('base','mcr_bands.Options.showplots')==1)
            
                                %disp('plot of band profiles for species: '),disp(ioptim);
                                %figure
                                subplot(noptim,2,2*ioptim-1),plot(cmax(:,ioptim))
                                hold on
                                subplot(noptim,2,2*ioptim-1),plot(cmin(:,ioptim))
                                subplot(noptim,2,2*ioptim-1),plot(c(:,ioptim),'r:')
                                title(['Plot of C bands profiles for species: ',num2str(ioptim)]);
                                hold off
            
                                subplot(noptim,2,2*ioptim),plot(smax(ioptim,:)')
                                hold on
                                subplot(noptim,2,2*ioptim),plot(smin(ioptim,:)')
                                subplot(noptim,2,2*ioptim),plot(s(ioptim,:),'r:')
                                title(['Plot of S bands profiles for species: ',num2str(ioptim)]);
                                hold off
            
                            else
                            end
            
                            %**************************************************************************
                            %**************
            
            
                            normmin(ioptim)=norm(cmin(:,ioptim)*smin(ioptim,:),'fro')/tnorm;
                            normmax(ioptim)=norm(cmax(:,ioptim)*smax(ioptim,:),'fro')/tnorm;
                            normband=[normband;normmax(ioptim);normmin(ioptim)];
            
                            % Calculation of initial function values for comparison with th eoptimized ones
                            % To check that the optimization was good -> Added February 2009 and
                            % corrected in May 2009
            
                            % modification may 2009
                            disp('f values for initial solution')
                            [finic(ioptim)]=fmaxmin(eye(nsign),c,s,2,ioptim,1)
                            pause(1);
            
                        end


                        %****************************************************************************************
                        % End results
                        %****************************************************************************************
            
                        assignin('base','nconstr',nconstr);
                        assignin('base','sband',sband);
                        assignin('base','nsign',nsign);
                        %assignin('base','ispec',ispec);
                        assignin('base','cband',cband);
                        assignin('base','tband',tband);
                        assignin('base','tbandsvd',tbandsvd);
                        assignin('base','fband',fband);
                        assignin('base','finic',finic);
                        assignin('base','exitflag',exitflag);
            
                        evalin('base','mcr_bands.temp.sband=sband;');
                        evalin('base','mcr_bands.temp.cband=cband;');
                        evalin('base','mcr_bands.temp.nsign=nsign;');
                        %evalin('base','mcr_bands.temp.ispec=ispec;');
                        evalin('base','mcr_bands.temp.ncosntr=nconstr;');
                        evalin('base','mcr_bands.temp.tband=tband;');
                        evalin('base','mcr_bands.temp.fband=fband;');
                        evalin('base','mcr_bands.temp.finic=finic;');
                        evalin('base','mcr_bands.temp.tbandsvd=tbandsvd;');
                        evalin('base','mcr_bands.temp.exitflag=exitflag;');
            
                        % evalin('base','clear sband cband nsign ispec nconstr detcbands detsbands exitflag');
                        evalin('base','clear sband cband nsign tband tbandsvd fband ispec nconstr exitflag finic');
            
            
                        if (evalin('base','mcr_bands.Options.flagOptim;'))==1
                            delete(mcrbands_fr);
                        end
            
                        mcrbands_fr_App;
                        evalin('base','mcr_bands.Options.flagOptim=1;');


                        %% ************************************************************************************
                        %% Output
                        %% ************************************************************************
            
                        %[sband,cband,normband,tband,fband]
                        assignin('base','sband',sband);
                        assignin('base','cband',cband);
                        assignin('base','tband',tband);
                        assignin('base','tbandsvd',tbandsvd);
                        assignin('base','fband',fband);
                        assignin('base','finic',finic);
                        assignin('base','normband',normband);
                        assignin('base','exitflag',exitflag); % ---------------------------------------
            
                        evalin('base','mcr_bands.Results.sband=sband;');
                        evalin('base','mcr_bands.Results.cband=cband;');
                        evalin('base','mcr_bands.Results.tband=tband;');
                        evalin('base','mcr_bands.Results.tbandsvd=tbandsvd;');
                        evalin('base','mcr_bands.Results.fband=fband;');
                        evalin('base','mcr_bands.Results.finic=finic;');
                        evalin('base','mcr_bands.Results.normband=normband;');
                        evalin('base','mcr_bands.Results.exitflag=exitflag;');% ---------------------------------------
            
                        evalin('base','clear sband cband tband tbandsvd fband finic normband exitflag');
            
                        if evalin('base','mcr_bands.Options.Resultats') == 1
                            assignin('base',evalin('base','mcr_bands.Options.Nom_resultats'),evalin('base','mcr_bands.Results'))
                        end
            
            
                    end
                end
            end
        end

        % Button pushed function: push_optparam
        function push_optparam_Callback(app, event)
            % --- Executes on button press in push_optparam.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_optparam (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            change_opt_param;
            evalin('base','mcr_bands.Optional.param_flag=1;');
        end

        % Button pushed function: push_reset
        function push_reset_Callback(app, event)
            % --- Executes on button press in push_reset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            disp('reset mcrbandsg');
            evalin('base','clear mcr_bands;');
            close;
            mcrbandsg;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MCR-Bands GUI';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'MCR-Bands Graphical User Interface';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Obligatory Parameters';
            app.uipanel2.Tag = 'uipanel2';
            app.text3 = uilabel(app.uipanel2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Concentration Profiles';
            app.text4 = uilabel(app.uipanel2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Spectra Profiles';
            app.popup_spec = uidropdown(app.uipanel2);
            app.popup_spec.Items = {'select a variable from the WS'};
            app.popup_spec.ValueChangedFcn = createCallbackFcn(app, @popup_spec_Callback, true);
            app.popup_spec.Tag = 'popup_spec';
            app.popup_spec.Enable = 'off';
            app.popup_spec.Value = 'select a variable from the WS';
            app.popup_conc = uidropdown(app.uipanel2);
            app.popup_conc.Items = {'select a variable from the WS'};
            app.popup_conc.ValueChangedFcn = createCallbackFcn(app, @popup_conc_Callback, true);
            app.popup_conc.Tag = 'popup_conc';
            app.popup_conc.Value = 'select a variable from the WS';
            app.uipanel5 = uipanel(app.uipanel2);
            app.uipanel5.Title = 'Constraints';
            app.uipanel5.Tag = 'uipanel5';
            app.text16 = uilabel(app.uipanel5);
            app.text16.Tag = 'text16';
            app.text16.Text = 'Normalization constraints';
            app.text17 = uilabel(app.uipanel5);
            app.text17.Tag = 'text17';
            app.text17.Text = 'Non-negativity constraints';
            app.text18 = uilabel(app.uipanel5);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Conc. equality constraints';
            app.text19 = uilabel(app.uipanel5);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Spec. equality constraints';
            app.text20 = uilabel(app.uipanel5);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Unimodality constraints';
            app.text21 = uilabel(app.uipanel5);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Trilinearity constraints';
            app.popup_norm = uidropdown(app.uipanel5);
            app.popup_norm.Items = {'default: no closure nor normalization', 'apply closure normalization', 'spectra normalization', 'spectra normalization using only off-diagonal T values'};
            app.popup_norm.ValueChangedFcn = createCallbackFcn(app, @popup_norm_Callback, true);
            app.popup_norm.Tag = 'popup_norm';
            app.popup_norm.Enable = 'off';
            app.popup_norm.Value = 'default: no closure nor normalization';
            app.popup_nonneg = uidropdown(app.uipanel5);
            app.popup_nonneg.Items = {'default: do not apply non-negativity constraints', 'apply non-negativity constraints in conc', 'non-negativity constraints in spec and conc', 'non-negativity constraints only in spec '};
            app.popup_nonneg.ValueChangedFcn = createCallbackFcn(app, @popup_nonneg_Callback, true);
            app.popup_nonneg.Tag = 'popup_nonneg';
            app.popup_nonneg.Enable = 'off';
            app.popup_nonneg.Value = 'default: do not apply non-negativity constraints';
            app.popup_unimod = uidropdown(app.uipanel5);
            app.popup_unimod.Items = {'default: do not apply unimodality constraints', 'apply unimodality in concn', 'apply unimodality in spectra (not implemented yet)', 'apply unimodality in both (not implemented yet)'};
            app.popup_unimod.ValueChangedFcn = createCallbackFcn(app, @popup_unimod_Callback, true);
            app.popup_unimod.Tag = 'popup_unimod';
            app.popup_unimod.Enable = 'off';
            app.popup_unimod.Value = 'default: do not apply unimodality constraints';
            app.popup_speceq = uidropdown(app.uipanel5);
            app.popup_speceq.Items = {'default: do not apply equality constraints', 'apply equality constraints', 'apply selectivity constraints (<=)'};
            app.popup_speceq.ValueChangedFcn = createCallbackFcn(app, @popup_speceq_Callback, true);
            app.popup_speceq.Tag = 'popup_speceq';
            app.popup_speceq.Enable = 'off';
            app.popup_speceq.Value = 'default: do not apply equality constraints';
            app.popup_conceq = uidropdown(app.uipanel5);
            app.popup_conceq.Items = {'default: do not apply equality constraints', 'apply equality constraints', 'apply selectivity constraints (<=)'};
            app.popup_conceq.ValueChangedFcn = createCallbackFcn(app, @popup_conceq_Callback, true);
            app.popup_conceq.Tag = 'popup_conceq';
            app.popup_conceq.Enable = 'off';
            app.popup_conceq.Value = 'default: do not apply equality constraints';
            app.popup_trilin = uidropdown(app.uipanel5);
            app.popup_trilin.Items = {'default: single data matrix = no three-way data', 'three-way non-trilinear data', 'three-way trilinear data'};
            app.popup_trilin.ValueChangedFcn = createCallbackFcn(app, @popup_trilin_Callback, true);
            app.popup_trilin.Tag = 'popup_trilin';
            app.popup_trilin.Enable = 'off';
            app.popup_trilin.Value = 'default: single data matrix = no three-way data';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Optional Parameters';
            app.uipanel3.Tag = 'uipanel3';
            app.text5 = uilabel(app.uipanel3);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Rotation Matrix';
            app.text7 = uilabel(app.uipanel3);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Known Concentrations';
            app.text22 = uilabel(app.uipanel3);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Number of experiments';
            app.edit_nr = uieditfield(app.uipanel3, 'text');
            app.edit_nr.ValueChangedFcn = createCallbackFcn(app, @edit_nr_Callback, true);
            app.edit_nr.Tag = 'edit_nr';
            app.edit_nr.Enable = 'off';
            app.edit_nr.Value = '1';
            app.check_plots = uicheckbox(app.uipanel3);
            app.check_plots.ValueChangedFcn = createCallbackFcn(app, @check_plots_Callback, true);
            app.check_plots.Tag = 'check_plots';
            app.check_plots.Text = 'Show intermediate plots';
            app.check_plots.Value = true;
            app.text24 = uilabel(app.uipanel3);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Optimization parameters';
            app.push_optparam = uibutton(app.uipanel3, 'push');
            app.push_optparam.ButtonPushedFcn = createCallbackFcn(app, @push_optparam_Callback, true);
            app.push_optparam.Tag = 'push_optparam';
            app.push_optparam.Text = 'Change';
            app.text25 = uilabel(app.uipanel3);
            app.text25.Tag = 'text25';
            app.text25.Text = '* Modification of default values only recommended for expert users';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Known Spectra';
            app.text26 = uilabel(app.uipanel3);
            app.text26.Tag = 'text26';
            app.text26.Text = '* Only if three-way data is selected in the trilinearity constraint';
            app.push_isp = uibutton(app.uipanel3, 'push');
            app.push_isp.ButtonPushedFcn = createCallbackFcn(app, @push_isp_Callback, true);
            app.push_isp.Tag = 'push_isp';
            app.push_isp.Enable = 'off';
            app.push_isp.Text = 'Go';
            app.popup_knownS = uidropdown(app.uipanel3);
            app.popup_knownS.Items = {'select a variable'};
            app.popup_knownS.ValueChangedFcn = createCallbackFcn(app, @popup_knownS_Callback, true);
            app.popup_knownS.Tag = 'popup_knownS';
            app.popup_knownS.Enable = 'off';
            app.popup_knownS.Value = 'select a variable';
            app.popup_knownC = uidropdown(app.uipanel3);
            app.popup_knownC.Items = {'select a variable or "use isp matrix"', 'use isp matrix'};
            app.popup_knownC.ValueChangedFcn = createCallbackFcn(app, @popup_knownC_Callback, true);
            app.popup_knownC.Tag = 'popup_knownC';
            app.popup_knownC.Enable = 'off';
            app.popup_knownC.Value = 'select a variable or "use isp matrix"';
            app.popup_rotation = uidropdown(app.uipanel3);
            app.popup_rotation.Items = {'default is t=eye(nsign) or select a variable'};
            app.popup_rotation.ValueChangedFcn = createCallbackFcn(app, @popup_rotation_Callback, true);
            app.popup_rotation.Tag = 'popup_rotation';
            app.popup_rotation.Value = 'default is t=eye(nsign) or select a variable';
            app.push_cancel = uibutton(app.figure1, 'push');
            app.push_cancel.ButtonPushedFcn = createCallbackFcn(app, @push_cancel_Callback, true);
            app.push_cancel.Tag = 'push_cancel';
            app.push_cancel.Text = 'Cancel';
            app.push_opt = uibutton(app.figure1, 'push');
            app.push_opt.ButtonPushedFcn = createCallbackFcn(app, @push_opt_Callback, true);
            app.push_opt.Tag = 'push_opt';
            app.push_opt.Text = 'Optimize';
            app.text23 = uilabel(app.figure1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'Results are saved in mcr_bands.results structure';
            app.check_results = uicheckbox(app.figure1);
            app.check_results.ValueChangedFcn = createCallbackFcn(app, @check_results_Callback, true);
            app.check_results.Tag = 'check_results';
            app.check_results.Text = 'Save also the results in the WS in a structure called ...';
            app.edit_results = uieditfield(app.figure1, 'text');
            app.edit_results.ValueChangedFcn = createCallbackFcn(app, @edit_results_Callback, true);
            app.edit_results.Tag = 'edit_results';
            app.edit_results.Enable = 'off';
            app.push_reset = uibutton(app.figure1, 'push');
            app.push_reset.ButtonPushedFcn = createCallbackFcn(app, @push_reset_Callback, true);
            app.push_reset.Tag = 'push_reset';
            app.push_reset.Text = 'Reset';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mcrbandsg_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)mcrbandsg_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end