classdef norm_old_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        push_close  matlab.ui.control.Button
        push_print  matlab.ui.control.Button
        text1       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function norm_old_OpeningFcn(app, varargin)
            % --- Executes just before norm_old is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to norm_old (see VARARGIN)
            
            % Choose default command line output for norm_old
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            close;
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            
            disp('**********************************************************');
            disp('Summary of the evaluation of final norms of band solutions');
            disp('**********************************************************');
            
            fband=evalin('base','mcr_bands.Results.fband');
            tband=evalin('base','mcr_bands.Results.tband');
            tbandsvd=evalin('base','mcr_bands.Results.tbandsvd');
            nsign=evalin('base','mcr_bands.temp.nsign');
            
            ij=1;ik=1;il=1;
            
            for i=1:nsign*2,
                disp('mcr and svd t values and corrsponding f optim value for species'),disp(il);
                if mod(i,2)==0, disp('min');il=il+1;else disp('max'), end
                disp('tband mcr');disp(tband(ij:ij+nsign-1,:));disp('tband svd');disp(tbandsvd(ij:ij+nsign-1,:));disp('foptim');disp(fband(ik));
                disp(' ')
                ij=ij+nsign;ik=ik+1;
            end
            
            close;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Warning! Matlab version';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = {'The GUI interface for this option is only available for '; 'MATLAB versions newer than R2008A. As you have '; 'an older version, you only can print this information '; 'in the command line. '; ''; 'This information can be found at variables: '; ' - mcr_bands.Results.fband'; ' - mcr_bands.Results.tband'; ' - mcr_bands.Results.tbandsvd'; ''; 'Do you want to print it in the command line?'};
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Text = 'Print';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = norm_old_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)norm_old_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end