classdef normgui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        popupmenu1    matlab.ui.control.DropDown
        edit_diff     matlab.ui.control.EditField
        text7         matlab.ui.control.Label
        push_print    matlab.ui.control.Button
        push_copy     matlab.ui.control.Button
        edit_finic    matlab.ui.control.EditField
        text_finic    matlab.ui.control.Label
        text5         matlab.ui.control.Label
        text4         matlab.ui.control.Label
        push_back     matlab.ui.control.Button
        text3         matlab.ui.control.Label
        text2         matlab.ui.control.Label
        uipanel3      matlab.ui.container.Panel
        taula_minSVD  matlab.ui.control.Table
        taula_minMCR  matlab.ui.control.Table
        edit_min      matlab.ui.control.EditField
        uipanel1      matlab.ui.container.Panel
        taula_maxSVD  matlab.ui.control.Table
        taula_maxMCR  matlab.ui.control.Table
        edit_max      matlab.ui.control.EditField
        text1         matlab.ui.control.Label
    end

    
    methods (Access = private)
        % Update components that require runtime configuration
        function addRuntimeConfigurations(app)
            
            % Set component properties that require runtime configuration
            app.taula_maxMCR.ColumnFormat = {[] [] []};
            app.taula_maxSVD.ColumnFormat = {[] [] []};
            app.taula_minMCR.ColumnFormat = {[] [] []};
            app.taula_minSVD.ColumnFormat = {[] [] []};
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function normgui_OpeningFcn(app, varargin)
            % --- Executes just before normgui is made visible.
            
            % Add runtime required configuration - Added by Migration Tool
            addRuntimeConfigurations(app);
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to normgui (see VARARGIN)
            
            % Choose default command line output for normgui
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            % popupmenu1_CreateFcn
            dim=evalin('base','mcr_bands.temp.nsign');
            j=2;
            llistannc(1)={'select a species...'};
            for i=1:dim
                llistnnc=['Species nr. ',num2str(i)];
                llistannc(j)={llistnnc};
                j=j+1;
            end
            
            set(app.popupmenu1,'string',llistannc)
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            
            valorpop=get(hObject,'Value')-1;
            
            fband=evalin('base','mcr_bands.Results.fband');
            finic=evalin('base','mcr_bands.Results.finic');
            tband=evalin('base','mcr_bands.Results.tband');
            tbandsvd=evalin('base','mcr_bands.Results.tbandsvd');
            nsign=evalin('base','mcr_bands.temp.nsign');
            [rows,cols]=size(tband);
            
            if valorpop == 0
                set(handles.edit_max,'String',' ');
                set(handles.edit_min,'String',' ');
                set(handles.edit_finic,'String',' ');
                set(handles.edit_diff,'String',' ');
                set(handles.taula_maxMCR,'Data',[]);
                set(handles.taula_maxSVD,'Data',[]);
                set(handles.taula_minMCR,'Data',[]);
                set(handles.taula_minSVD,'Data',[]);
            
            
            else
                %% MAX F
                format short e
                valor=num2str(fband(1,valorpop),4);
                set(handles.edit_max,'String',valor);
            
                %% MIN F
                format short e
                valor=num2str(fband(2,valorpop),4);
                set(handles.edit_min,'String',valor);
            
                %% DIFF F
                format short e
                valor=num2str([fband(1,valorpop)-fband(2,valorpop)],4);
                set(handles.edit_diff,'String',valor);
            
                %% MAX MCR
                format short e
                data=tband([1+((valorpop-1)*rows/nsign):((valorpop-1)*nsign)+(valorpop*nsign)],:);
                set(handles.taula_maxMCR,'Data',data);
            
                %% MIN MCR
                format short e
                data=tband([((valorpop-1)*nsign)+(valorpop*nsign)+1:2*valorpop*nsign],:);
                set(handles.taula_minMCR,'Data',data);
            
                %% MAX SVD
                format short e
                data=tbandsvd([1+((valorpop-1)*rows/nsign):((valorpop-1)*nsign)+(valorpop*nsign)],:);
                set(handles.taula_maxSVD,'Data',data);
            
                %% MIN SVD
                format short e
                data=tbandsvd([((valorpop-1)*nsign)+(valorpop*nsign)+1:2*valorpop*nsign],:);
                set(handles.taula_minSVD,'Data',data);
                %% F INIC
                format short e
                valor=num2str(finic(1,valorpop),4);
                set(handles.edit_finic,'String',valor);
            end
        end

        % Button pushed function: push_back
        function push_back_Callback(app, event)
            % --- Executes on button press in push_back.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_back (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            delete(handles.figure1);
        end

        % Button pushed function: push_copy
        function push_copy_Callback(app, event)
            % --- Executes on button press in push_copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            print -dbitmap;
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf,'PaperPositionMode','auto');
            print('MCRBANDS_NORMGUI','-dpdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Values of the optimization function';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Optimized function (component contribution) values';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Maximum';
            app.uipanel1.Tag = 'uipanel1';
            app.edit_max = uieditfield(app.uipanel1, 'text');
            app.edit_max.Tag = 'edit_max';
            app.taula_maxMCR = uitable(app.uipanel1);
            app.taula_maxMCR.ColumnEditable = [false false false];
            app.taula_maxMCR.Tag = 'taula_maxMCR';
            app.taula_maxSVD = uitable(app.uipanel1);
            app.taula_maxSVD.ColumnEditable = [false false false];
            app.taula_maxSVD.Tag = 'taula_maxSVD';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Minimum';
            app.uipanel3.Tag = 'uipanel3';
            app.edit_min = uieditfield(app.uipanel3, 'text');
            app.edit_min.Tag = 'edit_min';
            app.taula_minMCR = uitable(app.uipanel3);
            app.taula_minMCR.ColumnEditable = [false false false];
            app.taula_minMCR.Tag = 'taula_minMCR';
            app.taula_minSVD = uitable(app.uipanel3);
            app.taula_minSVD.ColumnEditable = [false false false];
            app.taula_minSVD.Tag = 'taula_minSVD';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'F value for the optimization';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Species to be considered';
            app.push_back = uibutton(app.figure1, 'push');
            app.push_back.ButtonPushedFcn = createCallbackFcn(app, @push_back_Callback, true);
            app.push_back.Tag = 'push_back';
            app.push_back.Text = 'Close';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Optimized t valued - MCR';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Optimized t valued - SVD';
            app.text_finic = uilabel(app.figure1);
            app.text_finic.Tag = 'text_finic';
            app.text_finic.Text = 'F value for the  species in the initial solution';
            app.edit_finic = uieditfield(app.figure1, 'text');
            app.edit_finic.Tag = 'edit_finic';
            app.push_copy = uibutton(app.figure1, 'push');
            app.push_copy.ButtonPushedFcn = createCallbackFcn(app, @push_copy_Callback, true);
            app.push_copy.Tag = 'push_copy';
            app.push_copy.Text = 'Copy';
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Text = 'Print';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Difference between fmax and fmin for the considered species';
            app.edit_diff = uieditfield(app.figure1, 'text');
            app.edit_diff.Tag = 'edit_diff';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'select a species...', 'Species nr. 1', 'Species nr. 2'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'select a species...';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = normgui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)normgui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end