classdef normgui_old_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        edit4        matlab.ui.control.EditField
        text8        matlab.ui.control.Label
        popupmenu1   matlab.ui.control.DropDown
        push_print   matlab.ui.control.Button
        push_copy    matlab.ui.control.Button
        edit_finic   matlab.ui.control.EditField
        text6        matlab.ui.control.Label
        text5        matlab.ui.control.Label
        text4        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        uipanel2     matlab.ui.container.Panel
        edit_min     matlab.ui.control.EditField
        uipanel1     matlab.ui.container.Panel
        edit_max     matlab.ui.control.EditField
        text2        matlab.ui.control.Label
        pushbutton1  matlab.ui.control.Button
        text1        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function normgui_old_OpeningFcn(app, varargin)
            % --- Executes just before normgui_old is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to normgui_old (see VARARGIN)
            
            % Choose default command line output for normgui_old
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: popupmenu1
        function popupmenu1_Callback(app, event)
            % --- Executes on selection change in popupmenu1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popupmenu1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popupmenu1 contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popupmenu1
            
            valorpop=get(hObject,'Value')-1;
            
            fband=evalin('base','mcr_bands.Results.fband');
            finic=evalin('base','mcr_bands.Results.finic');
            tband=evalin('base','mcr_bands.Results.tband');
            tbandsvd=evalin('base','mcr_bands.Results.tbandsvd');
            nsign=evalin('base','mcr_bands.temp.nsign');
            [rows,cols]=size(tband);
            
            if valorpop == 0
                set(handles.edit_max,'String',' ');
                set(handles.edit_min,'String',' ');
                set(handles.edit_finic,'String',' ');
            else
                %% MAX F
                format short e
                valor=num2str(fband(1,valorpop),4);
                set(handles.edit_max,'String',valor);
            
                %% MIN F
                format short e
                valor=num2str(fband(2,valorpop),4);
                set(handles.edit_min,'String',valor);
                %% DIFF F
                format short e
                valor=num2str([fband(1,valorpop)-fband(2,valorpop)],4);
                set(handles.edit4,'String',valor);
            
                %% MAX MCR
                format short e
                data=tband([1+((valorpop-1)*rows/nsign):((valorpop-1)*nsign)+(valorpop*nsign)],:);
                data=round(10000*data)/10000;
                tablesize = size(data);    % This demo data is 24-by-3
                for i=1:tablesize;
                    colnames{1,i} = i;
                    colfmt{1,i}='numeric';
                    coledit=false;
                    colwdt(1,i)= 40;
                end
                htable1 = uitable(data,colnames,'ColumnWidth',60,'Position', [160 250 200 100]);
            
                %% MIN MCR
                format short e
                data=tband([((valorpop-1)*nsign)+(valorpop*nsign)+1:2*valorpop*nsign],:);
                data=round(10000*data)/10000;
                %     set(handles.taula_minMCR,'Data',data);
                htable2 = uitable(data,colnames,'ColumnWidth',60,'Position', [410 250 200 100]);
            
            
                %% MAX SVD
                format short e
                data=tbandsvd([1+((valorpop-1)*rows/nsign):((valorpop-1)*nsign)+(valorpop*nsign)],:);
                data=round(10000*data)/10000;
                %     set(handles.taula_maxSVD,'Data',data);
                htable3 = uitable(data,colnames,'ColumnWidth',60,'Position', [160 120 200 100]);
            
                %% MIN SVD
                format short e
                data=tbandsvd([((valorpop-1)*nsign)+(valorpop*nsign)+1:2*valorpop*nsign],:);
                data=round(10000*data)/10000;
                %     set(handles.taula_minSVD,'Data',data);
                htable4 = uitable(data,colnames,'ColumnWidth',60,'Position', [410 120 200 100]);
                %% F INIC
                format short e
                valor=num2str(finic(1,valorpop),4);
                set(handles.edit_finic,'String',valor);
            end
        end

        % Button pushed function: push_copy
        function push_copy_Callback(app, event)
            % --- Executes on button press in push_copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            print -dbitmap;
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf,'PaperPositionMode','auto');
            print('MCRBANDS_NORM','-dpdf');
        end

        % Button pushed function: pushbutton1
        function pushbutton1_Callback(app, event)
            % --- Executes on button press in pushbutton1.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to pushbutton1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            delete(handles.figure1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Values of the optimization function';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Optimized function (component contribution) values';
            app.pushbutton1 = uibutton(app.figure1, 'push');
            app.pushbutton1.ButtonPushedFcn = createCallbackFcn(app, @pushbutton1_Callback, true);
            app.pushbutton1.Tag = 'pushbutton1';
            app.pushbutton1.Text = 'Close';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Species to be considered';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Maximum';
            app.uipanel1.Tag = 'uipanel1';
            app.edit_max = uieditfield(app.uipanel1, 'text');
            app.edit_max.Tag = 'edit_max';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Minimum';
            app.uipanel2.Tag = 'uipanel2';
            app.edit_min = uieditfield(app.uipanel2, 'text');
            app.edit_min.Tag = 'edit_min';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = {'F value for the'; ' optimization'};
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = {'Optimized t value'; '-'; 'MCR'};
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Optimized t value'; '-'; 'SVD'};
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'F value for the  species in the initial solution';
            app.edit_finic = uieditfield(app.figure1, 'text');
            app.edit_finic.Tag = 'edit_finic';
            app.push_copy = uibutton(app.figure1, 'push');
            app.push_copy.ButtonPushedFcn = createCallbackFcn(app, @push_copy_Callback, true);
            app.push_copy.Tag = 'push_copy';
            app.push_copy.Text = 'Copy';
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Text = 'Print';
            app.popupmenu1 = uidropdown(app.figure1);
            app.popupmenu1.Items = {'select a species...', 'Species nr. 1', 'Species nr. 2'};
            app.popupmenu1.ValueChangedFcn = createCallbackFcn(app, @popupmenu1_Callback, true);
            app.popupmenu1.Tag = 'popupmenu1';
            app.popupmenu1.Value = 'select a species...';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Difference between fmax and fmin for the considered species';
            app.edit4 = uieditfield(app.figure1, 'text');
            app.edit4.Tag = 'edit4';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = normgui_old_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)normgui_old_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end