classdef push_isp_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        popup_ispm    matlab.ui.control.DropDown
        push_cont     matlab.ui.control.Button
        push_print    matlab.ui.control.Button
        text4         matlab.ui.control.Label
        push_OK       matlab.ui.control.Button
        push_back     matlab.ui.control.Button
        text2         matlab.ui.control.Label
        text1         matlab.ui.control.Label
        uipanel1      matlab.ui.container.Panel
        edit_ispvar   matlab.ui.control.EditField
        text5         matlab.ui.control.Label
        text3         matlab.ui.control.Label
        popup_ispvar  matlab.ui.control.DropDown
        checkbox_num  matlab.ui.control.CheckBox
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function push_isp_OpeningFcn(app, varargin)
            % --- Executes just before push_isp is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to push_isp (see VARARGIN)
            
            % Choose default command line output for push_isp
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            % popup_ispm_CreateFcn
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=2;
            lsv(1)={'select a variable'};
            for i=1:aa
                csize=length(lsvar(i).class);
                if csize==6
                    if lsvar(i).class=='double'
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end
                end
            end
            
            set(app.popup_ispm,'string',lsv)

            % popup_ispvar_CreateFcn
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=3;
            lsv(1)={'select a variable'};
            lsv(2)={'write in the editable box'}; 
            for i=1:aa
                csize=length(lsvar(i).class);
                if csize==6
                    if lsvar(i).class=='double'
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end
                end
            end
            
            set(app.popup_ispvar,'string',lsv)
        end

        % Value changed function: checkbox_num
        function checkbox_num_Callback(app, event)
            % --- Executes on button press in checkbox_num.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to checkbox_num (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            equalrows=get(hObject,'Value');
            assignin('base','equalrows',equalrows);
            
            if equalrows==1;
                [nrtotal,nspecies]=evalin('base','size(mcr_bands.Data.conc);');
                [nrexperiments,nspecies]=evalin('base','size(mcr_bands.Optional.Cequality.ispm);');
                Nrrows=nrtotal/nrexperiments;
                assignin('base','Nrrows',Nrrows);
                assignin('base','NrRowsXMat',ones(1,nrexperiments)*Nrrows);
                evalin('base','mcr_bands.Optional.Cequality.Nrrows=Nrrows;');
                evalin('base','mcr_bands.Optional.Cequality.NrRowsXMat=NrRowsXMat;');
                set(handles.popup_ispvar,'enable','off');
                set(handles.push_OK,'enable','on');
                evalin('base','clear Nrrows NrRowsXMat');
            else
                evalin('base','mcr_bands.Optional.NrRows=[];');
                set(handles.popup_ispvar,'enable','on');
                set(handles.push_OK,'enable','off');
                end;
            evalin('base','clear equalrows ');
        end

        % Value changed function: edit_ispvar
        function edit_ispvar_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_ispvar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_ispvar as text
            %        str2double(get(hObject,'String')) returns contents of edit_ispvar as a double
            
            valors=str2num(get(hObject,'String'));
            assignin('base','valors',valors);
            evalin('base','mcr_bands.Optional.Cequality.NrRowsXMat=valors;');
            evalin('base','clear valors');
            set(handles.push_OK,'enable','on');
        end

        % Value changed function: popup_ispm
        function popup_ispm_Callback(app, event)
            % --- Executes on selection change in popup_ispm.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_ispm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_ispm contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_ispm
            
            popUp=get(handles.popup_ispm,'String');
            pU=get(handles.popup_ispm,'Value');
            
            if pU==1
                evalin('base','mcr_bands.Optional.Cequality.ispm=[];');
            else
                sel=char([popUp(pU)]);
                ispm=evalin('base',sel);
                assignin('base','ispm',ispm);
                evalin('base','mcr_bands.Optional.Cequality.ispm=ispm;');
                evalin('base','clear ispm');
            
                set(handles.checkbox_num,'enable','on','value',0);
                set(handles.popup_ispvar,'enable','on');
                set(handles.push_OK,'enable','off');
            end
        end

        % Value changed function: popup_ispvar
        function popup_ispvar_Callback(app, event)
            % Hint: get(hObject,'Value') returns toggle state of checkbox_num
            % --- Executes on selection change in popup_ispvar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_ispvar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_ispvar contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_ispvar
            
            popUp=get(handles.popup_ispvar,'String');
            pU=get(handles.popup_ispvar,'Value');
            
            if pU==1
                evalin('base','mcr_bands.Optional.Cequality.ispvar=[];');
                set(handles.edit_ispvar,'enable','off');
            elseif pU==2
                set(handles.edit_ispvar,'enable','on','string','  ');
            else
                set(handles.edit_ispvar,'enable','off');
                sel=char([popUp(pU)]);
                ispm=evalin('base',sel);
                assignin('base','ispm',ispm);
                evalin('base','mcr_bands.Optional.Cequality.ispvar=ispvar;');
                evalin('base','mcr_bands.Optional.Cequality.NrRowsXMat=ispvar;');
                evalin('base','clear ispvar');
                set(handles.push_OK,'enable','on');
            end
        end

        % Button pushed function: push_OK
        function push_OK_Callback(app, event)
            % --- Executes on button press in push_OK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_OK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)

                [nrtotal,nspecies]=evalin('base','size(mcr_bands.Data.conc);');
                [nrexperiments,nspecies]=evalin('base','size(mcr_bands.Optional.Cequality.ispm);');
                NrRowsXMat=evalin('base','mcr_bands.Optional.Cequality.NrRowsXMat;');
            
                files=[0,cumsum(NrRowsXMat)];
                nrexp=nrexperiments;
                ispmat=evalin('base','mcr_bands.Optional.Cequality.ispm;');
                csel=1./zeros(nrtotal,nspecies);
            
                for j=1:nrtotal
            
                    for k=1:nspecies
                      for test=1:nrexp
                       if (j>files(test))
                           numexp=test;
                       end
                       end
            
                    valor=ispmat(numexp,k);
            
                    if valor==1
                    elseif valor == 0
                        csel(j,k)=0;
                    end
                    end
                end
            
                assignin('base','csel',csel);
                evalin('base','mcr_bands.Optional.Cequality.cknown=csel;');
                evalin('base','clear csel');
                set(handles.push_print,'enable','on','backgroundcolor',[0 0.5 0]);
                set(handles.push_cont,'enable','on');
        end

        % Button pushed function: push_back
        function push_back_Callback(app, event)
            % --- Executes on button press in push_back.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_back (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_bands.Optional=rmfield(mcr_bands.Optional,''Cequality'');');
            evalin('base','mcr_bands.Optional.Cequality.cknown=[];'); %defaut
            close;
        end

        % Button pushed function: push_cont
        function push_cont_Callback(app, event)
            % --- Executes on button press in push_cont.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_cont (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            view_csel_App;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Generate a CSEL matrix from a ISP matrix';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.checkbox_num = uicheckbox(app.uipanel1);
            app.checkbox_num.ValueChangedFcn = createCallbackFcn(app, @checkbox_num_Callback, true);
            app.checkbox_num.Tag = 'checkbox_num';
            app.checkbox_num.Enable = 'off';
            app.checkbox_num.Text = 'All the experiments have the same number of rows';
            app.popup_ispvar = uidropdown(app.uipanel1);
            app.popup_ispvar.Items = {'select a variable', 'write in the editable box'};
            app.popup_ispvar.ValueChangedFcn = createCallbackFcn(app, @popup_ispvar_Callback, true);
            app.popup_ispvar.Tag = 'popup_ispvar';
            app.popup_ispvar.Enable = 'off';
            app.popup_ispvar.Value = 'select a variable';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Select a variable with the number of rows of each matrix or verify the chekbox if the different experiments have the same number of rows.';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = '* ex: [25 30 20 25]';
            app.edit_ispvar = uieditfield(app.uipanel1, 'text');
            app.edit_ispvar.ValueChangedFcn = createCallbackFcn(app, @edit_ispvar_Callback, true);
            app.edit_ispvar.Tag = 'edit_ispvar';
            app.edit_ispvar.Enable = 'off';
            app.edit_ispvar.Value = 'rows x mat';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Generation of a CSEL matrix from a ISP matrix ';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select the ISP matrix used in the ALS optimization';
            app.push_back = uibutton(app.figure1, 'push');
            app.push_back.ButtonPushedFcn = createCallbackFcn(app, @push_back_Callback, true);
            app.push_back.Tag = 'push_back';
            app.push_back.Text = 'Go back';
            app.push_OK = uibutton(app.figure1, 'push');
            app.push_OK.ButtonPushedFcn = createCallbackFcn(app, @push_OK_Callback, true);
            app.push_OK.Tag = 'push_OK';
            app.push_OK.Enable = 'off';
            app.push_OK.Text = 'Generate';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Generate the CSEL matrix:';
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Enable = 'off';
            app.push_print.Text = 'Show CSEL';
            app.push_cont = uibutton(app.figure1, 'push');
            app.push_cont.ButtonPushedFcn = createCallbackFcn(app, @push_cont_Callback, true);
            app.push_cont.Tag = 'push_cont';
            app.push_cont.Enable = 'off';
            app.push_cont.Text = 'Continue';
            app.popup_ispm = uidropdown(app.figure1);
            app.popup_ispm.Items = {'select a variable'};
            app.popup_ispm.ValueChangedFcn = createCallbackFcn(app, @popup_ispm_Callback, true);
            app.popup_ispm.Tag = 'popup_ispm';
            app.popup_ispm.Value = 'select a variable';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = push_isp_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)push_isp_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end