classdef multiwayC_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        push_back          matlab.ui.control.Button
        push_done          matlab.ui.control.Button
        popup_type         matlab.ui.control.DropDown
        text_type          matlab.ui.control.Label
        text1              matlab.ui.control.Label
        uipanel1           matlab.ui.container.Panel
        text_qelemNr       matlab.ui.control.Label
        edit_qelemNr       matlab.ui.control.EditField
        listbox_qelem      matlab.ui.control.ListBox
        text_qelem         matlab.ui.control.Label
        listbox_complex    matlab.ui.control.ListBox
        text_complex       matlab.ui.control.Label
        uipanel2           matlab.ui.container.Panel
        text_help          matlab.ui.control.Label
        edit_CintP         matlab.ui.control.EditField
        text_CintP         matlab.ui.control.Label
        edit_CcompNr       matlab.ui.control.EditField
        text_CcompNr       matlab.ui.control.Label
        listbox_Cinteract  matlab.ui.control.ListBox
        text_Cinteract     matlab.ui.control.Label
        edit_Cvector       matlab.ui.control.EditField
        text_Cvector       matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function multiwayC_OpeningFcn(app, varargin)
            % --- Executes just before multiwayC is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to multiwayC (see VARARGIN)
            
            % Choose default command line output for multiwayC
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_CcompNr
        function edit_CcompNr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_CcompNr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_CcompNr as text
            %        str2double(get(hObject,'String')) returns contents of edit_CcompNr as a double
            
            Ccomp=str2num(get(hObject,'String'));
            assignin('base','Ccomp',Ccomp);
            Iactiu=evalin('base','mcr_als.alsOptions.trilin.aux.Iactiu;');
            assignin('base','Iactiu',Iactiu);
            expressionX=['mcr_als.alsOptions.trilin.modeltuckc(',num2str(Iactiu),')=Ccomp;'];
            evalin('base',expressionX);
            evalin('base','clear Iactiu Ccomp');
        end

        % Value changed function: edit_CintP
        function edit_CintP_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_CintP (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_CintP as text
            %        str2double(get(hObject,'String')) returns contents of edit_CintP as a double
            
            Ccomp=str2num(get(hObject,'String'));
            assignin('base','Ccomp',Ccomp);
            Iactiu=evalin('base','mcr_als.alsOptions.trilin.aux.Iactiu;');
            assignin('base','Iactiu',Iactiu);
            expressionX=['mcr_als.alsOptions.trilin.spetuckc(',num2str(Iactiu),',:)=Ccomp;'];
            evalin('base',expressionX);
            evalin('base','clear Iactiu Ccomp');
        end

        % Value changed function: edit_Cvector
        function edit_Cvector_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_Cvector (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_Cvector as text
            %        str2double(get(hObject,'String')) returns contents of edit_Cvector as a double
            
            spetric=str2num(get(hObject,'String'));
            assignin('base','spetric',spetric);
            evalin('base','mcr_als.alsOptions.trilin.spetric=spetric;');
            evalin('base','clear spetric');
            
            set(handles.push_done,'enable','on');
        end

        % Value changed function: edit_qelemNr
        function edit_qelemNr_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_qelemNr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_qelemNr as text
            %        str2double(get(hObject,'String')) returns contents of edit_qelemNr as a double
            
            nei=str2num(get(hObject,'String'));
            assignin('base','nei',nei);
            Mactiu=evalin('base','mcr_als.alsOptions.trilin.aux.Mactiu;');
            assignin('base','Mactiu',Mactiu);
            expressionX=['mcr_als.alsOptions.trilin.ne(',num2str(Mactiu),')=nei;'];
            evalin('base',expressionX);
            evalin('base','clear Mactiu nei');
        end

        % Value changed function: listbox_Cinteract
        function listbox_Cinteract_Callback(app, event)
            % --- Executes on selection change in listbox_Cinteract.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_Cinteract (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_Cinteract contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_Cinteract
            
            Iactiu=get(hObject,'Value');
            assignin('base','Iactiu',Iactiu);
            evalin('base','mcr_als.alsOptions.trilin.aux.Iactiu=Iactiu;');
            % expressionX=['mcr_als.alsOptions.trilin.kinetic.models.model',num2str(modelActiu),'.constants.kactiu=kactiu;'];
            % evalin('base',expressionX);
            evalin('base','clear Iactiu');
            
            % change the value of the edit box by the value of the memory
            expressionX=['mcr_als.alsOptions.trilin.modeltuckc(',num2str(Iactiu),');'];
            valor=evalin('base',expressionX);
            set(handles.edit_CcompNr,'enable','on','string',num2str(valor));
            
            expressionX=['mcr_als.alsOptions.trilin.spetuckc(',num2str(Iactiu),',:);'];
            valor=evalin('base',expressionX);
            set(handles.edit_CintP,'enable','on','string',num2str(valor));
        end

        % Value changed function: listbox_complex
        function listbox_complex_Callback(app, event)
            % --- Executes on selection change in listbox_complex.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_complex (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_complex contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_complex
            
            iquadril=get(hObject,'Value')-1;
            assignin('base','iquadril',iquadril);
            evalin('base','mcr_als.alsOptions.trilin.iquadril=iquadril;');
            
            if iquadril==1
                set(handles.text_qelem,'enable','on');
                set(handles.listbox_qelem,'enable','on','value',1);
                set(handles.text_qelemNr,'enable','on');
                set(handles.edit_qelemNr,'enable','on','string','0');
            elseif iquadril==0
                set(handles.text_qelem,'enable','off');
                set(handles.listbox_qelem,'enable','off','value',1);
                set(handles.text_qelemNr,'enable','off');
                set(handles.edit_qelemNr,'enable','off','string','');
            end
            evalin('base','clear iquadril');
        end

        % Value changed function: listbox_qelem
        function listbox_qelem_Callback(app, event)
            % --- Executes on selection change in listbox_qelem.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_qelem (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_qelem contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_qelem
            
            Mactiu=get(hObject,'Value');
            assignin('base','Mactiu',Mactiu);
            evalin('base','mcr_als.alsOptions.trilin.aux.Mactiu=Mactiu;');
            % expressionX=['mcr_als.alsOptions.trilin.kinetic.models.model',num2str(modelActiu),'.constants.kactiu=kactiu;'];
            % evalin('base',expressionX);
            evalin('base','clear Mactiu');
            
            % change the value of the edit box by the value of the memory
            expressionX=['mcr_als.alsOptions.trilin.ne(',num2str(Mactiu),');'];
            valor=evalin('base',expressionX);
            set(handles.edit_qelemNr,'enable','on','string',num2str(valor));
        end

        % Value changed function: popup_type
        function popup_type_Callback(app, event)
            % --- Executes on selection change in popup_type.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_type (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_type contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_type
            
            
            ishape=get(hObject,'Value')-2;
            
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''trilin'');');
            multiway_parameters;
            
            assignin('base','ishape',ishape);
            evalin('base','mcr_als.alsOptions.trilin.ishape=ishape;');
            
            if ishape==-1 % select option
                set(handles.text_complex,'enable','off');
                set(handles.listbox_complex,'enable','off','value',1);
                set(handles.text_qelem,'enable','off');
                set(handles.listbox_qelem,'enable','off','value',1);
                set(handles.text_qelemNr,'enable','off');
                set(handles.edit_qelemNr,'enable','off','string',' ');
                set(handles.text_Cvector,'enable','off');
                set(handles.edit_Cvector,'enable','off','string',' ');
                set(handles.text_Cinteract,'enable','off');
                set(handles.listbox_Cinteract,'enable','off','value',1);
                set(handles.text_CcompNr,'enable','off');
                set(handles.edit_CcompNr,'enable','off','string',' ');
                set(handles.text_CintP,'enable','off');
                set(handles.edit_CintP,'enable','off','string',' ');
                set(handles.push_done,'enable','off');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''trilin'');');
                evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
            elseif ishape==0
                set(handles.text_complex,'enable','off');
                set(handles.listbox_complex,'enable','off','value',1);
                set(handles.text_qelem,'enable','off');
                set(handles.listbox_qelem,'enable','off','value',1);
                set(handles.text_qelemNr,'enable','off');
                set(handles.edit_qelemNr,'enable','off','string',' ');
                set(handles.text_Cvector,'enable','off');
                set(handles.edit_Cvector,'enable','off','string',' ');
                set(handles.text_Cinteract,'enable','off');
                set(handles.listbox_Cinteract,'enable','off','value',1);
                set(handles.text_CcompNr,'enable','off');
                set(handles.edit_CcompNr,'enable','off','string',' ');
                set(handles.text_CintP,'enable','off');
                set(handles.edit_CintP,'enable','off','string',' ');
                set(handles.push_done,'enable','on');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''trilin'');');
                % antic
                % evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
                % nou
                evalin('base','mcr_als.alsOptions.trilin.appTril=2;'); % cas bilinear
                evalin('base','mcr_als.alsOptions.trilin.iquadril=0;');
            elseif ishape==1
                set(handles.text_complex,'enable','on');
                set(handles.listbox_complex,'enable','on','value',1);
                set(handles.text_qelem,'enable','off');
                set(handles.listbox_qelem,'enable','off','value',1);
                set(handles.text_qelemNr,'enable','off');
                set(handles.edit_qelemNr,'enable','off','string',' ');
                set(handles.text_Cvector,'enable','on');
            
                uns=ones(1,evalin('base','mcr_als.CompNumb.nc;'));
                set(handles.edit_Cvector,'enable','inactive','string',num2str(uns));
                assignin('base','spetric',uns);
                evalin('base','mcr_als.alsOptions.trilin.spetric=spetric;');
                evalin('base','clear spetric');
            
                set(handles.text_Cinteract,'enable','off');
                set(handles.listbox_Cinteract,'enable','off','value',1);
                set(handles.text_CcompNr,'enable','off');
                set(handles.edit_CcompNr,'enable','off','string',' ');
                set(handles.text_CintP,'enable','off');
                set(handles.edit_CintP,'enable','off','string',' ');
                set(handles.push_done,'enable','on');
                % values
                evalin('base','mcr_als.alsOptions.trilin.iquadril=0;');
                evalin('base','mcr_als.alsOptions.trilin.appTril=1;');
            elseif ishape==2
                set(handles.text_complex,'enable','on');
                set(handles.listbox_complex,'enable','on','value',1);
                set(handles.text_qelem,'enable','off');
                set(handles.listbox_qelem,'enable','off','value',1);
                set(handles.text_qelemNr,'enable','off');
                set(handles.edit_qelemNr,'enable','off','string',' ');
                set(handles.text_Cvector,'enable','on');
            
                uns=ones(1,evalin('base','mcr_als.CompNumb.nc;'));
                set(handles.edit_Cvector,'enable','inactive','string',num2str(uns));
            
                assignin('base','spetric',uns);
                evalin('base','mcr_als.alsOptions.trilin.spetric=spetric;');
                evalin('base','clear spetric');
            
                set(handles.text_Cinteract,'enable','off');
                set(handles.listbox_Cinteract,'enable','off','value',1);
                set(handles.text_CcompNr,'enable','off');
                set(handles.edit_CcompNr,'enable','off','string',' ');
                set(handles.text_CintP,'enable','off');
                set(handles.edit_CintP,'enable','off','string',' ');
                set(handles.push_done,'enable','on');
                % values
                evalin('base','mcr_als.alsOptions.trilin.iquadril=0;');
                evalin('base','mcr_als.alsOptions.trilin.appTril=1;');
            elseif ishape==3
                set(handles.text_complex,'enable','on');
                set(handles.listbox_complex,'enable','on','value',1);
                set(handles.text_qelem,'enable','off');
                set(handles.listbox_qelem,'enable','off','value',1);
                set(handles.text_qelemNr,'enable','off');
                set(handles.edit_qelemNr,'enable','off','string',' ');
                set(handles.text_Cvector,'enable','on');
                set(handles.edit_Cvector,'enable','on','string',' ');
                set(handles.text_Cinteract,'enable','off');
                set(handles.listbox_Cinteract,'enable','off','value',1);
                set(handles.text_CcompNr,'enable','off');
                set(handles.edit_CcompNr,'enable','off','string',' ');
                set(handles.text_CintP,'enable','off');
                set(handles.edit_CintP,'enable','off','string',' ');
                set(handles.push_done,'enable','off');
                % values
                evalin('base','mcr_als.alsOptions.trilin.iquadril=0;');
                evalin('base','mcr_als.alsOptions.trilin.appTril=1;');
            elseif ishape==4
                set(handles.text_complex,'enable','off');
                set(handles.listbox_complex,'enable','off','value',1);
                set(handles.text_qelem,'enable','off');
                set(handles.listbox_qelem,'enable','off','value',1);
                set(handles.text_qelemNr,'enable','off');
                set(handles.edit_qelemNr,'enable','off','string',' ');
                set(handles.text_Cvector,'enable','off');
                set(handles.edit_Cvector,'enable','off','string',' ');
                set(handles.text_Cinteract,'enable','on');
                set(handles.listbox_Cinteract,'enable','on','value',1);
            
                set(handles.text_CcompNr,'enable','on');
                expressionX=['mcr_als.alsOptions.trilin.modeltuckc(1);'];
                valor=evalin('base',expressionX);
                set(handles.edit_CcompNr,'enable','on','string',num2str(valor));
            
                set(handles.text_CintP,'enable','on');
                expressionX=['mcr_als.alsOptions.trilin.spetuckc(1,:);'];
                valor=evalin('base',expressionX);
                set(handles.edit_CintP,'enable','on','string',num2str(valor));
            
                set(handles.push_done,'enable','on');
                % values
                evalin('base','mcr_als.alsOptions.trilin.iquadril=0;');
                evalin('base','mcr_als.alsOptions.trilin.appTril=1;');
            end
            evalin('base','clear ishape');
        end

        % Button pushed function: push_back
        function push_back_Callback(app, event)
            % --- Executes on button press in push_back.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_back (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''trilin'');');
                evalin('base','mcr_als.alsOptions.trilin.appTril=0;');
                close;
        end

        % Button pushed function: push_done
        function push_done_Callback(app, event)
            % --- Executes on button press in push_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_done (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            close;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Multiway Options';
            app.figure1.Tag = 'figure1';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Rows';
            app.uipanel2.Tag = 'uipanel2';
            app.text_Cvector = uilabel(app.uipanel2);
            app.text_Cvector.Tag = 'text_Cvector';
            app.text_Cvector.Enable = 'off';
            app.text_Cvector.Text = 'Write the vector of trilinear C profiles';
            app.edit_Cvector = uieditfield(app.uipanel2, 'text');
            app.edit_Cvector.ValueChangedFcn = createCallbackFcn(app, @edit_Cvector_Callback, true);
            app.edit_Cvector.Tag = 'edit_Cvector';
            app.edit_Cvector.Enable = 'off';
            app.text_Cinteract = uilabel(app.uipanel2);
            app.text_Cinteract.Tag = 'text_Cinteract';
            app.text_Cinteract.Enable = 'off';
            app.text_Cinteract.Text = 'Interaction models. For each model:';
            app.listbox_Cinteract = uilistbox(app.uipanel2);
            app.listbox_Cinteract.Items = {'Rows', 'Columns', 'Slices'};
            app.listbox_Cinteract.ValueChangedFcn = createCallbackFcn(app, @listbox_Cinteract_Callback, true);
            app.listbox_Cinteract.Tag = 'listbox_Cinteract';
            app.listbox_Cinteract.Enable = 'off';
            app.listbox_Cinteract.Value = 'Rows';
            app.text_CcompNr = uilabel(app.uipanel2);
            app.text_CcompNr.Tag = 'text_CcompNr';
            app.text_CcompNr.Enable = 'off';
            app.text_CcompNr.Text = 'Write the Nr. of components:';
            app.edit_CcompNr = uieditfield(app.uipanel2, 'text');
            app.edit_CcompNr.ValueChangedFcn = createCallbackFcn(app, @edit_CcompNr_Callback, true);
            app.edit_CcompNr.Tag = 'edit_CcompNr';
            app.edit_CcompNr.Enable = 'off';
            app.text_CintP = uilabel(app.uipanel2);
            app.text_CintP.Tag = 'text_CintP';
            app.text_CintP.Enable = 'off';
            app.text_CintP.Text = 'Interacting profiles in this mode: *';
            app.edit_CintP = uieditfield(app.uipanel2, 'text');
            app.edit_CintP.ValueChangedFcn = createCallbackFcn(app, @edit_CintP_Callback, true);
            app.edit_CintP.Tag = 'edit_CintP';
            app.edit_CintP.Enable = 'off';
            app.edit_CintP.Tooltip = 'ex [1,1,3, 0] species 2 interacts with 1, species 3 does not interact but it is trilinear, forth is not trilinear nor interact...';
            app.text_help = uilabel(app.uipanel2);
            app.text_help.Tag = 'text_help';
            app.text_help.Enable = 'off';
            app.text_help.Text = '* Edit if interactions are present';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'General Options';
            app.uipanel1.Tag = 'uipanel1';
            app.text_complex = uilabel(app.uipanel1);
            app.text_complex.Tag = 'text_complex';
            app.text_complex.Enable = 'off';
            app.text_complex.Text = 'Model complexity:';
            app.listbox_complex = uilistbox(app.uipanel1);
            app.listbox_complex.Items = {'trilinear', 'quadrilinear'};
            app.listbox_complex.ValueChangedFcn = createCallbackFcn(app, @listbox_complex_Callback, true);
            app.listbox_complex.Tag = 'listbox_complex';
            app.listbox_complex.Enable = 'off';
            app.listbox_complex.Value = 'trilinear';
            app.text_qelem = uilabel(app.uipanel1);
            app.text_qelem.Tag = 'text_qelem';
            app.text_qelem.Enable = 'off';
            app.text_qelem.Text = 'Nr. of elements in row modes (only quadrilinear models)';
            app.listbox_qelem = uilistbox(app.uipanel1);
            app.listbox_qelem.Items = {'Mode 1', 'Mode 3', 'Mode 4'};
            app.listbox_qelem.ValueChangedFcn = createCallbackFcn(app, @listbox_qelem_Callback, true);
            app.listbox_qelem.Tag = 'listbox_qelem';
            app.listbox_qelem.Enable = 'off';
            app.listbox_qelem.Value = 'Mode 1';
            app.edit_qelemNr = uieditfield(app.uipanel1, 'text');
            app.edit_qelemNr.ValueChangedFcn = createCallbackFcn(app, @edit_qelemNr_Callback, true);
            app.edit_qelemNr.Tag = 'edit_qelemNr';
            app.edit_qelemNr.Enable = 'off';
            app.text_qelemNr = uilabel(app.uipanel1);
            app.text_qelemNr.Tag = 'text_qelemNr';
            app.text_qelemNr.Enable = 'off';
            app.text_qelemNr.Text = 'Write the number';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Multiway options';
            app.text_type = uilabel(app.figure1);
            app.text_type.Tag = 'text_type';
            app.text_type.Text = 'Type of model constraints:';
            app.popup_type = uidropdown(app.figure1);
            app.popup_type.Items = {'select type of model...', 'Non multilinear, only bilinear', 'multilinear, equal shape and synchronization (all species)', 'multilinear without synchronization (all species)', 'multiinear and synchronization for only some species', 'dif. nr. of components in the three modes, interaction models'};
            app.popup_type.ValueChangedFcn = createCallbackFcn(app, @popup_type_Callback, true);
            app.popup_type.Tag = 'popup_type';
            app.popup_type.Value = 'select type of model...';
            app.push_done = uibutton(app.figure1, 'push');
            app.push_done.ButtonPushedFcn = createCallbackFcn(app, @push_done_Callback, true);
            app.push_done.Tag = 'push_done';
            app.push_done.Enable = 'off';
            app.push_done.Text = 'Done';
            app.push_back = uibutton(app.figure1, 'push');
            app.push_back.ButtonPushedFcn = createCallbackFcn(app, @push_back_Callback, true);
            app.push_back.Tag = 'push_back';
            app.push_back.Text = 'Back';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = multiwayC_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)multiwayC_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end