classdef optim_info_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                   matlab.ui.Figure
        push_close                matlab.ui.control.Button
        uipanel2                  matlab.ui.container.Panel
        ConstraintSettingsButton  matlab.ui.control.Button
        push_images               matlab.ui.control.Button
        push_kinetic              matlab.ui.control.Button
        push_correl               matlab.ui.control.Button
        push_3p                   matlab.ui.control.Button
        uipanel1                  matlab.ui.container.Panel
        text4                     matlab.ui.control.Label
        text3                     matlab.ui.control.Label
        popup_graph               matlab.ui.control.DropDown
        text2                     matlab.ui.control.Label
        axes1                     matlab.ui.control.UIAxes
        text1                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function optim_info_OpeningFcn(app, varargin)
            % --- Executes just before optim_info is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to optim_info (see VARARGIN)
            
            % Choose default command line output for optim_info
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes optim_info wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            appCorrel=evalin('base','mcr_als.alsOptions.correlation.appCorrelation;');
            if appCorrel==1
                set(handles.push_correl,'enable','on');
            else
                set(handles.push_correl,'enable','off');
            end
            
            appTril=evalin('base','mcr_als.alsOptions.trilin.appTril;');
            if appTril>0
                set(handles.push_3p,'enable','on');
            else
                set(handles.push_3p,'enable','off');
            end
            
            appKin=evalin('base','mcr_als.alsOptions.kinetic.appKinetic;');
            if appKin>0
                set(handles.push_kinetic,'enable','on');
            else
                set(handles.push_kinetic,'enable','off');
            end
        end

        % Value changed function: popup_graph
        function popup_graph_Callback(app, event)
            % --- Executes on selection change in popup_graph.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_graph (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_graph contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_graph
            
            valor=get(hObject,'Value')-2;
            
            if valor==-1
            
                % nothing happen
                axes(handles.axes1);
                cla;
                axis([0 1 0 1]);
                title('');
                xlabel('');
                ylabel('');
            
            elseif valor==0
            
                % plot R2
                plot_R2=evalin('base','mcr_als.alsOptions.resultats.plot_R2;');
                optim_niter=evalin('base','mcr_als.alsOptions.resultats.optim_niter;');
                plot_optim_R2=evalin('base','mcr_als.alsOptions.resultats.plot_optim_R2;');
                axes(handles.axes1);
                X=length(plot_R2);
                plot(plot_R2,'.-');axis([1 X min(plot_R2) max(plot_R2)]);title('Evolution of R2');
                xlabel('Number of iterations');
                ylabel('% R2');
                hold on;
                plot(optim_niter,plot_optim_R2,'ro');
                zoom on;
                hold off;
            
              elseif valor==1
            
                % plot lof
                plot_lof=evalin('base','mcr_als.alsOptions.resultats.plot_lof;');
                optim_niter=evalin('base','mcr_als.alsOptions.resultats.optim_niter;');
                plot_optim_lof=evalin('base','mcr_als.alsOptions.resultats.plot_optim_lof;');
                axes(handles.axes1);
                X=length(plot_lof);
                plot(plot_lof,'.-');axis([1 X min(plot_lof) max(plot_lof)]);title('Evolution of lof');
                xlabel('Number of iterations');
                ylabel('% lof');
                hold on;
                plot(optim_niter,plot_optim_lof,'ro');
                zoom on;
                hold off;
            
            elseif valor==2
            
                % sigma change
                plot_sigmaC=evalin('base','mcr_als.alsOptions.resultats.plot_sigmaC;');
                axes(handles.axes1);
                X=length(plot_sigmaC);
                plot(plot_sigmaC,'.-');axis([1 X min(plot_sigmaC) max(plot_sigmaC)]);title('Evolution of % change of sigma');
                xlabel('Number of iterations');
                ylabel('% sigma');
                zoom on;
                hold on;
                convcrit=evalin('base','mcr_als.alsOptions.opt.tolsigma;');
                hline(convcrit,'g:');
                hline(-convcrit,'g:');
                hold off;
            
            
             elseif valor==3
            
                % log(ssq)
                sigmaN=evalin('base','mcr_als.alsOptions.resultats.plot_sigmaN;');
                optim_niter=evalin('base','mcr_als.alsOptions.resultats.optim_niter;');
                plot_sigmaN=log10(sigmaN);
                plot_sigmaN_optim=plot_sigmaN(optim_niter);
                axes(handles.axes1);
                X=length(plot_sigmaN);
                plot(plot_sigmaN,'.-');axis([1 X min(plot_sigmaN) max(plot_sigmaN)]);title('Evolution of log(ssq)');
                xlabel('Number of iterations');
                ylabel('log10(ssq)');
                zoom on;
                hold on;
                plot(optim_niter,plot_sigmaN_optim,'ro');
                zoom on;
                hold off;
            
            elseif valor==4
            
                % plot spectra
                plot_specs=evalin('base','mcr_als.alsOptions.resultats.plot_specs;');
                optim_specs=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
                axes(handles.axes1);
                [m,X]=size(plot_specs);
                set(gcf,'defaultaxescolororder',jet(m))
                plot(plot_specs','-');axis([1 X min(min(plot_specs)) max(max(plot_specs))]);title('Evolution of Spectra Profiles');
                xlabel('Channel');
                ylabel('Intensity (a.u.)');
                hold on;
                plot(optim_specs','r.');
                zoom on;
                hold off;
            
            elseif valor==5
            
                % plot concs
                plot_concs=evalin('base','mcr_als.alsOptions.resultats.plot_concs;');
                optim_concs=evalin('base','mcr_als.alsOptions.resultats.optim_concs;');
                axes(handles.axes1);
                [X,m]=size(plot_concs);
                set(gcf,'defaultaxescolororder',jet(m))
                plot(plot_concs,'-');axis([1 X min(min(plot_concs)) max(max(plot_concs))]);title('Evolution of Concentration Profiles');
                xlabel('Row number');
                ylabel('Concentration signal)');
                hold on;
                plot(optim_concs,'r.');
                zoom on;
                hold off;
            
            end
        end

        % Button pushed function: push_3p
        function push_3p_Callback(app, event)
            % --- Executes on button press in push_3p.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_3p (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            if evalin('base','mcr_als.alsOptions.trilin.iquadril;')==0;
                if evalin('base','mcr_als.alsOptions.trilin.appTril;')==2;
                   bilinear_info;
                else
                   trilinear_info;
                end
            elseif evalin('base','mcr_als.alsOptions.trilin.iquadril;')==1;
            quadrilinear_info;
            end
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end

        % Button pushed function: push_correl
        function push_correl_Callback(app, event)
            % --- Executes on button press in push_correl.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_correl (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            correlation_info;
        end

        % Button pushed function: push_images
        function push_images_Callback(app, event)
            % --- Executes on button press in push_images.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_images (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            images_info;
        end

        % Button pushed function: push_kinetic
        function push_kinetic_Callback(app, event)
            % --- Executes on button press in push_kinetic.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_kinetic (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            kinetic_info;
        end

        % Callback function: not associated with a component
        function ConstInfo_but(app, event)
            Constraints.nonegC = evalin('base','mcr_als.alsOptions.nonegC'); % Non-negativity
            Constraints.nonegS = evalin('base','mcr_als.alsOptions.nonegS');
            Constraints.unimodC = evalin('base','mcr_als.alsOptions.unimodC'); % Unimodality
            Constraints.unimods = evalin('base','mcr_als.alsOptions.unimodS');
            Constraints.closure = evalin('base','mcr_als.alsOptions.closure'); % Closure Constraint
            Constraints.cselecC = evalin('base','mcr_als.alsOptions.cselcC'); % Equality Constraint
            Constraints.sselcS = evalin('base','mcr_als.alsOptions.sselcS');
            Constraints.trilin = evalin('base','mcr_als.alsOptions.trilin'); % Trilinearity
            Constraints.correlation = evalin('base','mcr_als.alsOptions.correlation'); % Correlation Constraint
            Constraints.kinetic = evalin('base','mcr_als.alsOptions.kinetic'); % Kinetic Constraint
            Constraints.multi = evalin('base','mcr_als.alsOptions.multi'); % Multiway?
            Constraints.opt = evalin('base','mcr_als.alsOptions.opt'); % Options for iterations
            uisave("Constraints")
%             ConstraintSettings
            
        end

        % Button pushed function: ConstraintSettingsButton
        function constSet(app, event)
           ConstraintSettings 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Summary of optimization evolution';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Optimization information';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Optimization profiles';
            app.uipanel1.Tag = 'uipanel1';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.Tag = 'axes1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select...';
            app.popup_graph = uidropdown(app.uipanel1);
            app.popup_graph.Items = {'select...', 'Plot R^2', 'Plot LOF', 'Plot % sigma change', 'Plot log(ssq)', 'Plot row profiles evolution', 'Plot column profiles evolution'};
            app.popup_graph.ValueChangedFcn = createCallbackFcn(app, @popup_graph_Callback, true);
            app.popup_graph.Tag = 'popup_graph';
            app.popup_graph.Value = 'select...';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = ' = optimal conditions';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'o';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Alternative';
            app.uipanel2.Tag = 'uipanel2';
            app.push_3p = uibutton(app.uipanel2, 'push');
            app.push_3p.ButtonPushedFcn = createCallbackFcn(app, @push_3p_Callback, true);
            app.push_3p.Tag = 'push_3p';
            app.push_3p.Text = 'Multiway plots';
            app.push_correl = uibutton(app.uipanel2, 'push');
            app.push_correl.ButtonPushedFcn = createCallbackFcn(app, @push_correl_Callback, true);
            app.push_correl.Tag = 'push_correl';
            app.push_correl.Text = 'Correlation info';
            app.push_kinetic = uibutton(app.uipanel2, 'push');
            app.push_kinetic.ButtonPushedFcn = createCallbackFcn(app, @push_kinetic_Callback, true);
            app.push_kinetic.Tag = 'push_kinetic';
            app.push_kinetic.Text = 'Kinetic info';
            app.push_images = uibutton(app.uipanel2, 'push');
            app.push_images.ButtonPushedFcn = createCallbackFcn(app, @push_images_Callback, true);
            app.push_images.Tag = 'push_images';
            app.push_images.Text = 'Images';
            app.ConstraintSettingsButton = uibutton(app.uipanel2, 'push');
            app.ConstraintSettingsButton.ButtonPushedFcn = createCallbackFcn(app, @constSet, true);
            app.ConstraintSettingsButton.Text = 'Constraint Settings';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = optim_info_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)optim_info_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end