classdef plot_dades_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        text2        matlab.ui.control.Label
        text1        matlab.ui.control.Label
        listbox_sel  matlab.ui.control.ListBox
        push_close   matlab.ui.control.Button
        axes1        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function plot_dades_OpeningFcn(app, varargin)
            % --- Executes just before plot_dades is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to plot_dades (see VARARGIN)
            
            % Choose default command line output for plot_dades
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            % plot_dates_OutputFcn
            matdad=evalin('base','mcr_als.data');
            axes(handles.axes1);
            [xi,yi]=size(matdad);
            maxim=max(max(matdad));
            minim=min(min(matdad));
            
            if maxim > 0
                maxim1=maxim+0.2*maxim;
            else
                maxim1=maxim-0.2*abs(maxim)
            end
            
            if minim >= 0
                minim1=minim-0.2*minim;
            else
                minim1=minim-0.2*abs(minim);
            end
            set(gcf,'defaultaxescolororder',jet(xi))
            plot(matdad');axis([1 yi minim1 maxim1]);title('Rows of raw data matrix');xlabel('Channels');ylabel('Intensity (a.u.)');
            zoom on;

        end

        % Value changed function: listbox_sel
        function listbox_sel_Callback(app, event)
            % --- Executes on selection change in listbox_sel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to listbox_sel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns listbox_sel contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from listbox_sel
            
            valor=get(hObject,'Value');
            
            if valor==1
                matdad=evalin('base','mcr_als.data');
                axes(handles.axes1);
                [xi,yi]=size(matdad);
                maxim=max(max(matdad));
                minim=min(min(matdad));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim >= 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
                set(gcf,'defaultaxescolororder',jet(xi))
                plot(matdad');axis([1 yi minim1 maxim1]);title('Rows of raw data matrix');xlabel('Channels');ylabel('Intensity (a.u.)');
                zoom on;
            elseif valor==2
                matdad=evalin('base','mcr_als.data');
                axes(handles.axes1);
                [xi,yi]=size(matdad);
                maxim=max(max(matdad));
                minim=min(min(matdad));
            
                if maxim > 0
                    maxim1=maxim+0.2*maxim;
                else
                    maxim1=maxim-0.2*abs(maxim)
                end
            
                if minim >= 0
                    minim1=minim-0.2*minim;
                else
                    minim1=minim-0.2*abs(minim);
                end
                set(gcf,'defaultaxescolororder',jet(yi))
                plot(matdad);axis([1 xi minim1 maxim1]);title('Columns of raw data matrix');xlabel('Channels');ylabel('Intensity (a.u.)');
                zoom on;
            end
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Raw data plots';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            app.listbox_sel = uilistbox(app.figure1);
            app.listbox_sel.Items = {'Rows', 'Columns'};
            app.listbox_sel.ValueChangedFcn = createCallbackFcn(app, @listbox_sel_Callback, true);
            app.listbox_sel.Tag = 'listbox_sel';
            app.listbox_sel.Value = 'Rows';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Select the direction of the plot:';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Plot the selected data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plot_dades_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)plot_dades_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end