classdef puregui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel4    matlab.ui.container.Panel
        check_sort  matlab.ui.control.CheckBox
        uipanel2    matlab.ui.container.Panel
        text1       matlab.ui.control.Label
        text3       matlab.ui.control.Label
        text2       matlab.ui.control.Label
        listbox1    matlab.ui.control.ListBox
        uipanel1    matlab.ui.container.Panel
        popup_dir   matlab.ui.control.DropDown
        push_fer    matlab.ui.control.Button
        text6       matlab.ui.control.Label
        edit_noise  matlab.ui.control.EditField
        text5       matlab.ui.control.Label
        text4       matlab.ui.control.Label
        push_canc   matlab.ui.control.Button
        push_Copy   matlab.ui.control.Button
        push_Print  matlab.ui.control.Button
        push_OK     matlab.ui.control.Button
        axes1       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function puregui_OpeningFcn(app, varargin)
            % --- Executes just before puregui is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to puregui (see VARARGIN)
            
            % Choose default command line output for puregui
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

            % initialization
            evalin('base','mcr_als.InitEstim.Sort=0;');

            % edit_noise_OpeningFcn
            evalin('base','mcr_als.InitEstim.pureNoiseLevel=10;');
        end

        % Value changed function: check_sort
        function check_sort_Callback(app, event)
            % --- Executes on button press in check_sort.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_sort (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_sort
            
            pon=get(handles.check_sort,'Value');
            
            if pon==1 % yes
            evalin('base','mcr_als.InitEstim.Sort=1;');
            else % no
            evalin('base','mcr_als.InitEstim.Sort=0;');
            end
        end

        % Value changed function: edit_noise
        function edit_noise_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_noise (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_noise as text
            %        str2double(get(hObject,'String')) returns contents of edit_noise as a double
            
            noiseLevel=str2double(get(hObject,'String'));
            assignin('base','noiseLevel',noiseLevel);
            evalin('base','mcr_als.InitEstim.pureNoiseLevel=noiseLevel;');
            evalin('base','clear noiseLevel');
        end

        % Value changed function: popup_dir
        function popup_dir_Callback(app, event)
            % --- Executes on selection change in popup_dir.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_dir (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns popup_dir contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_dir
            
            direction=get(hObject,'Value')-1;
            
            if direction==0
                set(handles.push_fer,'enable','off');
                set(handles.push_OK,'enable','off');
            else
                set(handles.push_fer,'enable','on');
            end
            
            assignin('base','direction',direction);
            evalin('base','mcr_als.InitEstim.pureDirection=direction;');
            evalin('base','clear direction');
        end

        % Button pushed function: push_Copy
        function push_Copy_Callback(app, event)
            % --- Executes on button press in push_Copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_Copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            print -dbitmap;
        end

        % Button pushed function: push_OK
        function push_OK_Callback(app, event)
            % --- Executes on button press in push_OK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_OK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=2;');
            evalin('base','mcr_als.InitEstim.method=''Pur'';');
            
            % addition for sorting
            if evalin('base','mcr_als.InitEstim.Sort') == 0
                % nothing to do
            else
                % sort
                indexs=evalin('base','mcr_als.InitEstim.indices;');
                sp=evalin('base','mcr_als.InitEstim.iniesta;');
                [x,i]=sort(indexs);
                sp=sp(i,:);
            
                assignin('base','sp',sp);
                evalin('base','mcr_als.InitEstim.iniesta=sp;');
                evalin('base','clear sp');
            
            end
            
            close;
        end

        % Button pushed function: push_Print
        function push_Print_Callback(app, event)
            % --- Executes on button press in push_Print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_Print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf,'PaperPositionMode','auto');
            print('PUREGUI','-dpdf');
        end

        % Button pushed function: push_canc
        function push_canc_Callback(app, event)
            % --- Executes on button press in push_canc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_canc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=3;');
            evalin('base','mcr_als=rmfield(mcr_als,''InitEstim'');');
            close;
        end

        % Button pushed function: push_fer
        function push_fer_Callback(app, event)
            % --- Executes on button press in push_fer.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_fer (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            d=evalin('base','mcr_als.data;'); % data matrix
            nr=evalin('base','mcr_als.CompNumb.nc;');
            if (evalin('base','mcr_als.InitEstim.pureDirection;')==2) % if Spectral direction, flip data
                d=d';
            end
            f=evalin('base','mcr_als.InitEstim.pureNoiseLevel;'); % Noise allowed %
            [nrow,ncol]=size(d);
            
            % calculation of the purity spectrum--------------------------1

            % First Purest Variable            
            f=f/100; % Noise allowed decimal
            s=std(d); 
            m=mean(d);
            ll=s.*s+m.*m; % std. dev squared plus mean squared = length squared  (λ2)
            f=max(m)*f; % Calculate constant offset: Noise % of max mean
            p=s./(m+f); % Calculate purity variable with purest function
            
            [mp,imp(1)]=max(p); %The max purity variable is signefies a purest variable for a component
            
            l=sqrt((s.*s+(m+f).*(m+f))); % root of s squared plus (m + f) squared = corrected length or  λ
            
            % Second Purest Variable--------------------------------------2
            % Calculate corelation around the origin, COO matrix 
            for j=1:ncol % Scale data matrix by its corrected length,  λ
                dl(:,j)=d(:,j)./l(j);
            end
            c=(dl'*dl)./nrow; % COO matrix C =(l/c)D(X)D(X)T
            
            % calculation of the weights
            % first weight   
            w(1,:)=ll./(l.*l); % length squared / corrected length squared
            p(1,:)=w(1,:).*p(1,:); % Correct purity variable with weight
            s(1,:)=w(1,:).*s(1,:); % Correct standard deviation with weight
            
            % next weights
            for i=2:nr % For the rest of the rows...
                for j=1:ncol % For each column
                    [dm]=wmat(c,imp,i,j);
                    w(i,j)=det(dm); % Weight is the determinant of 
                    p(i,j)=p(1,j).*w(i,j);
                    s(i,j)=s(1,j).*w(i,j);
                end
            
                % next purest and standard deviation spectrum
            
                [mp(i),imp(i)]=max(p(i,:));
            %     disp('next purest variable: ');disp(imp(i))
            end
            
            for i=1:nr
                impi=imp(i);
                sp(1:nrow,i)=d(1:nrow,impi);
            end
            
            sp=normv2(sp');
            axes(handles.axes1)
            plot(sp');
            
            for i=1:length(imp)
                Scell(i)={imp(i)};
            end
            
            % list of purest variables
            assignin('base','imp',imp);
            evalin('base','mcr_als.InitEstim.indices=imp;');
            evalin('base','clear imp');
            
            %set(handles.list_eigval,'string',Scell)
            set(handles.listbox1,'string',Scell,'value',1);
            set(handles.push_OK,'enable','on');
            
            assignin('base','sp',sp);
            evalin('base','mcr_als.InitEstim.iniesta=sp;');
            evalin('base','clear sp');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Multivariate Curve Resolution Toolbox - Determination of the purest variables';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.push_OK = uibutton(app.figure1, 'push');
            app.push_OK.ButtonPushedFcn = createCallbackFcn(app, @push_OK_Callback, true);
            app.push_OK.Tag = 'push_OK';
            app.push_OK.Enable = 'off';
            app.push_OK.Text = 'OK';
            app.push_Print = uibutton(app.figure1, 'push');
            app.push_Print.ButtonPushedFcn = createCallbackFcn(app, @push_Print_Callback, true);
            app.push_Print.Tag = 'push_Print';
            app.push_Print.Text = 'Print';
            app.push_Copy = uibutton(app.figure1, 'push');
            app.push_Copy.ButtonPushedFcn = createCallbackFcn(app, @push_Copy_Callback, true);
            app.push_Copy.Tag = 'push_Copy';
            app.push_Copy.Text = 'Copy';
            app.push_canc = uibutton(app.figure1, 'push');
            app.push_canc.ButtonPushedFcn = createCallbackFcn(app, @push_canc_Callback, true);
            app.push_canc.Tag = 'push_canc';
            app.push_canc.Text = 'Cancel';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Options: ';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Noise allowed (%):';
            app.edit_noise = uieditfield(app.uipanel1, 'text');
            app.edit_noise.ValueChangedFcn = createCallbackFcn(app, @edit_noise_Callback, true);
            app.edit_noise.Tag = 'edit_noise';
            app.edit_noise.Value = '10';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Direction of the variable selection:';
            app.push_fer = uibutton(app.uipanel1, 'push');
            app.push_fer.ButtonPushedFcn = createCallbackFcn(app, @push_fer_Callback, true);
            app.push_fer.Tag = 'push_fer';
            app.push_fer.Enable = 'off';
            app.push_fer.Text = 'Do';
            app.popup_dir = uidropdown(app.uipanel1);
            app.popup_dir.Items = {'select...', 'Concentrations', 'Spectra'};
            app.popup_dir.ValueChangedFcn = createCallbackFcn(app, @popup_dir_Callback, true);
            app.popup_dir.Tag = 'popup_dir';
            app.popup_dir.Value = 'select...';
            app.listbox1 = uilistbox(app.figure1);
            app.listbox1.Items = {'waiting...'};
            app.listbox1.Tag = 'listbox1';
            app.listbox1.Value = 'waiting...';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Purest variables representation';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'List of purest variables';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.text1 = uilabel(app.uipanel2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Pure variable detection method';
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Tag = 'uipanel4';
            app.check_sort = uicheckbox(app.uipanel4);
            app.check_sort.ValueChangedFcn = createCallbackFcn(app, @check_sort_Callback, true);
            app.check_sort.Tag = 'check_sort';
            app.check_sort.Text = 'Sort the list of purest variables in the output';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = puregui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)puregui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end