classdef quadrilinear_info_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        text_4R2        matlab.ui.control.Label
        text_4LOF       matlab.ui.control.Label
        text15          matlab.ui.control.Label
        text14          matlab.ui.control.Label
        text13          matlab.ui.control.Label
        text_3R2        matlab.ui.control.Label
        text_3LOF       matlab.ui.control.Label
        text10          matlab.ui.control.Label
        text9           matlab.ui.control.Label
        text8           matlab.ui.control.Label
        text_2R2        matlab.ui.control.Label
        text_2LOF       matlab.ui.control.Label
        text5           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        popup_species   matlab.ui.control.DropDown
        push_multiplot  matlab.ui.control.Button
        push_close      matlab.ui.control.Button
        push_export     matlab.ui.control.Button
        text2           matlab.ui.control.Label
        text1           matlab.ui.control.Label
        axes4           matlab.ui.control.UIAxes
        axes3           matlab.ui.control.UIAxes
        axes2           matlab.ui.control.UIAxes
        axes1           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function quadrilinear_info_OpeningFcn(app, varargin)
            % --- Executes just before quadrilinear_info is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to quadrilinear_info (see VARARGIN)
            
            % Choose default command line output for quadrilinear_info
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes quadrilinear_info wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
                uaug=evalin('base','mcr_als.alsOptions.resultats.optim_concs;');
                vaug=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
                nmat=evalin('base','mcr_als.alsOptions.trilin.ne;');
                d=evalin('base','mcr_als.alsOptions.matdad;');
            
            % [u1,u2,l,dc,dtc,dt]=umodeaugq(copt,sopt,nmat,1,d);
            % umodeaugq
            
            [nrt,ns]=size(uaug);
            [ns,nc]=size(vaug);
            [nd1,nd2]=size(nmat);
            u1=[];u2=[];u3=[];l=[];ll=[];um=[];
            
            if nd2>1,
                disp('quadrilinear model is applied'),
                iquadril=1;
                %ne1=nmat(1);ne2=nmat(2);ne3=nmat(3);
                ne1=nmat(3);ne2=nmat(2);ne3=nmat(1);
                nr1=nrt/ne1;nr2=nr1/ne2;
                dq=zeros(ne1,ne2,ne3,nc);
                dt=zeros(ne1,ne2*ne3,nc);
            end


            % *************************************************************************
            
            irow=0;
            for i1=1:ne1
                jrow=0;
                for i2=1:ne2
                    for i3=1:ne3
                        irow=irow+1;
                        jrow=jrow+1;
                        dq(i1,i2,i3,:)=d(irow,:);
                        dt(i1,jrow,:)=d(irow,:);
                    end
                end
            end
            
            for j=1:ns
                m=[];
                irow=0;
                for i1=1:ne1
                    mrow=0;
                    for i2=1:ne2*ne3
                        irow=irow+1;
                        mrow=mrow+1;
                        m1(mrow,1)=uaug(irow,j);
                    end
                    m=[m,m1];
                end
                [u,s,v]=svd(m,0);
                l1=[];for il=1:ne1,l1=[l1;s(il,il)];end
                l=[l,l1];
                % lofbil(m,u(:,1),v(:,1)'.*s(1,1));
                u1=[u1,abs(v(:,1)*s(1,1))];
                um=[um,abs(u(:,1))];
            
                m=[];
                irow=0;
                for i2=1:ne2
                    mrow=0;
                    for i3=1:ne3
                        irow=irow+1;
                        mrow=mrow+1;
                        m2(mrow,1)=um(irow,j);
                    end
                    m=[m,m2];
                end
            
                [u,s,v]=svd(m,0);
                u2=[u2,abs(v(:,1)*s(1,1))];
                u3=[u3,abs(u(:,1))];
                ll1=[];for il=1:ne2,ll1=[ll1;s(il,il)];end
                ll=[ll,ll1];
                size(u2);
                size(u3);
                %pause
            
            % repeat for all components, ns
            
            end
            
            
            disp('check of multilinearity from svd of u1 folded profiles: ');disp(l)
            disp('check of multilinearity from svd of u2-u3 folded profiles: ');disp(ll)
            
            % Test lofbil (x,uaug,vaug)
            
            disp(' ');disp('lof and R2 for a bilinear model')
            ifn=find(isfinite(d)==1);
            for i=1:nrt
                for j=1:nc
                    if isfinite(d(i,j))==1,
                        dc(i,j)=0;
                        for ls=1:ns
                            dc(i,j)=dc(i,j)+uaug(i,ls)*vaug(ls,j);
                        end
                    end
                end
            end
            
            res=d(ifn)-dc(ifn);
            sumd=sum(sum(d(ifn).*d(ifn)));
            sumdc=sum(sum(dc(ifn).*dc(ifn)));
            sumres=sum(sum(res.*res));
            % disp(['sstot,sscalc and ssres = ',num2str([sumd,sumdc,sumres])]);
            loft=(sqrt(sumres/sumd))*100;
            r2t1=((sumdc)/sumd)*100;
            r2t2=((sumd-sumres)/sumd)*100;
            disp(['lof (%) = ',num2str(loft)]);
            disp(['R2 % = ',num2str(r2t2)]);
            
            set(handles.text_2LOF,'string',num2str(loft));
            set(handles.text_2R2,'string',num2str(r2t2));
            
            % Test loftril (x,u1,um=u2*u3,vaug')
            
            disp(' ');disp('lof and R2 for a trilinear model')
            for i=1:ne1
                    for j=1:ne2*ne3
                        for k=1:nc
                            if isfinite(dt(i,j,k))==1,
                                dtc(i,j,k)=0;
                                for ls=1:ns
                                    dtc(i,j,k)=dtc(i,j,k)+u1(i,ls)*um(j,ls)*vaug(ls,k);
                                end
                            end
                        end
                    end
            end
            
            
            ifn=find(isfinite(dt)==1);
            res=dt(ifn)-dtc(ifn);
            sumdt=sum(sum(sum(dt(ifn).*dt(ifn))));
            sumdtc=sum(sum(sum(dtc(ifn).*dtc(ifn))));
            sumres=sum(sum(sum(res.*res)));
            % disp(['sstot,sscalc and ssres = ',num2str([sumdt,sumdtc,sumres])]);
            loft=(sqrt(sumres/sumdt))*100;
            r2t1=((sumdtc)/sumdt)*100;
            r2t2=((sumdt-sumres)/sumdt)*100;
            disp(['lof (%) = ',num2str(loft)]);
            disp(['R2 % = ',num2str(r2t2)]);
            
            set(handles.text_3LOF,'string',num2str(loft));
            set(handles.text_3R2,'string',num2str(r2t2));
            
            
            % Test lofquadril (x,u1,u2,u3,vaug')
            
            disp(' ');disp('lof and R2 for a quadrilinear model')
            for i=1:ne1
                for j=1:ne2
                    for k=1:ne3
                        for l=1:nc
                            if isfinite(dq(i,j,k,l))==1,
                                dqc(i,j,k,l)=0;
                                for ls=1:ns
                                    dqc(i,j,k,l)=dqc(i,j,k,l)+u1(i,ls)*u2(j,ls)*u3(k,ls)*vaug(ls,l);
                                end
                            end
                        end
                    end
                end
            end
            
            % Build the column-wise augmented recalculated two-way data matrix
            
            nrow=0;
            for i=1:ne1
                for j=1:ne2
                    for k=1:ne3
                        %for l=1:ne4
                            nrow=nrow+1;
                            for ls=1:ns
                                    dc(nrow,:)=dqc(i,j,k,:);
                            end
                        % end
                    end
                end
            end
            
            ifn=find(isfinite(dq)==1);
            res=dq(ifn)-dqc(ifn);
            sumdt=sum(sum(sum(sum(dq(ifn).*dq(ifn)))));
            sumdtc=sum(sum(sum(sum(dqc(ifn).*dqc(ifn)))));
            sumres=sum(sum(sum(sum((res.*res)))));
            % disp(['sstot,sscalc and ssres = ',num2str([sumdt,sumdtc,sumres])]);
            loft=(sqrt(sumres/sumdt))*100;
            r2t1=((sumdtc)/sumdt)*100;
            r2t2=((sumdt-sumres)/sumdt)*100;
            disp(['lof (%) = ',num2str(loft)]);
            disp(['R2 % = ',num2str(r2t2)]);
            
            set(handles.text_4LOF,'string',num2str(loft));
            set(handles.text_4R2,'string',num2str(r2t2));


            %save umodeaug variables
            % [u1,u2,l,dc,dtc,dt]=umodeaug(copt,sopt,nmat,1,);
            
            assignin('base','u1QUAT',u1);
            evalin('base','mcr_als.aux.u1=u1QUAT;');
            evalin('base','clear u1QUAT');
            
            assignin('base','u2QUAT',u2);
            evalin('base','mcr_als.aux.u2=u2QUAT;');
            evalin('base','clear u2QUAT');
            
            assignin('base','u3QUAT',u3);
            evalin('base','mcr_als.aux.u3=u3QUAT;');
            evalin('base','clear u3QUAT');
            
            assignin('base','vaugQUAT',vaug);
            evalin('base','mcr_als.aux.vaug=vaugQUAT;');
            evalin('base','clear vaugQUAT');
            
            assignin('base','lQUAT',l);
            evalin('base','mcr_als.aux.l=lQUAT;');
            evalin('base','clear lQUAT');
            
            assignin('base','llQUAT',ll);
            evalin('base','mcr_als.aux.ll=llQUAT;');
            evalin('base','clear llQUAT');
            
            assignin('base','dcQUAT',dc);
            evalin('base','mcr_als.aux.dc=dcQUAT;');
            evalin('base','clear dcQUAT');
            
            assignin('base','dqcQUAT',dqc);
            evalin('base','mcr_als.aux.dqc=dqcQUAT;');
            evalin('base','clear dqcQUAT');
            
            assignin('base','dqTRIL',dq);
            evalin('base','mcr_als.aux.dq=dqTRIL;');
            evalin('base','clear dqTRIL');
        end

        % Value changed function: popup_species
        function popup_species_Callback(app, event)
            % --- Executes on selection change in popup_species.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_species (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_species contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_species
            
            valorpop=get(hObject,'Value')-1;
            
            if valorpop>0
            
                u1=evalin('base','mcr_als.aux.u1;');
                u2=evalin('base','mcr_als.aux.u2;');
                u3=evalin('base','mcr_als.aux.u3;');
                vaug=evalin('base','mcr_als.aux.vaug;');
            
                % mode 1
                axes(handles.axes4);
                plot(u1(:,valorpop));
                mm=length(u1);
                axis([0 mm+1 min(u1(:,valorpop))-0.1*min(u1(:,valorpop)) max(u1(:,valorpop))+0.1*max(u1(:,valorpop))]);
                %title('Mode 1');
                title('Mode 4');
            
                % mode 2
                axes(handles.axes3);
                plot(u2(:,valorpop));
                mm=length(u2);
                axis([0 mm+1 min(u2(:,valorpop))-0.1*min(u2(:,valorpop)) max(u2(:,valorpop))+0.1*max(u2(:,valorpop))]);
                % title('Mode 2');
                title('Mode 3');
            
                % mode 1
                axes(handles.axes1);
                plot(u3(:,valorpop));
                mm=length(u3);
                axis([0 mm+1 min(u3(:,valorpop))-0.1*min(u3(:,valorpop)) max(u3(:,valorpop))+0.1*max(u3(:,valorpop))]);
                % title('Mode 3');
                title('Mode 1');
            
                % mode 4
                axes(handles.axes2);
                plot(vaug(valorpop,:));
                mm=length(vaug);
                axis([0 mm+1 min(vaug(valorpop,:))-0.1*min(vaug(valorpop,:)) max(vaug(valorpop,:))+0.1*max(vaug(valorpop,:))]);
                %title('Mode 4');
                title('Mode 2');
            else
            
                axes(handles.axes1);
                cla;
                axes(handles.axes2);
                cla;
                axes(handles.axes3);
                cla;
                axes(handles.axes4);
                cla;
            end
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end

        % Button pushed function: push_export
        function push_export_Callback(app, event)
            % --- Executes on button press in push_export.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_export (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            u1=evalin('base','mcr_als.aux.u1;');
            assignin('base','u1',u1);
            
            u2=evalin('base','mcr_als.aux.u2;');
            assignin('base','u2',u2);
            
            u3=evalin('base','mcr_als.aux.u3;');
            assignin('base','u3',u3);
            
            vaug=evalin('base','mcr_als.aux.vaug;');
            assignin('base','vaug',vaug);
        end

        % Button pushed function: push_multiplot
        function push_multiplot_Callback(app, event)
            % --- Executes on button press in push_multiplot.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_multiplot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            kk=0;
            ns=evalin('base','mcr_als.CompNumb.nc');
            
            u1=evalin('base','mcr_als.aux.u1;');
            u2=evalin('base','mcr_als.aux.u2;');
            u3=evalin('base','mcr_als.aux.u3;');
            vaug=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
            
            figure('Name','quadrilinear info multiplot');
            
            for k=1:ns
                if k==1
                    kk=kk+1;subplot(ns,4,kk),plot(u3(:,k),'c'),title('Mode 1');ylabel('Component 1');
                    kk=kk+1;subplot(ns,4,kk),plot(vaug(k,:),'b'),title('Mode 2');
                    kk=kk+1;subplot(ns,4,kk),plot(u2(:,k),'g'),title('Mode 3');
                    kk=kk+1;subplot(ns,4,kk),plot(u1(:,k),'r'),title('Mode 4');
                else
                        kk=kk+1;subplot(ns,4,kk),plot(u3(:,k),'c');ylabel(['Component ',num2str(k)]);
                        kk=kk+1;subplot(ns,4,kk),plot(vaug(k,:),'b')
                        kk=kk+1;subplot(ns,4,kk),plot(u2(:,k),'g')
                        kk=kk+1;subplot(ns,4,kk),plot(u1(:,k),'r')
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Quadrilinear Information Results';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.axes4 = uiaxes(app.figure1);
            app.axes4.Tag = 'axes4';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Quadrilinear model information';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select component number:';
            app.push_export = uibutton(app.figure1, 'push');
            app.push_export.ButtonPushedFcn = createCallbackFcn(app, @push_export_Callback, true);
            app.push_export.Tag = 'push_export';
            app.push_export.Tooltip = 'Export to WS variable u1, u2, u3 and vaug. Other variables at mcr_als.aux (dt, dtc, dc and l)';
            app.push_export.Text = 'Save in WS';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            app.push_multiplot = uibutton(app.figure1, 'push');
            app.push_multiplot.ButtonPushedFcn = createCallbackFcn(app, @push_multiplot_Callback, true);
            app.push_multiplot.Tag = 'push_multiplot';
            app.push_multiplot.Text = 'Multiplot';
            app.popup_species = uidropdown(app.figure1);
            app.popup_species.Items = {'select a species...', 'Species nr. 1', 'Species nr. 2', 'Species nr. 3'};
            app.popup_species.ValueChangedFcn = createCallbackFcn(app, @popup_species_Callback, true);
            app.popup_species.Tag = 'popup_species';
            app.popup_species.Value = 'select a species...';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Bilinear model:';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Lof (%) =';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'R2 (%) =';
            app.text_2LOF = uilabel(app.figure1);
            app.text_2LOF.Tag = 'text_2LOF';
            app.text_2LOF.Text = '0.00';
            app.text_2R2 = uilabel(app.figure1);
            app.text_2R2.Tag = 'text_2R2';
            app.text_2R2.Text = '0.00';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Trilinear model:';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Lof (%) =';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'R2 (%) =';
            app.text_3LOF = uilabel(app.figure1);
            app.text_3LOF.Tag = 'text_3LOF';
            app.text_3LOF.Text = '0.00';
            app.text_3R2 = uilabel(app.figure1);
            app.text_3R2.Tag = 'text_3R2';
            app.text_3R2.Text = '0.00';
            app.text13 = uilabel(app.figure1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Quatrilinear model:';
            app.text14 = uilabel(app.figure1);
            app.text14.Tag = 'text14';
            app.text14.Text = 'Lof (%) =';
            app.text15 = uilabel(app.figure1);
            app.text15.Tag = 'text15';
            app.text15.Text = 'R2 (%) =';
            app.text_4LOF = uilabel(app.figure1);
            app.text_4LOF.Tag = 'text_4LOF';
            app.text_4LOF.Text = '0.00';
            app.text_4R2 = uilabel(app.figure1);
            app.text_4R2.Tag = 'text_4R2';
            app.text_4R2.Text = '0.00';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = quadrilinear_info_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)quadrilinear_info_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end