classdef rowMultiConstraints_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        push_ConstInfo    matlab.ui.control.Button
        uipanel15         matlab.ui.container.Panel
        uipanel17         matlab.ui.container.Panel
        text_popISP       matlab.ui.control.Label
        popup_isp         matlab.ui.control.DropDown
        text26            matlab.ui.control.Label
        check_ISPdefault  matlab.ui.control.CheckBox
        uipanel_Actual    matlab.ui.container.Panel
        check_sameC       matlab.ui.control.CheckBox
        popup_matrixNr    matlab.ui.control.DropDown
        text_MatrixNr     matlab.ui.control.Label
        text_num          matlab.ui.control.Label
        text_total        matlab.ui.control.Label
        push_reset        matlab.ui.control.Button
        push_continue     matlab.ui.control.Button
        uipanel3          matlab.ui.container.ButtonGroup
        uipanel9          matlab.ui.container.Panel
        check_C2sp        matlab.ui.control.CheckBox
        edit_C2sp         matlab.ui.control.EditField
        popup_Cnr         matlab.ui.control.DropDown
        check_Cvar        matlab.ui.control.CheckBox
        text_C2sp         matlab.ui.control.Label
        text_C2c          matlab.ui.control.Label
        edit_C2v          matlab.ui.control.EditField
        text_C2v          matlab.ui.control.Label
        text_C2           matlab.ui.control.Label
        edit_C2           matlab.ui.control.EditField
        check_C1sp        matlab.ui.control.CheckBox
        edit_C1sp         matlab.ui.control.EditField
        popup_C1c         matlab.ui.control.DropDown
        edit_C1v          matlab.ui.control.EditField
        edit_C1           matlab.ui.control.EditField
        popup_C2c         matlab.ui.control.DropDown
        text_C1sp         matlab.ui.control.Label
        text_C1c          matlab.ui.control.Label
        text_C1v          matlab.ui.control.Label
        text_C1           matlab.ui.control.Label
        text_Cnr          matlab.ui.control.Label
        check_closC       matlab.ui.control.CheckBox
        uipanel8          matlab.ui.container.Panel
        popup_eqCc        matlab.ui.control.DropDown
        text_eqCc         matlab.ui.control.Label
        popup_eqCs        matlab.ui.control.DropDown
        text_eqCs         matlab.ui.control.Label
        check_eqC         matlab.ui.control.CheckBox
        uipanel6          matlab.ui.container.Panel
        popup_Up          matlab.ui.control.DropDown
        text_Up           matlab.ui.control.Label
        check_unimod      matlab.ui.control.CheckBox
        popup_Ui          matlab.ui.control.DropDown
        text_Ui           matlab.ui.control.Label
        text_Utol         matlab.ui.control.Label
        text_Uv           matlab.ui.control.Label
        edit_Utol         matlab.ui.control.EditField
        edit_Uv           matlab.ui.control.EditField
        uipanel4          matlab.ui.container.ButtonGroup
        text_nnCp         matlab.ui.control.Label
        edit_nnCp         matlab.ui.control.EditField
        popup_nnCi        matlab.ui.control.DropDown
        popup_nnCsp       matlab.ui.control.DropDown
        text_nnCsp        matlab.ui.control.Label
        text_nnCi         matlab.ui.control.Label
        check_nnC         matlab.ui.control.CheckBox
        uipanel2          matlab.ui.container.ButtonGroup
        push_correlation  matlab.ui.control.Button
        push_kinetic      matlab.ui.control.Button
        push_multiway     matlab.ui.control.Button
        uipanel1          matlab.ui.container.ButtonGroup
        text2             matlab.ui.control.Label
        text1             matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function rowMultiConstraints_OpeningFcn(app, varargin)
            % --- Executes just before rowMultiConstraints is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to rowMultiConstraints (see VARARGIN)
            
            % Choose default command line output for rowMultiConstraints
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            numbExp=evalin('base','mcr_als.alsOptions.nexp');
            if numbExp==1
                set(handles.push_multiway,'enable','off');
            else
                set(handles.push_multiway,'enable','on');
            end
            
            evalin('base','mcr_als.alsOptions.multi.ccons=0;'); % equal constraint to all Cs
            evalin('base','mcr_als.alsOptions.multi.curr_cmat=1;'); % current matrix
            
            % default isp
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            nsp=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
            isp=ones(matc,nsp);
            assignin('base','isp',isp);
            evalin('base','mcr_als.alsOptions.multi.isp=isp;');
            evalin('base','clear isp');
            
            % total Nr of row submetrics
            set(handles.text_num,'String',num2str(matc));
        end

        % Value changed function: check_C1sp
        function check_C1sp_Callback(app, event)
            % --- Executes on button press in check_C1sp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_C1sp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_C1sp
            
            check1clos=get(hObject,'Value');
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            if check1clos==0
                set(handles.text_C1sp,'enable','on');
                set(handles.edit_C1sp,'enable','on','string',' ');
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                ceros=zeros(1,nsign);
                sclos1=evalin('base','mcr_als.alsOptions.closure.sclos1');
            
                if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                    if evalin('base','mcr_als.alsOptions.closure.dc')==1
                        for i=1:matc;
                            sclos1(i,:)=ceros;
                        end
                    end
                else
                    if evalin('base','mcr_als.alsOptions.closure.dc')==1
                        sclos1(curr_cmat,:)=ceros;
                    end
                end
            
                assignin('base','sclos1',sclos1);
                evalin('base','mcr_als.alsOptions.closure.sclos1=sclos1;');
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,5)=0;');
                evalin('base','clear sclos1 matc curr_cmat');
            
            
            elseif check1clos==1
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                unos=ones(1,nsign);
                set(handles.text_C1sp,'enable','on');
                set(handles.edit_C1sp,'enable','inactive','string',num2str(unos));
            
                sclos1=evalin('base','mcr_als.alsOptions.closure.sclos1');
            
                if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                    if evalin('base','mcr_als.alsOptions.closure.dc')==1
                        for i=1:matc;
                            sclos1(i,:)=unos;
                        end
                    end
                else
                    if evalin('base','mcr_als.alsOptions.closure.dc')==1
                        sclos1(curr_cmat,:)=unos;
                    end
                end
            
                assignin('base','sclos1',sclos1);
                evalin('base','mcr_als.alsOptions.closure.sclos1=sclos1;');
                suma_sp=sum(sclos1(curr_cmat,:));
                assignin('base','suma_sp',suma_sp);
            
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,5)=suma_sp;');
                evalin('base','clear sclos1 matc curr_cmat suma_sp');
            
            end
        end

        % Value changed function: check_C2sp
        function check_C2sp_Callback(app, event)
            % --- Executes on button press in check_C2sp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_C2sp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_C2sp
            
            
            check2clos=get(hObject,'Value');
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            if check2clos==0
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                ceros=zeros(1,nsign);
                set(handles.text_C2sp,'enable','on');
                set(handles.edit_C2sp,'enable','on','string',' ');
            
                sclos2=evalin('base','mcr_als.alsOptions.closure.sclos2');
            
                if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                    if evalin('base','mcr_als.alsOptions.closure.dc')==1
                        for i=1:matc;
                            sclos2(i,:)=ceros;
                        end
            
                    end
                else
                    if evalin('base','mcr_als.alsOptions.closure.dc')==1
                        sclos2(curr_cmat,:)=ceros;
                    end
                end
            
                assignin('base','sclos2',sclos2);
                evalin('base','mcr_als.alsOptions.closure.sclos1=sclos2;');
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,8)=0')
                evalin('base','clear sclos2 matc curr_cmat');
            
            elseif check2clos==1
                nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                unos=ones(1,nsign);
                set(handles.text_C2sp,'enable','on');
                set(handles.edit_C2sp,'enable','inactive','string',num2str(unos));
            
                sclos2=evalin('base','mcr_als.alsOptions.closure.sclos2');
            
                if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                    if evalin('base','mcr_als.alsOptions.closure.dc')==1
                        for i=1:matc;
                            sclos2(i,:)=unos;
                        end
                    end
                else
                    if evalin('base','mcr_als.alsOptions.closure.dc')==1
                        sclos2(curr_cmat,:)=unos;
                    end
                end
            
                assignin('base','sclos2',sclos2);
                evalin('base','mcr_als.alsOptions.closure.sclos2=sclos2;');
            
                suma_sp=sum(sclos2(curr_cmat,:));
                assignin('base','suma_sp',suma_sp);
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,8)=suma_sp;');
                evalin('base','clear sclos2 matc curr_cmat suma_sp');
            
            end
        end

        % Value changed function: check_Cvar
        function check_Cvar_Callback(app, event)
            % --- Executes on button press in check_Cvar.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_Cvar (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_Cvar
            
            
            vclos=get(hObject,'Value');
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            
            if vclos==1;
                iclos=evalin('base','mcr_als.alsOptions.closure.iclos');
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1;
                    activa=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
                elseif evalin('base','mcr_als.alsOptions.closure.dc')==2;
                    activa=evalin('base','mcr_als.alsOptions.multi.curr_smat');
                end
            
                if iclos(activa)==1
            
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,3)=Inf');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,4)=0;');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,5)=0;');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,6)=0;');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,7)=0;');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,8)=0;');
            
                    set(handles.text_C1v,'enable','on');
                    set(handles.edit_C1v,'enable','on','string',' ');
                    set(handles.text_C2v,'enable','off');
                    set(handles.edit_C2v,'enable','off','string',' ');
                    set(handles.text_C1,'enable','off');
                    set(handles.edit_C1,'enable','off','string',' ');
                    set(handles.text_C2,'enable','off');
                    set(handles.edit_C2,'enable','off','string',' ');
                    set(handles.text_C1c,'enable','off');
                    set(handles.popup_C1c,'enable','off','value',1);
                    set(handles.text_C2c,'enable','off');
                    set(handles.popup_C2c,'enable','off','value',1);
                    set(handles.text_C1sp,'enable','on');
                    set(handles.edit_C1sp,'enable','on','value',1);
                    set(handles.text_C2sp,'enable','off');
                    set(handles.edit_C2sp,'enable','off','value',1);
            
                    iclos1=1; % equal
                    assignin('base','iclos1',iclos1);
                    evalin('base','mcr_als.alsOptions.closure.iclos1=iclos1;');
                    evalin('base','clear iclos1');
            
                    vc=1;  % equal
                    assignin('base','vc',vc);
                    evalin('base','mcr_als.alsOptions.closure.vc=vc;');
                    evalin('base','clear vc');
            
                elseif iclos(activa)==2
            
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,3)=Inf');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,4)=0;');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,5)=0;');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,6)=Inf;');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,7)=0;');
                    evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,8)=0;');
            
                    set(handles.text_C1v,'enable','on');
                    set(handles.edit_C1v,'enable','on','string',' ');
                    set(handles.text_C2v,'enable','on');
                    set(handles.edit_C2v,'enable','on','string',' ');
                    set(handles.text_C1,'enable','off');
                    set(handles.edit_C1,'enable','off','string',' ');
                    set(handles.text_C2,'enable','off');
                    set(handles.edit_C2,'enable','off','string',' ');
                    set(handles.text_C1c,'enable','off');
                    set(handles.popup_C1c,'enable','off','value',1);
                    set(handles.text_C2c,'enable','off');
                    set(handles.popup_C2c,'enable','off','value',1);
                    set(handles.text_C1sp,'enable','on');
                    set(handles.edit_C1sp,'enable','on','value',1);
                    set(handles.text_C2sp,'enable','on');
                    set(handles.edit_C2sp,'enable','on','value',1);
            
                    iclos1=1; % equal
                    assignin('base','iclos1',iclos1);
                    evalin('base','mcr_als.alsOptions.closure.iclos1=iclos1;');
                    evalin('base','clear iclos1');
            
                    iclos2=1; % equal
                    assignin('base','iclos2',iclos2);
                    evalin('base','mcr_als.alsOptions.closure.iclos2=iclos2;');
                    evalin('base','clear iclos2');
            
                    vc=1; % equal
                    assignin('base','vc',vc);
                    evalin('base','mcr_als.alsOptions.closure.vc=vc;');
                    evalin('base','clear vc');
            
                end
            
            else
            
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,3)=0');
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,4)=0;');
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,5)=0;');
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,6)=0;');
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,7)=0;');
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,8)=0;');
            
                set(handles.text_C1v,'enable','off');
                set(handles.edit_C1v,'enable','off','string',' ');
                set(handles.text_C2v,'enable','off');
                set(handles.edit_C2v,'enable','off','string',' ');
                set(handles.text_Cnr,'enable','on');
                set(handles.popup_Cnr,'enable','on','value',1);
                set(handles.text_C1,'enable','off');
                set(handles.edit_C1,'enable','off','string',' ');
                set(handles.text_C2,'enable','off');
                set(handles.edit_C2,'enable','off','string',' ');
                set(handles.text_C1c,'enable','off');
                set(handles.popup_C1c,'enable','off','value',1);
                set(handles.text_C2c,'enable','off');
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.text_C1sp,'enable','off');
                set(handles.edit_C1sp,'enable','off','string',' ');
                set(handles.text_C2sp,'enable','off');
                set(handles.edit_C2sp,'enable','off','string',' ');
            
                vc=0; % equal
                assignin('base','vc',vc);
                evalin('base','mcr_als.alsOptions.closure.vc=vc;');
                evalin('base','clear vc');
            end
        end

        % Value changed function: check_ISPdefault
        function check_ISPdefault_Callback(app, event)
            % --- Executes on button press in check_ISPdefault.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_ISPdefault (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_ISPdefault
            
            ispcon=get(hObject,'Value');
            
            if ispcon==1;
                matc=evalin('base','mcr_als.alsOptions.multi.matc');
                nsp=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                isp=ones(matc,nsp);
            
                assignin('base','isp',isp);
                evalin('base','mcr_als.alsOptions.multi.isp=isp;');
                evalin('base','clear isp');
            
            
                set(handles.popup_isp,'enable','off','value',1);
                set(handles.text_popISP,'enable','off');
            
            else
            
                set(handles.popup_isp,'enable','on','value',1);
                set(handles.text_popISP,'enable','on');
            
            end
        end

        % Value changed function: check_closC
        function check_closC_Callback(app, event)
            % --- Executes on button press in check_closC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_closC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_closC
            
            closure=get(hObject,'Value');
            
            if closure==1;
            
                set(handles.text_Cnr,'enable','on');
                set(handles.popup_Cnr,'enable','on','value',1);
            
                assignin('base','dc',1);
                evalin('base','mcr_als.alsOptions.closure.dc=dc;');
            
                dim=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                assignin('base','dim',dim);
                matc=evalin('base','mcr_als.alsOptions.multi.matc');
                assignin('base','matc',matc);
                evalin('base','mcr_als.alsOptions.closure.tclos1(1:matc)=zeros(1,matc);');
                evalin('base','mcr_als.alsOptions.closure.tclos2(1:matc)=zeros(1,matc);');
                evalin('base','mcr_als.alsOptions.closure.sclos1(1:matc,1:dim)=zeros(matc,dim);');
                evalin('base','mcr_als.alsOptions.closure.sclos2(1:matc,1:dim)=zeros(matc,dim);');
                evalin('base','mcr_als.alsOptions.closure.iclos(1:matc)=zeros(1,matc);');
                evalin('base','mcr_als.alsOptions.closure.iclos1(1:matc)=zeros(1,matc);');
                evalin('base','mcr_als.alsOptions.closure.iclos2(1:matc)=zeros(1,matc);');
                evalin('base','mcr_als.alsOptions.closure.vclos1(1:matc)=zeros(1,matc);');
                evalin('base','mcr_als.alsOptions.closure.vclos2(1:matc)=zeros(1,matc);');
            
                evalin('base','mcr_als.alsOptions.closure.treated=zeros(matc,8);'); % treated/nr closures/valor 1/condition1 /nr species 1/valor 2/condition 2/ nr species 2
                evalin('base','clear dc dim matc');
            
            else
            
                set(handles.text_Cnr,'enable','off');
                set(handles.popup_Cnr,'enable','off','value',1);
                set(handles.check_Cvar,'enable','off','value',0);
            
                set(handles.text_C1,'enable','off');
                set(handles.text_C1v,'enable','off');
                set(handles.text_C1c,'enable','off');
                set(handles.text_C1sp,'enable','off');
                set(handles.check_C1sp,'enable','off','value',0);
                set(handles.popup_C1c,'enable','off','value',1);
                set(handles.edit_C1,'enable','off','string',' ');
                set(handles.edit_C1v,'enable','off','string',' ');
                set(handles.edit_C1sp,'enable','off','string',' ');
            
                set(handles.text_C2,'enable','off');
                set(handles.text_C2v,'enable','off');
                set(handles.text_C2c,'enable','off');
                set(handles.text_C2sp,'enable','off');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.edit_C2,'enable','off','string',' ');
                set(handles.edit_C2v,'enable','off','string',' ');
                set(handles.edit_C2sp,'enable','off','string',' ');
            
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''closure'');');
            
                closX='None';
                assignin('base','closX',closX);
                evalin('base','mcr_als.alsOptions.closure.type=closX;');
                evalin('base','clear closX');
            
            end;
            
            assignin('base','closure',closure);
            evalin('base','mcr_als.alsOptions.closure.closure=closure;');
            evalin('base','clear closure');
            
            vc=0;
            assignin('base','vc',vc);
            evalin('base','mcr_als.alsOptions.closure.vc=vc;');
            evalin('base','clear vc');
        end

        % Value changed function: check_eqC
        function check_eqC_Callback(app, event)
            % --- Executes on button press in check_eqC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_eqC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_eqC
            
            cselcon=get(hObject,'Value');
            
            if cselcon==1;
            
                appCorrel=evalin('base','mcr_als.alsOptions.correlation.appCorrelation;');
                if appCorrel==0
                    set(handles.text_eqCs,'enable','on');
                    set(handles.popup_eqCs,'enable','on','value',1);
                    set(handles.text_eqCc,'enable','on');
                    set(handles.popup_eqCc,'enable','on','value',1);
                else
                    warndlg('no és possible aplicar Equality i Correlation');
                    set(hObject,'Value',0);
                    cselcon=0;
                end
            
            else
                set(handles.text_eqCs,'enable','off');
                set(handles.popup_eqCs,'enable','off','value',1);
                set(handles.text_eqCc,'enable','off');
                set(handles.popup_eqCc,'enable','off','value',1);
            
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''cselcC'');');
            
            end;
            assignin('base','cselcon',cselcon);
            evalin('base','mcr_als.alsOptions.cselcC.cselcon=cselcon;');
            evalin('base','clear cselcon');
        end

        % Value changed function: check_nnC
        function check_nnC_Callback(app, event)
            % --- Executes on button press in check_nnC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_nnC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_nnC
            
            noneg=get(hObject,'Value');
            
            if noneg==0;
                set(handles.text_nnCi,'enable','off');
                set(handles.popup_nnCi,'enable','off','value',1);
                set(handles.text_nnCsp,'enable','off');
                set(handles.popup_nnCsp,'enable','off','value',1);
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''nonegC'');');
            else
                set(handles.text_nnCi,'enable','on');
                set(handles.popup_nnCi,'enable','on','value',1);
                set(handles.text_nnCsp,'enable','off');
                set(handles.popup_nnCsp,'enable','off','value',1);
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions.nonegC.cneg=[];');
            
                evalin('base','mcr_als.alsOptions.nonegC.treated=zeros(mcr_als.alsOptions.multi.matc,3);');
            end;
            
            assignin('base','noneg',noneg);
            evalin('base','mcr_als.alsOptions.nonegC.noneg=noneg;');
            evalin('base','clear noneg');
        end

        % Value changed function: check_sameC
        function check_sameC_Callback(app, event)
            % --- Executes on button press in check_sameC.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_sameC (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_sameC
            
            ccons=get(hObject,'Value');
            
            if ccons==0
                set(handles.text_MatrixNr,'enable','on');
                matrixNR=evalin('base','mcr_als.alsOptions.multi.matc');
                j=1;
                for i=1:matrixNR,
                    lsb=[i];
                    lsv(j)={lsb};
                    j=j+1;
                end;
                set(handles.popup_matrixNr,'enable','on','string',lsv,'value',1);
            
                %reset all values
                set(handles.check_nnC,'enable','on','value',0);
                set(handles.text_nnCi,'enable','off');
                set(handles.popup_nnCi,'enable','off','value',1);
                set(handles.text_nnCsp,'enable','off');
                set(handles.popup_nnCsp,'enable','off','value',1);
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''nonegC'');');
            
                set(handles.check_unimod,'enable','on','value',0);
                set(handles.text_Ui,'enable','off');
                set(handles.popup_Ui,'enable','off','value',1);
                set(handles.text_Up,'enable','off');
                set(handles.popup_Up,'enable','off','value',1);
                set(handles.text_Utol,'enable','off');
                set(handles.edit_Utol,'enable','off','string',' ');
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''unimodC'');');
            
                set(handles.check_closC,'enable','on','value',0);
                set(handles.text_Cnr,'enable','off');
                set(handles.popup_Cnr,'enable','off','value',1);
                set(handles.check_Cvar,'enable','off','value',0);
                set(handles.text_C1,'enable','off');
                set(handles.text_C1v,'enable','off');
                set(handles.text_C1c,'enable','off');
                set(handles.text_C1sp,'enable','off');
                set(handles.check_C1sp,'enable','off','value',0);
                set(handles.popup_C1c,'enable','off','value',1);
                set(handles.edit_C1,'enable','off','string',' ');
                set(handles.edit_C1v,'enable','off','string',' ');
                set(handles.edit_C1sp,'enable','off','string',' ');
                set(handles.text_C2,'enable','off');
                set(handles.text_C2v,'enable','off');
                set(handles.text_C2c,'enable','off');
                set(handles.text_C2sp,'enable','off');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.edit_C2,'enable','off','string',' ');
                set(handles.edit_C2v,'enable','off','string',' ');
                set(handles.edit_C2sp,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''closure'');');
                closX='None';
                assignin('base','closX',closX);
                evalin('base','mcr_als.alsOptions.closure.type=closX;');
                evalin('base','clear closX');
            
                set(handles.check_eqC,'enable','on','value',0);
                set(handles.text_eqCs,'enable','off');
                set(handles.popup_eqCs,'enable','off','value',1);
                set(handles.text_eqCc,'enable','off');
                set(handles.popup_eqCc,'enable','off','value',1);
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''cselcC'');');
            
                evalin('base','mcr_als.alsOptions.nonegC.noneg=0;');
                evalin('base','mcr_als.alsOptions.unimodC.unimodal=0;');
                evalin('base','mcr_als.alsOptions.closure.closure=0;');
                evalin('base','mcr_als.alsOptions.cselcC.cselcon=0;');
            
            else
                set(handles.text_MatrixNr,'enable','off');
                set(handles.popup_matrixNr,'enable','off','Value',1,'String','Same constraints');
            
                %reset all values
                set(handles.check_nnC,'enable','on','value',0);
                set(handles.text_nnCi,'enable','off');
                set(handles.popup_nnCi,'enable','off','value',1);
                set(handles.text_nnCsp,'enable','off');
                set(handles.popup_nnCsp,'enable','off','value',1);
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''nonegC'');');
            
                set(handles.check_unimod,'enable','on','value',0);
                set(handles.text_Ui,'enable','off');
                set(handles.popup_Ui,'enable','off','value',1);
                set(handles.text_Up,'enable','off');
                set(handles.popup_Up,'enable','off','value',1);
                set(handles.text_Utol,'enable','off');
                set(handles.edit_Utol,'enable','off','string',' ');
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''unimodC'');');
            
                set(handles.check_closC,'enable','on','value',0);
                set(handles.text_Cnr,'enable','off');
                set(handles.popup_Cnr,'enable','off','value',1);
                set(handles.check_Cvar,'enable','off','value',0);
                set(handles.text_C1,'enable','off');
                set(handles.text_C1v,'enable','off');
                set(handles.text_C1c,'enable','off');
                set(handles.text_C1sp,'enable','off');
                set(handles.check_C1sp,'enable','off','value',0);
                set(handles.popup_C1c,'enable','off','value',1);
                set(handles.edit_C1,'enable','off','string',' ');
                set(handles.edit_C1v,'enable','off','string',' ');
                set(handles.edit_C1sp,'enable','off','string',' ');
                set(handles.text_C2,'enable','off');
                set(handles.text_C2v,'enable','off');
                set(handles.text_C2c,'enable','off');
                set(handles.text_C2sp,'enable','off');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.edit_C2,'enable','off','string',' ');
                set(handles.edit_C2v,'enable','off','string',' ');
                set(handles.edit_C2sp,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''closure'');');
                closX='None';
                assignin('base','closX',closX);
                evalin('base','mcr_als.alsOptions.closure.type=closX;');
                evalin('base','clear closX');
            
                set(handles.check_eqC,'enable','on','value',0);
                set(handles.text_eqCs,'enable','off');
                set(handles.popup_eqCs,'enable','off','value',1);
                set(handles.text_eqCc,'enable','off');
                set(handles.popup_eqCc,'enable','off','value',1);
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''cselcC'');');
            
                evalin('base','mcr_als.alsOptions.nonegC.noneg=0;');
                evalin('base','mcr_als.alsOptions.unimodC.unimodal=0;');
                evalin('base','mcr_als.alsOptions.closure.closure=0;');
                evalin('base','mcr_als.alsOptions.cselcC.cselcon=0;');
            
            end
            
            assignin('base','ccons',ccons);
            evalin('base','mcr_als.alsOptions.multi.ccons=ccons;');
            evalin('base','clear ccons');
        end

        % Value changed function: check_unimod
        function check_unimod_Callback(app, event)
            % --- Executes on button press in check_unimod.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_unimod (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_unimod
            
            unimodal=get(hObject,'Value');
            
            if unimodal==1;
                set(handles.text_Ui,'enable','on');
                set(handles.popup_Ui,'enable','on','value',1);
                set(handles.text_Up,'enable','on');
                set(handles.popup_Up,'enable','on','value',1);
                set(handles.text_Utol,'enable','on');
                set(handles.edit_Utol,'enable','on','string',' 1.1');
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string',' ');
            
                evalin('base','mcr_als.alsOptions.unimodC.spmod=[];');
                % default value of Utol
                rmod=1.1;
                assignin('base','rmod',rmod);
                evalin('base','mcr_als.alsOptions.unimodC.rmod=rmod;');
                evalin('base','clear rmod');
            
                evalin('base','mcr_als.alsOptions.unimodC.treated=zeros(mcr_als.alsOptions.multi.matc,2);');
            
            
            else
                set(handles.text_Ui,'enable','off');
                set(handles.popup_Ui,'enable','off','value',1);
                set(handles.text_Up,'enable','off');
                set(handles.popup_Up,'enable','off','value',1);
                set(handles.text_Utol,'enable','off');
                set(handles.edit_Utol,'enable','off','string',' ');
                set(handles.text_Uv,'enable','off');
                set(handles.edit_Uv,'enable','off','string',' ');
                evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''unimodC'');');
            end;
            
            assignin('base','unimodal',unimodal);
            evalin('base','mcr_als.alsOptions.unimodC.unimodal=unimodal;');
            evalin('base','clear unimodal');
        end

        % Value changed function: edit_C1
        function edit_C1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C1 as text
            %        str2double(get(hObject,'String')) returns contents of edit_C1 as a double
            
            form_tclos1= str2num(get(hObject,'String'));
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            tclos1=evalin('base','mcr_als.alsOptions.closure.tclos1');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    tclos1(1,[1:matc])=form_tclos1;
                end
            
            else
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    tclos1(1,curr_cmat)=form_tclos1;
                end
            end
            
            assignin('base','tclos1',tclos1);
            evalin('base','mcr_als.alsOptions.closure.tclos1=tclos1;');
            
            assignin('base','form_tclos1',form_tclos1);
            evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,3)=form_tclos1;');
            evalin('base','clear tclos1 matc curr_cmat form_tclos1');
        end

        % Value changed function: edit_C1sp
        function edit_C1sp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C1sp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C1sp as text
            %        str2double(get(hObject,'String')) returns contents of edit_C1sp as a double
            
            
            form_sclos1= str2num(get(hObject,'String'));
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            sclos1=evalin('base','mcr_als.alsOptions.closure.sclos1');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    for i=1:matc;
                        sclos1(i,:)=form_sclos1;
                    end
                end
            else
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    sclos1(curr_cmat,:)=form_sclos1;
                end
            end
            
            assignin('base','sclos1',sclos1);
            evalin('base','mcr_als.alsOptions.closure.sclos1=sclos1;');
            suma_sp=sum(form_sclos1);
            assignin('base','suma_sp',suma_sp);
            evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,5)=suma_sp;');
            evalin('base','clear sclos1 matc curr_cmat suma_sp');
        end

        % Value changed function: edit_C1v
        function edit_C1v_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C1v (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C1v as text
            %        str2double(get(hObject,'String')) returns contents of edit_C1v as a double
            
            vclos1=get(hObject,'String');
            form_vclos1=evalin('base',vclos1);
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            vclos1=evalin('base','mcr_als.alsOptions.closure.vclos1');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    vclos1([1:matc],1)=form_vclos1;
                end
            
            else
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    vclos1(curr_cmat,1)=form_vclos1;
                end
            
            end
            
            assignin('base','vclos1',vclos1);
            evalin('base','mcr_als.alsOptions.closure.vclos1=vclos1;');
            evalin('base','clear vclos1 matc curr_cmat');
        end

        % Value changed function: edit_C2
        function edit_C2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C2 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C2 as text
            %        str2double(get(hObject,'String')) returns contents of edit_C2 as a double
            
            form_tclos2= str2num(get(hObject,'String'));
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            tclos2=evalin('base','mcr_als.alsOptions.closure.tclos2');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    tclos2(1,[1:matc])=form_tclos2;
                end
            
            else
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    tclos2(1,curr_cmat)=form_tclos2;
                end
            
            end
            
            assignin('base','tclos2',tclos2);
            evalin('base','mcr_als.alsOptions.closure.tclos2=tclos2;');
            
            assignin('base','form_tclos2',form_tclos2);
            evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,6)=form_tclos2;');
            
            evalin('base','clear tclos2 matc curr_cmat form_tclos2');
        end

        % Value changed function: edit_C2sp
        function edit_C2sp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C2sp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C2sp as text
            %        str2double(get(hObject,'String')) returns contents of edit_C2sp as a double
            
            form_sclos2= str2num(get(hObject,'String'));
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            sclos2=evalin('base','mcr_als.alsOptions.closure.sclos2');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    for i=1:matc;
                        sclos2(i,:)=form_sclos2;
                    end
                end
            
            else
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    sclos2(curr_cmat,:)=form_sclos2;
                end
            end
            
            assignin('base','sclos2',sclos2);
            evalin('base','mcr_als.alsOptions.closure.sclos2=sclos2;');
            
            suma_sp=sum(form_sclos2);
            assignin('base','suma_sp',suma_sp);
            evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,8)=suma_sp;');
            
            evalin('base','clear sclos2 matc curr_cmat form_sclos2 suma_sp');
        end

        % Value changed function: edit_C2v
        function edit_C2v_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_C2v (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_C2v as text
            %        str2double(get(hObject,'String')) returns contents of edit_C2v as a double
            
            vclos2=get(hObject,'String');
            form_vclos2=evalin('base',vclos2);
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            vclos2=evalin('base','mcr_als.alsOptions.closure.vclos2');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    vclos2([1:matc],1)=form_vclos2;
                end
            
            else
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    vclos2(curr_cmat,1)=form_vclos2;
                end
            
            end
            
            assignin('base','vclos2',vclos2);
            evalin('base','mcr_als.alsOptions.closure.vclos2=vclos2;');
            evalin('base','clear vclos2 matc curr_cmat');
        end

        % Value changed function: edit_Utol
        function edit_Utol_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_Utol (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_Utol as text
            %        str2double(get(hObject,'String')) returns contents of edit_Utol as a double
            
            rmod= str2num(get(hObject,'String'));
            
            if rmod==1,
                rmod=1.0001;
            end
            
            assignin('base','rmod',rmod);
            evalin('base','mcr_als.alsOptions.unimodC.rmod=rmod;');
            evalin('base','clear rmod');
        end

        % Value changed function: edit_Uv
        function edit_Uv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_Uv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_Uv as text
            %        str2double(get(hObject,'String')) returns contents of edit_Uv as a double
            
            form_spmod= str2num(get(hObject,'String'));
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            spmod=evalin('base','mcr_als.alsOptions.unimodC.spmod');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                for i=1:matc;
                    spmod(i,:)=form_spmod;
                end
            elseif evalin('base','mcr_als.alsOptions.multi.ccons')==0
                spmod(curr_cmat,:)=form_spmod;
            end
            
            assignin('base','spmod',spmod);
            evalin('base','mcr_als.alsOptions.unimodC.spmod=spmod;');
            evalin('base','clear spmod');
        end

        % Value changed function: edit_nnCp
        function edit_nnCp_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_nnCp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_nnCp as text
            %        str2double(get(hObject,'String')) returns contents of edit_nnCp as a double
            
            cnegr=str2num(get(hObject,'String'));
            
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            cneg=evalin('base','mcr_als.alsOptions.nonegC.cneg');
            assignin('base','curr_cmat',curr_cmat);
            cneg(curr_cmat,:)=cnegr;
            
            assignin('base','cneg',cneg);
            evalin('base','mcr_als.alsOptions.nonegC.cneg=cneg;');
            evalin('base','clear cneg');
        end

        % Value changed function: popup_C1c
        function popup_C1c_Callback(app, event)
            % --- Executes on selection change in popup_C1c.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_C1c (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_C1c contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_C1c
            
            form_iclos1=get(hObject,'Value')-1;
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            iclos1=evalin('base','mcr_als.alsOptions.closure.iclos1');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    iclos1(1,[1:matc])=form_iclos1;
                end
            
            else
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    iclos1(1,curr_cmat)=form_iclos1;
            
                end
            end
            
            assignin('base','iclos1',iclos1);
            evalin('base','mcr_als.alsOptions.closure.iclos1=iclos1;');
            
            assignin('base','form_iclos1',form_iclos1);
            evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,4)=form_iclos1;');
            evalin('base','clear iclos1 matc curr_cmat form_iclos1');
        end

        % Value changed function: popup_C2c
        function popup_C2c_Callback(app, event)
            % --- Executes on selection change in popup_C2c.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_C2c (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_C2c contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_C2c
            
            
            form_iclos2=get(hObject,'Value')-1;
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            iclos2=evalin('base','mcr_als.alsOptions.closure.iclos1');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    iclos2(1,[1:matc])=form_iclos2;
                end
            
            
            else
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    iclos2(1,curr_cmat)=form_iclos2;
                end
            
            end
            
            assignin('base','iclos2',iclos2);
            evalin('base','mcr_als.alsOptions.closure.iclos2=iclos2;');
            
            assignin('base','form_iclos2',form_iclos2);
            evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,7)=form_iclos2;');
            evalin('base','clear iclos2 matc curr_cmat form_iclos2');
        end

        % Value changed function: popup_Cnr
        function popup_Cnr_Callback(app, event)
            % --- Executes on selection change in popup_Cnr.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_Cnr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_Cnr contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_Cnr
            
            form_iclos=get(hObject,'Value')-2;
            
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            assignin('base','matc',matc);
            assignin('base','curr_cmat',curr_cmat);
            
            if form_iclos == 1;
                set(handles.check_Cvar,'enable','on','value',0);
            
                set(handles.text_C1,'enable','on');
                set(handles.edit_C1,'enable','on');
                set(handles.text_C1c,'enable','on');
                set(handles.popup_C1c,'enable','on','value',1);
                set(handles.text_C1sp,'enable','on');
                set(handles.edit_C1sp,'enable','on');
                set(handles.check_C1sp,'enable','on','value',0);
                set(handles.text_C1v,'enable','off');
                set(handles.edit_C1v,'enable','off','string',' ');
            
                set(handles.text_C2,'enable','off');
                set(handles.edit_C2,'enable','off','string',' ');
                set(handles.text_C2c,'enable','off');
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.text_C2sp,'enable','off');
                set(handles.edit_C2sp,'enable','off','string',' ');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.text_C2v,'enable','off');
                set(handles.edit_C2v,'enable','off','string',' ');
            
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,1)=1;');
            
            
            elseif  form_iclos==2
                warndlg('Warning: there should not be common species to the two closures','WARNING!!');
            
                set(handles.check_Cvar,'enable','on','value',0);
            
                set(handles.text_C1,'enable','on');
                set(handles.edit_C1,'enable','on');
                set(handles.text_C1c,'enable','on');
                set(handles.popup_C1c,'enable','on','value',1);
                set(handles.text_C1sp,'enable','on');
                set(handles.edit_C1sp,'enable','on');
                set(handles.check_C1sp,'enable','on','value',0);
                set(handles.text_C1v,'enable','off');
                set(handles.edit_C1v,'enable','off','string',' ');
            
                set(handles.text_C2,'enable','on');
                set(handles.edit_C2,'enable','on');
                set(handles.text_C2c,'enable','on');
                set(handles.popup_C2c,'enable','on','value',1);
                set(handles.text_C2sp,'enable','on');
                set(handles.edit_C2sp,'enable','on');
                set(handles.check_C2sp,'enable','on','value',0);
                set(handles.text_C2v,'enable','off');
                set(handles.edit_C2v,'enable','off','string',' ');
            
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,1)=1;');
            
            elseif form_iclos == 0;
                set(handles.check_Cvar,'enable','off','value',0);
            
                set(handles.text_C1,'enable','off');
                set(handles.edit_C1,'enable','off');
                set(handles.text_C1c,'enable','off');
                set(handles.popup_C1c,'enable','off','value',1);
                set(handles.text_C1sp,'enable','off');
                set(handles.edit_C1sp,'enable','off');
                set(handles.check_C1sp,'enable','off','value',0);
                set(handles.text_C1v,'enable','off');
                set(handles.edit_C1v,'enable','off','string',' ');
            
                set(handles.text_C2,'enable','off');
                set(handles.edit_C2,'enable','off');
                set(handles.text_C2c,'enable','off');
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.text_C2sp,'enable','off');
                set(handles.edit_C2sp,'enable','off');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.text_C2v,'enable','off');
                set(handles.edit_C2v,'enable','off','string',' ');
            
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,1)=1;');
            
            else
                set(handles.check_Cvar,'enable','off','value',0);
            
                set(handles.text_C1,'enable','off');
                set(handles.edit_C1,'enable','off');
                set(handles.text_C1c,'enable','off');
                set(handles.popup_C1c,'enable','off','value',1);
                set(handles.text_C1sp,'enable','off');
                set(handles.edit_C1sp,'enable','off');
                set(handles.check_C1sp,'enable','off','value',0);
                set(handles.text_C1v,'enable','off');
                set(handles.edit_C1v,'enable','off','string',' ');
            
                set(handles.text_C2,'enable','off');
                set(handles.edit_C2,'enable','off');
                set(handles.text_C2c,'enable','off');
                set(handles.popup_C2c,'enable','off','value',1);
                set(handles.text_C2sp,'enable','off');
                set(handles.edit_C2sp,'enable','off');
                set(handles.check_C2sp,'enable','off','value',0);
                set(handles.text_C2v,'enable','off');
                set(handles.edit_C2v,'enable','off','string',' ');
            
                evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,1)=0;');
            end
            
            % matr=evalin('base','mcr_als.alsOptions.multi.matr');
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            % curr_smat=evalin('base','mcr_als.alsOptions.multi.curr_smat');
            iclos=evalin('base','mcr_als.alsOptions.closure.iclos');
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    iclos(1,[1:matc])=form_iclos;
                end
            
            else
            
                if evalin('base','mcr_als.alsOptions.closure.dc')==1
                    iclos(1,curr_cmat)=form_iclos;
                end
            
            end
            
            assignin('base','iclos',iclos);
            evalin('base','mcr_als.alsOptions.closure.iclos=iclos;');
            assignin('base','form_iclos',form_iclos);
            evalin('base','mcr_als.alsOptions.closure.treated(curr_cmat,2)=form_iclos;');
            evalin('base','clear iclos curr_cmat matc');
        end

        % Value changed function: popup_Ui
        function popup_Ui_Callback(app, event)
            % --- Executes on selection change in popup_Ui.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_Ui (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_Ui contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_Ui
            
            cmod=get(hObject,'Value')-2;
            assignin('base','cmod',cmod);
            evalin('base','mcr_als.alsOptions.unimodC.cmod=cmod;');
            evalin('base','clear cmod');
        end

        % Value changed function: popup_Up
        function popup_Up_Callback(app, event)
            % --- Executes on selection change in popup_Up.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_Up (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_Up contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_Up
            
            
            nmod=get(hObject,'Value')-2;
            
            assignin('base','nmod',nmod);
            evalin('base','mcr_als.alsOptions.unimodC.nmod=nmod;');
            
            dim=evalin('base','min(size(mcr_als.alsOptions.iniesta));');
            nexp=evalin('base','mcr_als.alsOptions.nexp;');
            matc=evalin('base','mcr_als.alsOptions.multi.matc;');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat;');
            spmod=evalin('base','mcr_als.alsOptions.unimodC.spmod;');
            assignin('base','matc',matc);
            assignin('base','dim',dim);
            assignin('base','curr_cmat',curr_cmat);
            
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
            
                if nmod == dim
                    spmod=ones(matc,dim);
                    set(handles.text_Uv,'enable','off');
                    set(handles.edit_Uv,'enable','off','string','');
                elseif  nmod == 0
                    spmod=zeros(matc,dim);
                    set(handles.text_Uv,'enable','off');
                    set(handles.edit_Uv,'enable','off','string','');
                elseif  nmod == -1
                    spmod=[];
                    set(handles.text_Uv,'enable','off');
                    set(handles.edit_Uv,'enable','off','string','');
                else
                    spmod=[];
                    set(handles.text_Uv,'enable','on');
                    set(handles.edit_Uv,'enable','on');
                end
            
            
            elseif evalin('base','mcr_als.alsOptions.multi.ccons')==0
            
                if nmod == dim
                    form_spmod=ones(1,dim);
                    spmod(curr_cmat,:)=form_spmod;
                    set(handles.text_Uv,'enable','off');
                    set(handles.edit_Uv,'enable','off','string','');
                elseif  nmod == 0
                    form_spmod=zeros(1,dim);
                    spmod(curr_cmat,:)=form_spmod;
                    set(handles.text_Uv,'enable','off');
                    set(handles.edit_Uv,'enable','off','string','');
                elseif  nmod == -1
                    evalin('base','mcr_als.alsOptions.unimodC.spmod=[]');
                    set(handles.text_Uv,'enable','off');
                    set(handles.edit_Uv,'enable','off','string','');
                else
                    evalin('base','mcr_als.alsOptions.unimodC.spmod=[]');
                    set(handles.text_Uv,'enable','on');
                    set(handles.edit_Uv,'enable','on');
                end
            
                evalin('base','mcr_als.alsOptions.unimodC.treated(curr_cmat,1)=1;');
                evalin('base','mcr_als.alsOptions.unimodC.treated(curr_cmat,2)=nmod;');
            
            
            end
            
            assignin('base','spmod',spmod);
            evalin('base','mcr_als.alsOptions.unimodC.spmod=spmod;');
            evalin('base','clear spmod curr_cmat dim matc nmod');
        end

        % Value changed function: popup_eqCc
        function popup_eqCc_Callback(app, event)
            % --- Executes on selection change in popup_eqCc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_eqCc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_eqCc contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_eqCc
            
            type_csel=get(hObject,'Value')-2;
            assignin('base','type_csel',type_csel);
            evalin('base','mcr_als.alsOptions.cselcC.type_csel=type_csel;');
            evalin('base','clear type_csel');
        end

        % Value changed function: popup_eqCs
        function popup_eqCs_Callback(app, event)
            % --- Executes on selection change in popup_eqCs.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_eqCs (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_eqCs contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_eqCs
            
            popmenu3=get(handles.popup_eqCs,'String');
            pm3=get(handles.popup_eqCs,'Value');
            
            if pm3==1
            
            else
                selcsel_char=char([popmenu3(pm3)]);
                selcsel=evalin('base',selcsel_char);
                iisel=find(isfinite(selcsel));
            
                assignin('base','selcsel',selcsel);
                assignin('base','iisel',iisel);
                assignin('base','selcsel_char',selcsel_char);
            
                evalin('base','mcr_als.alsOptions.cselcC.iisel=iisel;');
                evalin('base','mcr_als.alsOptions.cselcC.csel=selcsel;');
                evalin('base','mcr_als.alsOptions.cselcC.selcsel_char=selcsel_char;');
            
                evalin('base','clear selcsel iisel selcsel_char');
            
            end
        end

        % Value changed function: popup_isp
        function popup_isp_Callback(app, event)
            % --- Executes on selection change in popup_isp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_isp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_isp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_isp
            
            popmenu3=get(handles.popup_isp,'String');
            pm3=get(handles.popup_isp,'Value');
            
            if pm3==1
            
            else
                ispmat_char=char([popmenu3(pm3)]);
                ispmat=evalin('base',ispmat_char);
            
                assignin('base','isp',ispmat);
                assignin('base','ispmat_char',ispmat_char);
            
                evalin('base','mcr_als.alsOptions.multi.isp=isp;');
                evalin('base','mcr_als.alsOptions.multi.ispmat_char=ispmat_char;');
            
                evalin('base','clear isp ispmat_char');
            
            end
        end

        % Value changed function: popup_matrixNr
        function popup_matrixNr_Callback(app, event)
            % --- Executes on selection change in popup_matrixNr.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_matrixNr (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_matrixNr contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_matrixNr
            
            nmat = get(hObject,'value');
            former_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            
            curr_cmat=nmat;
            assignin('base','curr_cmat',curr_cmat);
            evalin('base','mcr_als.alsOptions.multi.curr_cmat=curr_cmat;');
            evalin('base','clear curr_cmat');
            
            % start -> values that could be modified
            
            if get(handles.popup_nnCsp,'value') ~=1 & (former_cmat~=curr_cmat);
                treated=evalin('base','mcr_als.alsOptions.nonegC.treated');
                cneg=evalin('base','mcr_als.alsOptions.nonegC.cneg');
                if treated(1,3)==0
                    % forzed to zero
                    if treated(curr_cmat,1)==0
                        set(handles.popup_nnCsp,'value',1);
                        set(handles.edit_nnCp,'string',' ','enable','off');
                    else
                        valor=treated(curr_cmat,2)+2;
                        nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                        nsign=nsign+2;
                        set(handles.popup_nnCsp,'value',valor);
                        if valor==(0+2)
                            set(handles.edit_nnCp,'string',' ','enable','off');
                        elseif valor==(nsign)
                            set(handles.edit_nnCp,'string',' ','enable','off');
                        else
                            set(handles.edit_nnCp,'string',num2str(cneg(curr_cmat,:)),'enable','off');
                        end
            
                    end
                elseif treated(1,3)==1 | treated(1,3)==2
                    % nnls and fnnls
                    if treated(curr_cmat,1)==0
                        set(handles.popup_nnCsp,'value',1);
                        set(handles.edit_nnCp,'string',' ','enable','off');
                    else
                        valor=treated(curr_cmat,2)+2;
                        set(handles.popup_nnCsp,'value',valor);
                        set(handles.edit_nnCp,'string',' ','enable','off');
                    end
                end
            end
            
            if get(handles.popup_Up,'value') ~=1 & (former_cmat~=curr_cmat);
                treated=evalin('base','mcr_als.alsOptions.unimodC.treated');
                spmod=evalin('base','mcr_als.alsOptions.unimodC.spmod');
                if treated(curr_cmat,1)==0
                    set(handles.popup_Up,'value',1);
                    set(handles.edit_Uv,'string',' ','enable','off');
                else
                    valor=treated(curr_cmat,2)+2;
                    nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                    nsign=nsign+2;
                    set(handles.popup_Up,'value',valor);
                    if valor==(0+2)
                        set(handles.edit_Uv,'string',' ','enable','off');
                    elseif valor==(nsign)
                        set(handles.edit_Uv,'string',' ','enable','off');
                    else
                        set(handles.edit_Uv,'string',num2str(spmod(curr_cmat,:)),'enable','off');
                    end
            
                end
            end
            
            if get(handles.popup_Cnr,'value') ~=1 & (former_cmat~=curr_cmat);
                treated=evalin('base','mcr_als.alsOptions.closure.treated');
                sclos1=evalin('base','mcr_als.alsOptions.closure.sclos1');
                sclos2=evalin('base','mcr_als.alsOptions.closure.sclos2');
            
                if treated(curr_cmat,1)==0
                    set(handles.popup_Cnr,'value',1);
                    set(handles.edit_C1,'string',' ','enable','off');
                    set(handles.edit_C2,'string',' ','enable','off');
                    set(handles.edit_C1v,'string',' ','enable','off');
                    set(handles.edit_C2v,'string',' ','enable','off');
                    set(handles.popup_C1c,'value',1,'enable','off');
                    set(handles.popup_C2c,'value',1,'enable','off');
                    set(handles.edit_C1sp,'string',' ','enable','off');
                    set(handles.edit_C2sp,'string',' ','enable','off');
                    set(handles.check_C1sp,'value',0,'enable','off');
                    set(handles.check_C2sp,'value',0,'enable','off');
                    set(handles.check_Cvar,'value',0,'enable','off');
                else
                    % number of closures
                    clos_number=treated(curr_cmat,2);
                    if clos_number==0
                        set(handles.popup_Cnr,'value',clos_number+2);
                        set(handles.edit_C1,'string',' ','enable','off');
                        set(handles.edit_C2,'string',' ','enable','off');
                        set(handles.edit_C1v,'string',' ','enable','off');
                        set(handles.edit_C2v,'string',' ','enable','off');
                        set(handles.popup_C1c,'value',1,'enable','off');
                        set(handles.popup_C2c,'value',1,'enable','off');
                        set(handles.edit_C1sp,'string',' ','enable','off');
                        set(handles.edit_C2sp,'string',' ','enable','off');
                        set(handles.check_C1sp,'value',0,'enable','off');
                        set(handles.check_C2sp,'value',0,'enable','off');
                        set(handles.check_Cvar,'value',0,'enable','off');
                    elseif clos_number==1
                        set(handles.popup_Cnr,'value',clos_number+2);
                        set(handles.edit_C1,'string',num2str(treated(curr_cmat,3)),'enable','on');
                        set(handles.edit_C2,'string',' ','enable','off');
                        set(handles.edit_C1v,'string',' ','enable','off');
                        set(handles.edit_C2v,'string',' ','enable','off');
                        set(handles.popup_C1c,'value',treated(curr_cmat,4)+1,'enable','on');
                        set(handles.popup_C2c,'value',1,'enable','off');
                        set(handles.edit_C1sp,'string',num2str(sclos1(curr_cmat,:)),'enable','on');
                        set(handles.edit_C2sp,'string',' ','enable','off');
            
                        nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                        clos_sp1=treated(curr_cmat,5);
                        if nsign==clos_sp1
                            set(handles.check_C1sp,'value',1,'enable','on');
                        else
                            set(handles.check_C1sp,'value',0,'enable','on');
                        end
                        set(handles.check_C2sp,'value',0,'enable','off');
            
                        set(handles.check_Cvar,'value',0,'enable','off');
            
                    elseif clos_number==2
                        set(handles.popup_Cnr,'value',clos_number+2);
                        set(handles.edit_C1,'string',num2str(treated(curr_cmat,3)),'enable','on');
                        set(handles.edit_C2,'string',num2str(treated(curr_cmat,6)),'enable','on');
                        set(handles.edit_C1v,'string',' ','enable','off');
                        set(handles.edit_C2v,'string',' ','enable','off');
                        set(handles.popup_C1c,'value',treated(curr_cmat,4)+1,'enable','on');
                        set(handles.popup_C2c,'value',treated(curr_cmat,7)+1,'enable','on');
                        set(handles.edit_C1sp,'string',num2str(sclos1(curr_cmat,:)),'enable','on');
                        set(handles.edit_C2sp,'string',num2str(sclos2(curr_cmat,:)),'enable','on');
            
                        nsign=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
                        clos_sp1=treated(curr_cmat,5);
                        clos_sp2=treated(curr_cmat,8);
                        if nsign==clos_sp1
                            set(handles.check_C1sp,'value',1,'enable','on');
                        else
                            set(handles.check_C1sp,'value',0,'enable','on');
                        end
                        if nsign==clos_sp2
                            set(handles.check_C2sp,'value',1,'enable','on');
                        else
                            set(handles.check_C2sp,'value',0,'enable','on');
                        end
            
                        set(handles.check_Cvar,'value',0,'enable','off');
            
                    end
            
                end
            end
            
            
            % end -> values that could be modified
            
            assignin('base','curr_cmat',curr_cmat);
            evalin('base','mcr_als.alsOptions.multi.curr_cmat=curr_cmat;');
            evalin('base','clear curr_cmat');
        end

        % Value changed function: popup_nnCi
        function popup_nnCi_Callback(app, event)
            % --- Executes on selection change in popup_nnCi.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_nnCi (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_nnCi contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_nnCi
            
            ialg=get(hObject,'Value')-2;
            assignin('base','ialg',ialg);
            evalin('base','mcr_als.alsOptions.nonegC.ialg=ialg;');
            
            if ialg==-1
            
                set(handles.text_nnCsp,'enable','off');
                set(handles.popup_nnCsp,'enable','off'),
                set(handles.popup_nnCsp,'string','select...');
            
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string','');
            
            elseif ialg==0
            
                dim=evalin('base','min(size(mcr_als.alsOptions.iniesta));');
                j=2;
                llistannc(1)={'select...'};
                for i=0:1:dim;
                    llistnnc=[i];
                    llistannc(j)={llistnnc};
                    j=j+1;
                end;
            
                set(handles.text_nnCsp,'enable','on');
                set(handles.popup_nnCsp,'enable','on','value',1)
                set(handles.popup_nnCsp,'string',llistannc)
            
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string','');
            
            elseif (ialg==1 | ialg==2)
                dim=evalin('base','min(size(mcr_als.alsOptions.iniesta));');
                llistannc(1)={'select...'};
                llistnnc=[0 dim];
                llistannc(2)={llistnnc};
            
                set(handles.text_nnCsp,'enable','on');
                set(handles.popup_nnCsp,'enable','on','value',1)
                set(handles.popup_nnCsp,'string',llistannc)
            
                set(handles.text_nnCp,'enable','off');
                set(handles.edit_nnCp,'enable','off','string','');
            
            end
            
            evalin('base','mcr_als.alsOptions.nonegC.treated(:,3)=ialg;');
            
            evalin('base','clear ialg');
        end

        % Value changed function: popup_nnCsp
        function popup_nnCsp_Callback(app, event)
            % --- Executes on selection change in popup_nnCsp.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_nnCsp (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_nnCsp contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_nnCsp
            
            
            ncneg=get(hObject,'Value')-2;
            
            assignin('base','ncneg',ncneg);
            evalin('base','mcr_als.alsOptions.nonegC.ncneg=ncneg;');
            
            dim=evalin('base','min(size(mcr_als.alsOptions.iniesta))');
            matc=evalin('base','mcr_als.alsOptions.multi.matc');
            nexp=evalin('base','mcr_als.alsOptions.nexp');
            ialg=evalin('base','mcr_als.alsOptions.nonegC.ialg');
            curr_cmat=evalin('base','mcr_als.alsOptions.multi.curr_cmat');
            cneg=evalin('base','mcr_als.alsOptions.nonegC.cneg');
            assignin('base','matc',matc);
            assignin('base','dim',dim);
            assignin('base','curr_cmat',curr_cmat);
            
            if evalin('base','mcr_als.alsOptions.multi.ccons')==1
                if ialg == 0
                    if ncneg == dim;
                        cneg = ones(matc,dim);
                        set(handles.text_nnCp,'enable','off');
                        set(handles.edit_nnCp,'enable','off','string','');
                    elseif  ncneg == -1
                        cneg=zeros(matc,dim);
                        set(handles.text_nnCp,'enable','off');
                        set(handles.edit_nnCp,'enable','off','string','');
                    elseif  ncneg == 0
                        cneg = zeros(matc,dim);
                        set(handles.text_nnCp,'enable','off');
                        set(handles.edit_nnCp,'enable','off','string','');
                    else
                        cneg=[];
                        set(handles.text_nnCp,'enable','on');
                        set(handles.edit_nnCp,'enable','on');
                    end
                else
                    %         cneg = ones(nexp,dim);
                    if ncneg==0;
                        cneg = zeros(matc,dim);
                    elseif ncneg==1;
                        cneg = ones(matc,dim);
                    end
            
                end
            elseif evalin('base','mcr_als.alsOptions.multi.ccons')==0
                if ialg == 0
                    if ncneg == dim;
                        cnegr=ones(1,dim);
                        cneg(curr_cmat,:)=cnegr;
                        assignin('base','cneg',cneg);
                        evalin('base','mcr_als.alsOptions.nonegC.cneg = cneg;');
                        set(handles.text_nnCp,'enable','off');
                        set(handles.edit_nnCp,'enable','off','string','');
                    elseif  ncneg == -1
                        cnegr=zeros(1,dim);
                        cneg(curr_cmat,:)=cnegr;
                        assignin('base','cneg',cneg);
                        evalin('base','mcr_als.alsOptions.nonegC.cneg = cneg;');
                        set(handles.text_nnCp,'enable','off');
                        set(handles.edit_nnCp,'enable','off','string','');
                    elseif  ncneg == 0
                        cnegr=zeros(1,dim);
                        cneg(curr_cmat,:)=cnegr;
                        assignin('base','cneg',cneg);
                        evalin('base','mcr_als.alsOptions.nonegC.cneg = cneg;');
                        set(handles.text_nnCp,'enable','off');
                        set(handles.edit_nnCp,'enable','off','string','');
                    else
                        set(handles.text_nnCp,'enable','on');
                        set(handles.edit_nnCp,'enable','on');
                    end
                else
                    if ncneg==0;
                        form_cneg = zeros(1,dim);
                        cneg(curr_cmat,:)=form_cneg;
                    elseif ncneg==1;
                        form_cneg= ones(1,dim);
                        cneg(curr_cmat,:)=form_cneg;
                    end
                end
            
                evalin('base','mcr_als.alsOptions.nonegC.treated(curr_cmat,1)=1;');
                evalin('base','mcr_als.alsOptions.nonegC.treated(curr_cmat,2)=ncneg;');
            
            end
            
            
            assignin('base','cneg',cneg);
            evalin('base','mcr_als.alsOptions.nonegC.cneg=cneg;');
            evalin('base','clear cneg ncneg curr_cmat matc dim');
        end

        % Button pushed function: push_continue
        function push_continue_Callback(app, event)
            % --- Executes on button press in push_continue.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_continue (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
            if evalin('base','mcr_als.alsOptions.multi.datamod')==1
                columnModeConstraints_App;
            elseif evalin('base','mcr_als.alsOptions.multi.datamod')==3
                columnMultiConstraints_App;
            end
        end

        % Button pushed function: push_correlation
        function push_correlation_Callback(app, event)
            % --- Executes on button press in push_correlation.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_correlation (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            appCorrel=evalin('base','mcr_als.alsOptions.correlation.appCorrelation;');
            appEquality=evalin('base','mcr_als.alsOptions.cselcC.cselcon;');
            
            if appEquality==1
                warndlg('no és possible aplicar Equality i Correlation');
            else
                if appCorrel==0
                    correlCons_App;
                else
                    correlCons_reload_App;
                end
            end
        end

        % Button pushed function: push_kinetic
        function push_kinetic_Callback(app, event)
            % --- Executes on button press in push_kinetic.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_kinetic (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            kineticHM_multi_App;
        end

        % Button pushed function: push_multiway
        function push_multiway_Callback(app, event)
            % --- Executes on button press in push_multiway.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_multiway (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            appTril=evalin('base','mcr_als.alsOptions.trilin.appTril;');
            multiwayC_App;
        end

        % Button pushed function: push_reset
        function push_reset_Callback(app, event)
            % --- Executes on button press in push_reset.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_reset (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
            rowMultiConstraints_App;
        end

        % Button pushed function: push_ConstInfo
        function ConstInfo(app, event)
            ConstraintInfo
        end

        % Clicked callback: popup_eqCs, popup_isp
        function ClickedFcn(app, event)
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=2;
            lsv(1)={'select...'};
            for i=1:aa
                csize=length(lsvar(i).class);
                if csize==6
                    if lsvar(i).class=='double'
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end
                end
            end
            event.Source.Items = lsv;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Constraints: row mode (Concentrations)';
            app.figure1.Tag = 'figure1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Constraints: row mode (concentrations and multiple experiments)';
            app.uipanel1 = uibuttongroup(app.figure1);
            app.uipanel1.Title = 'Multimatriu';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'De moment, una matriu';
            app.uipanel2 = uibuttongroup(app.figure1);
            app.uipanel2.Title = 'Advanced constraints';
            app.uipanel2.Tag = 'uipanel2';
            app.push_multiway = uibutton(app.uipanel2, 'push');
            app.push_multiway.ButtonPushedFcn = createCallbackFcn(app, @push_multiway_Callback, true);
            app.push_multiway.Tag = 'push_multiway';
            app.push_multiway.Text = 'Multiway';
            app.push_kinetic = uibutton(app.uipanel2, 'push');
            app.push_kinetic.ButtonPushedFcn = createCallbackFcn(app, @push_kinetic_Callback, true);
            app.push_kinetic.Tag = 'push_kinetic';
            app.push_kinetic.Text = 'Kinetic HM';
            app.push_correlation = uibutton(app.uipanel2, 'push');
            app.push_correlation.ButtonPushedFcn = createCallbackFcn(app, @push_correlation_Callback, true);
            app.push_correlation.Tag = 'push_correlation';
            app.push_correlation.Text = 'Correlation';
            app.uipanel3 = uibuttongroup(app.figure1);
            app.uipanel3.Title = 'Constraints';
            app.uipanel3.Tag = 'uipanel3';
            app.uipanel4 = uibuttongroup(app.uipanel3);
            app.uipanel4.Title = 'Non-negativity';
            app.uipanel4.Tag = 'uipanel4';
            app.check_nnC = uicheckbox(app.uipanel4);
            app.check_nnC.ValueChangedFcn = createCallbackFcn(app, @check_nnC_Callback, true);
            app.check_nnC.Tag = 'check_nnC';
            app.check_nnC.Text = 'Apply?';
            app.text_nnCi = uilabel(app.uipanel4);
            app.text_nnCi.Tag = 'text_nnCi';
            app.text_nnCi.Enable = 'off';
            app.text_nnCi.Text = 'Implementation';
            app.text_nnCsp = uilabel(app.uipanel4);
            app.text_nnCsp.Tag = 'text_nnCsp';
            app.text_nnCsp.Enable = 'off';
            app.text_nnCsp.Text = 'Nr. of species with non-negative profiles?';
            app.popup_nnCsp = uidropdown(app.uipanel4);
            app.popup_nnCsp.Items = {'select...'};
            app.popup_nnCsp.ValueChangedFcn = createCallbackFcn(app, @popup_nnCsp_Callback, true);
            app.popup_nnCsp.Tag = 'popup_nnCsp';
            app.popup_nnCsp.Enable = 'off';
            app.popup_nnCsp.Value = 'select...';
            app.popup_nnCi = uidropdown(app.uipanel4);
            app.popup_nnCi.Items = {'select...', 'forced to zero', 'nnls', 'fnnls'};
            app.popup_nnCi.ValueChangedFcn = createCallbackFcn(app, @popup_nnCi_Callback, true);
            app.popup_nnCi.Tag = 'popup_nnCi';
            app.popup_nnCi.Enable = 'off';
            app.popup_nnCi.Value = 'select...';
            app.edit_nnCp = uieditfield(app.uipanel4, 'text');
            app.edit_nnCp.ValueChangedFcn = createCallbackFcn(app, @edit_nnCp_Callback, true);
            app.edit_nnCp.Tag = 'edit_nnCp';
            app.edit_nnCp.Enable = 'off';
            app.text_nnCp = uilabel(app.uipanel4);
            app.text_nnCp.Tag = 'text_nnCp';
            app.text_nnCp.Enable = 'off';
            app.text_nnCp.Text = 'Enter a vector of positive profiles:';
            app.uipanel6 = uipanel(app.uipanel3);
            app.uipanel6.Title = 'Unimodality';
            app.uipanel6.Tag = 'uipanel6';
            app.edit_Uv = uieditfield(app.uipanel6, 'text');
            app.edit_Uv.ValueChangedFcn = createCallbackFcn(app, @edit_Uv_Callback, true);
            app.edit_Uv.Tag = 'edit_Uv';
            app.edit_Uv.Enable = 'off';
            app.edit_Utol = uieditfield(app.uipanel6, 'text');
            app.edit_Utol.ValueChangedFcn = createCallbackFcn(app, @edit_Utol_Callback, true);
            app.edit_Utol.Tag = 'edit_Utol';
            app.edit_Utol.Enable = 'off';
            app.text_Uv = uilabel(app.uipanel6);
            app.text_Uv.Tag = 'text_Uv';
            app.text_Uv.Enable = 'off';
            app.text_Uv.Text = 'Enter a vector of unimodal profiles:';
            app.text_Utol = uilabel(app.uipanel6);
            app.text_Utol.Tag = 'text_Utol';
            app.text_Utol.Enable = 'off';
            app.text_Utol.Text = 'Constraint tolerance:';
            app.text_Ui = uilabel(app.uipanel6);
            app.text_Ui.Tag = 'text_Ui';
            app.text_Ui.Enable = 'off';
            app.text_Ui.Text = 'Implementation';
            app.popup_Ui = uidropdown(app.uipanel6);
            app.popup_Ui.Items = {'select...', 'vertical', 'horizontal', 'average'};
            app.popup_Ui.ValueChangedFcn = createCallbackFcn(app, @popup_Ui_Callback, true);
            app.popup_Ui.Tag = 'popup_Ui';
            app.popup_Ui.Enable = 'off';
            app.popup_Ui.Value = 'select...';
            app.check_unimod = uicheckbox(app.uipanel6);
            app.check_unimod.ValueChangedFcn = createCallbackFcn(app, @check_unimod_Callback, true);
            app.check_unimod.Tag = 'check_unimod';
            app.check_unimod.Text = 'Apply?';
            app.text_Up = uilabel(app.uipanel6);
            app.text_Up.Tag = 'text_Up';
            app.text_Up.Enable = 'off';
            app.text_Up.Text = 'Nr. of species with unimodal profiles?';
            app.popup_Up = uidropdown(app.uipanel6);
            app.popup_Up.Items = {'select...', '0', '1', '2', '3'};
            app.popup_Up.ValueChangedFcn = createCallbackFcn(app, @popup_Up_Callback, true);
            app.popup_Up.Tag = 'popup_Up';
            app.popup_Up.Enable = 'off';
            app.popup_Up.Value = 'select...';
            app.uipanel8 = uipanel(app.uipanel3);
            app.uipanel8.Title = 'Equality constraints';
            app.uipanel8.Tag = 'uipanel8';
            app.check_eqC = uicheckbox(app.uipanel8);
            app.check_eqC.ValueChangedFcn = createCallbackFcn(app, @check_eqC_Callback, true);
            app.check_eqC.Tag = 'check_eqC';
            app.check_eqC.Text = 'Apply?';
            app.text_eqCs = uilabel(app.uipanel8);
            app.text_eqCs.Tag = 'text_eqCs';
            app.text_eqCs.Enable = 'off';
            app.text_eqCs.Text = 'Select csel matrix:';
            app.popup_eqCs = uidropdown(app.uipanel8);
            app.popup_eqCs.Items = {'select a variable from the WS', 'DataDC', 'DataTime', 'Log10HistData', 'currentT50Times', 'exportT0_intensities', 'f', 'imageData', 'k', 'len', 'p', 'r'};
            app.popup_eqCs.ValueChangedFcn = createCallbackFcn(app, @popup_eqCs_Callback, true);
            app.popup_eqCs.Tag = 'popup_eqCs';
            app.popup_eqCs.Enable = 'off';
            app.popup_eqCs.ClickedFcn = createCallbackFcn(app, @ClickedFcn, true);
            app.popup_eqCs.Value = 'select a variable from the WS';
            app.text_eqCc = uilabel(app.uipanel8);
            app.text_eqCc.Tag = 'text_eqCc';
            app.text_eqCc.Enable = 'off';
            app.text_eqCc.Text = 'Constraints are:';
            app.popup_eqCc = uidropdown(app.uipanel8);
            app.popup_eqCc.Items = {'select...', 'equal to', 'lower than or equal to', 'greater than'};
            app.popup_eqCc.ValueChangedFcn = createCallbackFcn(app, @popup_eqCc_Callback, true);
            app.popup_eqCc.Tag = 'popup_eqCc';
            app.popup_eqCc.Enable = 'off';
            app.popup_eqCc.Value = 'select...';
            app.uipanel9 = uipanel(app.uipanel3);
            app.uipanel9.Title = 'Closure';
            app.uipanel9.Tag = 'uipanel9';
            app.check_closC = uicheckbox(app.uipanel9);
            app.check_closC.ValueChangedFcn = createCallbackFcn(app, @check_closC_Callback, true);
            app.check_closC.Tag = 'check_closC';
            app.check_closC.Text = 'Apply?';
            app.text_Cnr = uilabel(app.uipanel9);
            app.text_Cnr.Tag = 'text_Cnr';
            app.text_Cnr.Enable = 'off';
            app.text_Cnr.Text = 'Nr. of closure constraints to be included?';
            app.text_C1 = uilabel(app.uipanel9);
            app.text_C1.Tag = 'text_C1';
            app.text_C1.Enable = 'off';
            app.text_C1.Text = 'First closure constraint equal to:';
            app.text_C1v = uilabel(app.uipanel9);
            app.text_C1v.Tag = 'text_C1v';
            app.text_C1v.Enable = 'off';
            app.text_C1v.Text = 'First variable closure:';
            app.text_C1c = uilabel(app.uipanel9);
            app.text_C1c.Tag = 'text_C1c';
            app.text_C1c.Enable = 'off';
            app.text_C1c.Text = 'Closure condition:';
            app.text_C1sp = uilabel(app.uipanel9);
            app.text_C1sp.Tag = 'text_C1sp';
            app.text_C1sp.Enable = 'off';
            app.text_C1sp.Text = 'Which species are in 1st closure?';
            app.popup_C2c = uidropdown(app.uipanel9);
            app.popup_C2c.Items = {'select...', 'equal to', 'least-squares closure', 'lower than or equal to'};
            app.popup_C2c.ValueChangedFcn = createCallbackFcn(app, @popup_C2c_Callback, true);
            app.popup_C2c.Tag = 'popup_C2c';
            app.popup_C2c.Enable = 'off';
            app.popup_C2c.Value = 'select...';
            app.edit_C1 = uieditfield(app.uipanel9, 'text');
            app.edit_C1.ValueChangedFcn = createCallbackFcn(app, @edit_C1_Callback, true);
            app.edit_C1.Tag = 'edit_C1';
            app.edit_C1.Enable = 'off';
            app.edit_C1v = uieditfield(app.uipanel9, 'text');
            app.edit_C1v.ValueChangedFcn = createCallbackFcn(app, @edit_C1v_Callback, true);
            app.edit_C1v.Tag = 'edit_C1v';
            app.edit_C1v.Enable = 'off';
            app.popup_C1c = uidropdown(app.uipanel9);
            app.popup_C1c.Items = {'select...', 'equal to', 'least-squares closure', 'lower than or equal to'};
            app.popup_C1c.ValueChangedFcn = createCallbackFcn(app, @popup_C1c_Callback, true);
            app.popup_C1c.Tag = 'popup_C1c';
            app.popup_C1c.Enable = 'off';
            app.popup_C1c.Value = 'select...';
            app.edit_C1sp = uieditfield(app.uipanel9, 'text');
            app.edit_C1sp.ValueChangedFcn = createCallbackFcn(app, @edit_C1sp_Callback, true);
            app.edit_C1sp.Tag = 'edit_C1sp';
            app.edit_C1sp.Enable = 'off';
            app.check_C1sp = uicheckbox(app.uipanel9);
            app.check_C1sp.ValueChangedFcn = createCallbackFcn(app, @check_C1sp_Callback, true);
            app.check_C1sp.Tag = 'check_C1sp';
            app.check_C1sp.Enable = 'off';
            app.check_C1sp.Text = 'All';
            app.edit_C2 = uieditfield(app.uipanel9, 'text');
            app.edit_C2.ValueChangedFcn = createCallbackFcn(app, @edit_C2_Callback, true);
            app.edit_C2.Tag = 'edit_C2';
            app.edit_C2.Enable = 'off';
            app.text_C2 = uilabel(app.uipanel9);
            app.text_C2.Tag = 'text_C2';
            app.text_C2.Enable = 'off';
            app.text_C2.Text = 'Second closure constraint equal to:';
            app.text_C2v = uilabel(app.uipanel9);
            app.text_C2v.Tag = 'text_C2v';
            app.text_C2v.Enable = 'off';
            app.text_C2v.Text = 'Second variable closure:';
            app.edit_C2v = uieditfield(app.uipanel9, 'text');
            app.edit_C2v.ValueChangedFcn = createCallbackFcn(app, @edit_C2v_Callback, true);
            app.edit_C2v.Tag = 'edit_C2v';
            app.edit_C2v.Enable = 'off';
            app.text_C2c = uilabel(app.uipanel9);
            app.text_C2c.Tag = 'text_C2c';
            app.text_C2c.Enable = 'off';
            app.text_C2c.Text = 'Closure condition:';
            app.text_C2sp = uilabel(app.uipanel9);
            app.text_C2sp.Tag = 'text_C2sp';
            app.text_C2sp.Enable = 'off';
            app.text_C2sp.Text = 'Which species are in 2nd closure?';
            app.check_Cvar = uicheckbox(app.uipanel9);
            app.check_Cvar.ValueChangedFcn = createCallbackFcn(app, @check_Cvar_Callback, true);
            app.check_Cvar.Tag = 'check_Cvar';
            app.check_Cvar.Enable = 'off';
            app.check_Cvar.Text = 'Closure variable?';
            app.popup_Cnr = uidropdown(app.uipanel9);
            app.popup_Cnr.Items = {'select...', '0', '1', '2'};
            app.popup_Cnr.ValueChangedFcn = createCallbackFcn(app, @popup_Cnr_Callback, true);
            app.popup_Cnr.Tag = 'popup_Cnr';
            app.popup_Cnr.Enable = 'off';
            app.popup_Cnr.Value = 'select...';
            app.edit_C2sp = uieditfield(app.uipanel9, 'text');
            app.edit_C2sp.ValueChangedFcn = createCallbackFcn(app, @edit_C2sp_Callback, true);
            app.edit_C2sp.Tag = 'edit_C2sp';
            app.edit_C2sp.Enable = 'off';
            app.check_C2sp = uicheckbox(app.uipanel9);
            app.check_C2sp.ValueChangedFcn = createCallbackFcn(app, @check_C2sp_Callback, true);
            app.check_C2sp.Tag = 'check_C2sp';
            app.check_C2sp.Enable = 'off';
            app.check_C2sp.Text = 'All';
            app.push_continue = uibutton(app.figure1, 'push');
            app.push_continue.ButtonPushedFcn = createCallbackFcn(app, @push_continue_Callback, true);
            app.push_continue.Tag = 'push_continue';
            app.push_continue.Text = 'Continue';
            app.push_reset = uibutton(app.figure1, 'push');
            app.push_reset.ButtonPushedFcn = createCallbackFcn(app, @push_reset_Callback, true);
            app.push_reset.Tag = 'push_reset';
            app.push_reset.Text = 'Reset';
            app.uipanel15 = uipanel(app.figure1);
            app.uipanel15.Title = 'Multiexperiment Analysis';
            app.uipanel15.Tag = 'uipanel15';
            app.text_total = uilabel(app.uipanel15);
            app.text_total.Tag = 'text_total';
            app.text_total.Text = 'Total Nr. of Row submatrices';
            app.text_num = uilabel(app.uipanel15);
            app.text_num.Tag = 'text_num';
            app.text_num.Text = 'X';
            app.uipanel_Actual = uipanel(app.uipanel15);
            app.uipanel_Actual.Title = 'Augmented Matrix';
            app.uipanel_Actual.Tag = 'uipanel_Actual';
            app.text_MatrixNr = uilabel(app.uipanel_Actual);
            app.text_MatrixNr.Tag = 'text_MatrixNr';
            app.text_MatrixNr.Text = 'Matrix Nr.';
            app.popup_matrixNr = uidropdown(app.uipanel_Actual);
            app.popup_matrixNr.Items = {'1', '2', '3', '4', '5'};
            app.popup_matrixNr.ValueChangedFcn = createCallbackFcn(app, @popup_matrixNr_Callback, true);
            app.popup_matrixNr.Tag = 'popup_matrixNr';
            app.popup_matrixNr.Value = '1';
            app.check_sameC = uicheckbox(app.uipanel_Actual);
            app.check_sameC.ValueChangedFcn = createCallbackFcn(app, @check_sameC_Callback, true);
            app.check_sameC.Tag = 'check_sameC';
            app.check_sameC.Text = 'Apply the same constraints to all submatrices?';
            app.uipanel17 = uipanel(app.uipanel15);
            app.uipanel17.Title = 'Identification of species';
            app.uipanel17.Tag = 'uipanel17';
            app.check_ISPdefault = uicheckbox(app.uipanel17);
            app.check_ISPdefault.ValueChangedFcn = createCallbackFcn(app, @check_ISPdefault_Callback, true);
            app.check_ISPdefault.Tag = 'check_ISPdefault';
            app.check_ISPdefault.Text = 'Default: all species in all experiments';
            app.check_ISPdefault.Value = true;
            app.text26 = uilabel(app.uipanel17);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Correspondence among the species in the experiments';
            app.popup_isp = uidropdown(app.uipanel17);
            app.popup_isp.Items = {'select...', 'DataDC', 'DataTime', 'Log10HistData', 'currentT50Times', 'exportT0_intensities', 'f', 'imageData', 'k', 'len', 'p', 'r'};
            app.popup_isp.ValueChangedFcn = createCallbackFcn(app, @popup_isp_Callback, true);
            app.popup_isp.Tag = 'popup_isp';
            app.popup_isp.Enable = 'off';
            app.popup_isp.ClickedFcn = createCallbackFcn(app, @ClickedFcn, true);
            app.popup_isp.Value = 'select...';
            app.text_popISP = uilabel(app.uipanel17);
            app.text_popISP.Tag = 'text_popISP';
            app.text_popISP.Enable = 'off';
            app.text_popISP.Text = 'Select a variable from the WS:';
            app.push_ConstInfo = uibutton(app.figure1, 'push');
            app.push_ConstInfo.ButtonPushedFcn = createCallbackFcn(app, @ConstInfo, true);
            app.push_ConstInfo.Tag = 'push_alsInfo';
            app.push_ConstInfo.Icon = fullfile(pathToMLAPP, 'info-circle-regular-24.png');
            app.push_ConstInfo.Text = '';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = rowMultiConstraints_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)rowMultiConstraints_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end