classdef svd_selection_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1    matlab.ui.Figure
        text7      matlab.ui.control.Label
        text6      matlab.ui.control.Label
        text5      matlab.ui.control.Label
        text2      matlab.ui.control.Label
        push_cont  matlab.ui.control.Button
        edit_svd   matlab.ui.control.EditField
        text1      matlab.ui.control.Label
        uipanel1   matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function svd_selection_OpeningFcn(app, varargin)
            % --- Executes just before svd_selection is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to svd_selection (see VARARGIN)
            
            % Choose default command line output for svd_selection
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
        end

        % Value changed function: edit_svd
        function edit_svd_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_svd (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_svd as text
            %        str2double(get(hObject,'String')) returns contents of edit_svd as a double
            
            
            nrsvd=str2num(get(hObject,'String'));
            assignin('base','nrsvd',nrsvd);
            evalin('base','mcr_als.aux.nrsvd=nrsvd;');
            evalin('base','clear nrsvd');
        end

        % Button pushed function: push_cont
        function push_cont_Callback(app, event)
            % --- Executes on button press in push_cont.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_cont (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            evalin('base','mcr_als.aux.estat_svd=0;');
            close;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Maximum number of singular values';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Write the number of singular valuest to be calculated by SVD:';
            app.edit_svd = uieditfield(app.figure1, 'text');
            app.edit_svd.ValueChangedFcn = createCallbackFcn(app, @edit_svd_Callback, true);
            app.edit_svd.Tag = 'edit_svd';
            app.push_cont = uibutton(app.figure1, 'push');
            app.push_cont.ButtonPushedFcn = createCallbackFcn(app, @push_cont_Callback, true);
            app.push_cont.Tag = 'push_cont';
            app.push_cont.Text = 'Continue';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Singular values limit selection ';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'One of the dimensions of the considered matrix is large';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = '(more than 10000 elements).';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'To avoid computer memory problems, calculation of only a few singular values is recommended.';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = svd_selection_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)svd_selection_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end