classdef svdgui_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1      matlab.ui.Figure
        push_canc    matlab.ui.control.Button
        uipanel2     matlab.ui.container.Panel
        edit_nc      matlab.ui.control.EditField
        text7        matlab.ui.control.Label
        text6        matlab.ui.control.Label
        push_print   matlab.ui.control.Button
        push_copy    matlab.ui.control.Button
        push_OK      matlab.ui.control.Button
        text5        matlab.ui.control.Label
        text3        matlab.ui.control.Label
        text2        matlab.ui.control.Label
        text1        matlab.ui.control.Label
        check_norm   matlab.ui.control.CheckBox
        list_eigval  matlab.ui.control.ListBox
        svectors     matlab.ui.control.UIAxes
        svalues      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function svdgui_OpeningFcn(app, varargin)
            % --- Executes just before svdgui is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to svdgui (see VARARGIN)


            % Choose default command line output for svdgui
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % matriu_svd=evalin('base','mcr_als.data');
            % [a,b]=size(matriu_svd);
            % evalin('base','mcr_als.aux.estat_svd=1;');
            % status=1;
            %
            % if (a>10000 | b>10000)
            %     svd_selection;
            %     while evalin('base','mcr_als.aux.estat_svd')==1
            % pause(0.3);
            %     end
            %     nrsvd=evalin('base','mcr_als.aux.nrsvd;');
            %     [U,S,V]=svds(matriu_svd,nrsvd);
            % else
            %     [U,S,V]=svd(matriu_svd,'econ');
            % end
            axes(handles.svalues);
            S=evalin('base','mcr_als.CompNumb.S;');
            plot(diag(S),'o');
            zoom on;
        end

        % Value changed function: check_norm
        function check_norm_Callback(app, event)
            % --- Executes on button press in check_norm.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_norm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_norm
            
            pon=get(handles.check_norm,'Value');
            
            if pon==1;
                axes(handles.svalues);
                evalin('base',' plot(diag(mcr_als.CompNumb.S)/max(diag(mcr_als.CompNumb.S)),''o'')');
                zoom on;
            else
                axes(handles.svalues);
                evalin('base',' plot(diag(mcr_als.CompNumb.S),''o'')');
                zoom on;
            end
        end

        % Value changed function: list_eigval
        function list_eigval_Callback(app, event)
            % --- Executes on selection change in list_eigval.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to list_eigval (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = get(hObject,'String') returns list_eigval contents as cell array
            %        contents{} returns selected item from list_eigval
            
            U=evalin('base','mcr_als.CompNumb.U');
            S=evalin('base','mcr_als.CompNumb.S');
            ns=get(hObject,'Value');
            axes(handles.svectors);
            plot(U(:,1:ns));
            zoom on;
            
            normx=get(handles.check_norm,'Value');
            x=diag(S);
            axes(handles.svalues);
            if normx==1;
            plot(x/max(x),'o');
            elseif normx==0;
                plot(x,'o');
            end
            zoom on;
            
            set(handles.edit_nc,'string',num2str(ns));
            set(handles.push_OK,'enable','on');
            assignin('base','nc',ns);
            evalin('base','mcr_als.CompNumb.nc=nc;');
            evalin('base','clear nc');
        end

        % Button pushed function: push_OK
        function push_OK_Callback(app, event)
            % Butons
            % ************************************************************************
            % --- Executes on button press in push_OK.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_OK (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=2;');
            evalin('base','mcr_als.CompNumb.method=''SVD'';');
            close;
        end

        % Button pushed function: push_canc
        function push_canc_Callback(app, event)
            % --- Executes on button press in push_canc.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_canc (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.aux.estat=3;');
            evalin('base','mcr_als=rmfield(mcr_als,''CompNumb'');');
            close;
        end

        % Button pushed function: push_copy
        function push_copy_Callback(app, event)
            % --- Executes on button press in push_copy.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_copy (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            print -dbitmap;
        end

        % Button pushed function: push_print
        function push_print_Callback(app, event)
            % --- Executes on button press in push_print.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_print (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            set(gcf,'PaperPositionMode','auto');
            print('SVDGUI','-dpdf');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Multivariate Curve Resolution Toolbox - Estimation of the number of components';
            app.figure1.Tag = 'figure1';
            app.svalues = uiaxes(app.figure1);
            app.svalues.Tag = 'svalues';
            app.svectors = uiaxes(app.figure1);
            app.svectors.Tag = 'svectors';
            app.list_eigval = uilistbox(app.figure1);
            app.list_eigval.Items = {'17.3039', '0.265942', '0.222166', '0.0378958', '0.0368988', '0.0360168', '0.0351808', '0.0348865', '0.0347416', '0.0345254', '0.0339571', '0.0337963', '0.0333754', '0.0325476', '0.0322466', '0.0318815', '0.0317693', '0.0314414', '0.0309753', '0.0303298', '0.0302073', '0.0298695', '0.0296976', '0.0295795', '0.0292835', '0.0288094', '0.0283778', '0.0280977', '0.0279683', '0.0278558', '0.0276292', '0.0272647', '0.0271406', '0.0269411', '0.0266139', '0.0263781', '0.0261838', '0.0261497', '0.0256947', '0.0256063', '0.0253144', '0.025218', '0.0248805', '0.0246295', '0.0244634', '0.0239302', '0.0238594', '0.023457', '0.0232752', '0.0230774', '0.0229251', '0.022734', '0.0225847', '0.0220438', '0.0218791', '0.0215144', '0.0212935', '0.0212415', '0.0209228', '0.020819', '0.0203396', '0.0202009', '0.019993', '0.0198201', '0.0197197', '0.0194383', '0.0192783', '0.0190546', '0.0187929', '0.0184177', '0.018197', '0.0179159', '0.0177985', '0.0176241', '0.0174703', '0.0172304', '0.0170535', '0.0168097', '0.0164793', '0.0161547', '0.0156657', '0.015548', '0.0154645', '0.0153428', '0.0150604', '0.0147095', '0.0144939', '0.0143647', '0.0142319', '0.0137333', '0.0133717', '0.0132515', '0.012957', '0.0127669', '0.0123894', '0.0121408', '0.0119405', '0.0113214', '0.0113005', '0.0105686'};
            app.list_eigval.ValueChangedFcn = createCallbackFcn(app, @list_eigval_Callback, true);
            app.list_eigval.Tag = 'list_eigval';
            app.list_eigval.Value = '17.3039';
            app.check_norm = uicheckbox(app.figure1);
            app.check_norm.ValueChangedFcn = createCallbackFcn(app, @check_norm_Callback, true);
            app.check_norm.Tag = 'check_norm';
            app.check_norm.Text = 'SVD Normalization?';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'EigenValues';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'EIgenValues Representation';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'EigenVectors Representation';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Singular Value Decomposition';
            app.push_OK = uibutton(app.figure1, 'push');
            app.push_OK.ButtonPushedFcn = createCallbackFcn(app, @push_OK_Callback, true);
            app.push_OK.Tag = 'push_OK';
            app.push_OK.Enable = 'off';
            app.push_OK.Text = 'OK';
            app.push_copy = uibutton(app.figure1, 'push');
            app.push_copy.ButtonPushedFcn = createCallbackFcn(app, @push_copy_Callback, true);
            app.push_copy.Tag = 'push_copy';
            app.push_copy.Text = 'Copy';
            app.push_print = uibutton(app.figure1, 'push');
            app.push_print.ButtonPushedFcn = createCallbackFcn(app, @push_print_Callback, true);
            app.push_print.Tag = 'push_print';
            app.push_print.Text = 'Print';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Graphical Options';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Tag = 'uipanel2';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Selected Number Of Components:';
            app.edit_nc = uieditfield(app.uipanel2, 'text');
            app.edit_nc.Tag = 'edit_nc';
            app.edit_nc.Enable = 'off';
            app.edit_nc.Value = '0';
            app.push_canc = uibutton(app.figure1, 'push');
            app.push_canc.ButtonPushedFcn = createCallbackFcn(app, @push_canc_Callback, true);
            app.push_canc.Tag = 'push_canc';
            app.push_canc.Text = 'Cancel';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = svdgui_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)svdgui_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end