classdef trilinear_info_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        text_3R2        matlab.ui.control.Label
        text_3LOF       matlab.ui.control.Label
        text10          matlab.ui.control.Label
        text9           matlab.ui.control.Label
        text8           matlab.ui.control.Label
        text_2R2        matlab.ui.control.Label
        text_2LOF       matlab.ui.control.Label
        text5           matlab.ui.control.Label
        text4           matlab.ui.control.Label
        text3           matlab.ui.control.Label
        popup_species   matlab.ui.control.DropDown
        push_multiplot  matlab.ui.control.Button
        push_close      matlab.ui.control.Button
        push_export     matlab.ui.control.Button
        text2           matlab.ui.control.Label
        text1           matlab.ui.control.Label
        axes3           matlab.ui.control.UIAxes
        axes2           matlab.ui.control.UIAxes
        axes1           matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function trilinear_info_OpeningFcn(app, varargin)
            % --- Executes just before trilinear_info is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to trilinear_info (see VARARGIN)
            
            % Choose default command line output for trilinear_info
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);
            
            % UIWAIT makes trilinear_info wait for user response (see UIRESUME)
            % uiwait(handles.figure1);
            
            trildir=evalin('base','mcr_als.alsOptions.trilin.trildir;');
            if trildir==1
                uaug=evalin('base','mcr_als.alsOptions.resultats.optim_concs;');
                vaug=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
                d=evalin('base','mcr_als.alsOptions.matdad;');
            
                % multi row and col - trilinearity %07/07/21
                datamod=evalin('base','mcr_als.alsOptions.multi.datamod;');
                if datamod==1;
                    nmat=evalin('base','mcr_als.alsOptions.nexp;');  % equal to matr
                elseif datamod==2;
                    % no multiway constraints
                elseif datamod==3
                    nmat=evalin('base','mcr_als.alsOptions.multi.matr;');
                else
                end
            
            
            else
                warndlg('no trilinear constraint applied');
            end
            
            
            % [u1,u2,l,dc,dtc,dt]=umodeaug(copt,sopt,nmat,1,d);
            % umodeaug
            
            [nrt,ns]=size(uaug);
            [ns,nc]=size(vaug);
            nr=nrt/nmat;
            u1=[];u2=[];l=[];a1=[];a2=[];st1=[];st2=[];
            
            for i=1:nmat
                inicrow=(i-1)*nr+1;
                endrow=i*nr;
                dt(:,:,i)=d(inicrow:endrow,:);
            end
            
            for j=1:ns
                m=[];
                for i=1:nmat
                    inicrow=(i-1)*nr+1;
                    endrow=i*nr;
                    m=[m,uaug(inicrow:endrow,j)];
                end
                [u,s,v]=svd(m);
                %log(s(1:4,1:4))
                %pause
                u1=[u1,u(:,1)*s(1,1)];
                u2=[u2,v(:,1)];
                if max(u1(:,j)<0) && max(u2(:,j)<0), u1(:,j)=-u1(:,j);u2(:,j)=-u2(:,j);end
            
                if nmat<nr;
                    l1=[];for il=1:nmat,l1=[l1;s(il,il)];end
                else
                    l1=[];for il=1:nr,l1=[l1;s(il,il)];end
                end
                l=[l,l1];
            end
            
            disp('check of trilineraity from svd of folded profiles: ');disp(l)
            
            % Test lofbil (x,uaug,vaug)
            disp(' ');disp('Test lof and R2 bilinear model')
            
            for i=1:nrt
                for j=1:nc
                    if isfinite(d(i,j))==1,
                        dc(i,j)=0;
                        for ls=1:ns
                            dc(i,j)=dc(i,j)+uaug(i,ls)*vaug(ls,j);
                        end
                    end
                end
            end
            
            
            ifn=find(isfinite(d)==1);
            res=d(ifn)-dc(ifn);
            sumd=sum(sum(d(ifn).*d(ifn)));
            sumdc=sum(sum(dc(ifn).*dc(ifn)));
            sumres=sum(sum(res.*res));
            
            loft=(sqrt(sumres/sumd))*100;
            r2t1=((sumdc)/sumd)*100;
            r2t2=((sumd-sumres)/sumd)*100;
            disp(['lof (%) = ',num2str(loft)]);
            
            disp(['R2 % = ',num2str(r2t2)]);
            
            set(handles.text_2LOF,'string',num2str(loft));
            set(handles.text_2R2,'string',num2str(r2t2));
            
            
            % Test loftril (x,u1,vaug',u2)
            
            disp(' ');disp('Test lof and R2 trilinear model')
            dtc=dt;
            for i=1:nr
                for j=1:nc
                    for k=1:nmat
                        if isfinite(dt(i,j,k))==1,
                            dtc(i,j,k)=0;
                            for ls=1:ns
                                dtc(i,j,k)=dtc(i,j,k)+u1(i,ls)*vaug(ls,j)*u2(k,ls);
                            end
                        end
                    end
                end
            end
            ifn=find(isfinite(dt)==1);
            res=dt(ifn)-dtc(ifn);
            sumdt=sum(sum(sum(dt(ifn).*dt(ifn))));
            sumdtc=sum(sum(sum(dtc(ifn).*dtc(ifn))));
            sumres=sum(sum(sum(res.*res)));
            % disp(['sstot,sscalc and ssres = ',num2str([sumdt,sumdtc,sumres])]);
            loft=(sqrt(sumres/sumdt))*100;
            r2t1=((sumdtc)/sumdt)*100;
            r2t2=((sumdt-sumres)/sumdt)*100;
            disp(['lof (%) = ',num2str(loft)]);
            
            disp(['R2 % = ',num2str(r2t2)]);
            
            set(handles.text_3LOF,'string',num2str(loft));
            set(handles.text_3R2,'string',num2str(r2t2));
            
            
            %save umodeaug variables
            
            assignin('base','u1TRIL',u1);
            evalin('base','mcr_als.aux.u1=u1TRIL;');
            evalin('base','clear u1TRIL');
            
            assignin('base','u2TRIL',u2);
            evalin('base','mcr_als.aux.u2=u2TRIL;');
            evalin('base','clear u2TRIL');
            
            assignin('base','vaugTRIL',vaug);
            evalin('base','mcr_als.aux.vaug=vaugTRIL;');
            evalin('base','clear vaugTRIL');
            
            assignin('base','lTRIL',l);
            evalin('base','mcr_als.aux.l=lTRIL;');
            evalin('base','clear lTRIL');
            
            assignin('base','dcTRIL',dc);
            evalin('base','mcr_als.aux.dc=dcTRIL;');
            evalin('base','clear dcTRIL');
            
            assignin('base','dtcTRIL',dtc);
            evalin('base','mcr_als.aux.dtc=dtcTRIL;');
            evalin('base','clear dtcTRIL');
            
            assignin('base','dtTRIL',dt);
            evalin('base','mcr_als.aux.dt=dtTRIL;');
            evalin('base','clear dtTRIL');
        end

        % Value changed function: popup_species
        function popup_species_Callback(app, event)
            % --- Executes on selection change in popup_species.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_species (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_species contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_species
            
            valorpop=get(hObject,'Value')-1;
            
            if valorpop>0
            
                u1=evalin('base','mcr_als.aux.u1;');
                u2=evalin('base','mcr_als.aux.u2;');
                vaug=evalin('base','mcr_als.aux.vaug;');
            
                % mode 1
                axes(handles.axes1);
                plot(u1(:,valorpop));
                mm=length(u1);
                axis([0 mm+1 min(u1(:,valorpop))-0.1*min(u1(:,valorpop)) max(u1(:,valorpop))+0.1*max(u1(:,valorpop))]);
                title('Mode 1');
            
                % mode 2
                axes(handles.axes2);
                plot(vaug(valorpop,:));
                mm=length(vaug);
                axis([0 mm+1 min(vaug(valorpop,:))-0.1*min(vaug(valorpop,:)) max(vaug(valorpop,:))+0.1*max(vaug(valorpop,:))]);
                title('Mode 2');
            
                % mode 3
                axes(handles.axes3);
                plot(u2(:,valorpop));
                mm=length(u2);
                axis([0 mm+1 min(u2(:,valorpop))-0.1*min(u2(:,valorpop)) max(u2(:,valorpop))+0.1*max(u2(:,valorpop))]);
                title('Mode 3');
            
            else
            
                axes(handles.axes1);
                cla;
                axes(handles.axes2);
                cla;
                axes(handles.axes3);
                cla;
            end
        end

        % Button pushed function: push_close
        function push_close_Callback(app, event)
            % --- Executes on button press in push_close.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_close (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            close;
        end

        % Button pushed function: push_export
        function push_export_Callback(app, event)
            % --- Executes on button press in push_export.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_export (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            u1=evalin('base','mcr_als.aux.u1;');
            assignin('base','u1',u1);
            
            u2=evalin('base','mcr_als.aux.u2;');
            assignin('base','u2',u2);
            
            vaug=evalin('base','mcr_als.aux.vaug;');
            assignin('base','vaug',vaug);
        end

        % Button pushed function: push_multiplot
        function push_multiplot_Callback(app, event)
            % --- Executes on button press in push_multiplot.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_multiplot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % disp('plot for each component, estimation of profiles in three modes (using svd)')
            kk=0;
            % if iplot>2,u1=sgolayfilt(u1,2,49);end
            
            ns=evalin('base','mcr_als.CompNumb.nc');
            
            trildir=evalin('base','mcr_als.alsOptions.trilin.trildir;');
            if trildir==1
                u1=evalin('base','mcr_als.aux.u1;');
                u2=evalin('base','mcr_als.aux.u2;');
                vaug=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
            elseif trildir==2 % turned
                u1=evalin('base','mcr_als.aux.u1;');
                u2=evalin('base','mcr_als.aux.u2;');
                vaug=evalin('base','mcr_als.alsOptions.resultats.optim_concs;');vaug=vaug';
            elseif trildir==3
                u1=evalin('base','mcr_als.aux.u1;');
                u2=evalin('base','mcr_als.aux.u2;');
                vaug=evalin('base','mcr_als.alsOptions.resultats.optim_specs;');
            else
                warndlg('no trilinear constraint applied');
            end
            
            figure('Name','trilinear info multiplot');
            for k=1:ns
                kk=kk+1;subplot(ns,3,kk),plot(u1(:,k),'r')
                kk=kk+1;subplot(ns,3,kk),plot(vaug(k,:),'b')
                kk=kk+1;subplot(ns,3,kk),plot(u2(:,k),'g')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'Trilinear Information Results';
            app.figure1.Tag = 'figure1';
            app.axes1 = uiaxes(app.figure1);
            app.axes1.Tag = 'axes1';
            app.axes2 = uiaxes(app.figure1);
            app.axes2.Tag = 'axes2';
            app.axes3 = uiaxes(app.figure1);
            app.axes3.Tag = 'axes3';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Trilinear model information';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Select component number:';
            app.push_export = uibutton(app.figure1, 'push');
            app.push_export.ButtonPushedFcn = createCallbackFcn(app, @push_export_Callback, true);
            app.push_export.Tag = 'push_export';
            app.push_export.Tooltip = 'Export to WS variable u1, u2 and vaug. Other variables at mcr_als.aux (dt, dtc, dc and l)';
            app.push_export.Text = 'Save in WS';
            app.push_close = uibutton(app.figure1, 'push');
            app.push_close.ButtonPushedFcn = createCallbackFcn(app, @push_close_Callback, true);
            app.push_close.Tag = 'push_close';
            app.push_close.Text = 'Close';
            app.push_multiplot = uibutton(app.figure1, 'push');
            app.push_multiplot.ButtonPushedFcn = createCallbackFcn(app, @push_multiplot_Callback, true);
            app.push_multiplot.Tag = 'push_multiplot';
            app.push_multiplot.Text = 'Multiplot';
            app.popup_species = uidropdown(app.figure1);
            app.popup_species.Items = {'select a species...', 'Species nr. 1', 'Species nr. 2', 'Species nr. 3'};
            app.popup_species.ValueChangedFcn = createCallbackFcn(app, @popup_species_Callback, true);
            app.popup_species.Tag = 'popup_species';
            app.popup_species.Value = 'select a species...';
            app.text3 = uilabel(app.figure1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Bilinear model:';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Lof (%) =';
            app.text5 = uilabel(app.figure1);
            app.text5.Tag = 'text5';
            app.text5.Text = 'R2 (%) =';
            app.text_2LOF = uilabel(app.figure1);
            app.text_2LOF.Tag = 'text_2LOF';
            app.text_2LOF.Text = '0.00';
            app.text_2R2 = uilabel(app.figure1);
            app.text_2R2.Tag = 'text_2R2';
            app.text_2R2.Text = '0.00';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Trilinear model:';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Lof (%) =';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'R2 (%) =';
            app.text_3LOF = uilabel(app.figure1);
            app.text_3LOF.Tag = 'text_3LOF';
            app.text_3LOF.Text = '0.00';
            app.text_3R2 = uilabel(app.figure1);
            app.text_3R2.Tag = 'text_3R2';
            app.text_3R2.Text = '0.00';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = trilinear_info_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)trilinear_info_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end