classdef weighted_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel3         matlab.ui.container.Panel
        check_use        matlab.ui.control.CheckBox
        push_cancel      matlab.ui.control.Button
        uipanel2         matlab.ui.container.Panel
        text_repro       matlab.ui.control.Label
        push_plot        matlab.ui.control.Button
        edit_term        matlab.ui.control.EditField
        text_term        matlab.ui.control.Label
        push_done        matlab.ui.control.Button
        text1            matlab.ui.control.Label
        uipanel1         matlab.ui.container.Panel
        text_dim         matlab.ui.control.Label
        text10           matlab.ui.control.Label
        text11           matlab.ui.control.Label
        edit_conv        matlab.ui.control.EditField
        edit_iterations  matlab.ui.control.EditField
        text_conv        matlab.ui.control.Label
        text_iterations  matlab.ui.control.Label
        text6            matlab.ui.control.Label
        push_eval        matlab.ui.control.Button
        edit_dim         matlab.ui.control.EditField
        popup_stdm       matlab.ui.control.DropDown
        text2            matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function weighted_OpeningFcn(app, varargin)
            % --- Executes just before weighted is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            
            % This function has no output args, see OutputFcn.
            % hObject    handle to figure
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            % varargin   command line arguments to weighted (see VARARGIN)
            
            % Choose default command line output for weighted
            handles.output = hObject;
            
            % Update handles structure
            guidata(hObject, handles);

        end

        % Value changed function: check_use
        function check_use_Callback(app, event)
            % --- Executes on button press in check_use.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to check_use (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hint: get(hObject,'Value') returns toggle state of check_use
            
            valorW=get(hObject,'Value');
            assignin('base','valorW',valorW);
            evalin('base','mcr_als.alsOptions.weighted.constrained=valorW;');
            evalin('base','clear valorW');
        end

        % Value changed function: edit_conv
        function edit_conv_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_conv (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_conv as text
            %        str2double(get(hObject,'String')) returns contents of edit_conv as a double
            
            Wconv=str2num(get(hObject,'String'));
            assignin('base','Wconv',Wconv);
            evalin('base','mcr_als.alsOptions.weighted.conv_limit=Wconv;');
            evalin('base','clear Wconv');
        end

        % Value changed function: edit_iterations
        function edit_iterations_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to edit_iterations (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: get(hObject,'String') returns contents of edit_iterations as text
            %        str2double(get(hObject,'String')) returns contents of edit_iterations as a double
            
            Witerations=str2num(get(hObject,'String'));
            assignin('base','Witerations',Witerations);
            evalin('base','mcr_als.alsOptions.weighted.max_iterations=Witerations;');
            evalin('base','clear Witerations');
        end

        % Value changed function: popup_stdm
        function popup_stdm_Callback(app, event)
            % --- Executes on selection change in popup_stdm.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to popup_stdm (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % Hints: contents = cellstr(get(hObject,'String')) returns popup_stdm contents as cell array
            %        contents{get(hObject,'Value')} returns selected item from popup_stdm
            
            popmenu3=get(handles.popup_stdm,'String');
            pm3=get(handles.popup_stdm,'Value');
            
            if pm3==1
                set(handles.push_eval,'enable','off')
            else
                selec3=char([popmenu3(pm3)]);
                y_matrix=evalin('base',selec3);
                assignin('base','y_matrix',y_matrix);
                evalin('base','mcr_als.alsOptions.weighted.y_matrix=y_matrix;');
                evalin('base','clear y_matrix');
                assignin('base','y_matrix_name',selec3);
                evalin('base','mcr_als.alsOptions.weighted.y_matrix_name=y_matrix_name;');
                evalin('base','clear y_matrix_name');
                set(handles.push_eval,'enable','on')
            end
        end

        % Button pushed function: push_cancel
        function push_cancel_Callback(app, event)
            % --- Executes on button press in push_cancel.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_cancel (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions=rmfield(mcr_als.alsOptions,''weighted'');');
            evalin('base','mcr_als.alsOptions.weighted.appWeight=0;');
            evalin('base','mcr_als.aux.estat=3;');
            close;
        end

        % Button pushed function: push_done
        function push_done_Callback(app, event)
            % --- Executes on button press in push_done.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_done (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            evalin('base','mcr_als.alsOptions.weighted.appWeight=1;');
            check=evalin('base','mcr_als.alsOptions.weighted.constrained;');
            if check==0
                warndlg('Weighted data will not be used');
            else
                evalin('base','mcr_als.aux.estat=2;');
            close;
            end
        end

        % Button pushed function: push_eval
        function push_eval_Callback(app, event)
            % --- Executes on button press in push_eval.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_eval (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            % nsign
            nsign=evalin('base','mcr_als.CompNumb.nc;');
            
            % original data matrix
            matdad=evalin('base','mcr_als.data;');
            
            % maxim nr of iterations
            max_iterations=evalin('base','mcr_als.alsOptions.weighted.max_iterations;');
            
            % conv_limit
            conv_limit=evalin('base','mcr_als.alsOptions.weighted.conv_limit;');
            
            % error matrix
            y_matrix=evalin('base','mcr_als.alsOptions.weighted.y_matrix;');
            
            [m1,n1]=size(matdad);
            [m2,n2]=size(y_matrix);
            
            if (m1==m2) & (n1==n2)
            
                % execute
                set(handles.edit_term,'string',' Working','BackgroundColor',[1 .6 .2]);
                pause(1);
                [Ux,Sx,Vx,SOBJx,ErrFlagx] = mlpca(matdad,y_matrix,nsign,conv_limit,max_iterations);
                Xmat_weighted=Ux*Sx*Vx';
                assignin('base','Xmat_weighted',Xmat_weighted);
                evalin('base','mcr_als.alsOptions.weighted.Xmat_weighted=Xmat_weighted;');
                evalin('base','clear Xmat_weighted');
            
                assignin('base','ErrFlagx',ErrFlagx);
                evalin('base','mcr_als.alsOptions.weighted.ErrFlagx=ErrFlagx;');
                evalin('base','clear ErrFlagx');
            
                % buttons active
                set(handles.push_plot,'enable','on')
                set(handles.check_use,'enable','on')
            
                % alarm
                t=0:1/44000:3;sound(sin(440*2*pi*t),44000)
            
                if ErrFlagx == 0
                    set(handles.edit_term,'string','Normal termination (convergence)','BackgroundColor','green');
                else
                    set(handles.edit_term,'string','Maximum number of iterations exceeded','BackgroundColor','yellow');
                    set(handles.push_done,'visible','on');
                end
            
            else
                warndlg('dimensions error');
            end
        end

        % Button pushed function: push_plot
        function push_plot_Callback(app, event)
            % --- Executes on button press in push_plot.
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % hObject    handle to push_plot (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    structure with handles and user data (see GUIDATA)
            
            Xmat_weighted=evalin('base','mcr_als.alsOptions.weighted.Xmat_weighted;');
            figure;
            plot(Xmat_weighted');
        end

        % Clicked callback: popup_stdm
        function stdm_ClickedFcn(app, event)
%             item = event.InteractionInformation.Item;
            % popup_stdm_CreateFcn
            lsvar=evalin('base','whos');
            [aa,bb]=size(lsvar);
            j=2;
            lsv(1)={'select a variable'};
            for i=1:aa
                csize=length(lsvar(i).class);
                if csize==6
                    if lsvar(i).class=='double'
                        lsb=[lsvar(i).name];
                        lsv(j)={lsb};
                        j=j+1;
                    end
                end
            end
            app.popup_stdm.Items = lsv;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            colormap(app.figure1, 'parula');
            app.figure1.Name = 'MLPCA filtering options';
            app.figure1.Tag = 'figure1';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Standard Deviation Matrix';
            app.popup_stdm = uidropdown(app.uipanel1);
            app.popup_stdm.Items = {'select a variable', 'DataDC', 'DataTime', 'colormap', 'copt', 'currentT50Times', 'exportT0_intensities', 'kin_data', 'sopt', 'times', 'waves'};
            app.popup_stdm.ValueChangedFcn = createCallbackFcn(app, @popup_stdm_Callback, true);
            app.popup_stdm.Tag = 'popup_stdm';
            app.popup_stdm.ClickedFcn = createCallbackFcn(app, @stdm_ClickedFcn, true);
            app.popup_stdm.Value = 'select a variable';
            app.edit_dim = uieditfield(app.uipanel1, 'text');
            app.edit_dim.Tag = 'edit_dim';
            app.edit_dim.Value = '3';
            app.push_eval = uibutton(app.uipanel1, 'push');
            app.push_eval.ButtonPushedFcn = createCallbackFcn(app, @push_eval_Callback, true);
            app.push_eval.Tag = 'push_eval';
            app.push_eval.Enable = 'off';
            app.push_eval.Text = 'Evaluate';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Modify parameters:';
            app.text_iterations = uilabel(app.uipanel1);
            app.text_iterations.Tag = 'text_iterations';
            app.text_iterations.Text = 'Number of iterations';
            app.text_conv = uilabel(app.uipanel1);
            app.text_conv.Tag = 'text_conv';
            app.text_conv.Text = 'Convergence limit';
            app.edit_iterations = uieditfield(app.uipanel1, 'text');
            app.edit_iterations.ValueChangedFcn = createCallbackFcn(app, @edit_iterations_Callback, true);
            app.edit_iterations.Tag = 'edit_iterations';
            app.edit_iterations.Value = '200000';
            app.edit_conv = uieditfield(app.uipanel1, 'text');
            app.edit_conv.ValueChangedFcn = createCallbackFcn(app, @edit_conv_Callback, true);
            app.edit_conv.Tag = 'edit_conv';
            app.edit_conv.Value = '1e-10';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = '* depending on the size of the matrix, the nr of iterations and ';
            app.text10 = uilabel(app.uipanel1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'the convergence limit long time could be required';
            app.text_dim = uilabel(app.uipanel1);
            app.text_dim.Tag = 'text_dim';
            app.text_dim.Text = 'Dimensionality of the model';
            app.text1 = uilabel(app.figure1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Weighted Options';
            app.push_done = uibutton(app.figure1, 'push');
            app.push_done.ButtonPushedFcn = createCallbackFcn(app, @push_done_Callback, true);
            app.push_done.Tag = 'push_done';
            app.push_done.Text = 'Done';
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Results';
            app.uipanel2.Tag = 'uipanel2';
            app.text_term = uilabel(app.uipanel2);
            app.text_term.Tag = 'text_term';
            app.text_term.Text = 'Termination';
            app.edit_term = uieditfield(app.uipanel2, 'text');
            app.edit_term.Tag = 'edit_term';
            app.edit_term.Enable = 'off';
            app.push_plot = uibutton(app.uipanel2, 'push');
            app.push_plot.ButtonPushedFcn = createCallbackFcn(app, @push_plot_Callback, true);
            app.push_plot.Tag = 'push_plot';
            app.push_plot.Enable = 'off';
            app.push_plot.Text = 'Plot';
            app.text_repro = uilabel(app.uipanel2);
            app.text_repro.Tag = 'text_repro';
            app.text_repro.Text = 'Reproduced data';
            app.push_cancel = uibutton(app.figure1, 'push');
            app.push_cancel.ButtonPushedFcn = createCallbackFcn(app, @push_cancel_Callback, true);
            app.push_cancel.Tag = 'push_cancel';
            app.push_cancel.Text = 'Cancel';
            app.uipanel3 = uipanel(app.figure1);
            app.uipanel3.Title = 'Decison';
            app.uipanel3.Tag = 'uipanel3';
            app.check_use = uicheckbox(app.uipanel3);
            app.check_use.ValueChangedFcn = createCallbackFcn(app, @check_use_Callback, true);
            app.check_use.Tag = 'check_use';
            app.check_use.Enable = 'off';
            app.check_use.Text = 'Use weighted data?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = weighted_App(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)weighted_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end